classdef App_MatBase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        NLayerPESIntensityModelAppButton  matlab.ui.control.Button
        PhotoionizationAppButton       matlab.ui.control.Button
        IMFPCalculatorAppButton        matlab.ui.control.Button
        CrystallographyAppButton       matlab.ui.control.Button
        MaterialsDatabaseEditorButton  matlab.ui.control.Button
        AppTitle                       matlab.ui.control.Label
        AppButtons_GridLayout          matlab.ui.container.GridLayout
        APPINFO_Button                 matlab.ui.control.Button
        RESIZEUI_Button                matlab.ui.control.Button
        RESTART_Button                 matlab.ui.control.Button
        CLOSEFIGS_Button               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBase-v1.0.1 (procopios.constantinou@psi.ch)';
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            % Remove the UI handle and restart
            close(gcbf); run App_MatBase;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 440;
            screen_size(4) = 250;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
        end

        % Button pushed function: IMFPCalculatorAppButton
        function IMFPCalculatorAppButtonPushed(app, event)
            run App_MatBase_IMFP;
        end

        % Button pushed function: CrystallographyAppButton
        function CrystallographyAppButtonPushed(app, event)
            run App_MatBase_Crystallography;
        end

        % Button pushed function: PhotoionizationAppButton
        function PhotoionizationAppButtonPushed(app, event)
            run App_MatBase_Photoionization;
        end

        % Button pushed function: MaterialsDatabaseEditorButton
        function MaterialsDatabaseEditorButtonPushed(app, event)
            run App_MatBase_DatabaseEditor;
        end

        % Button pushed function: NLayerPESIntensityModelAppButton
        function NLayerPESIntensityModelAppButtonPushed(app, event)
            run App_MatBase_NLayerPESIntensities;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'MatBase Main Menu';
            app.MaterialsDatabaseEditorButton = uibutton(app.GridLayout, 'push');
            app.MaterialsDatabaseEditorButton.ButtonPushedFcn = createCallbackFcn(app, @MaterialsDatabaseEditorButtonPushed, true);
            app.MaterialsDatabaseEditorButton.Icon = 'Icon_MaterialsProperties.jpeg';
            app.MaterialsDatabaseEditorButton.Tooltip = {'Effortlessly manage the Materials Properties Database with this user-friendly app. Add, delete, and edit entries with ease, streamlining your workflow and enhancing the efficiency of other apps that utilize the database for calculations.'};
            app.MaterialsDatabaseEditorButton.Text = 'Materials Database Editor';
            app.CrystallographyAppButton = uibutton(app.GridLayout, 'push');
            app.CrystallographyAppButton.ButtonPushedFcn = createCallbackFcn(app, @CrystallographyAppButtonPushed, true);
            app.CrystallographyAppButton.Icon = 'Icon_Crystallography.jpeg';
            app.CrystallographyAppButton.Tooltip = {'Explore the intricacies of crystal structures with our powerful visualization app. View unit cells in both real and reciprocal space, calculate the Brillouin zone, and extract 2D slices for in-depth analysis. Unlock new insights and enhance your understanding with ease.'};
            app.CrystallographyAppButton.Text = 'Crystallography App';
            app.IMFPCalculatorAppButton = uibutton(app.GridLayout, 'push');
            app.IMFPCalculatorAppButton.ButtonPushedFcn = createCallbackFcn(app, @IMFPCalculatorAppButtonPushed, true);
            app.IMFPCalculatorAppButton.Icon = 'Icon_IMFP.jpeg';
            app.IMFPCalculatorAppButton.Tooltip = {'Achieve unparalleled precision in your calculations of electron inelastic mean free path with our app. Utilize a range of formalisms, including the Universal, TPP-2M, S1, and S2 methods, to achieve the most accurate results. Conveniently access material parameters from the Materials Properties Database or manually enter data for materials not included in the database. Enhance your workflow and achieve fast calculations with ease!'};
            app.IMFPCalculatorAppButton.Text = 'IMFP Calculator App';
            app.PhotoionizationAppButton = uibutton(app.GridLayout, 'push');
            app.PhotoionizationAppButton.ButtonPushedFcn = createCallbackFcn(app, @PhotoionizationAppButtonPushed, true);
            app.PhotoionizationAppButton.Icon = 'Icon_Photoionization.jpeg';
            app.PhotoionizationAppButton.Tooltip = {'Extract the binding energy spectrum, photoionization cross-sections, and photoionization asymmetry parameters of all elements between 1-118 with our powerful app. Easily identify the best core-levels to probe by finding the maximum cross-sections and gain a comprehensive overview of the core-level spectra of all elements!'};
            app.PhotoionizationAppButton.Text = 'Photoionization App';
            app.NLayerPESIntensityModelAppButton = uibutton(app.GridLayout, 'push');
            app.NLayerPESIntensityModelAppButton.ButtonPushedFcn = createCallbackFcn(app, @NLayerPESIntensityModelAppButtonPushed, true);
            app.NLayerPESIntensityModelAppButton.Icon = 'Icon_NLayerPESIntensities.jpeg';
            app.NLayerPESIntensityModelAppButton.Tooltip = {'A powerful tool for material analysis and XPS data interpretation. It allows users to create a sample stack of N-layers with varying materials and thicknesses, sourced from the comprehensive Materials Properties Database. The app further utilizes the Photoionisation Cross-Section and Asymmetry Database to extract core-levels, cross-sections, and asymmetry parameters for each layer. Using the  Beer-Lambert law, it calculates the PES intensities for each layer, modulated by the electron inelastic mean free path using the TPP-2M formalism, while considering the experimental geometry. This enables users to compare XPS depth profile data and accurately determine the layer thicknesses in their samples from XPS. A must-have tool for anyone working with XPS data!'};
            app.NLayerPESIntensityModelAppButton.Text = 'N-Layer PES Intensity Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_MatBase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end