classdef App_MatBase_NLayerPESIntensities < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Global_GridLayout             matlab.ui.container.GridLayout
        Main_GridLayout               matlab.ui.container.GridLayout
        Model_Panel                   matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        OptimiseSampleStack_Button    matlab.ui.control.Button
        Bulk_UITable                  matlab.ui.control.Table
        LayerDelete_Button            matlab.ui.control.Button
        Layer_UITable                 matlab.ui.control.Table
        LayerAdd_Button               matlab.ui.control.Button
        ViewSampleStack_Button        matlab.ui.control.Button
        ExportData_Button             matlab.ui.control.Button
        gamma_EditField               matlab.ui.control.NumericEditField
        gammadegEditFieldLabel        matlab.ui.control.Label
        ShowXPSData_CheckBox          matlab.ui.control.CheckBox
        ViewPESModel_Button           matlab.ui.control.Button
        theta_EditField               matlab.ui.control.EditField
        thetaphotoemissionangleLabel  matlab.ui.control.Label
        ImportXPSData_Button          matlab.ui.control.Button
        RunPESModel_Button            matlab.ui.control.Button
        hv_EditField                  matlab.ui.control.EditField
        hveVEditFieldLabel            matlab.ui.control.Label
        BUTTONS_GridLayout            matlab.ui.container.GridLayout
        AppButtons_GridLayout         matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        SAVE_Button                   matlab.ui.control.Button
        TRANSFER_Button               matlab.ui.control.Button
        APPINFO_Button                matlab.ui.control.Button
        RESIZEUI_Button               matlab.ui.control.Button
        RESTART_Button                matlab.ui.control.Button
        CLOSEFIGS_Button              matlab.ui.control.Button
        AppTitle                      matlab.ui.control.Label
        MAINMENU_GridLayout           matlab.ui.container.GridLayout
        MAINMENU_Button               matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % -- Variables associated with modelling the PES intensities
        Data_ModelPES   % MATLAB Data-Structure for saving
        hv              % scalar or Nx1 column vector of the input photon energy (eV)
        theta           % scalar of the angle between the emitted photoelectrons and the surface normal (degrees)
        gamma           % scalar of the angle between the incident x-rays and the analyser (degrees)
        tab_layers      % Table the contains all the surface layer material properties
        tab_bulk        % Table the contains all the bulk material properties 
        % -- Variables associated with optimizing XPS depth profile data
        Data_ImportedXPS    % XPS Data that is imported into the App
        show_xps_data       % true or false to overlay the imported XPS data
    end
    % Private functions available to the app only
    methods (Access = private)
        % -- Load the current version of the MPD to extract all materials
        function list_of_all_mats = extract_materials(app)
            MPD_PCC = load('MPD_PCC'); MPD_PCC = MPD_PCC.MPD_PCC;
            MPD_PCC = sortrows(MPD_PCC,'ID');
            list_of_all_mats = MPD_PCC.ATOM_SYMB';
        end
        
        % -- For a given element, extract all possible core-levels
        function list_of_all_cls = extract_CLs(app, element)
            [~, xsect, ~]   = get_pixsad_props(element);
            init_val        = xsect.Properties.VariableNames;
            list_of_all_cls     = {};
            for i = 1:length(init_val)
                list_of_all_cls{i} = char(string(element) + string(init_val{i}(1:end-4)));
            end
        end
        
        % -- For a given material, extract all elements it contains
        function elements = ele_from_mats(app, material)
            % 1 - Ensure the input is a char
            material = char(material);
            % 2 - Remove all numbers from the compound symbols
            material = material(isletter(material));
            % 3 - Convert it to a string and add a space at each capital letter
            up = isstrprop(material, 'upper');
            T  = blanks(length(material) + sum(up));
            T(cumsum(up + 1)) = material; T = string(T(2:end));
            elements = strsplit(T, ' ', 'CollapseDelimiters', false);
        end

        % -- Plot the sample stack to be determined
        function view_sample_stack(app)
            % -- Defining the sample stack to be modelled
            % - 1 - Defining the thickness of each layer
            col_name = app.tab_layers.Properties.VariableNames{2};
            lyr_thick = {}; lyr_thick = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % - 2 - Defining the material of each layer, from left to right (top-to-bottom)
            col_name = app.tab_layers.Properties.VariableNames{3};
            lyr_mat = {}; lyr_mat = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % - 3 - Defining the core-level being probed for each layer
            col_name = app.tab_layers.Properties.VariableNames{4};
            lyr_cls = {}; lyr_cls = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % -- Plotting the sample stack
            view_nlayer_sample(lyr_mat, lyr_thick);
        end

        % -- For the given system, extract the PES intensity breakdown
        function Data_ModelPES = extract_Data_ModelPES(app)
            % -- 1 - Defining the sample stack to be modelled
            % - 1.1 - Defining the thickness of each layer
            col_name = app.tab_layers.Properties.VariableNames{2};
            lyr_thick = {}; lyr_thick = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % - 1.2 - Defining the material of each layer, from left to right (top-to-bottom)
            col_name = app.tab_layers.Properties.VariableNames{3};
            lyr_mat = {}; lyr_mat = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % - 1.3 - Defining the core-level being probed for each layer
            col_name = app.tab_layers.Properties.VariableNames{4};
            lyr_cls = {}; lyr_cls = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % -- 2 - Defining the photoemission geometry
            pes_hv = [];
            if length(app.hv) == 1; pes_hv  = app.hv;
            else;                   pes_hv  = app.hv(1):1:app.hv(2);
            end
            pes_theta = [];
            if length(app.theta) == 1;  pes_theta  = app.theta;
            else;                       pes_theta  = app.theta(1):1:app.theta(2);
            end
            % -- 3 - Verify that the photon energy is a row vector and theta is a column vector
            if size(pes_theta, 2) > 1;  pes_theta = pes_theta'; end
            if size(pes_hv, 1) > 1;     pes_hv = pes_hv'; end
            pes_gamma = app.gamma;
            % -- 4 - Extracting the PES intensity breakdown
            Data_ModelPES    = nlayer_pes_model(lyr_mat, lyr_cls, lyr_thick, pes_hv, pes_theta, pes_gamma, 0);
            % -- 5 - Renormalising the PES intensities from excluded layers
            col_name = app.tab_layers.Properties.VariableNames{5};
            lyr_excl = {}; lyr_excl = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            if sum(cell2mat(lyr_excl)) ~= 0
                init_lyr_ints0 = Data_ModelPES.lyr_ints0;
                % - Setting the layer intensity to zero for all excluded layers
                for i = 1:length(lyr_excl)
                    if lyr_excl{i} == 1; init_lyr_ints0{i} = 0.*init_lyr_ints0{i}; end
                end
                % - Renormalising the PES intensities of remaining layers
                lyr_norm = zeros(size(init_lyr_ints0{1}));
                for i = 1:length(init_lyr_ints0); lyr_norm = lyr_norm + init_lyr_ints0{i}; end
                for i = 1:length(init_lyr_ints0); init_lyr_ints0{i} ./ lyr_norm; end
                Data_ModelPES.lyr_ints0 = init_lyr_ints0;
            end
        end

        % -- Plot the PES intensity breakdown
        function view_Data_ModelPES(app)
            if isempty(app.Data_ModelPES); fprintf('Cannot view as the model has not been run yet.\n');
            elseif app.show_xps_data == false; view_nlayer_pes_model(app.Data_ModelPES);
            elseif app.show_xps_data == true
                % -- Plot the PES model intensities
                fig = view_nlayer_pes_model(app.Data_ModelPES);
                % -- Plot the XPS depth profile data
                for i = 1:length(fig)
                    figure(fig{i});
                    subplot(1,3,[2,3]); hold on;
                    xps_data = table2array(app.Data_ImportedXPS.Data);
                    def_cols = flipud(num2cell([0.25,0.25,0.25;lines(size(xps_data,2)-2)], 2));
                    for j = 1:size(xps_data,2)-1
                        plot(xps_data(:,1), xps_data(:,j+1), 'rx', 'color', def_cols{j}, 'HandleVisibility','off');
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBase - N-Layer PES Intensity App (procopios.constantinou@psi.ch)';
            % Variables associated with modelling the PES intensities
            % - 1 - Loading the Layer UI table properties
            default_material = 'Si';
            % --- Initialising the table
            app.Layer_UITable.ColumnName        = {'Layer', 'Thickness (nm)', 'Material', 'Core-Level', 'Exclude'};
            app.Layer_UITable.Data              = {1,2,default_material,'Si2p', false};
            app.Layer_UITable.ColumnEditable    = [false,true,true,true,true];
            app.Layer_UITable.ColumnEditable    = [false,true,true,true,true];
            % --- Initialising the column properties
            materials   = extract_materials(app);
            core_levels = extract_CLs(app,default_material);
            app.Layer_UITable.ColumnFormat      = {'numeric', 'numeric', materials, core_levels, 'logical'};
            % - 2 - Loading the Bulk UI table properties
            % --- Initialising the table
            app.Bulk_UITable.ColumnName         = {'Layer', 'Thickness (nm)', 'Material', 'Core-Level', 'Exclude'};
            app.Bulk_UITable.Data               = {'Bulk', Inf, default_material, 'Si2p', false};
            app.Bulk_UITable.ColumnEditable     = [false,false,true,true,true];
            app.Bulk_UITable.ColumnEditable     = [false,false,true,true,true];
            % --- Initialising the column properties
            materials   = extract_materials(app);
            core_levels = extract_CLs(app,default_material);
            app.Bulk_UITable.ColumnFormat      = {'numeric', 'numeric', materials, core_levels, 'logical'};
            % - 4 - Assigning the variables
            app.Data_ModelPES = struct([]);
            hv_EditFieldValueChanged(app);
            theta_EditFieldValueChanged(app);
            gamma_EditFieldValueChanged(app);
            app.tab_layers  = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
            app.tab_bulk    = array2table(app.Bulk_UITable.Data, 'VariableNames', app.Bulk_UITable.ColumnName);

            % Variables associated with optimizing XPS depth profile data
            % - 1 - Deactivating UI elements that require data
            app.ShowXPSData_CheckBox.Enable = 'off';
            app.OptimiseSampleStack_Button.Enable = 'off';
            % - 2 - Assigning the variables
            app.Data_ImportedXPS = struct();
            app.show_xps_data = false;
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run App_MatBase;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            % Remove the UI handle and restart
            close(gcbf); run App_MatBase_NLayerPESIntensities; 
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 650;
            screen_size(4) = 450;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app.tab_layers); disp(app.tab_bulk);
            disp('Data_ModelPES : '); disp(app.Data_ModelPES);
            disp('Data_ImportedXPS : '); disp(app.Data_ImportedXPS);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'Data_ModelPES', app.Data_ModelPES);
        end

        % Button pushed function: SAVE_Button
        function SAVE_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...');
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            app.Data_ModelPES = extract_Data_ModelPES(app);
            dataStruc = app.Data_ModelPES; 
            save(char(save_fullfile), 'dataStruc', '-v7.3');
            disp('-> saved data : '); display(app.Data_ModelPES);
            close(wbar);
            %% 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Value changed function: hv_EditField
        function hv_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.hv_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            if isnan(data_entry); data_entry = round(sort(str2double(split(value,';'))), 4); end
            if isnan(data_entry); data_entry = round(sort(str2double(split(value,','))), 4); end
            % 2 - Validity check on input
            data_entry(isnan(data_entry)) = [];
            minVal = 10; maxVal = 1e6;
            % - If no entry is made, default to a single hv value
            phoEne = [];
            if isempty(data_entry); phoEne = [350,1250];
            % - If a single entry is made
            elseif length(data_entry) == 1
                if isnan(data_entry); phoEne = [350,1250];
                else
                    if data_entry < minVal; data_entry = minVal;
                    elseif data_entry > maxVal; data_entry = maxVal; 
                    end
                    phoEne = data_entry;
                end
            % - File through all entered values and extract relevant ones only
            else
                data_entry = data_entry(~isnan(data_entry));
                if isempty(data_entry); phoEne = [350,1250];
                else
                    for i = 1:2
                        if data_entry(i) < minVal; data_entry(i) = minVal;
                        elseif data_entry(i) > maxVal; data_entry(i) = maxVal; 
                        end
                        phoEne(i) = data_entry(i);
                    end
                end
            end
            % 3 - Assigning and printing the output
            app.hv = unique(sort(phoEne(:)));
            if length(app.hv) == 1
                app.hv_EditField.Value = string(app.hv); 
                fprintf("-- hv = " + string(app.hv) + " eV \n");
            elseif length(app.hv) == 2
                hvStr = sprintf('%.0f:' , app.hv); hvStr = hvStr(1:end-1);
                app.hv_EditField.Value = hvStr;
                fprintf("-- hv (eV): " + hvStr + " eV \n");
            end
        end

        % Value changed function: theta_EditField
        function theta_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.theta_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            if isnan(data_entry); data_entry = round(sort(str2double(split(value,';'))), 4); end
            if isnan(data_entry); data_entry = round(sort(str2double(split(value,','))), 4); end
            % 2 - Validity check on input
            data_entry(isnan(data_entry)) = [];
            minVal = 0; maxVal = 90;
            % - If no entry is made, default to a single hv value
            tht = [];
            if isempty(data_entry); tht = 0;
            % - If a single entry is made
            elseif length(data_entry) == 1
                if isnan(data_entry); tht = 0;
                else
                    if data_entry < minVal; data_entry = minVal;
                    elseif data_entry > maxVal; data_entry = maxVal; 
                    end
                    tht = data_entry;
                end
            % - File through all entered values and extract relevant ones only
            else
                data_entry = data_entry(~isnan(data_entry));
                if isempty(data_entry); tht = 0;
                else
                    for i = 1:2
                        if data_entry(i) < minVal; data_entry(i) = minVal;
                        elseif data_entry(i) > maxVal; data_entry(i) = maxVal; 
                        end
                        tht(i) = data_entry(i);
                    end
                end
            end
            % 3 - Assigning and printing the output
            app.theta = unique(sort(tht(:)));
            if length(app.theta) == 1
                app.theta_EditField.Value = string(app.theta); 
                fprintf("-- theta = " + string(app.theta) + " deg. \n");
            elseif length(app.theta) == 2
                thtStr = sprintf('%.0f:' , app.theta); thtStr = thtStr(1:end-1);
                app.theta_EditField.Value = thtStr;
                fprintf("-- theta = " + thtStr + " deg. \n");
            end
        end

        % Value changed function: gamma_EditField
        function gamma_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.gamma_EditField.Value;
            app.gamma   = value;
            % 2 - Assigning output and printing change
            fprintf("-- gamma = " + string(app.gamma) + " deg. \n");
        end

        % Button pushed function: LayerAdd_Button
        function LayerAdd_ButtonPushed(app, event)
            % 1 - Adding one new layer to the table
            layer_number    = app.Layer_UITable.Data(end,1); layer_number = layer_number{1};
            if layer_number ~= 4
                layer_number    = layer_number+1;
                material        = app.Layer_UITable.Data(end,3); material = material{1};
                clevel          = app.Layer_UITable.Data(end,4); clevel = clevel{1};
                exclusion       = app.Layer_UITable.Data(end,5); exclusion = exclusion{1};
                app.Layer_UITable.Data(end+1,:) = {layer_number, 0, material, clevel, exclusion};
                % 2 - Assigning and printing the output
                app.tab_layers  = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
                fprintf("-- Layer Added: N = " + string(layer_number) + " \n");
            else; fprintf("-- Currently only supports 4 layers maximum...\n");
            end
        end

        % Button pushed function: LayerDelete_Button
        function LayerDelete_ButtonPushed(app, event)
            % 1 - Removing one layer from the table
            layer_number    = app.Layer_UITable.Data(end,1); layer_number = layer_number{1};
            if layer_number ~= 1 
                app.Layer_UITable.Data(end,:) = []; 
                fprintf("-- Layer Removed: N = " + string(layer_number-1) + " \n");
            else; fprintf("-- Cannot remove uppermost layer...\n");
            end
            % 2 - Assigning the output
            app.tab_layers  = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
        end

        % Cell edit callback: Layer_UITable
        function Layer_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - Update the core-levels to reflect the new material
            if indices(2) == 3
                % -- Extract all elements that are in the selected material
                elements = ele_from_mats(app, newData);
                core_levels = {};
                for i = 1:length(elements)
                    T = extract_CLs(app, elements(i));
                    core_levels = horzcat(core_levels, T);
                end
                materials                           = extract_materials(app);
                app.Layer_UITable.ColumnFormat      = {'numeric', 'numeric', materials, core_levels};
                app.Layer_UITable.Data{indices(1),4}      = core_levels{1};
            end
            % 2 - Assigning the output
            if indices(2) == 2;     layer_text = sprintf("Layer %i, Thickness = %.2f nm", indices(1), newData);
            elseif indices(2) == 3; layer_text = sprintf("Layer %i, Material = %s", indices(1), newData);
            elseif indices(2) == 4; layer_text = sprintf("Layer %i, Core-Level = %s", indices(1), newData);
            elseif indices(2) == 5; layer_text = sprintf("Layer %i, Exclude Layer Contribution = %i", indices(1), newData);
            end
            app.tab_layers  = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
            fprintf(layer_text + " \n");
        end

        % Cell selection callback: Layer_UITable
        function Layer_UITableCellSelection(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            % 2 - Update the core-levels to reflect the new material
            if ~isempty(indices)
                if indices(2) == 4
                    material = app.Layer_UITable.Data{indices(1),3};
                    % -- Extract all elements that are in the selected material
                    elements = ele_from_mats(app, material);
                    core_levels = {};
                    for i = 1:length(elements)
                        T = extract_CLs(app, elements(i));
                        core_levels = horzcat(core_levels, T);
                    end
                    app.Layer_UITable.ColumnFormat{4}   = core_levels;
                end
            end
        end

        % Cell edit callback: Bulk_UITable
        function Bulk_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - Update the core-levels to reflect the new material
            if indices(2) == 3
                % -- Extract all elements that are in the selected material
                elements = ele_from_mats(app, newData);
                core_levels = {};
                for i = 1:length(elements)
                    T = extract_CLs(app, elements(i));
                    core_levels = horzcat(core_levels, T);
                end
                materials                       = extract_materials(app);
                app.Bulk_UITable.ColumnFormat   = {'numeric', 'numeric', materials, core_levels};
                app.Bulk_UITable.Data{indices(1),4}      = core_levels{1};
            end
            % 2 - Assigning the output
            if indices(2) == 3; layer_text = sprintf("Bulk, Material = %s", newData);
            elseif indices(2) == 4; layer_text = sprintf("Bulk, Core-Level = %s", newData);
            elseif indices(2) == 5; layer_text = sprintf("Bulk, Exclude Layer Contribution = %i", newData);
            end
            app.tab_bulk  = array2table(app.Bulk_UITable.Data, 'VariableNames', app.Bulk_UITable.ColumnName);
            fprintf(layer_text + " \n");
        end

        % Button pushed function: ViewSampleStack_Button
        function ViewSampleStack_ButtonPushed(app, event)
            view_sample_stack(app);
        end

        % Button pushed function: RunPESModel_Button
        function RunPESModel_ButtonPushed(app, event)
            % - 1 - Run the PES Model Calculator
            app.Data_ModelPES = extract_Data_ModelPES(app);
            % - 2 - View the PES Intensity Breakdown
            view_Data_ModelPES(app);
        end

        % Button pushed function: ViewPESModel_Button
        function ViewPESModel_ButtonPushed(app, event)
            view_Data_ModelPES(app);
        end

        % Button pushed function: ExportData_Button
        function ExportData_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.txt'};
            fname = 'tester';
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Append the PES intensities w.r.t photon energy
            % -- Range of photon energies data
            if length(app.Data_ModelPES.hv) > 1
                hv_dom = app.Data_ModelPES.hv';
                for j = 1:length(app.Data_ModelPES.theta)
                    HV_DATA = []; hv_int = [];
                    for i = 1:app.Data_ModelPES.Nlyrs
                        hv_int(:,i) = app.Data_ModelPES.lyr_ints0{i}(j,:);
                    end
                    HV_DATA = table(hv_dom,hv_int);
                    writetable(HV_DATA,char(string(save_fullfile(1:end-4) + sprintf("_theta=%ideg_vs_hv.txt",app.Data_ModelPES.theta(j)))),'Delimiter','\t'); 
                end
            end
            % -- Range of theta data
            if length(app.Data_ModelPES.theta) > 1
                tht_dom = app.Data_ModelPES.theta;
                for j = 1:length(app.Data_ModelPES.hv)
                    THT_DATA = []; tht_int = [];
                    for i = 1:app.Data_ModelPES.Nlyrs
                        tht_int(:,i) = app.Data_ModelPES.lyr_ints0{i}(:,j);
                    end
                    THT_DATA = table(tht_dom,tht_int);
                    writetable(THT_DATA, char(string(save_fullfile(1:end-4) + sprintf("_hv=%ieV_vs_theta.txt",app.Data_ModelPES.hv(j)))),'Delimiter','\t');
                end
            end
            % -- Singleton values of the photon energy and theta
            if length(app.Data_ModelPES.theta) == 1 && length(app.Data_ModelPES.hv) == 1
                hv  = app.Data_ModelPES.hv(1);
                tht = app.Data_ModelPES.theta(1);
                for i = 1:app.Data_ModelPES.Nlyrs
                    lyr_ints0(:,i) = app.Data_ModelPES.lyr_ints0{i};
                end
                temp_table = table(hv,tht, lyr_ints0);
                writetable(temp_table, char(string(save_fullfile(1:end-4) + sprintf("_hv=%ieV_theta=%ideg.txt",app.Data_ModelPES.hv,app.Data_ModelPES.theta))),'Delimiter','\t');
            end
            waitbar(1,'Save complete!');
        end

        % Button pushed function: ImportXPSData_Button
        function ImportXPSData_ButtonPushed(app, event)
            % 1 - Loading in the FileName and PathName of the selected data
            [FileName, PathName] = uigetfile({'*.txt'}, 'Pick a *.txt data file...');
            % - If Cancel is pressed, then return nothing
            if isequal(PathName,0) || isequal(FileName,0); return; end
            % -- Load in the file selected
            app.Data_ImportedXPS.PathName = PathName;
            app.Data_ImportedXPS.FileName = FileName;
            app.Data_ImportedXPS.Data = readtable(string(PathName)+string(FileName));
            % 2 - Changing the variable names for consistency
            app.Data_ImportedXPS.Data.Properties.VariableNames{1} = 'Variable';
            for i = 2:length(app.Data_ImportedXPS.Data.Properties.VariableNames)-1
                app.Data_ImportedXPS.Data.Properties.VariableNames{i} = char(sprintf("Layer_%i_Int",i-1));
            end
            app.Data_ImportedXPS.Data.Properties.VariableNames{end} = 'Bulk_Int';
             % 3 - Printing the output
            fprintf(sprintf("-- Imported XPS Depth Profile Data: %s", app.Data_ImportedXPS.FileName));
            app.Data_ImportedXPS.Data
            % - Activating UI elements
            app.ShowXPSData_CheckBox.Enable = 'on';
            app.OptimiseSampleStack_Button.Enable = 'on';
        end

        % Value changed function: ShowXPSData_CheckBox
        function ShowXPSData_CheckBoxValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.ShowXPSData_CheckBox.Value;
            app.show_xps_data   = value;
            % 2 - Assigning output and printing change
            fprintf("-- Show XPS Data? " + string(app.show_xps_data) + ". \n");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Global_GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.Global_GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.Global_GridLayout);
            app.AppTitle.Text = 'Modelling PES Intensities of an N-Layer System';
            app.BUTTONS_GridLayout = uigridlayout(app.Global_GridLayout);
            app.AppButtons_GridLayout = uigridlayout(app.BUTTONS_GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.SAVE_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.SAVE_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVE_ButtonPushed, true);
            app.SAVE_Button.Tooltip = {'Save the data from the app as a MATLAB data structure file.'};
            app.SAVE_Button.Text = 'Save As... (.mat)';
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.ImageSource = 'Icon_NLayerPESIntensities.jpeg';
            app.Main_GridLayout = uigridlayout(app.Global_GridLayout);
            app.hveVEditFieldLabel = uilabel(app.Main_GridLayout);
            app.hveVEditFieldLabel.Text = 'hv (eV):';
            app.hv_EditField = uieditfield(app.Main_GridLayout, 'text');
            app.hv_EditField.ValueChangedFcn = createCallbackFcn(app, @hv_EditFieldValueChanged, true);
            app.hv_EditField.Tooltip = {'Scalar or Nx1 column vector of the input photon energy (eV)'};
            app.hv_EditField.Value = '350:1250';
            app.RunPESModel_Button = uibutton(app.Main_GridLayout, 'push');
            app.RunPESModel_Button.ButtonPushedFcn = createCallbackFcn(app, @RunPESModel_ButtonPushed, true);
            app.RunPESModel_Button.Tooltip = {'Run the N-Layer PES Model to calculate PES Intensities.'};
            app.RunPESModel_Button.Text = 'Run PES Model!';
            app.ImportXPSData_Button = uibutton(app.Main_GridLayout, 'push');
            app.ImportXPSData_Button.ButtonPushedFcn = createCallbackFcn(app, @ImportXPSData_ButtonPushed, true);
            app.ImportXPSData_Button.Tooltip = {'View the current model.'};
            app.ImportXPSData_Button.Text = 'Import XPS Data (.txt)';
            app.thetaphotoemissionangleLabel = uilabel(app.Main_GridLayout);
            app.thetaphotoemissionangleLabel.Text = 'theta (deg.)';
            app.theta_EditField = uieditfield(app.Main_GridLayout, 'text');
            app.theta_EditField.ValueChangedFcn = createCallbackFcn(app, @theta_EditFieldValueChanged, true);
            app.theta_EditField.Tooltip = {'Scalar of the angle between the emitted photoelectrons and the surface normal (degrees)'};
            app.theta_EditField.Value = '0';
            app.ViewPESModel_Button = uibutton(app.Main_GridLayout, 'push');
            app.ViewPESModel_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewPESModel_ButtonPushed, true);
            app.ViewPESModel_Button.Tooltip = {'View the current model.'};
            app.ViewPESModel_Button.Text = 'View PES Model';
            app.ShowXPSData_CheckBox = uicheckbox(app.Main_GridLayout);
            app.ShowXPSData_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowXPSData_CheckBoxValueChanged, true);
            app.ShowXPSData_CheckBox.Text = 'Overlay XPS Data?';
            app.gammadegEditFieldLabel = uilabel(app.Main_GridLayout);
            app.gammadegEditFieldLabel.Text = 'gamma (deg.):';
            app.gamma_EditField = uieditfield(app.Main_GridLayout, 'numeric');
            app.gamma_EditField.Limits = [0 90];
            app.gamma_EditField.ValueChangedFcn = createCallbackFcn(app, @gamma_EditFieldValueChanged, true);
            app.gamma_EditField.Tooltip = {'Scalar of the angle between the incident x-rays and the analyser (degrees)'};
            app.gamma_EditField.Value = 81;
            app.ExportData_Button = uibutton(app.Main_GridLayout, 'push');
            app.ExportData_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportData_ButtonPushed, true);
            app.ExportData_Button.Tooltip = {'Export the PES intensities into a .txt file.'};
            app.ExportData_Button.Text = 'Export PES Model (.txt)';
            app.Model_Panel = uipanel(app.Main_GridLayout);
            app.Model_Panel.Title = 'Defining the n-layer model';
            app.GridLayout = uigridlayout(app.Model_Panel);
            app.ViewSampleStack_Button = uibutton(app.GridLayout, 'push');
            app.ViewSampleStack_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewSampleStack_ButtonPushed, true);
            app.ViewSampleStack_Button.Tooltip = {'View the current sample stack.'};
            app.ViewSampleStack_Button.Text = 'View Sample Stack';
            app.LayerAdd_Button = uibutton(app.GridLayout, 'push');
            app.LayerAdd_Button.ButtonPushedFcn = createCallbackFcn(app, @LayerAdd_ButtonPushed, true);
            app.LayerAdd_Button.Tooltip = {'Add a new layer to the sample stack.'};
            app.LayerAdd_Button.Text = '+';
            app.Layer_UITable = uitable(app.GridLayout);
            app.Layer_UITable.ColumnName = {'Layer'; 'Thickness (nm)'; 'Material'; 'Core-Level'; 'Exclude'};
            app.Layer_UITable.RowName = {};
            app.Layer_UITable.CellEditCallback = createCallbackFcn(app, @Layer_UITableCellEdit, true);
            app.Layer_UITable.CellSelectionCallback = createCallbackFcn(app, @Layer_UITableCellSelection, true);
            app.Layer_UITable.Tooltip = {'Table the contains all the surface layer material properties. Layer 1 is the uppermost surface.'};
            app.LayerDelete_Button = uibutton(app.GridLayout, 'push');
            app.LayerDelete_Button.ButtonPushedFcn = createCallbackFcn(app, @LayerDelete_ButtonPushed, true);
            app.LayerDelete_Button.Tooltip = {'Remove a layer to the sample stack.'};
            app.LayerDelete_Button.Text = '-';
            app.Bulk_UITable = uitable(app.GridLayout);
            app.Bulk_UITable.ColumnName = {'Layer'; 'Thickness (nm)'; 'Material'; 'Core-Level'; 'Exclude'};
            app.Bulk_UITable.RowName = {};
            app.Bulk_UITable.CellEditCallback = createCallbackFcn(app, @Bulk_UITableCellEdit, true);
            app.Bulk_UITable.Tooltip = {'Table the contains all the bulk material properties.'};
            app.OptimiseSampleStack_Button = uibutton(app.GridLayout, 'push');
            app.OptimiseSampleStack_Button.Tooltip = {'View the current model.'};
            app.OptimiseSampleStack_Button.Text = 'Optimise To Fit XPS Data!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_MatBase_NLayerPESIntensities

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end