classdef App_MatBase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Version_Label                  matlab.ui.control.Label
        Author_Label                   matlab.ui.control.Label
        NLayerModelling_Panel          matlab.ui.container.Panel
        NLayerModelling_GridLayout     matlab.ui.container.GridLayout
        NLayerModeller02_Button        matlab.ui.control.Button
        NLayerModeller01_Button        matlab.ui.control.Button
        PESCurveFitting_Panel          matlab.ui.container.Panel
        PESCurveFitting_GridLayout     matlab.ui.container.GridLayout
        PESCurveFitting_Button         matlab.ui.control.Button
        PESModelViewer_Button          matlab.ui.control.Button
        MatBaseDatabase_Panel          matlab.ui.container.Panel
        MatBaseDatabase_GridLayout     matlab.ui.container.GridLayout
        XPSSensitivityFactorAppButton  matlab.ui.control.Button
        PhotoionizationAppButton       matlab.ui.control.Button
        IMFPCalculatorAppButton        matlab.ui.control.Button
        CrystallographyAppButton       matlab.ui.control.Button
        MaterialsDatabaseEditorButton  matlab.ui.control.Button
        AppTitle                       matlab.ui.control.Label
        AppButtons_GridLayout          matlab.ui.container.GridLayout
        APPINFO_Button                 matlab.ui.control.Button
        RESIZEUI_Button                matlab.ui.control.Button
        RESTART_Button                 matlab.ui.control.Button
        CLOSEFIGS_Button               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBase-v1.2 (procopios.constantinou@psi.ch)';
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            % Remove the UI handle and restart
            close(gcbf); run App_MatBase;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 500;
            screen_size(4) = 600;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
        end

        % Button pushed function: IMFPCalculatorAppButton
        function IMFPCalculatorAppButtonPushed(app, event)
            run App_MatBase_IMFP;
        end

        % Button pushed function: CrystallographyAppButton
        function CrystallographyAppButtonPushed(app, event)
            run App_MatBase_Crystallography;
        end

        % Button pushed function: PhotoionizationAppButton
        function PhotoionizationAppButtonPushed(app, event)
            run App_MatBase_Photoionization;
        end

        % Button pushed function: MaterialsDatabaseEditorButton
        function MaterialsDatabaseEditorButtonPushed(app, event)
            run App_MatBase_DatabaseEditor;
        end

        % Button pushed function: XPSSensitivityFactorAppButton
        function XPSSensitivityFactorAppButtonPushed(app, event)
            run App_MatBase_XPSSensitivityFactor;
        end

        % Button pushed function: NLayerModeller01_Button
        function NLayerModeller01_ButtonPushed(app, event)
            run App_MatBase_NLayerModeller01;
        end

        % Button pushed function: NLayerModeller02_Button
        function NLayerModeller02_ButtonPushed(app, event)
            run App_MatBase_NLayerModeller02;
        end

        % Button pushed function: PESModelViewer_Button
        function PESModelViewer_ButtonPushed(app, event)
            run App_MatBase_PESModelViewer;
        end

        % Button pushed function: PESCurveFitting_Button
        function PESCurveFitting_ButtonPushed(app, event)
            run App_MatBase_PESCurveFitter;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'MatBase Main Menu';
            app.MatBaseDatabase_Panel = uipanel(app.GridLayout);
            app.MatBaseDatabase_Panel.Tooltip = {'Navigate to all MatBase Databases'};
            app.MatBaseDatabase_Panel.Title = 'MatBase Databases';
            app.MatBaseDatabase_GridLayout = uigridlayout(app.MatBaseDatabase_Panel);
            app.MaterialsDatabaseEditorButton = uibutton(app.MatBaseDatabase_GridLayout, 'push');
            app.MaterialsDatabaseEditorButton.ButtonPushedFcn = createCallbackFcn(app, @MaterialsDatabaseEditorButtonPushed, true);
            app.MaterialsDatabaseEditorButton.Icon = 'Icon_MaterialsProperties.jpeg';
            app.MaterialsDatabaseEditorButton.Tooltip = {'Effortlessly manage the Materials Properties Database with this user-friendly app. Add, delete, and edit entries with ease, streamlining your workflow and enhancing the efficiency of other apps that utilize the database for calculations.'};
            app.MaterialsDatabaseEditorButton.Text = 'Materials Database Editor';
            app.CrystallographyAppButton = uibutton(app.MatBaseDatabase_GridLayout, 'push');
            app.CrystallographyAppButton.ButtonPushedFcn = createCallbackFcn(app, @CrystallographyAppButtonPushed, true);
            app.CrystallographyAppButton.Icon = 'Icon_Crystallography.jpeg';
            app.CrystallographyAppButton.Tooltip = {'Explore the intricacies of crystal structures with our powerful visualization app. View unit cells in both real and reciprocal space, calculate the Brillouin zone, and extract 2D slices for in-depth analysis. '};
            app.CrystallographyAppButton.Text = 'Crystallography App';
            app.IMFPCalculatorAppButton = uibutton(app.MatBaseDatabase_GridLayout, 'push');
            app.IMFPCalculatorAppButton.ButtonPushedFcn = createCallbackFcn(app, @IMFPCalculatorAppButtonPushed, true);
            app.IMFPCalculatorAppButton.Icon = 'Icon_IMFP.jpeg';
            app.IMFPCalculatorAppButton.Tooltip = {'Achieve unparalleled precision in your calculations of electron inelastic mean free path (IMFP) with our app. Utilize a range of formalisms including the Optical (NIST), Universal, TPP-2M, S1, S2, S3, S4 and JTP methods to achieve the most accurate results. Conveniently access material parameters from the Materials Properties Database or manually enter data for materials not included in the database. '};
            app.IMFPCalculatorAppButton.Text = 'IMFP Calculator App';
            app.PhotoionizationAppButton = uibutton(app.MatBaseDatabase_GridLayout, 'push');
            app.PhotoionizationAppButton.ButtonPushedFcn = createCallbackFcn(app, @PhotoionizationAppButtonPushed, true);
            app.PhotoionizationAppButton.Icon = 'Icon_Photoionization.jpeg';
            app.PhotoionizationAppButton.Tooltip = {'Extract the binding energy spectrum, photoionization cross-sections, and photoionization asymmetry parameters of all elements between 1-98 with our powerful app. Utilize a range of formalisms, including the Scofield (1973), Yeh & Lindau (1985), Trzhaskovskaya (2018) and Cant (2022) methods. Easily identify the best core-levels to probe by finding the maximum cross-sections and gain a comprehensive overview of the core-level spectra of all relevant elements!'};
            app.PhotoionizationAppButton.Text = 'Photoionization App';
            app.XPSSensitivityFactorAppButton = uibutton(app.MatBaseDatabase_GridLayout, 'push');
            app.XPSSensitivityFactorAppButton.ButtonPushedFcn = createCallbackFcn(app, @XPSSensitivityFactorAppButtonPushed, true);
            app.XPSSensitivityFactorAppButton.Icon = 'Icon_SensitivityFactorXPS.jpeg';
            app.XPSSensitivityFactorAppButton.Tooltip = {'Enhance your quantitative analysis with our app designed to calculate the XPS Sensitivity Factor - a crucial metric in analysis. It integrates the IMFP, photoionization cross-sections, and asymmetry databases. Customize your setup by specifying the experimental geometry, photon polarization, and the formalisms for both IMFP and cross-section determination. Select your desired core-level, and with a simple click, generate plots of the sensitivity factor against photon energy and emission angle. This tool is indispensable for precise, quantitative XPS analysis.'};
            app.XPSSensitivityFactorAppButton.Text = 'XPS Sensitivity Factor App';
            app.PESCurveFitting_Panel = uipanel(app.GridLayout);
            app.PESCurveFitting_Panel.Title = 'PES Curve Fitting Apps';
            app.PESCurveFitting_GridLayout = uigridlayout(app.PESCurveFitting_Panel);
            app.PESCurveFitting_GridLayout.Tooltip = {'Navigate to all MatBase PES Curve Fitting Apps'};
            app.PESModelViewer_Button = uibutton(app.PESCurveFitting_GridLayout, 'push');
            app.PESModelViewer_Button.ButtonPushedFcn = createCallbackFcn(app, @PESModelViewer_ButtonPushed, true);
            app.PESModelViewer_Button.Icon = 'Icon_PESModelViewer.jpeg';
            app.PESModelViewer_Button.Tooltip = {'Discover the PES Model Software available through MatBase. Construct PES Spectral Intensity Models, perform PES Background Subtraction, and generate Atomic Concentration Profiles. Use these tools to explore the full range of capabilities offered by MatBase.'};
            app.PESModelViewer_Button.Text = 'PES Model Viewer';
            app.PESCurveFitting_Button = uibutton(app.PESCurveFitting_GridLayout, 'push');
            app.PESCurveFitting_Button.ButtonPushedFcn = createCallbackFcn(app, @PESCurveFitting_ButtonPushed, true);
            app.PESCurveFitting_Button.Icon = 'Icon_PESModelFitter.jpeg';
            app.PESCurveFitting_Button.Tooltip = {'Leverage the power of the PES Curve Fitting App to accurately peak fit your XPS/PES data. The primary objective is to distinguish photoemission signals from various elemental or chemical states within your sample. Start by constructing an initial model with multiple curve shapes (Gaussian, Lorentzian, Voigt, or Doniach-Sunjic). Then, subtract the background using methods like Polynomial, Shirley, or Tougaard, and optimize the fit with reasonable constraints. '};
            app.PESCurveFitting_Button.Text = 'PES Curve Fitter';
            app.NLayerModelling_Panel = uipanel(app.GridLayout);
            app.NLayerModelling_Panel.Title = 'N-Layer Modelling';
            app.NLayerModelling_GridLayout = uigridlayout(app.NLayerModelling_Panel);
            app.NLayerModelling_GridLayout.Tooltip = {'Navigate to all MatBase N-Layer Modelling Apps'};
            app.NLayerModeller01_Button = uibutton(app.NLayerModelling_GridLayout, 'push');
            app.NLayerModeller01_Button.ButtonPushedFcn = createCallbackFcn(app, @NLayerModeller01_ButtonPushed, true);
            app.NLayerModeller01_Button.Icon = 'Icon_NLayerModeller01.jpeg';
            app.NLayerModeller01_Button.Tooltip = {'Explore the depths of material analysis and XPS with this robust tool. It empowers users to construct multi-layered models each layer featuring a UNIFORM and CONSTANT material concentration and thickness. The app draws upon an extensive database for material characteristics, integrates various options for IMFP parameters and offers a selection of photoionization cross-sections and asymmetry parameters from renowned databases. Utilizing the Beer-Lambert law the tool accurately computes the PES intensities for each layer modulated by the electron IMFP based on the specific experimental setup. Additionally it can graphically represent the variations in photon energy and emission angles.'};
            app.NLayerModeller01_Button.Text = 'Uniform & Homogenous Layers';
            app.NLayerModeller02_Button = uibutton(app.NLayerModelling_GridLayout, 'push');
            app.NLayerModeller02_Button.ButtonPushedFcn = createCallbackFcn(app, @NLayerModeller02_ButtonPushed, true);
            app.NLayerModeller02_Button.Icon = 'Icon_NLayerModeller02.jpeg';
            app.NLayerModeller02_Button.Tooltip = {'Explore the depths of material analysis and XPS with this robust tool. It empowers users to construct multi-layered models each layer featuring a UNIFORM and VARYING material concentration and thickness. The app draws upon an extensive database for material characteristics, integrates various options for IMFP parameters and offers a selection of photoionization cross-sections and asymmetry parameters from renowned databases. Utilizing the Beer-Lambert law the tool accurately computes the PES intensities for each layer modulated by the electron IMFP based on the specific experimental setup. Additionally it can graphically represent the variations in photon energy and emission angles.'};
            app.NLayerModeller02_Button.Text = 'Uniform & Varying Conc. Layers';
            app.Author_Label = uilabel(app.GridLayout);
            app.Author_Label.Text = 'Author: c0deta1ker';
            app.Version_Label = uilabel(app.GridLayout);
            app.Version_Label.Text = 'MatBase-v1.2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_MatBase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end