classdef App_MatBase_DatabaseEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Global_GridLayout       matlab.ui.container.GridLayout
        Local_GridLayout        matlab.ui.container.GridLayout
        MPD_UITable             matlab.ui.control.Table
        MPD_Buttons_GridLayout  matlab.ui.container.GridLayout
        UpdateMPD_Button        matlab.ui.control.Button
        DeleteRow_Button        matlab.ui.control.Button
        NewRow_Button           matlab.ui.control.Button
        BUTTONS_GridLayout      matlab.ui.container.GridLayout
        AppButtons_GridLayout   matlab.ui.container.GridLayout
        Image                   matlab.ui.control.Image
        Revert2Original_Button  matlab.ui.control.Button
        UndoAll_Button          matlab.ui.control.Button
        EXPORT_Button           matlab.ui.control.Button
        SAVE_Button             matlab.ui.control.Button
        TRANSFER_Button         matlab.ui.control.Button
        APPINFO_Button          matlab.ui.control.Button
        RESIZEUI_Button         matlab.ui.control.Button
        RESTART_Button          matlab.ui.control.Button
        CLOSEFIGS_Button        matlab.ui.control.Button
        AppTitle                matlab.ui.control.Label
        MAINMENU_GridLayout     matlab.ui.container.GridLayout
        MAINMENU_Button         matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        mpd     % Table the contains all the material proeprties database
    end
    
    % Private functions available to the app only
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBase - Materials Database Editor (procopios.constantinou@psi.ch)';
            % 1 - Load the current version of the MPD and display it
            MPD_PCC = load('MPD_PCC'); app.mpd = MPD_PCC.MPD_PCC;
            app.mpd = sortrows(app.mpd,'ID');
            app.MPD_UITable.ColumnName = app.mpd.Properties.VariableNames;
            app.MPD_UITable.Data = app.mpd;
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run App_MatBase;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            % Remove the UI handle and restart
            close(gcbf); run App_MatBase_DatabaseEditor; 
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 900;
            screen_size(4) = 640;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            disp('mpd : '); disp(app.mpd);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'mpd', app.mpd);
        end

        % Button pushed function: SAVE_Button
        function SAVE_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...');
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            MPD_PCC = app.mpd; 
            save(char(save_fullfile), 'MPD_PCC', '-v7.3');
            disp('-> saved data : '); display(MPD_PCC);
            close(wbar);
            %% 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: EXPORT_Button
        function EXPORT_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.txt'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...');
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            writetable(app.mpd,save_fullfile,'Delimiter','\t');
            disp('-> saved data : '); display(app.mpd);
            close(wbar);
            %% 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: NewRow_Button
        function NewRow_ButtonPushed(app, event)
            %% 1 - Defining the stucture of a new row
            newRow      = app.mpd(1,:);
            newRow.ID   = max(app.mpd.ID)+1;
            newRow.TYPE             = {''};
            newRow.ATOM_NAME        = {''};
            newRow.ATOM_SYMB        = {''};
            newRow.ATOM_ZNUM        = NaN;
            newRow.ATOM_MASS        = NaN;
            newRow.ATOM_RADIUS      = NaN;
            newRow.ATOM_TERM        = {''};
            newRow.ELECT_CONFIG     = {''};
            newRow.ELECT_VALENCY    = NaN;
            newRow.ELECT_NEGATIVITY         = NaN;
            newRow.ELECT_AFFINITY           = NaN;
            newRow.ELECT_FIRST_IONISATION   = NaN;
            newRow.NEUT_NUM                 = NaN;
            newRow.NEUT_XSECT               = NaN;
            newRow.NEUT_MASS_ABSORB         = NaN;
            newRow.RADACVTY                 = {''};
            newRow.PHASE_RT                 = {''};
            newRow.SPEC_HEAT                = NaN;
            newRow.T_SUPER              = NaN;
            newRow.T_CURIE              = NaN;
            newRow.T_NEEL               = NaN;
            newRow.T_MELT               = NaN;
            newRow.T_BOIL               = NaN;
            newRow.T_CRIT               = NaN;
            newRow.CRY_STRUCT           = {''};
            newRow.CRY_A                = NaN;
            newRow.CRY_B                = NaN;
            newRow.CRY_C                = NaN;
            newRow.CRY_ALPHA            = NaN;
            newRow.CRY_BETA             = NaN;
            newRow.CRY_GAMMA            = NaN;
            newRow.DENSITY              = NaN;
            newRow.YOUNGS_MOD           = NaN;
            newRow.ELE_TYPE             = {''};
            newRow.ELE_RESI             = NaN;
            newRow.ELE_BGAP             = NaN;
            newRow.ELE_WFUNC            = NaN;
            newRow.MAG_TYPE             = {''};
            newRow.MAG_SUS_MASS         = NaN;
            newRow.MAG_SUS_MOLE         = NaN;
            newRow.MAG_SUS_VOLU         = NaN;
            %% 2 - Adding a ne wrow to the MPD
            app.mpd = [app.mpd; newRow];
            app.MPD_UITable.Data = app.mpd;
        end

        % Button pushed function: DeleteRow_Button
        function DeleteRow_ButtonPushed(app, event)
            %% 1 - User defined Material ID to delete
            prompt      = {'Enter Material IDs to delete:'};
            dlgtitle    = 'Delete Entry';
            dims        = [1 50];
            answer = inputdlg(prompt,dlgtitle,dims);
            if ~isempty(answer)
                user_val = str2num(answer{1});
                for i = 1:length(user_val)
                    indx = find(app.mpd.ID == user_val(i));
                    if indx < 118; fprintf("-> Cannot delete element entries between 1 - 118. \n");
                    else; app.mpd(indx,:) = [];
                    end
                end
            end
            %% 2 - Updating Material ID numbers
            app.mpd = sortrows(app.mpd,'ID');
            app.mpd.ID(:,1) = 1:length(app.mpd.ID);
            app.MPD_UITable.Data = app.mpd;
        end

        % Button pushed function: UpdateMPD_Button
        function UpdateMPD_ButtonPushed(app, event)
            %% 1 - Finding the path of the Materials properties Database
            save_fullfile = fileparts(which('MPD_PCC.mat'));
            MPD_PCC = app.mpd; 
            save(char(string(save_fullfile) + "\MPD_PCC.mat"), 'MPD_PCC', '-v7.3');
            disp('-> saved data : '); display(MPD_PCC);
            %% 3 - Print confirmation that data is saved
            waitbar(1,'MPD has been updated!'); 
        end

        % Cell edit callback: MPD_UITable
        function MPD_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.mpd = app.MPD_UITable.Data;
        end

        % Button pushed function: UndoAll_Button
        function UndoAll_ButtonPushed(app, event)
            % 1 - Load the current version of the MPD and display it
            MPD_PCC = load('MPD_PCC'); app.mpd = MPD_PCC.MPD_PCC;
            app.mpd = sortrows(app.mpd,'ID');
            app.MPD_UITable.ColumnName = app.mpd.Properties.VariableNames;
            app.MPD_UITable.Data = app.mpd;
        end

        % Button pushed function: Revert2Original_Button
        function Revert2Original_ButtonPushed(app, event)
            % 1 - Load the current version of the MPD and display it
            MPD_PCC = load('MPD_PCC_v20240307'); app.mpd = MPD_PCC.MPD_PCC;
            app.mpd = sortrows(app.mpd,'ID');
            app.MPD_UITable.ColumnName = app.mpd.Properties.VariableNames;
            app.MPD_UITable.Data = app.mpd;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Global_GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.Global_GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.Global_GridLayout);
            app.AppTitle.Text = 'Materials Database Editor';
            app.BUTTONS_GridLayout = uigridlayout(app.Global_GridLayout);
            app.AppButtons_GridLayout = uigridlayout(app.BUTTONS_GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.SAVE_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.SAVE_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVE_ButtonPushed, true);
            app.SAVE_Button.Tooltip = {'Save the Materials Properties Database as a MATLAB data structure file.'};
            app.SAVE_Button.Text = 'Save As... (.mat)';
            app.EXPORT_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.EXPORT_Button.ButtonPushedFcn = createCallbackFcn(app, @EXPORT_ButtonPushed, true);
            app.EXPORT_Button.Tooltip = {'Export the Materials Properties Database as a .txt file.'};
            app.EXPORT_Button.Text = 'Export... (.txt)';
            app.UndoAll_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.UndoAll_Button.ButtonPushedFcn = createCallbackFcn(app, @UndoAll_ButtonPushed, true);
            app.UndoAll_Button.Tooltip = {'Undo all the changes made to the Materials Properties Database prior to saving.'};
            app.UndoAll_Button.Text = 'Undo All...';
            app.Revert2Original_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.Revert2Original_Button.ButtonPushedFcn = createCallbackFcn(app, @Revert2Original_ButtonPushed, true);
            app.Revert2Original_Button.Tooltip = {'Revert the Materials Properties Database to its original form, as defined on 20th July 2023.'};
            app.Revert2Original_Button.Text = 'Revert to original MPD...';
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Effortlessly manage the Materials Properties Database with this user-friendly app. Add, delete, and edit entries with ease, streamlining your workflow and enhancing the efficiency of other apps that utilize the database for calculations.'};
            app.Image.ImageSource = 'Icon_MaterialsProperties.jpeg';
            app.Local_GridLayout = uigridlayout(app.Global_GridLayout);
            app.MPD_Buttons_GridLayout = uigridlayout(app.Local_GridLayout);
            app.NewRow_Button = uibutton(app.MPD_Buttons_GridLayout, 'push');
            app.NewRow_Button.ButtonPushedFcn = createCallbackFcn(app, @NewRow_ButtonPushed, true);
            app.NewRow_Button.Tooltip = {'Add a new row to the Materials Properties Database.'};
            app.NewRow_Button.Text = 'Add New Material Entry';
            app.DeleteRow_Button = uibutton(app.MPD_Buttons_GridLayout, 'push');
            app.DeleteRow_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteRow_ButtonPushed, true);
            app.DeleteRow_Button.Tooltip = {'Delete the defined rows from the Materials Properties Database.'};
            app.DeleteRow_Button.Text = 'Delete Material Entry';
            app.UpdateMPD_Button = uibutton(app.MPD_Buttons_GridLayout, 'push');
            app.UpdateMPD_Button.ButtonPushedFcn = createCallbackFcn(app, @UpdateMPD_ButtonPushed, true);
            app.UpdateMPD_Button.Tooltip = {'Save all changes made to the Materials Properties Database.'};
            app.UpdateMPD_Button.Text = 'Save Changes to Materials Database!';
            app.MPD_UITable = uitable(app.Local_GridLayout);
            app.MPD_UITable.ColumnName = {'Column 1'};
            app.MPD_UITable.RowName = {};
            app.MPD_UITable.ColumnSortable = true;
            app.MPD_UITable.ColumnEditable = true;
            app.MPD_UITable.CellEditCallback = createCallbackFcn(app, @MPD_UITableCellEdit, true);
            app.MPD_UITable.Tooltip = {'The Materials Properties Database of elements and compounds.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_MatBase_DatabaseEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end