classdef App_MatBase_IMFP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Global_GridLayout           matlab.ui.container.GridLayout
        ExportDataRange_Panel       matlab.ui.container.Panel
        ExportDataRange_GridLayout  matlab.ui.container.GridLayout
        ExportIMFPData_Button       matlab.ui.control.Button
        MaxEk_EditField             matlab.ui.control.NumericEditField
        MaxeVEditFieldLabel         matlab.ui.control.Label
        PlotIMFPData_Button         matlab.ui.control.Button
        MinEk_EditField             matlab.ui.control.NumericEditField
        MineVEditFieldLabel         matlab.ui.control.Label
        ElecParams_Panel            matlab.ui.container.Panel
        ElecParams_GridLayout       matlab.ui.container.GridLayout
        dIMFP_EditField             matlab.ui.control.NumericEditField
        CalculatedIMFPnmLabel_2     matlab.ui.control.Label
        IMFP_EditField              matlab.ui.control.NumericEditField
        CalculatedIMFPnmLabel       matlab.ui.control.Label
        ElecEnergy_EditField        matlab.ui.control.NumericEditField
        ElectronEnergyeVLabel       matlab.ui.control.Label
        CalcFormalism_ButtonGroup   matlab.ui.container.ButtonGroup
        JTP_Button                  matlab.ui.control.ToggleButton
        S4_Button                   matlab.ui.control.ToggleButton
        S2_Button                   matlab.ui.control.ToggleButton
        TPP2Mavg_Button             matlab.ui.control.ToggleButton
        S3_Button                   matlab.ui.control.ToggleButton
        S1_Button                   matlab.ui.control.ToggleButton
        TPP2M_Button                matlab.ui.control.ToggleButton
        EAL_Label                   matlab.ui.control.Label
        IMFP_Label                  matlab.ui.control.Label
        Universal_Button            matlab.ui.control.ToggleButton
        MatParams_Panel             matlab.ui.container.Panel
        MatParams_GridLayout        matlab.ui.container.GridLayout
        Stoiciometry_EditField      matlab.ui.control.NumericEditField
        BandgapenergyeVLabel_2      matlab.ui.control.Label
        Bandgap_EditField           matlab.ui.control.NumericEditField
        BandgapenergyeVLabel        matlab.ui.control.Label
        AtomZ_EditField             matlab.ui.control.NumericEditField
        AtomicmassZLabel            matlab.ui.control.Label
        AtomWeight_EditField        matlab.ui.control.NumericEditField
        AtomicweightamuLabel        matlab.ui.control.Label
        Valency_EditField           matlab.ui.control.NumericEditField
        ValencyEditFieldLabel       matlab.ui.control.Label
        Density_EditField           matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label        matlab.ui.control.Label
        PeriodicTable_ButtonGroup   matlab.ui.container.ButtonGroup
        Label_18                    matlab.ui.control.Label
        Label_17                    matlab.ui.control.Label
        Label_16                    matlab.ui.control.Label
        Label_15                    matlab.ui.control.Label
        Label_14                    matlab.ui.control.Label
        Label_13                    matlab.ui.control.Label
        Label_12                    matlab.ui.control.Label
        Label_11                    matlab.ui.control.Label
        Label_10                    matlab.ui.control.Label
        Label_9                     matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label_1                     matlab.ui.control.Label
        Lr_Button                   matlab.ui.control.ToggleButton
        No_Button                   matlab.ui.control.ToggleButton
        Md_Button                   matlab.ui.control.ToggleButton
        Fm_Button                   matlab.ui.control.ToggleButton
        Es_Button                   matlab.ui.control.ToggleButton
        Cf_Button                   matlab.ui.control.ToggleButton
        Bk_Button                   matlab.ui.control.ToggleButton
        Cm_Button                   matlab.ui.control.ToggleButton
        Am_Button                   matlab.ui.control.ToggleButton
        Pu_Button                   matlab.ui.control.ToggleButton
        Np_Button                   matlab.ui.control.ToggleButton
        U_Button                    matlab.ui.control.ToggleButton
        Pa_Button                   matlab.ui.control.ToggleButton
        Th_Button                   matlab.ui.control.ToggleButton
        Lu_Button                   matlab.ui.control.ToggleButton
        Yb_Button                   matlab.ui.control.ToggleButton
        Tm_Button                   matlab.ui.control.ToggleButton
        Er_Button                   matlab.ui.control.ToggleButton
        Ho_Button                   matlab.ui.control.ToggleButton
        Dy_Button                   matlab.ui.control.ToggleButton
        Tb_Button                   matlab.ui.control.ToggleButton
        Gd_Button                   matlab.ui.control.ToggleButton
        Eu_Button                   matlab.ui.control.ToggleButton
        Sm_Button                   matlab.ui.control.ToggleButton
        Pm_Button                   matlab.ui.control.ToggleButton
        Nd_Button                   matlab.ui.control.ToggleButton
        Pr_Button                   matlab.ui.control.ToggleButton
        Ce_Button                   matlab.ui.control.ToggleButton
        Og_Button                   matlab.ui.control.ToggleButton
        Ts_Button                   matlab.ui.control.ToggleButton
        Lv_Button                   matlab.ui.control.ToggleButton
        Mc_Button                   matlab.ui.control.ToggleButton
        Fl_Button                   matlab.ui.control.ToggleButton
        Nh_Button                   matlab.ui.control.ToggleButton
        Cn_Button                   matlab.ui.control.ToggleButton
        Rg_Button                   matlab.ui.control.ToggleButton
        Ds_Button                   matlab.ui.control.ToggleButton
        Mt_Button                   matlab.ui.control.ToggleButton
        Hs_Button                   matlab.ui.control.ToggleButton
        Bh_Button                   matlab.ui.control.ToggleButton
        Sg_Button                   matlab.ui.control.ToggleButton
        Db_Button                   matlab.ui.control.ToggleButton
        Rf_Button                   matlab.ui.control.ToggleButton
        Ac_Button                   matlab.ui.control.ToggleButton
        Ra_Button                   matlab.ui.control.ToggleButton
        Fr_Button                   matlab.ui.control.ToggleButton
        Rn_Button                   matlab.ui.control.ToggleButton
        At_Button                   matlab.ui.control.ToggleButton
        Po_Button                   matlab.ui.control.ToggleButton
        Bi_Button                   matlab.ui.control.ToggleButton
        Pb_Button                   matlab.ui.control.ToggleButton
        Tl_Button                   matlab.ui.control.ToggleButton
        Hg_Button                   matlab.ui.control.ToggleButton
        Au_Button                   matlab.ui.control.ToggleButton
        Pt_Button                   matlab.ui.control.ToggleButton
        Ir_Button                   matlab.ui.control.ToggleButton
        Os_Button                   matlab.ui.control.ToggleButton
        Re_Button                   matlab.ui.control.ToggleButton
        W_Button                    matlab.ui.control.ToggleButton
        Ta_Button                   matlab.ui.control.ToggleButton
        Hf_Button                   matlab.ui.control.ToggleButton
        La_Button                   matlab.ui.control.ToggleButton
        Ba_Button                   matlab.ui.control.ToggleButton
        Cs_Button                   matlab.ui.control.ToggleButton
        Xe_Button                   matlab.ui.control.ToggleButton
        I_Button                    matlab.ui.control.ToggleButton
        Te_Button                   matlab.ui.control.ToggleButton
        Sb_Button                   matlab.ui.control.ToggleButton
        Sn_Button                   matlab.ui.control.ToggleButton
        In_Button                   matlab.ui.control.ToggleButton
        Cd_Button                   matlab.ui.control.ToggleButton
        Ag_Button                   matlab.ui.control.ToggleButton
        Pd_Button                   matlab.ui.control.ToggleButton
        Rh_Button                   matlab.ui.control.ToggleButton
        Ru_Button                   matlab.ui.control.ToggleButton
        Tc_Button                   matlab.ui.control.ToggleButton
        Mo_Button                   matlab.ui.control.ToggleButton
        Nb_Button                   matlab.ui.control.ToggleButton
        Zr_Button                   matlab.ui.control.ToggleButton
        Y_Button                    matlab.ui.control.ToggleButton
        Sr_Button                   matlab.ui.control.ToggleButton
        Rb_Button                   matlab.ui.control.ToggleButton
        Kr_Button                   matlab.ui.control.ToggleButton
        Br_Button                   matlab.ui.control.ToggleButton
        Se_Button                   matlab.ui.control.ToggleButton
        As_Button                   matlab.ui.control.ToggleButton
        Ge_Button                   matlab.ui.control.ToggleButton
        Ga_Button                   matlab.ui.control.ToggleButton
        Zn_Button                   matlab.ui.control.ToggleButton
        Cu_Button                   matlab.ui.control.ToggleButton
        Ni_Button                   matlab.ui.control.ToggleButton
        Co_Button                   matlab.ui.control.ToggleButton
        Fe_Button                   matlab.ui.control.ToggleButton
        Mn_Button                   matlab.ui.control.ToggleButton
        Cr_Button                   matlab.ui.control.ToggleButton
        V_Button                    matlab.ui.control.ToggleButton
        Ti_Button                   matlab.ui.control.ToggleButton
        Sc_Button                   matlab.ui.control.ToggleButton
        Ca_Button                   matlab.ui.control.ToggleButton
        K_Button                    matlab.ui.control.ToggleButton
        Ar_Button                   matlab.ui.control.ToggleButton
        Cl_Button                   matlab.ui.control.ToggleButton
        S_Button                    matlab.ui.control.ToggleButton
        P_Button                    matlab.ui.control.ToggleButton
        Si_Button                   matlab.ui.control.ToggleButton
        Al_Button                   matlab.ui.control.ToggleButton
        Mg_Button                   matlab.ui.control.ToggleButton
        Na_Button                   matlab.ui.control.ToggleButton
        Ne_Button                   matlab.ui.control.ToggleButton
        F_Button                    matlab.ui.control.ToggleButton
        O_Button                    matlab.ui.control.ToggleButton
        N_Button                    matlab.ui.control.ToggleButton
        C_Button                    matlab.ui.control.ToggleButton
        B_Button                    matlab.ui.control.ToggleButton
        Be_Button                   matlab.ui.control.ToggleButton
        Li_Button                   matlab.ui.control.ToggleButton
        He_Button                   matlab.ui.control.ToggleButton
        H_Button                    matlab.ui.control.ToggleButton
        Materials_Panel             matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        Compounds_DropDown          matlab.ui.control.DropDown
        AvailableCompoundsLabel     matlab.ui.control.Label
        Material_Lamp               matlab.ui.control.Lamp
        Material_EditField          matlab.ui.control.EditField
        MaterialEditFieldLabel      matlab.ui.control.Label
        AppButtons_GridLayout       matlab.ui.container.GridLayout
        Image                       matlab.ui.control.Image
        SAVE_Button                 matlab.ui.control.Button
        TRANSFER_Button             matlab.ui.control.Button
        APPINFO_Button              matlab.ui.control.Button
        RESIZEUI_Button             matlab.ui.control.Button
        RESTART_Button              matlab.ui.control.Button
        CLOSEFIGS_Button            matlab.ui.control.Button
        AppTitle                    matlab.ui.control.Label
        MAINMENU_GridLayout         matlab.ui.container.GridLayout
        MAINMENU_Button             matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)        
        % - MATLAB data structure to summarise all data for analysis
        imfpData
        % - Variables for determining the IMFP
        method          % IMFP calculator formalism
        material        % material being considered
        density         % density of the material (g/cc)
        ele_valency     % number of valence electrons per atom (for an element)
        atom_weight     % atomic or molecular weight (in amu == g/mol)
        atom_znum       % atomic mass number of the element (or average for compound)
        ele_bgap        % band gap energy (eV)
        stoic           % stoiciometry of the material (e.g. for elements, stoic = 1, for molecules of the form G_gH_h, the stoiciometry is g + h)
        ele_kin         % electron kinetic energy (for PES; KE = BE - PHI) (eV)
        ele_imfp        % electron IMFP values (Angstrom)
        ele_imfp_sig    % std. dev. of the electron IMFP values (Angstrom)
        % - Variables for plotting and exporting IMFP data
        ele_kin_min     % min. electron kinetic energy (for PES; KE = BE - PHI) (eV)
        ele_kin_max     % max. electron kinetic energy (for PES; KE = BE - PHI) (eV)
    end
    % Private methods available to the app only
    methods (Access = private)
        % Extract the data structure
        function imfpData = extract_myData(app)
            imfpData = struct();
            % -- Defining the input arguments
            imfpData.method               = app.method;
            imfpData.material             = app.material;
            imfpData.density              = app.density;
            imfpData.ele_valency          = app.ele_valency;
            imfpData.atom_weight          = app.atom_weight;
            imfpData.atom_znum            = app.atom_znum;
            imfpData.ele_bgap             = app.ele_bgap;
            imfpData.stoic                = app.stoic;
            % -- Defining the output arguments (kinetic energy value)
            imfpData.ele_kin              = app.ele_kin;
            imfpData.ele_imfp             = app.ele_imfp;
            imfpData.ele_imfp_sig         = app.ele_imfp_sig;
            % -- Defining the output arguments (kinetic energy range)
            imfpData.ele_kin_R            = app.ele_kin_min:1:app.ele_kin_max; 
            imfpData.ele_kin_R            = imfpData.ele_kin_R';
            if strcmpi(app.method,"TPP-2M");            imfpData.ele_imfp_R = imfp_tpp2m(imfpData.ele_kin_R, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            elseif strcmpi(app.method,"TPP-2M (avg.)"); imfpData.ele_imfp_R = imfp_tpp2m_avg(imfpData.ele_kin_R);
            elseif strcmpi(app.method,"Universal");     imfpData.ele_imfp_R = imfp_universal(imfpData.ele_kin_R);
            elseif strcmpi(app.method,"S1");            imfpData.ele_imfp_R = imfp_S1(imfpData.ele_kin_R, app.density, app.atom_weight, app.ele_bgap, app.atom_znum);
            elseif strcmpi(app.method,"S2");            imfpData.ele_imfp_R = imfp_S2(imfpData.ele_kin_R, app.atom_znum);
            elseif strcmpi(app.method,"S3");            imfpData.ele_imfp_R = eal_S3(imfpData.ele_kin_R, app.density, app.atom_weight, app.atom_znum, app.stoic);
            elseif strcmpi(app.method,"S4");            imfpData.ele_imfp_R = eal_S4(imfpData.ele_kin_R, app.atom_znum);
            elseif strcmpi(app.method,"JTP");           imfpData.ele_imfp_R = imfp_jtp(imfpData.ele_kin_R, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            end
        end

        % Code that executes to calculate the IMFP
        function calculate_imfp(app)
            % 1 - Calculate the latest IMFP value based on current inputs
            app.method = string(app.method);
            if strcmpi(app.method,"TPP-2M");            app.ele_imfp = imfp_tpp2m(app.ele_kin, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            elseif strcmpi(app.method,"TPP-2M (avg.)"); app.ele_imfp = imfp_tpp2m_avg(app.ele_kin);
            elseif strcmpi(app.method,"Universal");     app.ele_imfp = imfp_universal(app.ele_kin);
            elseif strcmpi(app.method,"S1");            app.ele_imfp = imfp_S1(app.ele_kin, app.density, app.atom_weight, app.ele_bgap, app.atom_znum);
            elseif strcmpi(app.method,"S2");            app.ele_imfp = imfp_S2(app.ele_kin, app.atom_znum);
            elseif strcmpi(app.method,"S3");            app.ele_imfp = eal_S3(app.ele_kin, app.density, app.atom_weight, app.atom_znum, app.stoic);
            elseif strcmpi(app.method,"S4");            app.ele_imfp = eal_S4(app.ele_kin, app.atom_znum);
            elseif strcmpi(app.method,"JTP");           app.ele_imfp = imfp_jtp(app.ele_kin, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            end
            % 2 - Update the IMFP edit-field to display value in Angstrom
            if app.ele_imfp < 0 || isnan(app.ele_imfp); app.ele_imfp = 0; end
            app.IMFP_EditField.Value = app.ele_imfp;
            fprintf("---> IMFP: " + string(app.ele_imfp) + " Ang.\n");
            % 3 - Update the Std. Dev. of the IMFP edit-field to display value in Angstrom
            ele_imfp_vals(1) = imfp_tpp2m(app.ele_kin, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            ele_imfp_vals(2) = imfp_tpp2m_avg(app.ele_kin);
            ele_imfp_vals(3) = imfp_universal(app.ele_kin);
            ele_imfp_vals(4) = imfp_S1(app.ele_kin, app.density, app.atom_weight, app.ele_bgap, app.atom_znum);
            ele_imfp_vals(5) = imfp_S2(app.ele_kin, app.atom_znum);
            ele_imfp_vals(6) = eal_S3(app.ele_kin, app.density, app.atom_weight, app.atom_znum, app.stoic);
            ele_imfp_vals(7) = eal_S4(app.ele_kin, app.atom_znum);
            ele_imfp_vals(8) = imfp_jtp(app.ele_kin, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            app.ele_imfp_sig = std(ele_imfp_vals);
            if app.ele_imfp_sig < 0 || isnan(app.ele_imfp_sig); app.ele_imfp_sig = 0; end
            app.dIMFP_EditField.Value = app.ele_imfp_sig;
        end
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBase - IMFP Calculator App (procopios.constantinou@psi.ch)';
            % 1 - Default parameters upon startup
            app.imfpData        = struct();
            app.method          = app.CalcFormalism_ButtonGroup.SelectedObject.Text;
            app.material        = app.Material_EditField.Value;
            app.density         = app.Density_EditField.Value;        
            app.ele_valency     = app.Valency_EditField.Value;
            app.atom_weight     = app.AtomWeight_EditField.Value;   
            app.atom_znum       = app.AtomZ_EditField.Value; 
            app.ele_bgap        = app.Bandgap_EditField.Value;
            app.stoic           = app.Stoiciometry_EditField.Value;
            app.ele_kin         = app.ElecEnergy_EditField.Value;
            app.ele_kin_min     = app.MinEk_EditField.Value;
            app.ele_kin_max     = app.MaxEk_EditField.Value;
            app.ele_imfp        = app.IMFP_EditField.Value;
            app.ele_imfp_sig    = app.dIMFP_EditField.Value;
            % 2 - Initialising the entries of the dropdown box
            MPD_PCC         = load('MPD_PCC.mat'); MPD_PCC = MPD_PCC.MPD_PCC;
            atom_symbols    = MPD_PCC.ATOM_SYMB;
            idx             = MPD_PCC.ID;
            for i = 119:length(idx); item(i-119+1) = string(atom_symbols(i)); end
            app.Compounds_DropDown.Items = item;
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run App_MatBase;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            % Remove the UI handle and restart
            close(gcbf); run App_MatBase_IMFP;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 930;
            screen_size(4) = 470;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            app.imfpData = extract_myData(app);
            disp('imfpData : '); disp(app.imfpData);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            app.imfpData = extract_myData(app);
            assignin('base', 'imfpData', app.imfpData);
        end

        % Button pushed function: SAVE_Button
        function SAVE_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...');
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            app.imfpData = extract_myData(app);
            dataStr = app.imfpData; 
            save(char(save_fullfile), 'dataStr', '-v7.3');
            disp('-> saved data : '); display(app.imfpData);
            close(wbar);
            %% 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Selection changed function: PeriodicTable_ButtonGroup
        function PeriodicTable_ButtonGroupSelectionChanged(app, event)
            % 1 - Extracting the input defined by the user
            selectedButton = app.PeriodicTable_ButtonGroup.SelectedObject;
            element = selectedButton.Text;
            app.Material_EditField.Value = element; Material_EditFieldValueChanged(app);
        end

        % Value changed function: Material_EditField
        function Material_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value           = app.Material_EditField.Value;
            app.material    = string(value);
            % 2 - Extracting the material parameters from the materials database
            mat_props = get_mpd_props(app.material);
            if ~isempty(mat_props)
                app.density         = mat_props.DENSITY;
                app.ele_valency     = mat_props.ELECT_VALENCY;
                app.atom_weight     = mat_props.ATOM_MASS;
                app.atom_znum       = mat_props.ATOM_ZNUM;
                app.ele_bgap        = mat_props.ELE_BGAP;
                app.stoic           = mat_props.STOICHIOMETRY;
                app.Material_Lamp.Color = [0 1 0];
            else
                app.Material_Lamp.Color = [1 0 0];
            end
            % 3 - Updating the materials parameters
            app.Density_EditField.Value     = app.density;
            app.Valency_EditField.Value     = app.ele_valency;
            app.AtomWeight_EditField.Value  = app.atom_weight;
            app.AtomZ_EditField.Value       = app.atom_znum;
            app.Bandgap_EditField.Value     = app.ele_bgap;
            app.Stoiciometry_EditField.Value     = app.stoic;
            % 4 - Assigning output and printing change
            fprintf("-> material: " + string(app.material) + "\n");
            % 5 - Calculating IMFP
            calculate_imfp(app);
        end

        % Value changed function: Density_EditField
        function Density_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Density_EditField.Value;
            app.density = value;
            % 2 - Validity check on the material
            mat_props = get_mpd_props(app.material);
            if ~isempty(mat_props)
                mat_density = mat_props.DENSITY;
                if app.density == mat_density;  app.Material_Lamp.Color = [0 1 0];
                else;                           app.Material_Lamp.Color = [1 0 0];
                end
            else; app.Material_Lamp.Color = [1 0 0];
            end
            % 3 - Calculating IMFP
            calculate_imfp(app);
        end

        % Value changed function: Valency_EditField
        function Valency_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Valency_EditField.Value;
            app.ele_valency = value;
            % 2 - Validity check on the material
            mat_props = get_mpd_props(app.material);
            if ~isempty(mat_props)
                mat_ele_valency = mat_props.ELECT_VALENCY;
                if app.ele_valency == mat_ele_valency;  app.Material_Lamp.Color = [0 1 0];
                else;                                   app.Material_Lamp.Color = [1 0 0];
                end
            else; app.Material_Lamp.Color = [1 0 0];
            end
            % 3 - Calculating IMFP
            calculate_imfp(app);
        end

        % Value changed function: AtomWeight_EditField
        function AtomWeight_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.AtomWeight_EditField.Value;
            app.atom_weight = value;
            % 2 - Validity check on the material
            mat_props = get_mpd_props(app.material);
            if ~isempty(mat_props)
                mat_atom_weight = mat_props.ATOM_MASS;
                if app.atom_weight == mat_atom_weight;  app.Material_Lamp.Color = [0 1 0];
                else;                                   app.Material_Lamp.Color = [1 0 0];
                end
            else; app.Material_Lamp.Color = [1 0 0];
            end
            % 3 - Calculating IMFP
            calculate_imfp(app);
        end

        % Value changed function: AtomZ_EditField
        function AtomZ_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.AtomZ_EditField.Value;
            app.atom_znum = value;
            % 2 - Validity check on the material
            mat_props = get_mpd_props(app.material);
            if ~isempty(mat_props)
                mat_atom_znum = mat_props.ATOM_ZNUM;
                if app.atom_znum == mat_atom_znum;      app.Material_Lamp.Color = [0 1 0];
                else;                                   app.Material_Lamp.Color = [1 0 0];
                end
            else; app.Material_Lamp.Color = [1 0 0];
            end
            % 3 - Calculating IMFP
            calculate_imfp(app);
        end

        % Value changed function: Bandgap_EditField
        function Bandgap_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Bandgap_EditField.Value;
            app.ele_bgap = value;
            % 2 - Validity check on the material
            mat_props = get_mpd_props(app.material);
            if ~isempty(mat_props)
                mat_ele_bgap = mat_props.ELE_BGAP;
                if app.ele_bgap == mat_ele_bgap;    app.Material_Lamp.Color = [0 1 0];
                else;                               app.Material_Lamp.Color = [1 0 0];
                end
            else; app.Material_Lamp.Color = [1 0 0];
            end
            % 3 - Calculating IMFP
            calculate_imfp(app);
        end

        % Value changed function: Stoiciometry_EditField
        function Stoiciometry_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Stoiciometry_EditField.Value;
            app.stoic = value;
            % 2 - Validity check on the material
            mat_props = get_mpd_props(app.material);
            if ~isempty(mat_props)
                mat_stoic = mat_props.STOICHIOMETRY;
                if app.stoic == mat_stoic;          app.Material_Lamp.Color = [0 1 0];
                else;                               app.Material_Lamp.Color = [1 0 0];
                end
            else; app.Material_Lamp.Color = [1 0 0];
            end
            % 3 - Calculating IMFP
            calculate_imfp(app);
        end

        % Value changed function: ElecEnergy_EditField
        function ElecEnergy_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.ElecEnergy_EditField.Value;
            app.ele_kin = value;  
            fprintf("--> Electron Energy: " + string(app.ele_kin) + " eV\n");
            % 2 - Calculating IMFP
            calculate_imfp(app);
        end

        % Selection changed function: CalcFormalism_ButtonGroup
        function CalcFormalism_ButtonGroupSelectionChanged(app, event)
            % 1 - Extracting the input defined by the user
            selectedButton  = app.CalcFormalism_ButtonGroup.SelectedObject;
            app.method      = string(selectedButton.Text);
            fprintf("--> method: " + string(app.method) + "\n");
            % 2 - Calculating IMFP
            calculate_imfp(app);
        end

        % Value changed function: Compounds_DropDown
        function Compounds_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Compounds_DropDown.Value;
            compound = value;
            app.Material_EditField.Value = compound; Material_EditFieldValueChanged(app);
        end

        % Button pushed function: PlotIMFPData_Button
        function PlotIMFPData_ButtonPushed(app, event)
            % 1 - Calculate all IMFPs  based on current inputs
            xx = linspace(app.ele_kin_min, app.ele_kin_max, 1e4);
            % -- Unversal curve from Seah (1979)
            label{1} = "Universal";     yy{1} = imfp_universal(xx);
            % -- TPP2M predictive equations from Tanuma (1994)
            label{2} = "TPP-2M";        yy{2} = imfp_tpp2m(xx, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            label{3} = "TPP-2M (avg.)"; yy{3} = imfp_tpp2m_avg(xx);
            % -- S1 & S2 predictive equations from Seah (2011)
            label{4} = "S1";            yy{4} = imfp_S1(xx, app.density, app.atom_weight, app.ele_bgap, app.atom_znum);
            label{5} = "S2";            yy{5} = imfp_S2(xx, app.atom_znum);
            % -- S3 & S4 predictive equations from Seah (2012)
            label{6} = "S3";            yy{6} = eal_S3(xx, app.density, app.atom_weight, app.atom_znum, app.stoic);
            label{7} = "S4";            yy{7} = eal_S4(xx, app.atom_znum);
            % -- JTP 2023 predictive equations
            label{8} = "JTP";           yy{8} = imfp_jtp(xx, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            % 2 - Plotting the figure
            fig = figure(); hold on; fig.Position(3) = 600; fig.Position(4) = 450;
            grid on; grid minor;
            cols = lines(12);
            % - Unversal curves
            loglog(xx, yy{1}, '-', 'color', 'k', 'LineWidth', 3.0);
            % - TPP2M predictive equations
            loglog(xx, yy{2}, '-', 'color', cols(4,:), 'LineWidth', 1.5);
            loglog(xx, yy{3}, ':', 'color', cols(4,:), 'LineWidth', 1.5);
            % - S1 & S2 predictive equations
            loglog(xx, yy{4}, '-', 'color', cols(5,:), 'LineWidth', 1.5);
            loglog(xx, yy{5}, ':', 'color', cols(5,:), 'LineWidth', 1.5);
            % - S3 & S4 predictive equations
            loglog(xx, yy{6}, '-', 'color', cols(6,:), 'LineWidth', 1.5);
            loglog(xx, yy{7}, ':', 'color', cols(6,:), 'LineWidth', 1.5);
            % - JTP predictive equations
            loglog(xx, yy{8}, '-', 'color', cols(7,:), 'LineWidth', 1.5);
            % 3 - Formatting the figure
            % -- Plotting the x- and y-axes
            a = xline(0, 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
            b = yline(0, 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            b.Annotation.LegendInformation.IconDisplayStyle = 'off';
            % -- Labelling the x- and y-axes
            xlabel('$$ \bf Electron\ Kinetic\ Energy\ [eV] $$', 'interpreter', 'latex');
            ylabel('$$ \bf IMFP\ [Angstrom] $$', 'interpreter', 'latex');
            ax = gca;
            ax.FontSize = 12;
            ax.XLim = [app.ele_kin_min-1, app.ele_kin_max+1]; 
            ax.YLim = [1, max(cell2mat(yy(:)))];
            ax.XScale = 'log'; ax.YScale = 'log';
            text(0.05, 0.07, sprintf("%s (Z=%i)", app.material, app.atom_znum), 'FontSize', 12, 'color', 'k', 'Units','normalized', 'FontWeight', 'bold');
            % -- Add a legend
            h       = zeros(6, 1);
            h(1)    = plot(NaN,NaN,'k-', 'LineWidth', 2.0);
            h(2)    = plot(NaN,NaN,'b-', 'LineWidth', 2.0, 'color',cols(4,:));
            h(3)    = plot(NaN,NaN,'b:', 'LineWidth', 2.0, 'color',cols(4,:));
            h(4)    = plot(NaN,NaN,'g-', 'LineWidth', 2.0, 'color',cols(5,:));
            h(5)    = plot(NaN,NaN,'g:', 'LineWidth', 2.0, 'color',cols(5,:));
            h(6)    = plot(NaN,NaN,'r-', 'LineWidth', 2.0, 'color',cols(6,:));
            h(7)    = plot(NaN,NaN,'r:', 'LineWidth', 2.0, 'color',cols(6,:));
            h(8)    = plot(NaN,NaN,'r-', 'LineWidth', 2.0, 'color',cols(7,:));
            legend(h, 'Universal(Seah-1979)','TPP-2M(Tanuma-1994)','TPP-2M(avg)(Tanuma-1994)','S1(Seah-2011)','S2(Seah-2011)','S3[EAL](Seah-2012)','S4[EAL](Seah-2012)', 'JTP(2023)', 'fontsize', 7, 'location', 'northwest');
        end

        % Value changed function: MinEk_EditField
        function MinEk_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.MinEk_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value)
                value = 1;
            elseif value > app.ele_kin_max
                value = app.ele_kin_max;
            end
            % 3 - Validity check on the input value
            app.ele_kin_min = value;
            app.MinEk_EditField.Value     = app.ele_kin_min;
        end

        % Value changed function: MaxEk_EditField
        function MaxEk_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.MaxEk_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value)
                value = 1500;
            elseif value < app.ele_kin_min
                value = app.ele_kin_min;
            end
            % 3 - Validity check on the input value
            app.ele_kin_max = value;
            app.MaxEk_EditField.Value     = app.ele_kin_max;
        end

        % Button pushed function: ExportIMFPData_Button
        function ExportIMFPData_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.txt'};
            fname = string(yyyymmdd(datetime)) + "_" + string(app.material) + "_" + string(app.method);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Calculate the IMFPs  based on current inputs
            xx = app.ele_kin_min:1:app.ele_kin_max; xx = xx';
            if strcmpi(app.method,"TPP-2M");            yy = imfp_tpp2m(xx, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            elseif strcmpi(app.method,"TPP-2M (avg.)"); yy = imfp_tpp2m_avg(xx);
            elseif strcmpi(app.method,"Universal");     yy = imfp_universal(xx);
            elseif strcmpi(app.method,"S1");            yy = imfp_S1(xx, app.density, app.atom_weight, app.ele_bgap, app.atom_znum);
            elseif strcmpi(app.method,"S2");            yy = imfp_S2(xx, app.atom_znum);
            elseif strcmpi(app.method,"S3");            yy = eal_S3(xx, app.density, app.atom_weight, app.atom_znum, app.stoic);
            elseif strcmpi(app.method,"S4");            yy = eal_S4(xx, app.atom_znum);
            elseif strcmpi(app.method,"JTP");           yy = imfp_jtp(xx, app.density, app.ele_valency, app.atom_weight, app.ele_bgap);
            end
            % 3 - Construct table to save data
            Ek_eV = xx; IMFP_Angstrom = yy; T = table(Ek_eV,IMFP_Angstrom);
            writetable(T,char(save_fullfile),'Delimiter','\t');
            waitbar(1,'Save complete!');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Global_GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.Global_GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.Global_GridLayout);
            app.AppTitle.Text = 'Electron Inelastic Mean Free Path Calculator';
            app.AppButtons_GridLayout = uigridlayout(app.Global_GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.SAVE_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.SAVE_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVE_ButtonPushed, true);
            app.SAVE_Button.Tooltip = {'Save the data from the app as a MATLAB data structure file.'};
            app.SAVE_Button.Text = 'Save As... (.mat)';
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Achieve unparalleled precision in your calculations of electron inelastic mean free path (IMFP) with our app. Utilize a range of formalisms, including the Optical (NIST), Universal, TPP-2M, S1, S2, S3 & S4 methods, to achieve the most accurate results. Conveniently access material parameters from the Materials Properties Database or manually enter data for materials not included in the database. '};
            app.Image.ImageSource = 'Icon_IMFP.jpeg';
            app.Materials_Panel = uipanel(app.Global_GridLayout);
            app.GridLayout = uigridlayout(app.Materials_Panel);
            app.MaterialEditFieldLabel = uilabel(app.GridLayout);
            app.MaterialEditFieldLabel.Text = 'Material:';
            app.Material_EditField = uieditfield(app.GridLayout, 'text');
            app.Material_EditField.ValueChangedFcn = createCallbackFcn(app, @Material_EditFieldValueChanged, true);
            app.Material_EditField.Tooltip = {'Enter an element / compound to search for the crystal structure parameters in the Materials Properties Database.'};
            app.Material_EditField.Value = 'Si';
            app.Material_Lamp = uilamp(app.GridLayout);
            app.Material_Lamp.Tooltip = {'If green, input parameters match the Materials Properties Database.'};
            app.AvailableCompoundsLabel = uilabel(app.GridLayout);
            app.AvailableCompoundsLabel.Text = 'Available Compounds:';
            app.Compounds_DropDown = uidropdown(app.GridLayout);
            app.Compounds_DropDown.ValueChangedFcn = createCallbackFcn(app, @Compounds_DropDownValueChanged, true);
            app.Compounds_DropDown.Tooltip = {'Select an available compound from the list taken from the Materials Properties Database.'};
            app.PeriodicTable_ButtonGroup = uibuttongroup(app.Global_GridLayout);
            app.PeriodicTable_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PeriodicTable_ButtonGroupSelectionChanged, true);
            app.PeriodicTable_ButtonGroup.Tooltip = {'Select an element.'};
            app.H_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.H_Button.Text = 'H';
            app.He_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.He_Button.Text = 'He';
            app.Li_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Li_Button.Text = 'Li';
            app.Be_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Be_Button.Text = 'Be';
            app.B_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.B_Button.Text = 'B';
            app.C_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.C_Button.Text = 'C';
            app.N_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.N_Button.Text = 'N';
            app.O_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.O_Button.Text = 'O';
            app.F_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.F_Button.Text = 'F';
            app.Ne_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ne_Button.Text = 'Ne';
            app.Na_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Na_Button.Text = 'Na';
            app.Mg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mg_Button.Text = 'Mg';
            app.Al_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Al_Button.Text = 'Al';
            app.Si_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Si_Button.Text = 'Si';
            app.Si_Button.Value = true;
            app.P_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.P_Button.Text = 'P';
            app.S_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.S_Button.Text = 'S';
            app.Cl_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cl_Button.Text = 'Cl';
            app.Ar_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ar_Button.Text = 'Ar';
            app.K_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.K_Button.Text = 'K';
            app.Ca_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ca_Button.Text = 'Ca';
            app.Sc_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sc_Button.Text = 'Sc';
            app.Ti_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ti_Button.Text = 'Ti';
            app.V_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.V_Button.Text = 'V';
            app.Cr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cr_Button.Text = 'Cr';
            app.Mn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mn_Button.Text = 'Mn';
            app.Fe_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fe_Button.Text = 'Fe';
            app.Co_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Co_Button.Text = 'Co';
            app.Ni_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ni_Button.Text = 'Ni';
            app.Cu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cu_Button.Text = 'Cu';
            app.Zn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Zn_Button.Text = 'Zn';
            app.Ga_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ga_Button.Text = 'Ga';
            app.Ge_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ge_Button.Text = 'Ge';
            app.As_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.As_Button.Text = 'As';
            app.Se_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Se_Button.Text = 'Se';
            app.Br_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Br_Button.Text = 'Br';
            app.Kr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Kr_Button.Text = 'Kr';
            app.Rb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rb_Button.Text = 'Rb';
            app.Sr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sr_Button.Text = 'Sr';
            app.Y_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Y_Button.Text = 'Y';
            app.Zr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Zr_Button.Text = 'Zr';
            app.Nb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Nb_Button.Text = 'Nb';
            app.Mo_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mo_Button.Text = 'Mo';
            app.Tc_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tc_Button.Text = 'Tc';
            app.Ru_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ru_Button.Text = 'Ru';
            app.Rh_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rh_Button.Text = 'Rh';
            app.Pd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pd_Button.Text = 'Pd';
            app.Ag_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ag_Button.Text = 'Ag';
            app.Cd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cd_Button.Text = 'Cd';
            app.In_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.In_Button.Text = 'In';
            app.Sn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sn_Button.Text = 'Sn';
            app.Sb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sb_Button.Text = 'Sb';
            app.Te_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Te_Button.Text = 'Te';
            app.I_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.I_Button.Text = 'I';
            app.Xe_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Xe_Button.Text = 'Xe';
            app.Cs_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cs_Button.Text = 'Cs';
            app.Ba_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ba_Button.Text = 'Ba';
            app.La_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.La_Button.Text = 'La';
            app.Hf_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Hf_Button.Text = 'Hf';
            app.Ta_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ta_Button.Text = 'Ta';
            app.W_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.W_Button.Text = 'W';
            app.Re_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Re_Button.Text = 'Re';
            app.Os_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Os_Button.Text = 'Os';
            app.Ir_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ir_Button.Text = 'Ir';
            app.Pt_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pt_Button.Text = 'Pt';
            app.Au_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Au_Button.Text = 'Au';
            app.Hg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Hg_Button.Text = 'Hg';
            app.Tl_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tl_Button.Text = 'Tl';
            app.Pb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pb_Button.Text = 'Pb';
            app.Bi_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Bi_Button.Text = 'Bi';
            app.Po_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Po_Button.Text = 'Po';
            app.At_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.At_Button.Text = 'At';
            app.Rn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rn_Button.Text = 'Rn';
            app.Fr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fr_Button.Text = 'Fr';
            app.Ra_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ra_Button.Text = 'Ra';
            app.Ac_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ac_Button.Text = 'Ac';
            app.Rf_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rf_Button.Text = 'Rf';
            app.Db_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Db_Button.Text = 'Db';
            app.Sg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sg_Button.Text = 'Sg';
            app.Bh_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Bh_Button.Text = 'Bh';
            app.Hs_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Hs_Button.Text = 'Hs';
            app.Mt_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mt_Button.Text = 'Mt';
            app.Ds_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ds_Button.Text = 'Ds';
            app.Rg_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Rg_Button.Text = 'Rg';
            app.Cn_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cn_Button.Text = 'Cn';
            app.Nh_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Nh_Button.Text = 'Nh';
            app.Fl_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fl_Button.Text = 'Fl';
            app.Mc_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Mc_Button.Text = 'Mc';
            app.Lv_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Lv_Button.Text = 'Lv';
            app.Ts_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ts_Button.Text = 'Ts';
            app.Og_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Og_Button.Text = 'Og';
            app.Ce_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ce_Button.Text = 'Ce';
            app.Pr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pr_Button.Text = 'Pr';
            app.Nd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Nd_Button.Text = 'Nd';
            app.Pm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pm_Button.Text = 'Pm';
            app.Sm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Sm_Button.Text = 'Sm';
            app.Eu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Eu_Button.Text = 'Eu';
            app.Gd_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Gd_Button.Text = 'Gd';
            app.Tb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tb_Button.Text = 'Tb';
            app.Dy_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Dy_Button.Text = 'Dy';
            app.Ho_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Ho_Button.Text = 'Ho';
            app.Er_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Er_Button.Text = 'Er';
            app.Tm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Tm_Button.Text = 'Tm';
            app.Yb_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Yb_Button.Text = 'Yb';
            app.Lu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Lu_Button.Text = 'Lu';
            app.Th_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Th_Button.Text = 'Th';
            app.Pa_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pa_Button.Text = 'Pa';
            app.U_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.U_Button.Text = 'U';
            app.Np_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Np_Button.Text = 'Np';
            app.Pu_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Pu_Button.Text = 'Pu';
            app.Am_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Am_Button.Text = 'Am';
            app.Cm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cm_Button.Text = 'Cm';
            app.Bk_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Bk_Button.Text = 'Bk';
            app.Cf_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Cf_Button.Text = 'Cf';
            app.Es_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Es_Button.Text = 'Es';
            app.Fm_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Fm_Button.Text = 'Fm';
            app.Md_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Md_Button.Text = 'Md';
            app.No_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.No_Button.Text = 'No';
            app.Lr_Button = uitogglebutton(app.PeriodicTable_ButtonGroup);
            app.Lr_Button.Text = 'Lr';
            app.Label_1 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_1.Text = '1';
            app.Label_2 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_8.Text = '8';
            app.Label_9 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_9.Text = '9';
            app.Label_10 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_10.Text = '10';
            app.Label_11 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_11.Text = '11';
            app.Label_12 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_12.Text = '12';
            app.Label_13 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_13.Text = '13';
            app.Label_14 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_14.Text = '14';
            app.Label_15 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_15.Text = '15';
            app.Label_16 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_16.Text = '16';
            app.Label_17 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_17.Text = '17';
            app.Label_18 = uilabel(app.PeriodicTable_ButtonGroup);
            app.Label_18.Text = '18';
            app.MatParams_Panel = uipanel(app.Global_GridLayout);
            app.MatParams_Panel.Title = 'Material Parameters';
            app.MatParams_GridLayout = uigridlayout(app.MatParams_Panel);
            app.Bulkdensitygcm3Label = uilabel(app.MatParams_GridLayout);
            app.Bulkdensitygcm3Label.Text = 'Bulk density (g/cm^3):';
            app.Density_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.Density_EditField.Limits = [0 Inf];
            app.Density_EditField.ValueDisplayFormat = '%.3f';
            app.Density_EditField.ValueChangedFcn = createCallbackFcn(app, @Density_EditFieldValueChanged, true);
            app.Density_EditField.Tooltip = {'Enter the bulk density of the material in grams per cubic cm (g/cm^3).'};
            app.Density_EditField.Value = 2.33;
            app.ValencyEditFieldLabel = uilabel(app.MatParams_GridLayout);
            app.ValencyEditFieldLabel.Text = 'Valency:';
            app.Valency_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.Valency_EditField.Limits = [0 Inf];
            app.Valency_EditField.ValueDisplayFormat = '%.0f';
            app.Valency_EditField.ValueChangedFcn = createCallbackFcn(app, @Valency_EditFieldValueChanged, true);
            app.Valency_EditField.Tooltip = {'Enter the valency of the material, which is the total number of electrons located in the outermost unoccupied electron shell.'};
            app.Valency_EditField.Value = 4;
            app.AtomicweightamuLabel = uilabel(app.MatParams_GridLayout);
            app.AtomicweightamuLabel.Text = 'Atomic weight (amu):';
            app.AtomWeight_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.AtomWeight_EditField.Limits = [0 Inf];
            app.AtomWeight_EditField.ValueDisplayFormat = '%.3f';
            app.AtomWeight_EditField.ValueChangedFcn = createCallbackFcn(app, @AtomWeight_EditFieldValueChanged, true);
            app.AtomWeight_EditField.Tooltip = {'Enter the atomic weight of the material, or average if a compound is used.'};
            app.AtomWeight_EditField.Value = 28.085;
            app.AtomicmassZLabel = uilabel(app.MatParams_GridLayout);
            app.AtomicmassZLabel.Text = 'Atomic mass (Z):';
            app.AtomZ_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.AtomZ_EditField.Limits = [0 Inf];
            app.AtomZ_EditField.ValueDisplayFormat = '%.3f';
            app.AtomZ_EditField.ValueChangedFcn = createCallbackFcn(app, @AtomZ_EditFieldValueChanged, true);
            app.AtomZ_EditField.Tooltip = {'Enter the atomic Z number of the material, or average if a compound is used.'};
            app.AtomZ_EditField.Value = 14;
            app.BandgapenergyeVLabel = uilabel(app.MatParams_GridLayout);
            app.BandgapenergyeVLabel.Text = 'Band gap energy (eV):';
            app.Bandgap_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.Bandgap_EditField.Limits = [0 Inf];
            app.Bandgap_EditField.ValueDisplayFormat = '%.3f';
            app.Bandgap_EditField.ValueChangedFcn = createCallbackFcn(app, @Bandgap_EditFieldValueChanged, true);
            app.Bandgap_EditField.Tooltip = {'Enter the electron band-gap energy of the material in electron-Volts (eV).'};
            app.Bandgap_EditField.Value = 1.107;
            app.BandgapenergyeVLabel_2 = uilabel(app.MatParams_GridLayout);
            app.BandgapenergyeVLabel_2.Text = 'Stoichiometry:';
            app.Stoiciometry_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.Stoiciometry_EditField.Limits = [0 Inf];
            app.Stoiciometry_EditField.ValueDisplayFormat = '%.0f';
            app.Stoiciometry_EditField.ValueChangedFcn = createCallbackFcn(app, @Stoiciometry_EditFieldValueChanged, true);
            app.Stoiciometry_EditField.Tooltip = {'Enter the stoiciometry of the material (e.g. for elements, stoic = 1, for molecules of the form G_gH_h, the stoiciometry is g + h).'};
            app.Stoiciometry_EditField.Value = 1;
            app.CalcFormalism_ButtonGroup = uibuttongroup(app.Global_GridLayout);
            app.CalcFormalism_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalcFormalism_ButtonGroupSelectionChanged, true);
            app.CalcFormalism_ButtonGroup.Title = 'Calculator Formalism';
            app.Universal_Button = uitogglebutton(app.CalcFormalism_ButtonGroup);
            app.Universal_Button.Tooltip = {'Determines the universal electron IMFP in elements based on the KE^0.5 equation described by M. P. Seah [1]. This gives a good, first order approximation of the IMFP values, with the only required input being the electron kinetic energy. See reference [1] for more information.'; '[1] M. P. Seah, Quantitative electron spectroscopy of surfaces A Standard Data Base for Electron Inelastic Mean Free Paths in Solids (1979)'};
            app.Universal_Button.Text = 'Universal';
            app.IMFP_Label = uilabel(app.CalcFormalism_ButtonGroup);
            app.IMFP_Label.Text = 'IMFP:';
            app.EAL_Label = uilabel(app.CalcFormalism_ButtonGroup);
            app.EAL_Label.Text = 'EAL:';
            app.TPP2M_Button = uitogglebutton(app.CalcFormalism_ButtonGroup);
            app.TPP2M_Button.Tooltip = {'Determines the electron IMFP based on the TPP-2M energy compensation factor, which is a more accurate method than the standard universal curve determination. The input parameters of the materials are now required. The TPP-2M equation is also relativistically corrected here. See references [1-4] for more information.'; '[1] S. Tanuma, Calculations of Electron Inelastic Mean Free Paths. V. Data for 14 Organic Compounds over the 50-2000 eV Range (1994)'; '[2] S. Tanuma, Calculation of electron inelastic mean free paths (IMFPs) VII. Reliability of the TPP-2M IMFP predictive equation (2003)'; '[3] S. Tanuma, Calculations of electron inelasticmean free paths. IX. Data for 41 elemental solids over the 50 eV to 30 keV range (2011)'; '[4] M. P. Seah, An accurate and simple universal curve for the energy-dependent electron inelastic mean free path (2011)'};
            app.TPP2M_Button.Text = 'TPP-2M';
            app.S1_Button = uitogglebutton(app.CalcFormalism_ButtonGroup);
            app.S1_Button.Tooltip = {'Determines the electron IMFP based on the S1 equation described by M. P. Seah [1]. The input parameters of the materials are now required and this formalism is compatible for elemental or binary materials. See reference [1] for more information.'; '[1] M. P. Seah, An accurate and simple universal curve for the energy-dependent electron inelastic mean free path (2011)'};
            app.S1_Button.Text = 'S1';
            app.S3_Button = uitogglebutton(app.CalcFormalism_ButtonGroup);
            app.S3_Button.Tooltip = {'Determines the electron effective attenuation length (EAL) based on S3 formalism described by M. P. Seah [1]. The input parameters of the materials are now required and this formalism is compatible for elemental or binary materials. See reference [1] for more information.'; '[1] M. P. Seah, Simple universal curve for the energy‐dependent electron attenuation length (2012)'};
            app.S3_Button.Text = 'S3';
            app.TPP2Mavg_Button = uitogglebutton(app.CalcFormalism_ButtonGroup);
            app.TPP2Mavg_Button.Tooltip = {'Determines the electron IMFP that is based on the average trend line of photoelectron kinetic energies for multiple material systems. This method is considered better than the universal method, but less precise than the TPP-2M calculations, where the specific material parameters are used. No material parameters are used in this calculation. See references [1-4] for more information.'; '[1] S. Tanuma, Calculations of Electron Inelastic Mean Free Paths. V. Data for 14 Organic Compounds over the 50-2000 eV Range (1994)'; '[2] S. Tanuma, Calculation of electron inelastic mean free paths (IMFPs) VII. Reliability of the TPP-2M IMFP predictive equation (2003)'; '[3] S. Tanuma, Calculations of electron inelasticmean free paths. IX. Data for 41 elemental solids over the 50 eV to 30 keV range (2011)'; '[4] M. P. Seah, An accurate and simple universal curve for the energy-dependent electron inelastic mean free path (2011)'};
            app.TPP2Mavg_Button.Text = 'TPP-2M (avg.)';
            app.S2_Button = uitogglebutton(app.CalcFormalism_ButtonGroup);
            app.S2_Button.Tooltip = {'Determines the electron IMFP based on the S2 equation described by M. P. Seah [1]. The IMFP here only depends on the value of Z. See reference [1] for more information.'; '[1] M. P. Seah, An accurate and simple universal curve for the energy-dependent electron inelastic mean free path (2011)'};
            app.S2_Button.Text = 'S2';
            app.S4_Button = uitogglebutton(app.CalcFormalism_ButtonGroup);
            app.S4_Button.Tooltip = {'Determines the electron effective attenuation length (EAL) based on S4 formalism described by M. P. Seah [1]. The EAL here only depends on the value of Z. See reference [1] for more information.'; '[1] M. P. Seah, Simple universal curve for the energy‐dependent electron attenuation length (2012)'};
            app.S4_Button.Text = 'S4';
            app.JTP_Button = uitogglebutton(app.CalcFormalism_ButtonGroup);
            app.JTP_Button.Tooltip = {'[Default Method for PES analysis]'; 'Determines the electron IMFP based on the JTP method, which is a more accurate method than the standard universal curve and TPP-2M determination. The input parameters of the materials are required. See references [1] for more information.'; '[1] Jablonski A, Tanuma S, Powell CJ. Surf Interface Anal. 2023; 55(8): 609-637. doi:10.1002/sia.7217'};
            app.JTP_Button.Text = 'JTP';
            app.JTP_Button.Value = true;
            app.ElecParams_Panel = uipanel(app.Global_GridLayout);
            app.ElecParams_Panel.Title = 'Electron Parameters';
            app.ElecParams_GridLayout = uigridlayout(app.ElecParams_Panel);
            app.ElectronEnergyeVLabel = uilabel(app.ElecParams_GridLayout);
            app.ElectronEnergyeVLabel.Text = 'Electron Kinetic Energy (eV):';
            app.ElecEnergy_EditField = uieditfield(app.ElecParams_GridLayout, 'numeric');
            app.ElecEnergy_EditField.Limits = [0 Inf];
            app.ElecEnergy_EditField.ValueDisplayFormat = '%.0f';
            app.ElecEnergy_EditField.ValueChangedFcn = createCallbackFcn(app, @ElecEnergy_EditFieldValueChanged, true);
            app.ElecEnergy_EditField.Tooltip = {'Enter the electron kinetic energy in electron-Volts (eV).'};
            app.ElecEnergy_EditField.Value = 1000;
            app.CalculatedIMFPnmLabel = uilabel(app.ElecParams_GridLayout);
            app.CalculatedIMFPnmLabel.Text = 'IMFP (Angstrom):';
            app.IMFP_EditField = uieditfield(app.ElecParams_GridLayout, 'numeric');
            app.IMFP_EditField.Limits = [0 Inf];
            app.IMFP_EditField.ValueDisplayFormat = '%.3f';
            app.IMFP_EditField.Editable = 'off';
            app.IMFP_EditField.Tooltip = {'The calculated IMFP value using the chosen calculator formalism.'};
            app.IMFP_EditField.Value = 21.085;
            app.CalculatedIMFPnmLabel_2 = uilabel(app.ElecParams_GridLayout);
            app.CalculatedIMFPnmLabel_2.Text = 'Std. Dev. Of All IMFP (Angstrom):';
            app.dIMFP_EditField = uieditfield(app.ElecParams_GridLayout, 'numeric');
            app.dIMFP_EditField.Limits = [0 Inf];
            app.dIMFP_EditField.ValueDisplayFormat = '%.3f';
            app.dIMFP_EditField.Editable = 'off';
            app.dIMFP_EditField.Tooltip = {'The standard deviation of the IMFP value obtained from all the calculator formalisms. Useful for estimating the precision of the IMFP value.'};
            app.dIMFP_EditField.Value = 2.725;
            app.ExportDataRange_Panel = uipanel(app.Global_GridLayout);
            app.ExportDataRange_Panel.Title = 'Export Data Range';
            app.ExportDataRange_GridLayout = uigridlayout(app.ExportDataRange_Panel);
            app.MineVEditFieldLabel = uilabel(app.ExportDataRange_GridLayout);
            app.MineVEditFieldLabel.Text = 'Min (eV):';
            app.MinEk_EditField = uieditfield(app.ExportDataRange_GridLayout, 'numeric');
            app.MinEk_EditField.Limits = [0 Inf];
            app.MinEk_EditField.ValueChangedFcn = createCallbackFcn(app, @MinEk_EditFieldValueChanged, true);
            app.MinEk_EditField.Tooltip = {'Enter the minimum electron kinetic energy in electron-Volts (eV).'};
            app.MinEk_EditField.Value = 1;
            app.PlotIMFPData_Button = uibutton(app.ExportDataRange_GridLayout, 'push');
            app.PlotIMFPData_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotIMFPData_ButtonPushed, true);
            app.PlotIMFPData_Button.Tooltip = {'Plot the IMFP & EAL curve values using all methods over the data range defined by the min-max values. '};
            app.PlotIMFPData_Button.Text = 'Plot IMFP Curve';
            app.MaxeVEditFieldLabel = uilabel(app.ExportDataRange_GridLayout);
            app.MaxeVEditFieldLabel.Text = 'Max (eV):';
            app.MaxEk_EditField = uieditfield(app.ExportDataRange_GridLayout, 'numeric');
            app.MaxEk_EditField.Limits = [0 Inf];
            app.MaxEk_EditField.ValueChangedFcn = createCallbackFcn(app, @MaxEk_EditFieldValueChanged, true);
            app.MaxEk_EditField.Tooltip = {'Enter the maximum electron kinetic energy in electron-Volts (eV).'};
            app.MaxEk_EditField.Value = 9000;
            app.ExportIMFPData_Button = uibutton(app.ExportDataRange_GridLayout, 'push');
            app.ExportIMFPData_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportIMFPData_ButtonPushed, true);
            app.ExportIMFPData_Button.Tooltip = {'Export the calculated IMFP values over the electron kinetic energy range defined by the min-max values. Only the selected calculator formalism values will be exported.'};
            app.ExportIMFPData_Button.Text = 'Export Data (.txt)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_MatBase_IMFP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end