classdef App_MatBase_NLayerModeller02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Params_Panel                    matlab.ui.container.Panel
        Params_GridLayout               matlab.ui.container.GridLayout
        Formalism_IMFP_DropDown         matlab.ui.control.DropDown
        IMFPFormalismDropDownLabel      matlab.ui.control.Label
        Formalism_XSect_DropDown        matlab.ui.control.DropDown
        XSectFormalismLabel             matlab.ui.control.Label
        ExportSF_Panel                  matlab.ui.container.Panel
        ExportModel_GridLayout          matlab.ui.container.GridLayout
        ThetaDep_Panel                  matlab.ui.container.Panel
        ExportTheta_GridLayout          matlab.ui.container.GridLayout
        ExportDataMat_Theta_Button      matlab.ui.control.Button
        ExportData_Theta_Button         matlab.ui.control.Button
        ViewPESModel_Theta_Button       matlab.ui.control.Button
        MaxTheta_EditField              matlab.ui.control.NumericEditField
        MaxdegLabel                     matlab.ui.control.Label
        RunPESModel_Theta_Button        matlab.ui.control.Button
        MinTheta_EditField              matlab.ui.control.NumericEditField
        MindegLabel                     matlab.ui.control.Label
        PhotonEnergy_EditField          matlab.ui.control.NumericEditField
        PhotonEnergyeVEditFieldLabel    matlab.ui.control.Label
        PhotonEnergyDep_Panel           matlab.ui.container.Panel
        ExportPhotonEnergy_GridLayout   matlab.ui.container.GridLayout
        ExportDataMat_PhotonEnergy_Button  matlab.ui.control.Button
        ExportData_PhotonEnergy_Button  matlab.ui.control.Button
        ViewPESModel_PhotonEnergy_Button  matlab.ui.control.Button
        MaxEph_EditField                matlab.ui.control.NumericEditField
        MaxeVEditFieldLabel             matlab.ui.control.Label
        RunPESModel_PhotonEnergy_Button  matlab.ui.control.Button
        MinEph_EditField                matlab.ui.control.NumericEditField
        MineVEditFieldLabel             matlab.ui.control.Label
        theta_EditField                 matlab.ui.control.NumericEditField
        phidegLabel_3                   matlab.ui.control.Label
        Model_Panel                     matlab.ui.container.Panel
        NLayerStack_GridLayout          matlab.ui.container.GridLayout
        Bulk_UITable                    matlab.ui.control.Table
        LayerDelete_Button              matlab.ui.control.Button
        Layer_UITable                   matlab.ui.control.Table
        LayerAdd_Button                 matlab.ui.control.Button
        ViewSampleStack_Button          matlab.ui.control.Button
        Geometry_Panel                  matlab.ui.container.Panel
        Geometry_GridLayout             matlab.ui.container.GridLayout
        P_EditField                     matlab.ui.control.NumericEditField
        PEditFieldLabel                 matlab.ui.control.Label
        omega_EditField                 matlab.ui.control.NumericEditField
        phidegLabel_2                   matlab.ui.control.Label
        phi_EditField                   matlab.ui.control.NumericEditField
        phidegLabel                     matlab.ui.control.Label
        Geometry_Label                  matlab.ui.control.Label
        Geometry_Image                  matlab.ui.control.Image
        AppButtons_GridLayout           matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        TRANSFER_Button                 matlab.ui.control.Button
        APPINFO_Button                  matlab.ui.control.Button
        RESIZEUI_Button                 matlab.ui.control.Button
        RESTART_Button                  matlab.ui.control.Button
        CLOSEFIGS_Button                matlab.ui.control.Button
        AppTitle                        matlab.ui.control.Label
        MAINMENU_GridLayout             matlab.ui.container.GridLayout
        MAINMENU_Button                 matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % - MATLAB data structure to summarise all data for analysis
        hvDataPES
        thetaDataPES
        % - Variables for the app
        % --- Sample Stack
        tab_layers      % Table the contains all the surface layer material properties
        tab_bulk        % Table the contains all the bulk material properties 
        selected_elements        % Sting list of all core-levls being probed
        selected_corelevels      % Sting list of all core-levls being probed
        % --- Experimental Geometry
        phi             % Azimuthal angle between the photon momentum vector relative to the projection of the photoelectron vector on to the plane perpendicular to the electric field vector (i.e. normal emission = 0) [degree]
        omega           % Angle between the monochromator-sampe-lens
        P               % Degree of polarization, where 1 or 0 is equivalent to full linear polarization, and 0.5 is equivalent to unpolarized light.
        % --- Calculators & Formalisms 
        formalism_imfp  % string for imfp calculator formalism. Default:"TPP2M" ["Universal","TPP2M","TPP2M-avg","Optical","S1","S2"]
        formalism_xsect % string of the photoionization cross-section formalism to use. Default:"Cant2022" ["Scofield1973","YehLindau1985","Trzhaskovskaya2018","Cant2022"]
        % --- Variables for running the PES model
        eph             % photon energy defined by user
        theta           % Polar angle between the photoelectron vector relative to electric field vector (i.e. at normal emission: LV (p-pol, E//MP) = 0, LH (s-pol, E⊥MP) = 90) [degree]
        theta_min       % minimum emission angle (deg.)
        theta_max       % maximum emission angle (deg.)
        eph_min         % minimum photon energy (eV)
        eph_max         % maximum photon energy (eV)
    end
    
    % Private methods available to the app only
    methods (Access = private)
        % -- Load the current version of the MPD to extract all materials
        function list_of_all_mats = extract_materials(app)
            MPD_PCC = load('MPD_PCC'); MPD_PCC = MPD_PCC.MPD_PCC;
            MPD_PCC = sortrows(MPD_PCC,'ID');
            list_of_all_mats = MPD_PCC.ATOM_SYMB';
        end
        
        % -- For a given material, extract all elements it contains
        function elements = ele_from_mats(app, material)
            % 1 - Ensure the input is a char
            material = char(material);
            % 2 - Remove all numbers from the compound symbols
            material = material(isletter(material));
            % 3 - Convert it to a string and add a space at each capital letter
            up = isstrprop(material, 'upper');
            T  = blanks(length(material) + sum(up));
            T(cumsum(up + 1)) = material; T = string(T(2:end));
            elements = strsplit(T, ' ', 'CollapseDelimiters', false);
        end

        % -- For a given material, extract all possible core-levels from all elements it contains
        function list_of_all_cls = extract_core_levels(app, material)
            % - Extract all elements that are in the selected material
            elements = ele_from_mats(app, material);
            % - Extract all core-levels associated with each element
            list_of_all_cls = {};
            for i = 1:length(elements)
                [be, cls] = calc_be(elements(i), [], [], 0);
                [be, indx] = sort(be); cls = cls(indx);
                for j = 1:length(be); list_of_all_cls{end+1} = char(sprintf("%s%s-%.2feV",elements(i),cls(j),be(j))); end
            end
        end
        
        % -- Find the minimum photon energy that can be used for a given stack
        function min_eph_val = find_min_eph(app)
            % 1 - Extracting the list of all core-levels being probed
            full_selected_cls = vertcat(app.tab_layers{:,4}, app.tab_bulk{:,4});
            full_selected_cls = string(full_selected_cls);
            % 2 - Extracting a list of the elements and specific core-levels
            be = [];
            for i = 1:length(full_selected_cls)
                indx                    = strfind(full_selected_cls(i),'-');
                char_cls                = char(full_selected_cls(i));
                selected_element        = string(char_cls(1:indx-4));
                selected_cl             = string(char_cls(indx-3:indx-1));
                [be(i), ~]              = calc_be(selected_element, selected_cl, [], 0);
            end
            % 3 - Finding the minimum photon energy
            min_eph_val = abs(max(be(:)));
        end

        % -- Plot the sample stack to be determined
        function view_sample_stack(app)
            % -- Defining the sample stack to be modelled
            % - 1 - Defining the thickness of each layer
            col_name = app.tab_layers.Properties.VariableNames{2};
            lyr_thick_init = {}; lyr_thick_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % - 2 - Defining the material of each layer, from left to right (top-to-bottom)
            col_name = app.tab_layers.Properties.VariableNames{3};
            lyr_mat_init = {}; lyr_mat_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % - 3 - Defining the atomic concentration for each layer
            col_name = app.tab_layers.Properties.VariableNames{5};
            lyr_conc_init = {}; lyr_conc_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % - 4 - Defining the layer numbers
            col_name = app.tab_layers.Properties.VariableNames{1};
            lyr_num_init = {}; lyr_num_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            lyr_num_init{end} = lyr_num_init{end-1}+1; lyr_num_init = cell2mat(lyr_num_init);
            % - 5 - Formatting the layer data into a consistent form
            lyr_thick = {}; lyr_mat = {}; lyr_conc = {};
            for i = 1:length(lyr_num_init)
                lyr_thick{lyr_num_init(i)} = lyr_thick_init{i};
                if i == 1
                    lyr_mat{lyr_num_init(i)}{1} = lyr_mat_init{i};
                    lyr_conc{lyr_num_init(i)}{1} = lyr_conc_init{i};
                else
                    if lyr_num_init(i) == lyr_num_init(i-1)
                        lyr_mat{lyr_num_init(i)}{end+1} = lyr_mat_init{i};
                        lyr_conc{lyr_num_init(i)}{end+1} = lyr_conc_init{i};
                    else
                        lyr_mat{lyr_num_init(i)}{1} = lyr_mat_init{i};
                        lyr_conc{lyr_num_init(i)}{1} = lyr_conc_init{i};
                    end
                end
            end
            % -- Plotting the sample stack
            view_nlayer_model02_atmconc(lyr_mat, lyr_thick, lyr_conc);
        end

        % -- Extract the photon energy dependence PES model
        function hvDataPES = extract_myData_hvPES(app)
            % -- 1 - Defining the sample stack to be modelled
            % - 1.1 - Defining the thickness of each layer
            col_name = app.tab_layers.Properties.VariableNames{2};
            lyr_thick_init = {}; lyr_thick_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % - 1.2 - Defining the material of each layer, from left to right (top-to-bottom)
            col_name = app.tab_layers.Properties.VariableNames{3};
            lyr_mat_init = {}; lyr_mat_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % - 1.3 - Defining the core-level being probed for each layer
            col_name = app.tab_layers.Properties.VariableNames{4};
            lyr_cls_init = {}; lyr_cls_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            for i = 1:length(lyr_cls_init) 
                indx = strfind(lyr_cls_init{i},'-'); 
                lyr_elmts_init{i}    = lyr_cls_init{i}(1:indx-4); 
                lyr_cls_init{i}      = lyr_cls_init{i}(indx-3:indx-1); 
            end
            % - 1.4 - Defining the atomic concentration for each layer
            col_name = app.tab_layers.Properties.VariableNames{5};
            lyr_conc_init = {}; lyr_conc_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % - 1.5 - Defining the layer numbers
            col_name = app.tab_layers.Properties.VariableNames{1};
            lyr_num_init = {}; lyr_num_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            lyr_num_init{end} = lyr_num_init{end-1}+1; lyr_num_init = cell2mat(lyr_num_init);
            % - 2 - Formatting the layer data into a consistent form
            lyr_thick = {}; lyr_mat = {}; lyr_conc = {};
            for i = 1:length(lyr_num_init)
                lyr_thick{lyr_num_init(i)} = lyr_thick_init{i};
                if i == 1
                    lyr_mat{lyr_num_init(i)}{1}     = lyr_mat_init{i};
                    lyr_conc{lyr_num_init(i)}{1}    = lyr_conc_init{i};
                    lyr_elmts{lyr_num_init(i)}{1}   = lyr_elmts_init{i};
                    lyr_cls{lyr_num_init(i)}{1}     = lyr_cls_init{i};
                else
                    if lyr_num_init(i) == lyr_num_init(i-1)
                        lyr_mat{lyr_num_init(i)}{end+1} = lyr_mat_init{i};
                        lyr_conc{lyr_num_init(i)}{end+1} = lyr_conc_init{i};
                        lyr_elmts{lyr_num_init(i)}{end+1}   = lyr_elmts_init{i};
                        lyr_cls{lyr_num_init(i)}{end+1}     = lyr_cls_init{i};
                    else
                        lyr_mat{lyr_num_init(i)}{1} = lyr_mat_init{i};
                        lyr_conc{lyr_num_init(i)}{1} = lyr_conc_init{i};
                        lyr_elmts{lyr_num_init(i)}{1}   = lyr_elmts_init{i};
                        lyr_cls{lyr_num_init(i)}{1}     = lyr_cls_init{i};
                    end
                end
            end
            % -- 2 - Defining the photoemission geometry
            pes_hv      = app.eph_min:1:app.eph_max;
            % -- 3 - Extracting the PES intensity breakdown
            hvDataPES    = nlayer_model02_run(lyr_mat, lyr_conc, lyr_elmts, lyr_cls, lyr_thick, pes_hv, app.theta, app.phi, app.P, app.formalism_xsect, app.formalism_imfp,0);
            % -- 4 - Renormalising the PES intensities from excluded layers
            col_name = app.tab_layers.Properties.VariableNames{6};
            lyr_excl = {}; lyr_excl = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            if sum(cell2mat(lyr_excl)) ~= 0
                init_lyr_Inorm = hvDataPES.lyr_Inorm;
                % - Setting the layer intensity to zero for all excluded layers
                for i = 1:length(lyr_mat)
                    idx = 0;
                    for j = 1:length(lyr_mat{i}); idx = idx + 1; if lyr_excl{i} == 1; init_lyr_Inorm{i}{j} = 0.*init_lyr_Inorm{i}{j}; end; end
                end
                % - Renormalising the PES intensities of remaining layers
                lyr_norm = zeros(size(init_lyr_Inorm{1}{1}));
                for i = 1:hvDataPES.Nlyrs; for j = 1:length(lyr_mat{i}); lyr_norm =  lyr_norm + init_lyr_Inorm{i}{j}; end; end
                for i = 1:hvDataPES.Nlyrs; for j = 1:length(lyr_mat{i}); lyr_Inorm{i}{j} = init_lyr_Inorm{i}{j} ./ lyr_norm; end; end
                hvDataPES.lyr_Inorm = lyr_Inorm;
            end
        end

        % -- Plot the photon energy dependence PES model
        function view_myData_hvPES(app)
            if isempty(app.hvDataPES); fprintf('Cannot view as the model has not been run yet.\n');
            else; nlayer_model02_view(app.hvDataPES);
            end
        end

        % -- Extract the emission angle dependence PES model
        function thetaDataPES = extract_myData_thetaPES(app)
            % -- 1 - Defining the sample stack to be modelled
            % - 1.1 - Defining the thickness of each layer
            col_name = app.tab_layers.Properties.VariableNames{2};
            lyr_thick_init = {}; lyr_thick_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % - 1.2 - Defining the material of each layer, from left to right (top-to-bottom)
            col_name = app.tab_layers.Properties.VariableNames{3};
            lyr_mat_init = {}; lyr_mat_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % - 1.3 - Defining the core-level being probed for each layer
            col_name = app.tab_layers.Properties.VariableNames{4};
            lyr_cls_init = {}; lyr_cls_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            for i = 1:length(lyr_cls_init) 
                indx = strfind(lyr_cls_init{i},'-'); 
                lyr_elmts_init{i}    = lyr_cls_init{i}(1:indx-4); 
                lyr_cls_init{i}      = lyr_cls_init{i}(indx-3:indx-1); 
            end
            % - 1.4 - Defining the atomic concentration for each layer
            col_name = app.tab_layers.Properties.VariableNames{5};
            lyr_conc_init = {}; lyr_conc_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            % - 1.5 - Defining the layer numbers
            col_name = app.tab_layers.Properties.VariableNames{1};
            lyr_num_init = {}; lyr_num_init = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
            lyr_num_init{end} = lyr_num_init{end-1}+1; lyr_num_init = cell2mat(lyr_num_init);
            % - 2 - Formatting the layer data into a consistent form
            lyr_thick = {}; lyr_mat = {}; lyr_conc = {};
            for i = 1:length(lyr_num_init)
                lyr_thick{lyr_num_init(i)} = lyr_thick_init{i};
                if i == 1
                    lyr_mat{lyr_num_init(i)}{1}     = lyr_mat_init{i};
                    lyr_conc{lyr_num_init(i)}{1}    = lyr_conc_init{i};
                    lyr_elmts{lyr_num_init(i)}{1}   = lyr_elmts_init{i};
                    lyr_cls{lyr_num_init(i)}{1}     = lyr_cls_init{i};
                else
                    if lyr_num_init(i) == lyr_num_init(i-1)
                        lyr_mat{lyr_num_init(i)}{end+1} = lyr_mat_init{i};
                        lyr_conc{lyr_num_init(i)}{end+1} = lyr_conc_init{i};
                        lyr_elmts{lyr_num_init(i)}{end+1}   = lyr_elmts_init{i};
                        lyr_cls{lyr_num_init(i)}{end+1}     = lyr_cls_init{i};
                    else
                        lyr_mat{lyr_num_init(i)}{1} = lyr_mat_init{i};
                        lyr_conc{lyr_num_init(i)}{1} = lyr_conc_init{i};
                        lyr_elmts{lyr_num_init(i)}{1}   = lyr_elmts_init{i};
                        lyr_cls{lyr_num_init(i)}{1}     = lyr_cls_init{i};
                    end
                end
            end
            % -- 2 - Defining the photoemission geometry
            pes_theta  = app.theta_min:1:app.theta_max;
            % -- 3 - Extracting the PES intensity breakdown
            thetaDataPES    = nlayer_model02_run(lyr_mat, lyr_conc, lyr_elmts, lyr_cls, lyr_thick, app.eph, pes_theta, app.phi, app.P, app.formalism_xsect, app.formalism_imfp,0);
            % -- 4 - Renormalising the PES intensities from excluded layers
            col_name = app.tab_layers.Properties.VariableNames{6};
            lyr_excl = {}; lyr_excl = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
             if sum(cell2mat(lyr_excl)) ~= 0
                init_lyr_Inorm = thetaDataPES.lyr_Inorm;
                % - Setting the layer intensity to zero for all excluded layers
                for i = 1:length(lyr_mat)
                    idx = 0;
                    for j = 1:length(lyr_mat{i}); idx = idx + 1; if lyr_excl{i} == 1; init_lyr_Inorm{i}{j} = 0.*init_lyr_Inorm{i}{j}; end; end
                end
                % - Renormalising the PES intensities of remaining layers
                lyr_norm = zeros(size(init_lyr_Inorm{1}{1}));
                for i = 1:thetaDataPES.Nlyrs; for j = 1:length(lyr_mat{i}); lyr_norm =  lyr_norm + init_lyr_Inorm{i}{j}; end; end
                for i = 1:thetaDataPES.Nlyrs; for j = 1:length(lyr_mat{i}); lyr_Inorm{i}{j} = init_lyr_Inorm{i}{j} ./ lyr_norm; end; end
                thetaDataPES.lyr_Inorm = lyr_Inorm;
            end
        end

        % -- Plot the photon energy dependence PES model
        function view_myData_thetaPES(app)
            if isempty(app.thetaDataPES); fprintf('Cannot view as the model has not been run yet.\n');
            else; nlayer_model02_view(app.thetaDataPES);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBase - N-Layer PES Intensity App (procopios.constantinou@psi.ch)';
            % Variables associated with modelling the PES intensities
            % - 1 - Loading the Layer UI table properties
            default_layer_material = 'Al2O3';
            % --- Initialising the table
            app.Layer_UITable.ColumnName        = {'Layer', 'Thickness (nm)', 'Material', 'Core-Level', 'Conc.(atm%)', 'Exclude'};
            app.Layer_UITable.Data              = {1,5,default_layer_material,'Al2p3-72.70eV', 100, false};
            app.Layer_UITable.ColumnEditable    = [true,true,true,true,true,true];
            % --- Initialising the column properties
            materials   = extract_materials(app);
            core_levels = extract_core_levels(app,default_layer_material);
            app.Layer_UITable.ColumnFormat      = {'numeric', 'numeric', materials, core_levels, 'numeric', 'logical'};
            % - 2 - Loading the Bulk UI table properties
            default_bulk_material = 'Si';
            % --- Initialising the table
            app.Bulk_UITable.ColumnName         = {'Layer', 'Thickness (nm)', 'Material', 'Core-Level', 'Conc.(atm%)', 'Exclude'};
            app.Bulk_UITable.Data               = {'Bulk', Inf, default_bulk_material, 'Si2p3-98.90eV', 100, false};
            app.Bulk_UITable.ColumnEditable     = [false,false,true,true,true,true];
            % --- Initialising the column properties
            materials   = extract_materials(app);
            core_levels = extract_core_levels(app,default_bulk_material);
            app.Bulk_UITable.ColumnFormat      = {'numeric', 'numeric', materials, core_levels, 'numeric', 'logical'};
            % 3 - Default parameters upon startup
            app.hvDataPES      = [];
            app.thetaDataPES   = [];
            % --- Experimental Geometry
            app.phi         = app.phi_EditField.Value;
            app.omega       = app.omega_EditField.Value;
            app.P           = app.P_EditField.Value;
            % --- IMFP parameters 
            app.formalism_imfp  = "JTP";
            app.formalism_xsect = "C2022";
            % --- Sensitivity Factor
            app.theta           = app.theta_EditField.Value;
            app.eph_min         = app.MinEph_EditField.Value;
            app.eph_max         = app.MaxEph_EditField.Value;
            app.eph             = app.PhotonEnergy_EditField.Value;
            app.theta_min       = app.MinTheta_EditField.Value;
            app.theta_max       = app.MaxTheta_EditField.Value;
            app.tab_layers      = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
            app.tab_bulk        = array2table(app.Bulk_UITable.Data, 'VariableNames', app.Bulk_UITable.ColumnName);
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run App_MatBase;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
           % Remove the UI handle and restart
            close(gcbf); run App_MatBase_NLayerModeller02;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 920;
            screen_size(4) = 480;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            app.hvDataPES  = extract_myData_hvPES(app);
            disp('hvDataPES : '); disp(app.hvDataPES);
            app.thetaDataPES  = extract_myData_thetaPES(app);
            disp('thetaDataPES : '); disp(app.thetaDataPES);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'hvDataPES', app.hvDataPES);
            assignin('base', 'thetaDataPES', app.thetaDataPES);
        end

        % Value changed function: phi_EditField
        function phi_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.phi_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value); value = 0;
            elseif value > 180; value = 180;
            end
            % 3 - Assigning the output
            app.phi = value;
            fprintf("--> phi: " + string(app.phi) + " deg.\n");
        end

        % Value changed function: omega_EditField
        function omega_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.omega_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value); value = 90;
            elseif value > 90; value = 90;
            end
            % 3 - Assigning the output
            app.omega = value;
            fprintf("--> omega: " + string(app.omega) + " deg.\n");
        end

        % Value changed function: P_EditField
        function P_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.P_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value) || value < 0; value = 0;
            elseif value > 1; value = 1;
            end
            % 3 - Assigning the output
            app.P = value;
            fprintf("--> P: " + string(app.P) + "\n");
        end

        % Value changed function: Formalism_IMFP_DropDown
        function Formalism_IMFP_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Formalism_IMFP_DropDown.Value;
            value = string(value);
            % 2 - Validity check on the material
            if strcmpi(value,"Universal");              value = "Universal";
            elseif strcmpi(value,"Optical(NIST)");      value = "NIST";
            elseif strcmpi(value,"TPP-2M");             value = "TPP2M";
            elseif strcmpi(value,"TPP-2M(avg.)");       value = "TPP2M-avg";
            elseif strcmpi(value,"S1");                 value = "S1";
            elseif strcmpi(value,"S2");                 value = "S2";
            elseif strcmpi(value,"S3");                 value = "S3";
            elseif strcmpi(value,"S4");                 value = "S4";
            elseif strcmpi(value,"JTP");                value = "JTP";
            end
            % 4 - Assigning the output
            app.formalism_imfp = value;
            fprintf("--> imfp formalism: " + string(app.formalism_imfp) + "\n");
        end

        % Value changed function: Formalism_XSect_DropDown
        function Formalism_XSect_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Formalism_XSect_DropDown.Value;
            value = string(value);
            % 2 - Validity check on the material
            if strcmpi(value,"Scofield(1973) - 1 to 1500 keV");                 value = "S1973";
            elseif strcmpi(value,"Yeh Lindau(1985) - 10 to 1500 eV");           value = "YL1985";
            elseif strcmpi(value,"Trzhaskovskaya(2018) - 1500 to 10000 eV");    value = "T2018";
            elseif strcmpi(value,"Cant(2022) - 1500 to 10000 eV");              value = "C2022";
            end
            % 4 - Assigning the output
            app.formalism_xsect = value;
            fprintf("--> xsect formalism: " + string(app.formalism_xsect) + "\n");
        end

        % Value changed function: theta_EditField
        function theta_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.theta_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value); value = 0;
            elseif value > 90; value = 90;
            end
            % 3 - Assigning the output
            app.theta = value;
            fprintf("--> theta: " + string(app.theta) + " deg.\n");
        end

        % Value changed function: MinEph_EditField
        function MinEph_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.MinEph_EditField.Value;
            % 2 - Validity check on the input value
            min_eph_val = find_min_eph(app);
            if isempty(value) || isnan(value) || value < min_eph_val
                value = min_eph_val;
            end
            if value > app.eph_max; value = app.eph_max; end
            % 3 - Validity check on the input value
            app.eph_min = value;
            app.MinEph_EditField.Value = app.eph_min;
        end

        % Value changed function: MaxEph_EditField
        function MaxEph_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.MaxEph_EditField.Value;
            % 2 - Validity check on the input value
            min_eph_val = find_min_eph(app);
            if isempty(value) || isnan(value) || value < min_eph_val
                value = min_eph_val;
            end
            if value < app.eph_min; value = app.eph_min; end
            % 3 - Validity check on the input value
            app.eph_max = value;
            app.MaxEph_EditField.Value     = app.eph_max;
        end

        % Value changed function: PhotonEnergy_EditField
        function PhotonEnergy_EditFieldValueChanged(app, event)
             % 1 - Extracting the input defined by the user
            value = app.PhotonEnergy_EditField.Value;
            % 2 - Validity check on the input value
            min_eph_val = find_min_eph(app);
            if isempty(value) || isnan(value) || value < min_eph_val
                value = min_eph_val;
            end
            app.eph = value;
            app.PhotonEnergy_EditField.Value = app.eph;
            % 2 - Assigning the output
            fprintf("--> Photon Energy: " + string(app.eph) + " eV\n");
        end

        % Value changed function: MinTheta_EditField
        function MinTheta_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.MinTheta_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value)
                value = 0;
            elseif value > app.theta_max
                value = app.theta_max;
            end
            % 3 - Validity check on the input value
            app.theta_min = value;
            app.MinTheta_EditField.Value = app.theta_min;
        end

        % Value changed function: MaxTheta_EditField
        function MaxTheta_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.MaxTheta_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value)
                value = 90;
            elseif value < app.theta_min
                value = app.theta_min;
            end
            % 3 - Validity check on the input value
            app.theta_max = value;
            app.MaxTheta_EditField.Value     = app.theta_max;
        end

        % Button pushed function: LayerAdd_Button
        function LayerAdd_ButtonPushed(app, event)
            % 1 - Adding one new layer to the table
            layer_number    = app.Layer_UITable.Data(end,1); layer_number = layer_number{1};
            if layer_number ~= 10
                layer_number    = layer_number+1;
                material        = app.Layer_UITable.Data(end,3); material = material{1};
                clevel          = app.Layer_UITable.Data(end,4); clevel = clevel{1};
                exclusion       = app.Layer_UITable.Data(end,6); exclusion = exclusion{1};
                app.Layer_UITable.Data(end+1,:) = {layer_number, 0, material, clevel, 100, exclusion};
                % 2 - Assigning and printing the output
                app.tab_layers  = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
                fprintf("-- Layer Added: N = " + string(layer_number) + " \n");
            else; fprintf("-- Currently only supports 10 layers maximum...\n");
            end
        end

        % Button pushed function: LayerDelete_Button
        function LayerDelete_ButtonPushed(app, event)
            % 1 - Removing one layer from the table
            layer_number    = app.Layer_UITable.Data(end,1); layer_number = layer_number{1};
            if layer_number ~= 1 
                app.Layer_UITable.Data(end,:) = []; 
                fprintf("-- Layer Removed: N = " + string(layer_number-1) + " \n");
            else; fprintf("-- Cannot remove uppermost layer...\n");
            end
            % 2 - Assigning the output
            app.tab_layers  = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
        end

        % Cell edit callback: Layer_UITable
        function Layer_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - Update the core-levels to reflect the new material
            if indices(2) == 3
                materials       = extract_materials(app);
                core_levels     = extract_core_levels(app, newData);
                app.Layer_UITable.ColumnFormat      = {'numeric', 'numeric', materials, core_levels};
                app.Layer_UITable.Data{indices(1),4}      = core_levels{1};
            end
            % 2 - Assigning the output
            if indices(2) == 1
                newData = round(newData,0); 
                layer_idxs = cell2mat(app.Layer_UITable.Data(:,1));
                layer_idxs = unique(layer_idxs);
                max_layer_num = length(layer_idxs);
                if newData > max_layer_num; newData = max_layer_num; end
                if newData < 1; newData = 1; end
                app.Layer_UITable.Data{indices(1), indices(2)} = newData;
                layer_text = sprintf("Row %i, Changed to Layer %i", indices(1), newData);
            elseif indices(2) == 2; layer_text = sprintf("Row %i, Thickness = %.2f nm", indices(1), newData);
            elseif indices(2) == 3; layer_text = sprintf("Row %i, Material = %s", indices(1), newData);
            elseif indices(2) == 4; layer_text = sprintf("Row %i, Core-Level = %s", indices(1), newData);
            elseif indices(2) == 5; layer_text = sprintf("Row %i, Concentration = %.2f pc", indices(1), newData);
            elseif indices(2) == 6; layer_text = sprintf("Row %i, Exclude Layer Contribution = %i", indices(1), newData);
            end
            app.tab_layers  = array2table(app.Layer_UITable.Data, 'VariableNames', app.Layer_UITable.ColumnName);
            fprintf(layer_text + " \n");
        end

        % Cell selection callback: Layer_UITable
        function Layer_UITableCellSelection(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            % 2 - Update the core-levels to reflect the new material
            if ~isempty(indices)
                if indices(2) == 4
                    material    = app.Layer_UITable.Data{indices(1),3};
                    core_levels = extract_core_levels(app, material);
                    app.Layer_UITable.ColumnFormat{4}   = core_levels;
                end
            end
        end

        % Cell edit callback: Bulk_UITable
        function Bulk_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - Update the core-levels to reflect the new material
            if indices(2) == 3
                materials       = extract_materials(app);
                core_levels     = extract_core_levels(app, newData);
                app.Bulk_UITable.ColumnFormat   = {'numeric', 'numeric', materials, core_levels};
                app.Bulk_UITable.Data{indices(1),4}      = core_levels{1};
            end
            % 2 - Assigning the output
            if indices(2) == 3; layer_text = sprintf("Bulk, Material = %s", newData);
            elseif indices(2) == 4; layer_text = sprintf("Bulk, Core-Level = %s", newData);
            elseif indices(2) == 5; layer_text = sprintf("Bulk, Concentration = %.2f pc", newData);
            elseif indices(2) == 6; layer_text = sprintf("Bulk, Exclude Layer Contribution = %i", newData);
            end
            app.tab_bulk  = array2table(app.Bulk_UITable.Data, 'VariableNames', app.Bulk_UITable.ColumnName);
            fprintf(layer_text + " \n");
        end

        % Button pushed function: ViewSampleStack_Button
        function ViewSampleStack_ButtonPushed(app, event)
            view_sample_stack(app);
        end

        % Button pushed function: RunPESModel_PhotonEnergy_Button
        function RunPESModel_PhotonEnergy_ButtonPushed(app, event)
            app.hvDataPES = extract_myData_hvPES(app);
            view_myData_hvPES(app);
        end

        % Button pushed function: ViewPESModel_PhotonEnergy_Button
        function ViewPESModel_PhotonEnergy_ButtonPushed(app, event)
            view_myData_hvPES(app);
        end

        % Button pushed function: ExportData_PhotonEnergy_Button
        function ExportData_PhotonEnergy_ButtonPushed(app, event)
            if isempty(app.hvDataPES); fprintf('Cannot save yet as the photon energy dependent model has not been run yet.\n');
            else
                %% 1 - User defined FileName and Path for the processed data
                filter = {'*.txt'};
                % -- 1 - Defining the sample stack to be modelled
                % - 1.1 - Defining the thickness of each layer
                col_name = app.tab_layers.Properties.VariableNames{2};
                lyr_thick = {}; lyr_thick = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                % - 1.2 - Defining the material of each layer, from left to right (top-to-bottom)
                col_name = app.tab_layers.Properties.VariableNames{3};
                lyr_mat = {}; lyr_mat = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                % - 1.3 - Defining the core-level being probed for each layer
                col_name = app.tab_layers.Properties.VariableNames{4};
                lyr_cls = {}; lyr_cls = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                for i = 1:length(lyr_cls); indx = strfind(lyr_cls{i},'-'); lyr_cls{i} = lyr_cls{i}(1:indx-1);  end
                % - 1.4 - Defining the atomic concentration for each layer
                col_name = app.tab_layers.Properties.VariableNames{5};
                lyr_conc = {}; lyr_conc = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                % - 1.5 - Defining the layer numbers
                col_name = app.tab_layers.Properties.VariableNames{1};
                lyr_num = {}; lyr_num = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                lyr_num{end} = lyr_num{end-1}+1;
                % - 1.6 - Defining the filename
                fname = string(yyyymmdd(datetime)) + "_";
                for i = 1:length(lyr_mat); fname = fname + sprintf("L%i%s(%s)[%ipc]_",lyr_num{i},lyr_mat{i},lyr_cls{i},lyr_conc{i}); end
                fname = fname + sprintf("tht%ideg",app.theta);
                [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
                save_fullfile = char(string(save_filepath) + string(save_filename));
                if isequal(save_filepath,0) || isequal(save_filename,0); return; end
                %% 2 - Executing the saving of the data
                wbar = waitbar(0.5,'Saving...'); 
                T_Data = table(app.hvDataPES.hv);
                T_Data.Properties.VariableNames{1} = 'Eph_eV';
                for i = 1:length(lyr_mat)
                    T_Data{:,i+1} = app.hvDataPES.lyr_Inorm{i}; 
                    T_Data.Properties.VariableNames{i+1} = char(sprintf("L%i%s(%s)[%.2fnm]", i,lyr_mat{i},lyr_cls{i},lyr_thick{i}));
                end
                writetable(T_Data, char(string(save_fullfile(1:end-4)) + ".txt"),'Delimiter','\t');
                %% 3 - Print confirmation that data is saved
                waitbar(1,'Save complete!'); close(wbar);
            end

        end

        % Button pushed function: ExportDataMat_PhotonEnergy_Button
        function ExportDataMat_PhotonEnergy_ButtonPushed(app, event)
            if isempty(app.hvDataPES); fprintf('Cannot save yet as the photon energy dependent model has not been run yet.\n');
            else
                %% 1 - User defined FileName and Path for the processed data
                filter = {'*.mat'};
                % -- 1 - Defining the sample stack to be modelled
                % - 1.1 - Defining the thickness of each layer
                col_name = app.tab_layers.Properties.VariableNames{2};
                lyr_thick = {}; lyr_thick = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                % - 1.2 - Defining the material of each layer, from left to right (top-to-bottom)
                col_name = app.tab_layers.Properties.VariableNames{3};
                lyr_mat = {}; lyr_mat = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                % - 1.3 - Defining the core-level being probed for each layer
                col_name = app.tab_layers.Properties.VariableNames{4};
                lyr_cls = {}; lyr_cls = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                for i = 1:length(lyr_cls); indx = strfind(lyr_cls{i},'-'); lyr_cls{i} = lyr_cls{i}(1:indx-1);  end
                % - 1.4 - Defining the atomic concentration for each layer
                col_name = app.tab_layers.Properties.VariableNames{5};
                lyr_conc = {}; lyr_conc = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                % - 1.5 - Defining the layer numbers
                col_name = app.tab_layers.Properties.VariableNames{1};
                lyr_num = {}; lyr_num = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                lyr_num{end} = lyr_num{end-1}+1;
                % - 1.6 - Defining the filename
                fname = string(yyyymmdd(datetime)) + "_";
                for i = 1:length(lyr_mat); fname = fname + sprintf("L%i%s(%s)[%ipc]_",lyr_num{i},lyr_mat{i},lyr_cls{i},lyr_conc{i}); end
                fname = fname + sprintf("tht%ideg",app.theta);
                [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
                save_fullfile = char(string(save_filepath) + string(save_filename));
                if isequal(save_filepath,0) || isequal(save_filename,0); return; end
                %% 2 - Executing the saving of the data
                wbar = waitbar(0.5,'Saving...'); 
                dataStr = app.hvDataPES; 
                save(char(save_fullfile), 'dataStr', '-v7.3');
                disp('-> saved data : '); display(app.hvDataPES);
                %% 3 - Print confirmation that data is saved
                waitbar(1,'Save complete!'); close(wbar);
            end
        end

        % Button pushed function: RunPESModel_Theta_Button
        function RunPESModel_Theta_ButtonPushed(app, event)
            app.thetaDataPES = extract_myData_thetaPES(app);
            view_myData_thetaPES(app);
        end

        % Button pushed function: ViewPESModel_Theta_Button
        function ViewPESModel_Theta_ButtonPushed(app, event)
            view_myData_thetaPES(app);
        end

        % Button pushed function: ExportData_Theta_Button
        function ExportData_Theta_ButtonPushed(app, event)
            if isempty(app.thetaDataPES); fprintf('Cannot save yet as the theta dependent model has not been run yet.\n');
            else
                %% 1 - User defined FileName and Path for the processed data
                filter = {'*.txt'};
                % -- 1 - Defining the sample stack to be modelled
                % - 1.1 - Defining the thickness of each layer
                col_name = app.tab_layers.Properties.VariableNames{2};
                lyr_thick = {}; lyr_thick = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                % - 1.2 - Defining the material of each layer, from left to right (top-to-bottom)
                col_name = app.tab_layers.Properties.VariableNames{3};
                lyr_mat = {}; lyr_mat = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                % - 1.3 - Defining the core-level being probed for each layer
                col_name = app.tab_layers.Properties.VariableNames{4};
                lyr_cls = {}; lyr_cls = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                for i = 1:length(lyr_cls); indx = strfind(lyr_cls{i},'-'); lyr_cls{i} = lyr_cls{i}(1:indx-1);  end
                % - 1.4 - Defining the atomic concentration for each layer
                col_name = app.tab_layers.Properties.VariableNames{5};
                lyr_conc = {}; lyr_conc = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                % - 1.5 - Defining the layer numbers
                col_name = app.tab_layers.Properties.VariableNames{1};
                lyr_num = {}; lyr_num = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                lyr_num{end} = lyr_num{end-1}+1;
                % - 1.6 - Defining the filename
                fname = string(yyyymmdd(datetime)) + "_";
                for i = 1:length(lyr_mat); fname = fname + sprintf("L%i%s(%s)[%ipc]_",lyr_num{i},lyr_mat{i},lyr_cls{i},lyr_conc{i}); end
                fname = fname + sprintf("hv%ieV",app.eph);
                [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
                save_fullfile = char(string(save_filepath) + string(save_filename));
                if isequal(save_filepath,0) || isequal(save_filename,0); return; end
                %% 2 - Executing the saving of the data
                wbar = waitbar(0.5,'Saving...'); 
                T_Data = table(app.thetaDataPES.theta');
                T_Data.Properties.VariableNames{1} = 'Theta_degree';
                for i = 1:length(lyr_mat)
                    T_Data{:,i+1} = app.thetaDataPES.lyr_Inorm{i}'; 
                    T_Data.Properties.VariableNames{i+1} = char(sprintf("L%i%s(%s)[%.2fnm]", i,lyr_mat{i},lyr_cls{i},lyr_thick{i}));
                end
                writetable(T_Data, char(string(save_fullfile(1:end-4)) + ".txt"),'Delimiter','\t');
                %% 3 - Print confirmation that data is saved
                waitbar(1,'Save complete!'); close(wbar);
            end
        end

        % Button pushed function: ExportDataMat_Theta_Button
        function ExportDataMat_Theta_ButtonPushed(app, event)
            if isempty(app.thetaDataPES); fprintf('Cannot save yet as the theta dependent model has not been run yet.\n');
            else
                %% 1 - User defined FileName and Path for the processed data
                filter = {'*.mat'};
                % -- 1 - Defining the sample stack to be modelled
                % - 1.1 - Defining the thickness of each layer
                col_name = app.tab_layers.Properties.VariableNames{2};
                lyr_thick = {}; lyr_thick = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                % - 1.2 - Defining the material of each layer, from left to right (top-to-bottom)
                col_name = app.tab_layers.Properties.VariableNames{3};
                lyr_mat = {}; lyr_mat = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                % - 1.3 - Defining the core-level being probed for each layer
                col_name = app.tab_layers.Properties.VariableNames{4};
                lyr_cls = {}; lyr_cls = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                for i = 1:length(lyr_cls); indx = strfind(lyr_cls{i},'-'); lyr_cls{i} = lyr_cls{i}(1:indx-1);  end
                % - 1.4 - Defining the atomic concentration for each layer
                col_name = app.tab_layers.Properties.VariableNames{5};
                lyr_conc = {}; lyr_conc = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                % - 1.5 - Defining the layer numbers
                col_name = app.tab_layers.Properties.VariableNames{1};
                lyr_num = {}; lyr_num = horzcat(app.tab_layers.(col_name)', app.tab_bulk.(col_name)');
                lyr_num{end} = lyr_num{end-1}+1;
                % - 1.6 - Defining the filename
                fname = string(yyyymmdd(datetime)) + "_";
                for i = 1:length(lyr_mat); fname = fname + sprintf("L%i%s(%s)[%ipc]_",lyr_num{i},lyr_mat{i},lyr_cls{i},lyr_conc{i}); end
                fname = fname + sprintf("hv%ieV",app.eph);
                [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
                save_fullfile = char(string(save_filepath) + string(save_filename));
                if isequal(save_filepath,0) || isequal(save_filename,0); return; end
                %% 2 - Executing the saving of the data
                wbar = waitbar(0.5,'Saving...'); 
                dataStr = app.thetaDataPES; 
                save(char(save_fullfile), 'dataStr', '-v7.3');
                disp('-> saved data : '); display(app.thetaDataPES);
                %% 3 - Print confirmation that data is saved
                waitbar(1,'Save complete!'); close(wbar);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'N-Layer PES Modelling : Uniform & Varying Concentration Layer Stack';
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Explore the depths of material analysis and XPS with this robust tool. It empowers users to construct multi-layered models, each layer featuring a uniform and varying material concentration and thickness. The app draws upon an extensive database for material characteristics and integrates various options for IMFP parameters, including Optical (NIST), Universal, TPP-2M, and S1 through S4. It also offers a selection of photoionization cross-sections and asymmetry parameters from renowned databases, such as the Scofield (1973), Yeh & Lindau (1985), Trzhaskovskaya (2018) and Cant (2022) databases. Utilizing the Beer-Lambert law, the tool accurately computes the PES intensities for each layer, modulated by the electron IMFP based on the specific experimental setup. Additionally, it can graphically represent the variations in photon energy and emission angles.'};
            app.Image.ImageSource = 'Icon_NLayerModeller02.jpeg';
            app.Geometry_Panel = uipanel(app.GridLayout);
            app.Geometry_Panel.Title = 'Experimental Geometry';
            app.Geometry_GridLayout = uigridlayout(app.Geometry_Panel);
            app.Geometry_Image = uiimage(app.Geometry_GridLayout);
            app.Geometry_Image.Tooltip = {'Image of the XPS experimental geometry.'};
            app.Geometry_Image.ImageSource = 'Photoionization_Experimental_Geometry.png';
            app.Geometry_Label = uilabel(app.Geometry_GridLayout);
            app.Geometry_Label.Text = 'Diagram illustrating the angles involved in the calculation of asymmetry effects, where hv denotes the direction of photon flux, E denotes the direction of polarisation, e denotes the direction of photoemission, and θ, ϕ and ω refer to the angles for anisotropic emission of electrons.';
            app.phidegLabel = uilabel(app.Geometry_GridLayout);
            app.phidegLabel.Text = 'phi (deg.):';
            app.phi_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.phi_EditField.Limits = [0 180];
            app.phi_EditField.ValueDisplayFormat = '%.2f';
            app.phi_EditField.ValueChangedFcn = createCallbackFcn(app, @phi_EditFieldValueChanged, true);
            app.phi_EditField.Tooltip = {'The azimuthal angle (in degrees) between the photon momentum vector relative to the projection of the photoelectron vector on to the plane perpendicular to the electric field vector (i.e. normal emission = 0)'};
            app.phidegLabel_2 = uilabel(app.Geometry_GridLayout);
            app.phidegLabel_2.Text = 'omega (deg.):';
            app.omega_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.omega_EditField.Limits = [0 90];
            app.omega_EditField.ValueDisplayFormat = '%.2f';
            app.omega_EditField.ValueChangedFcn = createCallbackFcn(app, @omega_EditFieldValueChanged, true);
            app.omega_EditField.Tooltip = {'The angle (in degrees) between the monochromator-sampe-lens geometry. '};
            app.omega_EditField.Value = 90;
            app.PEditFieldLabel = uilabel(app.Geometry_GridLayout);
            app.PEditFieldLabel.Text = 'P:';
            app.P_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.P_EditField.Limits = [0 1];
            app.P_EditField.ValueDisplayFormat = '%.2f';
            app.P_EditField.ValueChangedFcn = createCallbackFcn(app, @P_EditFieldValueChanged, true);
            app.P_EditField.Tooltip = {'Scalar of the degree of polarization, where 1 or 0 is equivalent to full linear polarization, and 0.5 is equivalent to unpolarized light.'};
            app.P_EditField.Value = 0.5;
            app.Model_Panel = uipanel(app.GridLayout);
            app.Model_Panel.Title = 'Defining the n-layer model';
            app.NLayerStack_GridLayout = uigridlayout(app.Model_Panel);
            app.ViewSampleStack_Button = uibutton(app.NLayerStack_GridLayout, 'push');
            app.ViewSampleStack_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewSampleStack_ButtonPushed, true);
            app.ViewSampleStack_Button.Tooltip = {'View the current sample stack.'};
            app.ViewSampleStack_Button.Text = 'View Sample Stack';
            app.LayerAdd_Button = uibutton(app.NLayerStack_GridLayout, 'push');
            app.LayerAdd_Button.ButtonPushedFcn = createCallbackFcn(app, @LayerAdd_ButtonPushed, true);
            app.LayerAdd_Button.Tooltip = {'Add a new layer to the sample stack.'};
            app.LayerAdd_Button.Text = '+';
            app.Layer_UITable = uitable(app.NLayerStack_GridLayout);
            app.Layer_UITable.ColumnName = {'Layer'; 'Thickness (nm)'; 'Material'; 'Core-Level'; 'Conc.(atm%)'; 'Exclude'};
            app.Layer_UITable.RowName = {};
            app.Layer_UITable.CellEditCallback = createCallbackFcn(app, @Layer_UITableCellEdit, true);
            app.Layer_UITable.CellSelectionCallback = createCallbackFcn(app, @Layer_UITableCellSelection, true);
            app.Layer_UITable.Tooltip = {'Table the contains all the surface layer material properties. Layer 1 is the uppermost surface.'};
            app.LayerDelete_Button = uibutton(app.NLayerStack_GridLayout, 'push');
            app.LayerDelete_Button.ButtonPushedFcn = createCallbackFcn(app, @LayerDelete_ButtonPushed, true);
            app.LayerDelete_Button.Tooltip = {'Remove a layer to the sample stack.'};
            app.LayerDelete_Button.Text = '-';
            app.Bulk_UITable = uitable(app.NLayerStack_GridLayout);
            app.Bulk_UITable.ColumnName = {'Layer'; 'Thickness (nm)'; 'Material'; 'Core-Level'; 'Conc.(atm%)'; 'Exclude'};
            app.Bulk_UITable.RowName = {};
            app.Bulk_UITable.CellEditCallback = createCallbackFcn(app, @Bulk_UITableCellEdit, true);
            app.Bulk_UITable.Tooltip = {'Table the contains all the bulk material properties.'};
            app.ExportSF_Panel = uipanel(app.GridLayout);
            app.ExportSF_Panel.Title = 'Run the PES Model';
            app.ExportModel_GridLayout = uigridlayout(app.ExportSF_Panel);
            app.PhotonEnergyDep_Panel = uipanel(app.ExportModel_GridLayout);
            app.PhotonEnergyDep_Panel.Title = 'Photon Energy Dependence';
            app.ExportPhotonEnergy_GridLayout = uigridlayout(app.PhotonEnergyDep_Panel);
            app.phidegLabel_3 = uilabel(app.ExportPhotonEnergy_GridLayout);
            app.phidegLabel_3.Text = 'theta (deg.):';
            app.theta_EditField = uieditfield(app.ExportPhotonEnergy_GridLayout, 'numeric');
            app.theta_EditField.Limits = [0 90];
            app.theta_EditField.ValueDisplayFormat = '%.2f';
            app.theta_EditField.ValueChangedFcn = createCallbackFcn(app, @theta_EditFieldValueChanged, true);
            app.theta_EditField.Tooltip = {'The polar angle (in degrees) between the photoelectron vector relative to electric field vector (i.e. at normal emission: LV (p-pol, E//MP) = 0, LH (s-pol, E⊥MP) = 90)'};
            app.MineVEditFieldLabel = uilabel(app.ExportPhotonEnergy_GridLayout);
            app.MineVEditFieldLabel.Text = 'Min (eV):';
            app.MinEph_EditField = uieditfield(app.ExportPhotonEnergy_GridLayout, 'numeric');
            app.MinEph_EditField.Limits = [0 Inf];
            app.MinEph_EditField.ValueDisplayFormat = '%.0f';
            app.MinEph_EditField.ValueChangedFcn = createCallbackFcn(app, @MinEph_EditFieldValueChanged, true);
            app.MinEph_EditField.Tooltip = {'Enter the minimum photon energy in units of eV'};
            app.MinEph_EditField.Value = 1500;
            app.RunPESModel_PhotonEnergy_Button = uibutton(app.ExportPhotonEnergy_GridLayout, 'push');
            app.RunPESModel_PhotonEnergy_Button.ButtonPushedFcn = createCallbackFcn(app, @RunPESModel_PhotonEnergy_ButtonPushed, true);
            app.RunPESModel_PhotonEnergy_Button.Tooltip = {'Run the N-Layer PES Model to calculate PES Intensities.'};
            app.RunPESModel_PhotonEnergy_Button.Text = 'Run PES Model!';
            app.MaxeVEditFieldLabel = uilabel(app.ExportPhotonEnergy_GridLayout);
            app.MaxeVEditFieldLabel.Text = 'Max (eV):';
            app.MaxEph_EditField = uieditfield(app.ExportPhotonEnergy_GridLayout, 'numeric');
            app.MaxEph_EditField.Limits = [0 1500000];
            app.MaxEph_EditField.ValueDisplayFormat = '%.0f';
            app.MaxEph_EditField.ValueChangedFcn = createCallbackFcn(app, @MaxEph_EditFieldValueChanged, true);
            app.MaxEph_EditField.Tooltip = {'Enter the maximum photon energy in units of eV'};
            app.MaxEph_EditField.Value = 10000;
            app.ViewPESModel_PhotonEnergy_Button = uibutton(app.ExportPhotonEnergy_GridLayout, 'push');
            app.ViewPESModel_PhotonEnergy_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewPESModel_PhotonEnergy_ButtonPushed, true);
            app.ViewPESModel_PhotonEnergy_Button.Tooltip = {'View the current model.'};
            app.ViewPESModel_PhotonEnergy_Button.Text = 'View PES Model';
            app.ExportData_PhotonEnergy_Button = uibutton(app.ExportPhotonEnergy_GridLayout, 'push');
            app.ExportData_PhotonEnergy_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportData_PhotonEnergy_ButtonPushed, true);
            app.ExportData_PhotonEnergy_Button.Tooltip = {'Export data as a text-file (.txt) of the sensitivity factor (SF) versus photon energy'};
            app.ExportData_PhotonEnergy_Button.Text = 'Export Data (.txt)';
            app.ExportDataMat_PhotonEnergy_Button = uibutton(app.ExportPhotonEnergy_GridLayout, 'push');
            app.ExportDataMat_PhotonEnergy_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportDataMat_PhotonEnergy_ButtonPushed, true);
            app.ExportDataMat_PhotonEnergy_Button.Tooltip = {'Save the data from the app as a MATLAB data structure file.'};
            app.ExportDataMat_PhotonEnergy_Button.Text = 'Export Data (.mat)';
            app.ThetaDep_Panel = uipanel(app.ExportModel_GridLayout);
            app.ThetaDep_Panel.Title = 'Theta Dependence';
            app.ExportTheta_GridLayout = uigridlayout(app.ThetaDep_Panel);
            app.PhotonEnergyeVEditFieldLabel = uilabel(app.ExportTheta_GridLayout);
            app.PhotonEnergyeVEditFieldLabel.Text = 'Photon Energy (eV):';
            app.PhotonEnergy_EditField = uieditfield(app.ExportTheta_GridLayout, 'numeric');
            app.PhotonEnergy_EditField.Limits = [0 Inf];
            app.PhotonEnergy_EditField.ValueDisplayFormat = '%.0f';
            app.PhotonEnergy_EditField.ValueChangedFcn = createCallbackFcn(app, @PhotonEnergy_EditFieldValueChanged, true);
            app.PhotonEnergy_EditField.Tooltip = {'Photon energy in eV.'};
            app.PhotonEnergy_EditField.Value = 2000;
            app.MindegLabel = uilabel(app.ExportTheta_GridLayout);
            app.MindegLabel.Text = 'Min (deg.):';
            app.MinTheta_EditField = uieditfield(app.ExportTheta_GridLayout, 'numeric');
            app.MinTheta_EditField.Limits = [0 90];
            app.MinTheta_EditField.ValueDisplayFormat = '%.0f';
            app.MinTheta_EditField.ValueChangedFcn = createCallbackFcn(app, @MinTheta_EditFieldValueChanged, true);
            app.MinTheta_EditField.Tooltip = {'Enter the minimum emission angle in units of degrees'};
            app.RunPESModel_Theta_Button = uibutton(app.ExportTheta_GridLayout, 'push');
            app.RunPESModel_Theta_Button.ButtonPushedFcn = createCallbackFcn(app, @RunPESModel_Theta_ButtonPushed, true);
            app.RunPESModel_Theta_Button.Tooltip = {'Run the N-Layer PES Model to calculate PES Intensities.'};
            app.RunPESModel_Theta_Button.Text = 'Run PES Model!';
            app.MaxdegLabel = uilabel(app.ExportTheta_GridLayout);
            app.MaxdegLabel.Text = 'Max (deg.):';
            app.MaxTheta_EditField = uieditfield(app.ExportTheta_GridLayout, 'numeric');
            app.MaxTheta_EditField.Limits = [0 90];
            app.MaxTheta_EditField.ValueDisplayFormat = '%.0f';
            app.MaxTheta_EditField.ValueChangedFcn = createCallbackFcn(app, @MaxTheta_EditFieldValueChanged, true);
            app.MaxTheta_EditField.Tooltip = {'Enter the maximum emission angle in units of degrees'};
            app.MaxTheta_EditField.Value = 90;
            app.ViewPESModel_Theta_Button = uibutton(app.ExportTheta_GridLayout, 'push');
            app.ViewPESModel_Theta_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewPESModel_Theta_ButtonPushed, true);
            app.ViewPESModel_Theta_Button.Tooltip = {'View the current model.'};
            app.ViewPESModel_Theta_Button.Text = 'View PES Model';
            app.ExportData_Theta_Button = uibutton(app.ExportTheta_GridLayout, 'push');
            app.ExportData_Theta_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportData_Theta_ButtonPushed, true);
            app.ExportData_Theta_Button.Tooltip = {'Export data as a text-file (.txt) of the sensitivity factor (SF) versus emission angle'};
            app.ExportData_Theta_Button.Text = 'Export Data (.txt)';
            app.ExportDataMat_Theta_Button = uibutton(app.ExportTheta_GridLayout, 'push');
            app.ExportDataMat_Theta_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportDataMat_Theta_ButtonPushed, true);
            app.ExportDataMat_Theta_Button.Tooltip = {'Save the data from the app as a MATLAB data structure file.'};
            app.ExportDataMat_Theta_Button.Text = 'Export Data (.mat)';
            app.Params_Panel = uipanel(app.GridLayout);
            app.Params_Panel.Title = 'IMFP, Cross-Sections & Asymmetry Formalisms';
            app.Params_GridLayout = uigridlayout(app.Params_Panel);
            app.XSectFormalismLabel = uilabel(app.Params_GridLayout);
            app.XSectFormalismLabel.Text = 'XSect Formalism:';
            app.Formalism_XSect_DropDown = uidropdown(app.Params_GridLayout);
            app.Formalism_XSect_DropDown.Items = {'Cant(2022) - 1500 to 10000 eV', 'Trzhaskovskaya(2018) - 1500 to 10000 eV', 'Yeh Lindau(1985) - 10 to 1500 eV', 'Scofield(1973) - 1 to 1500 keV'};
            app.Formalism_XSect_DropDown.ValueChangedFcn = createCallbackFcn(app, @Formalism_XSect_DropDownValueChanged, true);
            app.Formalism_XSect_DropDown.Tooltip = {'Select the formalism to be used to calculate the photoionisation cross-section and asymmetry parameter. For more information, see references:'; '[1] Scofield, J H. Theoretical photoionization cross sections from 1 to 1500 keV.. United States: N. p., 1973. Web. doi:10.2172/4545040.'; '[2] J.J. Yeh, I. Lindau. ATOMIC DATA AND NUCLEAR DATA TABLES 32, l-l 55 (1985). Web. doi:10.1016/0092-640X(85)90016-6'; '[3] M.B. Trzhaskovskaya, V.G. Yarzhemsky. Atomic Data and Nuclear Data Tables 119 (2018) 99–174. Web. doi:10.1016/j.adt.2017.04.003'; '[4] David J. H. Cant, Ben F. Spencer, Wendy R. Flavell, Alexander G. Shard. Surf Interface Anal. 2022; 54(4): 442-454. doi:10.1002/sia.7059'};
            app.Formalism_XSect_DropDown.Value = 'Cant(2022) - 1500 to 10000 eV';
            app.IMFPFormalismDropDownLabel = uilabel(app.Params_GridLayout);
            app.IMFPFormalismDropDownLabel.Text = 'IMFP Formalism:';
            app.Formalism_IMFP_DropDown = uidropdown(app.Params_GridLayout);
            app.Formalism_IMFP_DropDown.Items = {'TPP-2M', 'S1', 'S3', 'JTP'};
            app.Formalism_IMFP_DropDown.ValueChangedFcn = createCallbackFcn(app, @Formalism_IMFP_DropDownValueChanged, true);
            app.Formalism_IMFP_DropDown.Tooltip = {'Select the formalism to be used to calculate the inelastic mean free path (IMFP) parameter. For more information, see references:'; '[1] M. P. Seah, Quantitative electron spectroscopy of surfaces A Standard Data Base for Electron Inelastic Mean Free Paths in Solids (1979)'; '[2] S. Tanuma, Calculations of Electron Inelastic Mean Free Paths. V. Data for 14 Organic Compounds over the 50-2000 eV Range (1994)'; '[3] S. Tanuma, Calculation of electron inelastic mean free paths (IMFPs) VII. Reliability of the TPP-2M IMFP predictive equation (2003)'; '[4] S. Tanuma, Calculations of electron inelasticmean free paths. IX. Data for 41 elemental solids over the 50 eV to 30 keV range (2011)'; '[5] M. P. Seah, An accurate and simple universal curve for the energy-dependent electron inelastic mean free path (2011)'};
            app.Formalism_IMFP_DropDown.Value = 'JTP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_MatBase_NLayerModeller02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end