classdef App_MatBase_PESCurveFitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        CurveFittingModel_TabGroup      matlab.ui.container.TabGroup
        ParameterValues_Tab             matlab.ui.container.Tab
        ParameterValues_GridLayout      matlab.ui.container.GridLayout
        PeakDelete_Button               matlab.ui.control.Button
        PeakAdd_Button                  matlab.ui.control.Button
        Parameters_UITable              matlab.ui.control.Table
        FixParameters_Tab               matlab.ui.container.Tab
        FixParameters_GridLayout        matlab.ui.container.GridLayout
        FixParameters_UITable           matlab.ui.control.Table
        DynCI_Tab                       matlab.ui.container.Tab
        DynCI_GridLayout                matlab.ui.container.GridLayout
        DynCI_CheckBox                  matlab.ui.control.CheckBox
        DynCI_UITable                   matlab.ui.control.Table
        StaticCI_Tab                    matlab.ui.container.Tab
        StaticCI_GridLayout             matlab.ui.container.GridLayout
        StaticCI_CheckBox               matlab.ui.control.CheckBox
        StaticCI_UITable                matlab.ui.control.Table
        ImportParams_Button             matlab.ui.control.Button
        ExportParams_Button             matlab.ui.control.Button
        ViewModel_Button                matlab.ui.control.Button
        OptimizationAlgorithm_Panel     matlab.ui.container.Panel
        OptimizationAlgorithm_GridLayout  matlab.ui.container.GridLayout
        ChiSq_EditField                 matlab.ui.control.NumericEditField
        MeasureofBestFitLabel           matlab.ui.control.Label
        FinDiffRelStep_EditField        matlab.ui.control.NumericEditField
        NumberoffitparametersLabel_5    matlab.ui.control.Label
        FuncTol_EditField               matlab.ui.control.NumericEditField
        NumberoffitparametersLabel_4    matlab.ui.control.Label
        DoF_EditField                   matlab.ui.control.NumericEditField
        NumberoffitparametersLabel      matlab.ui.control.Label
        MaxFuncEval_EditField           matlab.ui.control.NumericEditField
        NumberoffitparametersLabel_2    matlab.ui.control.Label
        MaxIter_EditField               matlab.ui.control.NumericEditField
        NumberoffitparametersLabel_3    matlab.ui.control.Label
        RunOptimization_Button          matlab.ui.control.Button
        OptimizationType_DropDown       matlab.ui.control.DropDown
        SolverTypeLabel                 matlab.ui.control.Label
        BackgroundSubtractionPanel      matlab.ui.container.Panel
        BackgroundSubtraction_GridLayout  matlab.ui.container.GridLayout
        TougaardArguments_Panel         matlab.ui.container.Panel
        TougaardArguments_GridLayout    matlab.ui.container.GridLayout
        Tou_DParameter_EditField        matlab.ui.control.NumericEditField
        DParameterEditFieldLabel        matlab.ui.control.Label
        Tou_CParameter_EditField        matlab.ui.control.NumericEditField
        CParameterEditFieldLabel        matlab.ui.control.Label
        SOArguments_Panel               matlab.ui.container.Panel
        SOArguments_GridLayout          matlab.ui.container.GridLayout
        SO_DeltaParameter_EditField     matlab.ui.control.NumericEditField
        DeltaLabel                      matlab.ui.control.Label
        SO_LambdaParameter_EditField    matlab.ui.control.NumericEditField
        LambdaLabel                     matlab.ui.control.Label
        PolynomialArguments_Panel       matlab.ui.container.Panel
        PolynomialArguments_GridLayout  matlab.ui.container.GridLayout
        Poly_OrderEditField             matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        Bgrnd_ConstantOffset_EditField  matlab.ui.control.NumericEditField
        ConstantOffsetEditFieldLabel    matlab.ui.control.Label
        Bgrnd_WindowSize_EditField      matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        Bgrnd_RHS_EditField             matlab.ui.control.NumericEditField
        RHSEndEditFieldLabel            matlab.ui.control.Label
        Bgrnd_LHS_EditField             matlab.ui.control.NumericEditField
        LHSStartEditFieldLabel          matlab.ui.control.Label
        Bgrnd_Type_DropDown             matlab.ui.control.DropDown
        AppButtons_GridLayout           matlab.ui.container.GridLayout
        ViewNiceData_Button             matlab.ui.control.Button
        ViewRawData_Button              matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        RESET_Button                    matlab.ui.control.Button
        SAVEAS_Button                   matlab.ui.control.Button
        LOAD_Button                     matlab.ui.control.Button
        TRANSFER_Button                 matlab.ui.control.Button
        APPINFO_Button                  matlab.ui.control.Button
        RESIZEUI_Button                 matlab.ui.control.Button
        RESTART_Button                  matlab.ui.control.Button
        CLOSEFIGS_Button                matlab.ui.control.Button
        DataFile_Label                  matlab.ui.control.Label
        AppTitle                        matlab.ui.control.Label
        MAINMENU_GridLayout             matlab.ui.container.GridLayout
        MAINMENU_Button                 matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % 1 - Data Structure for storing all data & fits
        myData                  % Structure to store PES data
        myModel                 % Structure to store fitted PES data
        DoF                     % Degrees of freedom in the fits
        static_ci               % Checkbox value for static (=true) (or dynamic (=false)) confidence intervals
        % 2 - Background Properties
        bgrnd_type              % Background Type
        bgrnd_LHS               % LHS
        bgrnd_RHS               % RHS
        bgrnd_Win               % Window Size
        bgrnd_Offset            % Offset
        bgrnd_Order             % Order
        bgrnd_lambda            % C
        bgrnd_delta             % D
        bgrnd_C                 % C
        bgrnd_D                 % D
        % 3 - PES Peak Properties
        num_of_peaks            % Total number of peaks
        tab_parameters          % Table the contains all Curve Model Parameters
        tab_dyn_ci              % Table the contains all Dynamic Confidence Intervals
        tab_static_ci           % Table the contains all Static Confidence Intervals
        tab_fix_params          % Table the contains all fixed parameters
        % 4 - Optimization Properties
        solver_Type             % Solver Type
        solver_MaxIter          % Maximum Number of Iterations
        solver_FuncTol          % Minimum Function Tolerance
        solver_MaxFuncEval      % MAximum Number of Function Evaluations
        solver_FinDiffRelStep   % Finite Difference Relative Step
    end
    
    % Private methods available to the app only
    methods (Access = private)
        % Loading in the PES data
        function load_in_pes_data(app, PathName, FileName)
            % 2 - If a *.txt file is loaded
            if FileName(end-3:end) == '.txt'
                % 2.1 - Load in the relevant variables
                data_table              = readtable(string(PathName) + string(FileName));
                app.myData.TimeStamp    = datetime;
                app.myData.PathName     = PathName;
                app.myData.FileName     = FileName;
                app.myData.Type         = "PES";
                app.myData.hv           = [];
                app.myData.thtM         = [];
                app.myData.sweeps       = size(data_table(:,2:end-1), 2);
                app.myData.ydat_sweeps  = table2array(data_table(:,2:end-1));
                app.myData.xdat         = round(table2array(data_table(:,1)),3);
                app.myData.ydat         = round(mean(table2array(data_table(:,end)),2),3);
                app.myData.xdat_lims    = round([min(app.myData.xdat(:)), max(app.myData.xdat(:))],3);
                app.myData.ydat_lims    = round([min(app.myData.ydat(:)), max(app.myData.ydat(:))],3);
                % 2.2 - Initialising the app to the data that has been loaded
                initialiseFcn(app);
                % 2.3 - Plotting the raw PES data
                view_raw_pes_data(app);
            % 3 - If a *.mat file is loaded
            elseif FileName(end-3:end) == '.mat'
                % 3.1 - Load in the relevant variables
                dataStr         = load(string(PathName) + string(FileName));
                dataStr         = dataStr.dataStr;
                app.myData      = dataStr.myData;
                app.myData.TimeStamp    = datetime;
                app.myData.PathName     = PathName;
                app.myData.FileName     = FileName;
                app.myModel     = dataStr.myModel;
                % 3.2 - Initialising the app to the data that has been loaded
                initialiseFcn(app);
                % 3.3 - Updating table of parameters
                app.num_of_peaks    = dataStr.num_of_peaks;
                % 3.4 - Initialising the parameters
                app.Parameters_UITable.Data     = table2array(dataStr.tab_parameters);
                app.tab_parameters              = array2table(app.Parameters_UITable.Data, 'VariableNames', app.Parameters_UITable.ColumnName);
                % 3.5 - Initialising the static confidence intervals
                app.StaticCI_UITable.Data       = table2array(dataStr.tab_static_ci);
                app.tab_static_ci               = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
                % 3.6 - Initialising the dynamic confidence intervals
                app.DynCI_UITable.Data          = table2array(dataStr.tab_dyn_ci);
                app.tab_dyn_ci                  = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
                % 3.7 - Initialising the fixed parameters
                app.FixParameters_UITable.Data  = table2array(dataStr.tab_fix_params);
                app.tab_fix_params              = array2table(app.FixParameters_UITable.Data, 'VariableNames', app.FixParameters_UITable.ColumnName);
                % 3.8 - Choosing the type of confidence intervals to use
                app.static_ci                   = dataStr.static_ci;
                if app.static_ci == true;       app.StaticCI_CheckBox.Value = true; app.StaticCI_CheckBoxValueChanged();
                else;                           app.DynCI_CheckBox.Value = true; app.DynCI_CheckBoxValueChanged();
                end
                % 3.9 - Updating the background subtraction
                app.Bgrnd_Type_DropDown.Value               = dataStr.tab_background.Type;
                app.Bgrnd_LHS_EditField.Value               = dataStr.tab_background.LHS;
                app.Bgrnd_RHS_EditField.Value               = dataStr.tab_background.RHS;
                app.Bgrnd_WindowSize_EditField.Value        = dataStr.tab_background.Window;
                app.Bgrnd_ConstantOffset_EditField.Value    = dataStr.tab_background.Offset;
                app.Poly_OrderEditField.Value               = dataStr.tab_background.Order;
                app.SO_LambdaParameter_EditField.Value      = dataStr.tab_background.Lambda;
                app.SO_DeltaParameter_EditField.Value       = dataStr.tab_background.Delta;
                app.Tou_CParameter_EditField.Value          = dataStr.tab_background.C;
                app.Tou_DParameter_EditField.Value          = dataStr.tab_background.D;
                app.Bgrnd_Type_DropDownValueChanged(app);
                app.bgrnd_LHS           = app.Bgrnd_LHS_EditField.Value;
                app.bgrnd_RHS           = app.Bgrnd_RHS_EditField.Value;
                app.bgrnd_Win           = app.Bgrnd_WindowSize_EditField.Value;
                app.bgrnd_Offset        = app.Bgrnd_ConstantOffset_EditField.Value;
                app.bgrnd_Order         = app.Poly_OrderEditField.Value;
                app.bgrnd_lambda        = app.SO_LambdaParameter_EditField.Value;
                app.bgrnd_delta         = app.SO_DeltaParameter_EditField.Value;
                app.bgrnd_C             = app.Tou_CParameter_EditField.Value;
                app.bgrnd_D             = app.Tou_DParameter_EditField.Value;
                % 3.10 - Update degrees of freedom
                app.DoF = extract_degrees_of_freedom(app);
                app.DoF_EditField.Value = app.DoF;
                % 3.11 - Extract current PES Model & update best fit measure
                app.myModel = extract_pes_model(app);
                app.ChiSq_EditField.Value = app.myModel.COST_CHISQ_NORM;
                % 3.12 - View the current PES Model
                view_pes_model(app);
            end
        end
        
        % Loading in the PES data
        function view_raw_pes_data(app)
            fig = figure(); 
            fig.Position(3) = 750;
            fig.Position(4) = 450;
            hold on;
            plot(app.myData.xdat, app.myData.ydat, 'b-', 'linewidth', 2);
            title(sprintf("%s", app.myData.FileName(1:end-4)), 'interpreter', 'none', 'FontWeight', 'bold', 'FontSize', 12);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ylabel('Intensity (arb.)', 'FontWeight', 'bold', 'FontSize', 11);
            axis([min(app.myData.xdat(:)), max(app.myData.xdat(:)), min(app.myData.ydat(:)), 1.10*max(app.myData.ydat(:))]);
        end

        % Code that executes to initialise the app once data is loaded
        function initialiseFcn(app)
            % 1 - Updating handle text and gui elements
            % - 1.1 - Show the name, type and information of the ARPES data
            app.DataFile_Label.Text         = app.myData.FileName;
            % - 1.2 - Activating UI Elements
            app.RESET_Button.Enable                         = 'on';
            app.SAVEAS_Button.Enable                        = 'on';
            app.ViewModel_Button.Enable                     = 'on';
            app.ExportParams_Button.Enable                  = 'on';
            app.ImportParams_Button.Enable                  = 'on';
            app.ViewRawData_Button.Enable                   = 'on';
            app.ViewNiceData_Button.Enable                  = 'on';
            app.BackgroundSubtractionPanel.Enable           = 'on';
            app.OptimizationAlgorithm_Panel.Enable          = 'on';
            app.PeakAdd_Button.Enable                       = 'on';
            app.PeakDelete_Button.Enable                    = 'on';
            app.Parameters_UITable.Enable                   = 'on';
            app.DynCI_CheckBox.Enable                       = 'on';
            app.StaticCI_CheckBox.Enable                    = 'on';
            app.DynCI_UITable.Enable                        = 'on';
            app.FixParameters_UITable.Enable                = 'on';
            % 2 - Running UI call-backs to ensure consistency
            % - If the original model does not lie within the Region Of Interest
            if app.Bgrnd_LHS_EditField.Value < app.myData.xdat_lims(1) || app.Bgrnd_RHS_EditField.Value > app.myData.xdat_lims(2)
                % - UI Background arguments
                Bgrnd_Type_DropDownValueChanged(app);
                app.Bgrnd_LHS_EditField.Value = ceil(app.myData.xdat_lims(1)); Bgrnd_LHS_EditFieldValueChanged(app);
                app.Bgrnd_RHS_EditField.Value = floor(app.myData.xdat_lims(2)); Bgrnd_RHS_EditFieldValueChanged(app);
                % - UI Parameters & Confidence arguments
                for i = 1:app.num_of_peaks
                    app.Parameters_UITable.Data{i,3}    = mean(app.myData.xdat(:));
                    app.Parameters_UITable.Data{i,4}    = max(app.myData.ydat(:));
                    app.DynCI_UITable.Data{i,4}         = 0.5*max(app.myData.ydat(:));
                    app.StaticCI_UITable.Data{i,3}      = char(sprintf("%.2f:%.2f", ceil(min(app.myData.xdat(:))), floor(max(app.myData.xdat(:)))));
                    app.StaticCI_UITable.Data{i,4}      = char(sprintf("%.1f:%.1f", 0, max(app.myData.ydat(:))));
                end
                % - Update UI Tables
                app.tab_parameters          = array2table(app.Parameters_UITable.Data, 'VariableNames', app.Parameters_UITable.ColumnName);
                app.tab_static_ci           = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
                app.tab_dyn_ci              = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
            end
            % - Choosing the type of confidence intervals to use
            if app.static_ci == true;       app.StaticCI_CheckBox.Value = true; app.StaticCI_CheckBoxValueChanged();
            else;                           app.DynCI_CheckBox.Value = true; app.DynCI_CheckBoxValueChanged();
            end
            % - Update degrees of freedom
            app.DoF = extract_degrees_of_freedom(app);
            app.DoF_EditField.Value = app.DoF;
        end

        % Extract all the possible PES Background Types
        function bgrnd_types = extract_pes_bgrnd_types(app)
            bgrnd_types = {...
                'None',...
                'L - Linear',...
                'P - Polynomial',...
                'S - Shirley',...
                'SO - Shirley-Offset',...
                'SS - Shirley-Smart',...
                'T1 - Tougaard1',...
                'T2 - Tougaard2'};
        end

        % Extract all the possible PES Peak Types
        function peak_types = extract_pes_peak_types(app)
            peak_types = {...
                'G - Gaussian',...
                'L - Lorentizan',...
                'DS - Doniach',...
                'sGL - Voigt',...
                'sGLA - Voigt',...
                'pGL - Voigt',...
                'pGLA - Voigt'};
        end

        % Extract all the possible PES Peak Types
        function optimization_types = extract_optimization_types(app)
            optimization_types = {...
                'lsqcurvefit - (Least Squares Fitting)',...
                'fmincon - (Functional Minimization Fit)',...
                'simulannealbnd - (Simulated Annealing Fitting)',...
                'fmincon - (Functional Minimization Fit - Weighted Peaks)',...
                'simulannealbnd - (Simulated Annealing Fitting - Weighted Peaks)'};
        end

        % Extract Degrees of Freedom
        function DoF = extract_degrees_of_freedom(app)
            val     = cell2mat(app.tab_parameters{:,3:end});
            lb = []; ub = [];
            % -- Extracting the lower- and upper-bound constraints
            if app.static_ci == true
                values = app.tab_static_ci{:,3:end};
                for i = 1:size(values, 1)
                    for j = 1:size(values, 2)
                        data = round(sort(str2double(split(values{i,j},':'))), 2);
                        lb(i,j) = data(1);
                        ub(i,j) = data(2);
                    end
                end
            else
                lb      = cell2mat(app.tab_parameters{:,3:end}) - cell2mat(app.tab_dyn_ci{:,3:end});
                ub      = cell2mat(app.tab_parameters{:,3:end}) + cell2mat(app.tab_dyn_ci{:,3:end});
            end
            % -- Identifying any fixed parameters
            fixed_params = cell2mat(app.tab_fix_params{:,3:end});
            lb(fixed_params~=0) = val(fixed_params~=0);
            ub(fixed_params~=0) = val(fixed_params~=0);
            % -- Extracting the degrees of freedom
            DoF = sum(sum(ub-lb~=0));
        end

        % Calculate the PES Model
        function myModel = extract_pes_model(app)
            %% - 1 - Background subtraction of data
            xdat = app.myData.xdat;
            ydat = app.myData.ydat;
            % 1.1 - Extracting Start & End Point Indices
            % -- Start index points
            [~, StartIndx_lb]   = min(abs(xdat - app.bgrnd_LHS - app.bgrnd_Win));
            [~, StartIndx_ub]   = min(abs(xdat - app.bgrnd_LHS + app.bgrnd_Win));
            % -- End index points
            [~, EndIndx_lb]     = min(abs(xdat - app.bgrnd_RHS - app.bgrnd_Win));
            [~, EndIndx_ub]     = min(abs(xdat - app.bgrnd_RHS + app.bgrnd_Win));
            % - ROI indexes
            StartWin_indx      	= sort([StartIndx_lb, StartIndx_ub]);
            EndWin_indx     	= sort([EndIndx_lb, EndIndx_ub]);
            % - Extracting the data points of the background over the window size
            if iscolumn(xdat);  xdat_win      = [xdat(StartWin_indx(1):StartWin_indx(2));   xdat(EndWin_indx(1):EndWin_indx(2))];
            elseif isrow(xdat); xdat_win      = [xdat(StartWin_indx(1):StartWin_indx(2)),   xdat(EndWin_indx(1):EndWin_indx(2))];
            end
            if iscolumn(ydat); ydat_win       = [ydat(StartWin_indx(1):StartWin_indx(2));   ydat(EndWin_indx(1):EndWin_indx(2))];
            elseif isrow(ydat); ydat_win      = [ydat(StartWin_indx(1):StartWin_indx(2)),   ydat(EndWin_indx(1):EndWin_indx(2))];
            end
            % 1.2 - Background subtracting the data
            Type = char(lower(app.bgrnd_type));
            switch Type
                case lower({'None','L','S','SS'});      [X, D, B] = PES_BgrndSubCurve(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset);
                case lower({'SO'});                     [X, D, B] = PES_BgrndSubCurve(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_lambda, app.bgrnd_delta);
                case lower({'P'});                      [X, D, B] = PES_BgrndSubCurve(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_Order);
                case lower({'T1'});                     [X, D, B] = PES_BgrndSubCurve(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_C);
                case lower({'T2'});                     [X, D, B] = PES_BgrndSubCurve(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_C, app.bgrnd_D);
            end
            % 1.3 - Creating a table with the background parameters
            tab_background = table(string(app.Bgrnd_Type_DropDown.Value));     tab_background.Properties.VariableNames{1} = 'Type';
            tab_background{:,2} = app.bgrnd_LHS;        tab_background.Properties.VariableNames{2} = 'LHS';
            tab_background{:,3} = app.bgrnd_RHS;        tab_background.Properties.VariableNames{3} = 'RHS';
            tab_background{:,4} = app.bgrnd_Win;        tab_background.Properties.VariableNames{4} = 'Window';
            tab_background{:,5} = app.bgrnd_Offset;     tab_background.Properties.VariableNames{5} = 'Offset';
            tab_background{:,6} = app.bgrnd_Order;      tab_background.Properties.VariableNames{6} = 'Order';
            tab_background{:,7} = app.bgrnd_lambda;     tab_background.Properties.VariableNames{7} = 'Lambda';
            tab_background{:,8} = app.bgrnd_delta;      tab_background.Properties.VariableNames{8} = 'Delta';
            tab_background{:,9} = app.bgrnd_C;          tab_background.Properties.VariableNames{9} = 'C';
            tab_background{:,10} = app.bgrnd_D;         tab_background.Properties.VariableNames{10} = 'D';
            %% - 2 - Extracting all model data
            % 2.1 - Extracting model parameters
            cTYPE       = string(app.tab_parameters{:,2});
            BE          = cell2mat(app.tab_parameters{:,3});
            INT         = cell2mat(app.tab_parameters{:,4});
            FWHM        = cell2mat(app.tab_parameters{:,5});
            MR          = cell2mat(app.tab_parameters{:,6});
            LSE         = cell2mat(app.tab_parameters{:,7});
            LSI         = cell2mat(app.tab_parameters{:,8});
            LSW         = cell2mat(app.tab_parameters{:,9});
            ASY         = cell2mat(app.tab_parameters{:,10});
            % 2.2 - Extracting model confidence intervals
            % -- Extracting the lower- and upper-bound constraints
            if app.static_ci == true
                values = app.tab_static_ci{:,3:end};
                for i = 1:size(values, 1)
                    for j = 1:size(values, 2)
                        data = round(sort(str2double(split(values{i,j},':'))), 2);
                        lb(i,j) = data(1);
                        ub(i,j) = data(2);
                    end
                end
                BE_ci       = [lb(:,1), ub(:,1)];
                INT_ci      = [lb(:,2), ub(:,2)];
                FWHM_ci     = [lb(:,3), ub(:,3)];
                MR_ci       = [lb(:,4), ub(:,4)];
                LSE_ci      = [lb(:,5), ub(:,5)];
                LSI_ci      = [lb(:,6), ub(:,6)];
                LSW_ci      = [lb(:,7), ub(:,7)];
                ASY_ci      = [lb(:,8), ub(:,8)];
            else
                BE_ci       = BE    + cell2mat(app.tab_dyn_ci{:,3}).*[-1,1];
                INT_ci      = INT   + cell2mat(app.tab_dyn_ci{:,4}).*[-1,1];
                FWHM_ci     = FWHM  + cell2mat(app.tab_dyn_ci{:,5}).*[-1,1];
                MR_ci       = MR    + cell2mat(app.tab_dyn_ci{:,6}).*[-1,1];
                LSE_ci      = LSE   + cell2mat(app.tab_dyn_ci{:,7}).*[-1,1];
                LSI_ci      = LSI   + cell2mat(app.tab_dyn_ci{:,8}).*[-1,1];
                LSW_ci      = LSW   + cell2mat(app.tab_dyn_ci{:,9}).*[-1,1];
                ASY_ci      = ASY   + cell2mat(app.tab_dyn_ci{:,10}).*[-1,1];
            end
            % 2.3 - Checking for any fixed parameters
            for i = 1:app.num_of_peaks
                if app.tab_fix_params{i,3}{1} == true;  BE_ci(i,:) = BE(i).*[1,1]; end
                if app.tab_fix_params{i,4}{1} == true;  INT_ci(i,:) = INT(i).*[1,1]; end
                if app.tab_fix_params{i,5}{1} == true;  FWHM_ci(i,:) = FWHM(i).*[1,1]; end
                if app.tab_fix_params{i,6}{1} == true;  MR_ci(i,:) = MR(i).*[1,1]; end
                if app.tab_fix_params{i,7}{1} == true;  LSE_ci(i,:) = LSE(i).*[1,1]; end
                if app.tab_fix_params{i,8}{1} == true;  LSI_ci(i,:) = LSI(i).*[1,1]; end
                if app.tab_fix_params{i,9}{1} == true;  LSW_ci(i,:) = LSW(i).*[1,1]; end
                if app.tab_fix_params{i,10}{1} == true; ASY_ci(i,:) = ASY(i).*[1,1]; end
            end
            % 2.4 - Validity check on the confidence intervals to ensure they stay within physical values
            INT_ci(INT_ci<0) = 0;       % --- if INT < 0, then force it to 0
            FWHM_ci(FWHM_ci<0) = 0;     % --- if FWHM < 0, then make it 0
            MR_ci(MR_ci<0) = 0;         % --- if MR < 0, then make it 0
            MR_ci(MR_ci>1) = 1;         % --- if MR > 1, then make it 1
            LSI_ci(LSI_ci<0) = 0;       % --- if LSI < 0, then make it 0
            LSW_ci(LSW_ci<0) = 0;       % --- if LSW < 0, then make it 0
            ASY_ci(ASY_ci<-1) = -1;     % --- if ASY < -1, then make it -1
            ASY_ci(ASY_ci>1) = 1;       % --- if ASY > 1, then make it 1
            % -- Force MR and ASY to be zero for certain curve types
            for i = 1:app.num_of_peaks
                % Gaussian & Lorentzian Condition
                if strcmpi(cTYPE(i), "G - Gaussian") || strcmpi(cTYPE(i), "L - Lorentizan")
                    MR(i)       = 0;
                    MR_ci(i,:)  = 0 .* MR_ci(i,:);
                    ASY(i)      = 0;
                    ASY_ci(i,:) = 0 .* ASY_ci(i,:);
                % Doniach-Sunjic Condition
                elseif strcmpi(cTYPE(i), "DS - Doniach")
                    MR(i)       = 0;
                    MR_ci(i,:)  = 0 .* MR_ci(i,:);
                    if ASY(i)<0; ASY(i) = 0; end
                    if ASY(i)>1; ASY(i) = 1; end
                    temp_ASY    = ASY_ci(i,:);
                    temp_ASY(temp_ASY<0) = 0;
                    temp_ASY(temp_ASY>1) = 1;
                    ASY_ci(i,:) = temp_ASY;
                end
            end
            % 2.5 - Extract the curve components for each peak
            M = zeros(size(X));
            cM = {};
            for i = 1:app.num_of_peaks
                % -- Extracting the component intensities
                cM{i} = PES_SpecIntCurve(X,...
                    app.tab_parameters{i,2}{1},...
                    app.tab_parameters{i,3}{1}, app.tab_parameters{i,4}{1}, app.tab_parameters{i,5}{1},...
                    app.tab_parameters{i,6}{1}, app.tab_parameters{i,7}{1}, app.tab_parameters{i,8}{1},...
                    app.tab_parameters{i,9}{1}, app.tab_parameters{i,10}{1});
                M       = M + cM{i};
                AREA(i) = trapz(X, cM{i});
            end
            M(isnan(M)) = 0;
            AREA0 = AREA ./ trapz(X, M);
            % 2.6 - Determination of the residuals and chi-squared
            R               = M - (D - B);              % Residuals
            COST_R2         = sum(R.^2);                % Cost Function: Sum of Squared Residuals
            COST_CHISQ      = sum(R.^2 ./ abs(M));      % Cost Function: Chi-squared
            COST_CHISQ_NORM     = COST_CHISQ ./ (length(R) - 2);                            % Cost Function: Normalised Chi-squared
            COST_CHISQ_RED      = sum(R.^2 ./ abs(M)) ./ app.DoF ./ (length(R) - 2);        % Cost Function: Reduced Chi-squared
            %% - 3 - Storing all the relevant data
            myModel = struct();
            % -- Storing the initial arguments
            myModel.tab_background      = tab_background;
            myModel.tab_parameters      = app.tab_parameters;
            myModel.static_ci           = app.static_ci;
            myModel.tab_static_ci       = app.tab_static_ci;
            myModel.tab_dyn_ci          = app.tab_dyn_ci;
            myModel.tab_fix_params      = app.tab_fix_params;
            myModel.num_of_peaks        = app.num_of_peaks;
            % -- Storing original data
            myModel.xdat_win            = xdat_win;
            myModel.ydat_win            = ydat_win;
            myModel.xdat                = xdat;
            myModel.ydat                = ydat;
            % -- Storing ROI data
            myModel.X                   = X;
            myModel.D 	                = D;
            myModel.B                   = B;
            myModel.DB 	                = D - B;
            myModel.M 	                = M;
            myModel.cM 	                = cM;
            % -- Extracting the RESIDUALS (R = D - (M + B))
            myModel.R 	                = R;
            myModel.COST_R2 	        = COST_R2;
            myModel.COST_CHISQ 	        = COST_CHISQ;
            myModel.COST_CHISQ_NORM     = COST_CHISQ_NORM;
            myModel.COST_CHISQ_RED 	    = COST_CHISQ_RED;
            % -- Storing the degrees of freedom
            myModel.DoF                 = app.DoF;
            % -- Storing all relevant confidence intervals
            myModel.BE_ci               = BE_ci;
            myModel.INT_ci              = INT_ci;
            myModel.FWHM_ci             = FWHM_ci;
            myModel.MR_ci               = MR_ci;
            myModel.LSE_ci              = LSE_ci;
            myModel.LSI_ci              = LSI_ci;
            myModel.LSW_ci              = LSW_ci;
            myModel.ASY_ci              = ASY_ci;
            % -- Storing all relevant parameters
            myModel.cTYPE               = cTYPE;
            myModel.BE                  = BE;
            myModel.INT                 = INT;
            myModel.FWHM                = FWHM;
            myModel.MR                  = MR;
            myModel.LSE                 = LSE;
            myModel.LSI                 = LSI;
            myModel.LSW                 = LSW;
            myModel.ASY                 = ASY;
            myModel.AREA                = AREA;
            myModel.AREA0               = AREA0;
            % -- Storing the parameters to be used for the fit function
            myModel.x0                  = [myModel.BE; myModel.INT; myModel.FWHM; myModel.MR; myModel.LSE; myModel.LSI; myModel.LSW; myModel.ASY];
            myModel.lb                  = [myModel.BE_ci(:,1); myModel.INT_ci(:,1); myModel.FWHM_ci(:,1); myModel.MR_ci(:,1); myModel.LSE_ci(:,1); myModel.LSI_ci(:,1); myModel.LSW_ci(:,1); myModel.ASY_ci(:,1)];
            myModel.ub                  = [myModel.BE_ci(:,2); myModel.INT_ci(:,2); myModel.FWHM_ci(:,2); myModel.MR_ci(:,2); myModel.LSE_ci(:,2); myModel.LSI_ci(:,2); myModel.LSW_ci(:,2); myModel.ASY_ci(:,2)];
        end

        % View the PES Model
        function view_pes_model(app)
            cols = lines(10);
            % - Initialising the figure object
            fig = figure(); 
            fig.Position(1) = 100; fig.Position(2) = 100; 
            fig.Position(3) = 900; fig.Position(4) = 450;
            % -- Creating a tiled axis
            t = tiledlayout(4,2); t.TileSpacing = 'compact'; t.Padding = 'compact';
            %% 1 - Plotting the background subtraction method
            nexttile([4,1]); hold on;
            % - Extracting variables
            ROI     = [app.myModel.tab_background.LHS, app.myModel.tab_background.RHS];
            LHS     = app.myModel.tab_background.LHS + [-app.myModel.tab_background.Window, app.myModel.tab_background.Window];
            RHS     = app.myModel.tab_background.RHS + [-app.myModel.tab_background.Window, app.myModel.tab_background.Window];
            % -- Plotting the ROI, LHS and RHS analysis windows
            hMID    = patch([ROI(1), ROI(1), ROI(2), ROI(2), ROI(1)], [0, 1, 1, 0, 0].*2*max(app.myModel.ydat(:)), [0.8 0.8 0.8], 'facealpha', 0.5, 'edgecolor', 'none');
            hLHS    = patch([LHS(1), LHS(1), LHS(2), LHS(2), LHS(1)], [0, 1, 1, 0, 0].*2*max(app.myModel.ydat(:)), [0.2 0.7 0.2], 'facealpha', 0.2, 'edgecolor', 'none');
            hRHS    = patch([RHS(1), RHS(1), RHS(2), RHS(2), RHS(1)], [0, 1, 1, 0, 0].*2*max(app.myModel.ydat(:)), [0.7 0.2 0.2], 'facealpha', 0.2, 'edgecolor', 'none');
            hLHS.Annotation.LegendInformation.IconDisplayStyle = 'off';
            hRHS.Annotation.LegendInformation.IconDisplayStyle = 'off';
            hMID.Annotation.LegendInformation.IconDisplayStyle = 'off';
            % -- Plotting a vertical line to show the ROI
            a = xline(ROI(1), 'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
            b = xline(ROI(2), 'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
            c = yline(0, 'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            b.Annotation.LegendInformation.IconDisplayStyle = 'off';
            c.Annotation.LegendInformation.IconDisplayStyle = 'off';
            % -- Plotting the 1D data
            plot(app.myModel.xdat, app.myModel.ydat, 'b-', 'linewidth', 0.5);
            plot(app.myModel.X, app.myModel.D, 'b-', 'linewidth', 2);
            plot(app.myModel.X, app.myModel.B, 'r-', 'linewidth', 2);
            plot(app.myModel.X, app.myModel.DB, 'k-', 'linewidth', 2);
            plot(app.myModel.xdat_win, app.myModel.ydat_win, 'r.');
            % 5 - Formatting the figure
            title('Background Subtraction', 'FontWeight', 'bold', 'FontSize', 12);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ylabel('Intensity (arb.)', 'FontWeight', 'bold', 'FontSize', 11);
            legend({'Initial Data', 'ROI: Data', 'ROI: Background', 'ROI: Final'}, 'location', 'best', 'fontsize', 7);
            % -- Determining the best limits for the plot
            axLim_y = [app.myModel.D; app.myModel.DB; app.myModel.B];
            axLim_x = [mean(app.myModel.X(:)) - abs(0.65*range(app.myModel.X(:))); mean(app.myModel.X(:)) + abs(0.65*range(app.myModel.X(:)));];
            axis([axLim_x(1), axLim_x(2), min(axLim_y(:)), 1.10*max(axLim_y(:))]);
            % Font properties
            ax = gca; ax.FontName = 'Segoe UI';  ax.FontSize = 10;

            %% 2 - Plotting the PES Model Fit
            nexttile([3,1]); hold on;
            % 1 - Plotting the confidence intervals
            for i = 1:app.myModel.num_of_peaks
                % --- Plotting the primary peak uncertainties
                x_vals = [app.myModel.BE_ci(i,1)-0.02, app.myModel.BE_ci(i,1)-0.02, app.myModel.BE_ci(i,2)+0.02, app.myModel.BE_ci(i,2)+0.02, app.myModel.BE_ci(i,1)-0.02];
                y_vals = [app.myModel.INT_ci(i,1), app.myModel.INT_ci(i,2), app.myModel.INT_ci(i,2), app.myModel.INT_ci(i,1), app.myModel.INT_ci(i,1)];
                a = patch(x_vals, y_vals, cols(i,:), 'FaceAlpha', 0.65, 'EdgeAlpha', 0);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
                % --- Adding text to identify each peak index
                text(max(x_vals), max(y_vals), string(i), 'interpreter', 'latex', 'fontsize', 14, 'color', 'k');
                % --- Plotting the spin-orbit split (SOS) peak uncertainties
                x_vals  = app.myModel.BE(i) + [app.myModel.LSE_ci(i,1), app.myModel.LSE_ci(i,1), app.myModel.LSE_ci(i,2), app.myModel.LSE_ci(i,2), app.myModel.LSE_ci(i,1)];
                y_vals  = [app.myModel.INT_ci(i,1)*app.myModel.LSI_ci(i,1), app.myModel.INT_ci(i,2)*app.myModel.LSI_ci(i,2), app.myModel.INT_ci(i,2)*app.myModel.LSI_ci(i,2), app.myModel.INT_ci(i,1)*app.myModel.LSI_ci(i,1), app.myModel.INT_ci(i,1)*app.myModel.LSI_ci(i,1)];
                a = patch(x_vals, y_vals, cols(i,:), 'FaceAlpha', 0.65, 'EdgeAlpha', 0);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 2 - Plotting all of the peak components
            for i = 1:app.myModel.num_of_peaks
                area(app.myModel.X, app.myModel.cM{i}, 'FaceColor', cols(i,:), 'FaceAlpha', 0.50, 'EdgeAlpha', 0);
                a = plot(app.myModel.X, app.myModel.cM{i}, 'k-', 'linewidth', 0.25);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 3 - Plotting the peak component energy locations
            for i = 1:app.myModel.num_of_peaks
                a = line([app.myModel.BE(i), app.myModel.BE(i)], [0, max(app.myModel.cM{i})],...
                    'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 4 - Plotting the final PES intensity spectrum
            a = plot(app.myModel.X, app.myModel.DB, 'r-', 'linewidth', 0.5); a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            a = scatter(app.myModel.X, app.myModel.DB, 30, 'r', 'MarkerFaceColor', 'r', 'MarkerFaceAlpha',0.6,'MarkerEdgeAlpha',0.6);
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            plot(app.myModel.X, app.myModel.M, 'k-', 'color', 'k', 'linewidth', 2.0);
            % 5 - Formatting the figure
            title('PES Curve-Fit Model', 'FontWeight', 'bold', 'FontSize', 12);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ylabel('Intensity (arb.)', 'FontWeight', 'bold', 'FontSize', 11);
            axLim_y = [0;app.myModel.DB; app.myModel.M];
            axis([min(app.myModel.X(:)), max(app.myModel.X(:)), min(axLim_y(:)), 1.10*max(axLim_y(:))]);
            ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
            lgnd_txt = {};
            for i = 1:app.myModel.num_of_peaks; lgnd_txt{i} = sprintf("Peak %i (%.1fpc)", i, 100*app.myModel.AREA0(i)); end
            lgnd_txt{end+1} = "Final";
            legend(lgnd_txt, 'location', 'best', 'fontsize', 7);
            %% - 4.3 - Plotting the residuals
            nexttile([1,1]); hold on;
            bar(app.myModel.X, app.myModel.R, 1, 'facecolor', [0 0 0]);
            ylabel('Resid.', 'FontWeight', 'bold', 'FontSize', 11);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
        end

        % View the PES Model (nice version)
        function view_pes_model_nice(app)
            cols = lines(10);
            % - Initialising the figure object
            fig = figure(); 
            fig.Position(1) = 100; fig.Position(2) = 100; 
            fig.Position(3) = 600; fig.Position(4) = 600;
            % -- Creating a tiled axis
            t = tiledlayout(4,1); t.TileSpacing = 'compact'; t.Padding = 'compact';
            %% 1 - Plotting the PES Model Fit
            nexttile([3,1]); hold on;
            % 1.1 - Plotting all of the peak components
            for i = 1:app.myModel.num_of_peaks
                area(app.myModel.X, app.myModel.cM{i}, 'FaceColor', cols(i,:), 'FaceAlpha', 0.50, 'EdgeAlpha', 0);
                a = plot(app.myModel.X, app.myModel.cM{i}, 'k-', 'linewidth', 0.25);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 1.2 - Plotting the peak component energy locations
            for i = 1:app.myModel.num_of_peaks
                a = line([app.myModel.BE(i), app.myModel.BE(i)], [0, max(app.myModel.cM{i})],...
                    'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 1.3 - Plotting the final PES intensity spectrum
            a = plot(app.myModel.X, app.myModel.DB, 'r-', 'linewidth', 0.5); a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            a = scatter(app.myModel.X, app.myModel.DB, 40, 'r', 'MarkerFaceColor', 'r', 'MarkerFaceAlpha',0.6,'MarkerEdgeAlpha',0.6);
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            plot(app.myModel.X, app.myModel.M, 'k-', 'color', 'k', 'linewidth', 2.0);
            % 1.4 - Formatting the figure
            title(sprintf("%s", app.myData.FileName(1:end-4)), 'interpreter', 'none', 'FontWeight', 'bold', 'FontSize', 10);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ylabel('Intensity (arb.)', 'FontWeight', 'bold', 'FontSize', 11);
            axLim_y = [0;app.myModel.DB; app.myModel.M];
            axis([min(app.myModel.X(:)), max(app.myModel.X(:)), min(axLim_y(:)), 1.10*max(axLim_y(:))]);
            ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
            lgnd_txt = {};
            for i = 1:app.myModel.num_of_peaks; lgnd_txt{i} = sprintf("Peak %i (%.1fpc)", i, 100*app.myModel.AREA0(i)); end
            lgnd_txt{end+1} = "Final";
            legend(lgnd_txt, 'location', 'best', 'fontsize', 8);
            %% - 2 - Plotting the residuals
            nexttile([1,1]); hold on;
            bar(app.myModel.X, app.myModel.R, 1, 'facecolor', [0 0 0]);
            ylabel('Resid.', 'FontWeight', 'bold', 'FontSize', 11);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ax = gca; ax.FontName  = 'Segoe UI';  ax.FontSize = 10;
        end

        % Run the Optimization algorithm
        function run_optimization(app)
            % 1 - Running the Optimization algorithm
            Type = char(lower(app.solver_Type));
            switch Type
                % 1.1 - LOCAL SOLVER: BOUNDED LEAST SQUARES FITTING METHOD FOR NON-LINEAR CURVE FITTING
                case lower({'lsqcurvefit - (Least Squares Fitting)','lsqcurvefit','lsq','lsq'})
                    options = optimoptions(@lsqcurvefit,...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'FunctionTolerance', app.solver_FuncTol,...
                        'StepTolerance', app.solver_FuncTol,...
                        'OptimalityTolerance', app.solver_FuncTol,...
                        'MaxIterations', app.solver_MaxIter,...
                        'FinDiffRelStep', app.solver_FinDiffRelStep,...
                        'FinDiffType', 'central');
                    [params,meta_fits.rnorm,meta_fits.resid,meta_fits.exitflag,meta_fits.output,meta_fits.lambda,meta_fits.J] =...
                        lsqcurvefit(@(x,X) full_pes_function(app,x,app.myModel.X,app.myModel.cTYPE), app.myModel.x0, app.myModel.X, app.myModel.DB, app.myModel.lb, app.myModel.ub, options); 
                
                % 1.2 - LOCAL SOLVER: FIND MINIMUM OF BOUNDED MULTIVARIATE FUNCTION
                case lower({'fmincon - (Functional Minimization Fit)','fmincon', 'fcon'})
                    % -- Defining the optimisation options for the simulated annealing method
                    options = optimoptions('fmincon',...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'FunctionTolerance', app.solver_FuncTol,...
                        'StepTolerance', app.solver_FuncTol,...
                        'OptimalityTolerance', app.solver_FuncTol,...
                        'MaxIterations', app.solver_MaxIter,...
                        'ConstraintTolerance', app.solver_FuncTol,...
                        'FinDiffRelStep', app.solver_FinDiffRelStep,...
                        'FinDiffType', 'central');
                    [params,meta_fits.fval,meta_fits.exitflag,meta_fits.output,meta_fits.lambda,meta_fits.grad,meta_fits.hessian] =...
                        fmincon(@(x) minimize_function(app,x,app.myModel.X,app.myModel.DB,app.myModel.cTYPE), app.myModel.x0, [], [], [], [], app.myModel.lb, app.myModel.ub, [], options);  
                
                % 1.3 - LOCAL SOLVER: FIND MINIMUM OF BOUNDED MULTIVARIATE FUNCTION (WEIGHTED AROUND PEAKS)
                case lower({'fmincon - (Functional Minimization Fit - Weighted Peaks)','fmincon-peaks', 'fcon-peaks'})
                    % -- Defining the optimisation options for the simulated annealing method
                    options = optimoptions('fmincon',...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'FunctionTolerance', app.solver_FuncTol,...
                        'StepTolerance', app.solver_FuncTol,...
                        'OptimalityTolerance', app.solver_FuncTol,...
                        'MaxIterations', app.solver_MaxIter,...
                        'ConstraintTolerance', app.solver_FuncTol,...
                        'FinDiffRelStep', app.solver_FinDiffRelStep,...
                        'FinDiffType', 'central');
                    [params,meta_fits.fval,meta_fits.exitflag,meta_fits.output,meta_fits.lambda,meta_fits.grad,meta_fits.hessian] =...
                        fmincon(@(x) minimize_function_peak_weighted(app,x,app.myModel.X,app.myModel.DB,app.myModel.cTYPE), app.myModel.x0, [], [], [], [], app.myModel.lb, app.myModel.ub, [], options);  
                               
                % 1.4 - GLOBAL SOLVER: BOUNDED SIMULATED ANNEALING TO MINIMISE RESIDUALS
                case lower({'simulannealbnd - (Simulated Annealing Fitting)','simulannealbnd'})
                    % -- Defining the optimisation options for the hybrid minimisation that takes place after convergence
                    hybridopts = optimoptions('fmincon',...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'OptimalityTolerance', app.solver_FuncTol,...
                        'StepTolerance', app.solver_FuncTol,...
                        'ConstraintTolerance', app.solver_FuncTol);
                    % -- Defining the optimisation options for the simulated annealing method
                    TempFcn     = 'temperatureexp';
                    InitTemp    = 1e3;
                    ReannealInt = 1e2;
                    options = optimoptions(@simulannealbnd,...
                        'TemperatureFcn', TempFcn,...
                        'InitialTemperature', InitTemp,...
                        'ReannealInterval', ReannealInt,...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'MaxIterations', app.solver_MaxIter,...
                        'TolFun', app.solver_FuncTol,...
                        'FunctionTolerance', app.solver_FuncTol,...
                        'HybridFcn' , {'fmincon' , hybridopts});
                    [params,meta_fits.fval,meta_fits.exitflag,meta_fits.output] =...
                        simulannealbnd(@(x) minimize_function(app,x,app.myModel.X,app.myModel.DB,app.myModel.cTYPE), app.myModel.x0, app.myModel.lb, app.myModel.ub, options);
                
                % 1.4 - GLOBAL SOLVER: BOUNDED SIMULATED ANNEALING TO MINIMISE RESIDUALS (WEIGHTED AROUND PEAKS)
                case lower({'simulannealbnd - (Simulated Annealing Fitting - Weighted Peaks)','simulannealbnd-peaks'})
                    % -- Defining the optimisation options for the hybrid minimisation that takes place after convergence
                    hybridopts = optimoptions('fmincon',...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'OptimalityTolerance', app.solver_FuncTol,...
                        'StepTolerance', app.solver_FuncTol,...
                        'ConstraintTolerance', app.solver_FuncTol);
                    % -- Defining the optimisation options for the simulated annealing method
                    TempFcn     = 'temperatureexp';
                    InitTemp    = 1e3;
                    ReannealInt = 1e2;
                    options = optimoptions(@simulannealbnd,...
                        'TemperatureFcn', TempFcn,...
                        'InitialTemperature', InitTemp,...
                        'ReannealInterval', ReannealInt,...
                        'MaxFunctionEvaluations', app.solver_MaxFuncEval,...
                        'MaxIterations', app.solver_MaxIter,...
                        'TolFun', app.solver_FuncTol,...
                        'FunctionTolerance', app.solver_FuncTol,...
                        'HybridFcn' , {'fmincon' , hybridopts});
                    [params,meta_fits.fval,meta_fits.exitflag,meta_fits.output] =...
                        simulannealbnd(@(x) minimize_function_peak_weighted(app,x,app.myModel.X,app.myModel.DB,app.myModel.cTYPE), app.myModel.x0, app.myModel.lb, app.myModel.ub, options);

            end
            % 2 - Extracting the new best fit parameters
            params              = reshape(params,app.myModel.num_of_peaks,[]);
            params              = table2cell(array2table(params));
            new_tab_parameters  = app.myModel.tab_parameters;
            new_tab_parameters{:,3:end} = params;
            % 3 - Update the Parameter Values
            app.Parameters_UITable.Data     = table2array(new_tab_parameters);
            app.tab_parameters              = array2table(app.Parameters_UITable.Data, 'VariableNames', app.Parameters_UITable.ColumnName);
        end

        % Optimization Function - Minimization
        function MINFUN = minimize_function(app, x, X, DB, cType)
            M   = full_pes_function(app, x, X, cType);
            R   = DB - M;
            MINFUN = sum(R.^2) ./ app.DoF;
        end

        % Optimization Function - Minimization
        function MINFUN = minimize_function_peak_weighted(app, x, X, DB, cType)
            M   = full_pes_function(app, x, X, cType);
            R   = DB - M;
            MINFUN = sum(R.^2 ./ abs(M)) ./ app.DoF;
        end

        % Optimization Function - Full PES Function
        function M = full_pes_function(app, x, xdat, cType)
            % - 1 - Extract the curve component for each state
            nStates = length(cType);
            comp_int = {};
            for i = 1:nStates
                % -- Extracting the arguments for the component curve
                pes_args    = x(i:nStates:end);
                % -- Extracting the component intensities
                comp_int{i} = PES_SpecIntCurve(xdat, cType(i),...
                    pes_args(1), pes_args(2), pes_args(3),...
                    pes_args(4), pes_args(5), pes_args(6),...
                    pes_args(7), pes_args(8));
            end
            % - 2 - Sum up all of the curve components to get the final model PES curve
            pes_int = zeros(size(xdat));
            for i = 1:nStates; pes_int = pes_int + comp_int{i}; end
            M = pes_int;
            M(isnan(M)) = 0;
            if size(M, 2) > 1; M = M'; end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBase - PES Curve Fitter App (procopios.constantinou@psi.ch)';
            % 1 - Disabling all data processing until data is loaded
            app.DataFile_Label.Text = '<<  select a raw *.txt file or processed *.mat data file to begin fitting';
            app.RESET_Button.Enable                         = 'off';
            app.SAVEAS_Button.Enable                        = 'off';
            app.ViewModel_Button.Enable                     = 'off';
            app.ViewRawData_Button.Enable                   = 'off';
            app.ViewNiceData_Button.Enable                  = 'off';
            app.ExportParams_Button.Enable                  = 'off';
            app.ImportParams_Button.Enable                  = 'off';
            app.BackgroundSubtractionPanel.Enable           = 'off';
            app.OptimizationAlgorithm_Panel.Enable          = 'off';
            app.PeakAdd_Button.Enable                       = 'off';
            app.PeakDelete_Button.Enable                    = 'off';
            app.Parameters_UITable.Enable                   = 'off';
            app.DynCI_CheckBox.Enable                       = 'off';
            app.DynCI_UITable.Enable                        = 'off';
            app.StaticCI_UITable.Enable                     = 'off';
            app.StaticCI_CheckBox.Enable                    = 'off';
            app.FixParameters_UITable.Enable                = 'off';
            % 2 - Initialising variables
            app.myData  = struct();
            app.myModel = struct();
            % 3 - Initialising Background Subtraction Arguments
            bgrnd_types = extract_pes_bgrnd_types(app);
            app.Bgrnd_Type_DropDown.Items = bgrnd_types;
            app.Bgrnd_Type_DropDown.Value = app.Bgrnd_Type_DropDown.Items{4};
            app.bgrnd_type          = "S";
            app.bgrnd_LHS           = app.Bgrnd_LHS_EditField.Value;
            app.bgrnd_RHS           = app.Bgrnd_RHS_EditField.Value;
            app.bgrnd_Win           = app.Bgrnd_WindowSize_EditField.Value;
            app.bgrnd_Offset        = app.Bgrnd_ConstantOffset_EditField.Value;
            app.bgrnd_Order         = app.Poly_OrderEditField.Value;
            app.bgrnd_lambda        = app.SO_LambdaParameter_EditField.Value;
            app.bgrnd_delta         = app.SO_DeltaParameter_EditField.Value;
            app.bgrnd_C             = app.Tou_CParameter_EditField.Value;
            app.bgrnd_D             = app.Tou_DParameter_EditField.Value;
            % 3.1 - Initialising the parameters
            peak_types   = extract_pes_peak_types(app);
            app.Parameters_UITable.ColumnFormat      = {'numeric', peak_types, 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric'};
            app.Parameters_UITable.ColumnName        = {'Peak', 'Type', 'BE', 'INT', 'FWHM', 'MR', 'LSE', 'LSI', 'LSW', 'ASY'};
            app.Parameters_UITable.ColumnEditable    = [false,true,true,true,true,true,true,true,true,true];
            app.Parameters_UITable.Data              = {1,peak_types{5},0,1,1,0.5,0,0,0,0};
            app.num_of_peaks    = 1;
            app.tab_parameters  = array2table(app.Parameters_UITable.Data, 'VariableNames', app.Parameters_UITable.ColumnName);
            % 3.2 - Initialising the static confidence intervals
            app.static_ci    = false;
            app.StaticCI_UITable.ColumnFormat      = {'numeric', peak_types, [], [], [], [], [], [], [], []};
            app.StaticCI_UITable.ColumnName        = app.Parameters_UITable.ColumnName;
            app.StaticCI_UITable.ColumnEditable    = [false,false,true,true,true,true,true,true,true,true];
            app.StaticCI_UITable.Data              = {1,peak_types{5},'-1:1','0:100','0:5','0:1','0:0','0:0','0:0','0:0'};
            app.tab_static_ci  = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
            % 3.3 - Initialising the dynamic confidence intervals
            app.DynCI_UITable.ColumnFormat      = app.Parameters_UITable.ColumnFormat;
            app.DynCI_UITable.ColumnName        = app.Parameters_UITable.ColumnName;
            app.DynCI_UITable.ColumnEditable    = [false,false,true,true,true,true,true,true,true,true];
            app.DynCI_UITable.Data              = {1,peak_types{5},0.25,0.25,0.25,0.5,0,0,0,0};
            app.tab_dyn_ci  = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
            % 3.4 - Initialising the fixed parameters
            app.FixParameters_UITable.ColumnFormat      = {'numeric', peak_types, 'logical', 'logical', 'logical', 'logical', 'logical', 'logical', 'logical', 'logical'};
            app.FixParameters_UITable.ColumnName        = app.Parameters_UITable.ColumnName;
            app.FixParameters_UITable.ColumnEditable    = [false,false,true,true,true,true,true,true,true,true];
            app.FixParameters_UITable.Data              = {1,peak_types{5},false,false,false,false,false,false,false,false};
            app.tab_fix_params  = array2table(app.FixParameters_UITable.Data, 'VariableNames', app.FixParameters_UITable.ColumnName);
            % 4 - Initialising Optimization arguments
            optimization_types = extract_optimization_types(app);
            app.OptimizationType_DropDown.Items = optimization_types;
            app.OptimizationType_DropDown.Value = app.OptimizationType_DropDown.Items{1};
            app.solver_Type             = "lsqcurvefit";
            app.solver_MaxIter          = app.MaxIter_EditField.Value;
            app.solver_FuncTol          = app.FuncTol_EditField.Value;
            app.solver_MaxFuncEval      = app.MaxFuncEval_EditField.Value;
            app.solver_FinDiffRelStep   = app.FinDiffRelStep_EditField.Value;
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run App_MatBase;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
           % Remove the UI handle and restart
            close(gcbf); run App_MatBase_PESCurveFitter;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 1100;
            screen_size(4) = 575;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            disp('.myData : '); disp(app.myData);
            disp('.myModel : '); disp(app.myModel);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'myData', app.myData);
            assignin('base', 'myModel', app.myModel);
        end

        % Button pushed function: LOAD_Button
        function LOAD_ButtonPushed(app, event)
            % 1 - Loading in the FileName and PathName of the selected data
            [FileName, PathName] = uigetfile({'*.txt;*.mat'}, 'Pick a *.txt or *.mat data file...');
            % - If Cancel is pressed, then return nothing
            if isequal(PathName,0) || isequal(FileName,0); return; end
            app.myData = struct(); app.myModel = struct();
            load_in_pes_data(app, PathName, FileName);
        end

        % Button pushed function: SAVEAS_Button
        function SAVEAS_ButtonPushed(app, event)
            % 1 - Extract current PES Model & update best fit measure
            app.myModel = extract_pes_model(app);
            app.ChiSq_EditField.Value = app.myModel.COST_CHISQ_NORM;
            % 2 - User defined FileName and Path for the processed data
            fname = "Fit" + string(yyyymmdd(datetime)) + "_" + sprintf("C%i", app.num_of_peaks) + "_" + app.myData.FileName(1:end-4); filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 3 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStr = struct();
            dataStr.FileName        = save_filename;
            dataStr.TimeStamp       = datetime;
            dataStr.num_of_peaks    = app.myModel.num_of_peaks;
            dataStr.tab_background  = app.myModel.tab_background;
            dataStr.tab_parameters  = app.myModel.tab_parameters;
            dataStr.static_ci       = app.myModel.static_ci;
            dataStr.tab_static_ci   = app.myModel.tab_static_ci;
            dataStr.tab_dyn_ci      = app.myModel.tab_dyn_ci;
            dataStr.tab_fix_params  = app.myModel.tab_fix_params;
            dataStr.myData          = app.myData;
            dataStr.myModel         = app.myModel;
            dataStr.ChiSqNorm       = app.myModel.COST_CHISQ_NORM;
            save(char(save_fullfile), 'dataStr', '-v7.3');
            % 4 - Executing the saving of a png
            view_pes_model_nice(app); 
            saveas(gcf,save_fullfile(1:end-4), 'fig')
            saveas(gcf,save_fullfile(1:end-4), 'png')
            disp('-> saved data : '); display(dataStr);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Button pushed function: RESET_Button
        function RESET_ButtonPushed(app, event)
            % 1 - Re-load the raw data-file
            FileName = char(string(app.myData.FileName));
            PathName = char(string(app.myData.PathName));
            % 2 - Reseting the app to the initial state
            runStartupFcn(app, @startupFcn);
            % 3 - Load in the file selected
            app.myData = struct(); app.myModel = struct();
            load_in_pes_data(app, PathName, FileName);
        end

        % Button pushed function: ViewRawData_Button
        function ViewRawData_ButtonPushed(app, event)
            view_raw_pes_data(app);
        end

        % Button pushed function: ViewNiceData_Button
        function ViewNiceData_ButtonPushed(app, event)
            view_pes_model_nice(app);
        end

        % Value changed function: Bgrnd_Type_DropDown
        function Bgrnd_Type_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Bgrnd_Type_DropDown.Value;
            value = string(value);
            % 2 - Assigning string to the background type
            if strcmpi(value,"None");                         value = "None";
            elseif strcmpi(value,"L - Linear");               value = "L";
            elseif strcmpi(value,"P - Polynomial");           value = "P";
            elseif strcmpi(value,"S - Shirley");              value = "S";
            elseif strcmpi(value,"SO - Shirley-Offset");      value = "SO";
            elseif strcmpi(value,"SS - Shirley-Smart");       value = "SS";
            elseif strcmpi(value,"T1 - Tougaard1");           value = "T1";
            elseif strcmpi(value,"T2 - Tougaard2");           value = "T2";
            end
            % 3 - Validity check on the background
            Type = char(lower(value));
            switch Type
                case lower({'None','L','S','SS'})      
                    app.PolynomialArguments_Panel.Enable = 'off';
                    app.SOArguments_Panel.Enable = 'off';
                    app.TougaardArguments_Panel.Enable = 'off';
                case lower({'SO'})                    
                    app.PolynomialArguments_Panel.Enable = 'off';
                    app.SOArguments_Panel.Enable = 'on';
                    app.TougaardArguments_Panel.Enable = 'off';
                case lower({'P'})                    
                    app.PolynomialArguments_Panel.Enable = 'on';
                    app.SOArguments_Panel.Enable = 'off';
                    app.TougaardArguments_Panel.Enable = 'off';
                case lower({'T1', 'T2'})                    
                    app.PolynomialArguments_Panel.Enable = 'off';
                    app.SOArguments_Panel.Enable = 'off';
                    app.TougaardArguments_Panel.Enable = 'on';
            end
            % 4 - Assigning the output
            app.bgrnd_type = value;
            fprintf("- Background Type: " + string(app.bgrnd_type) + " \n");
        end

        % Value changed function: Bgrnd_LHS_EditField
        function Bgrnd_LHS_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Bgrnd_LHS_EditField.Value;
            % 2 - Validity check on the input value
            if value > app.bgrnd_RHS; value = app.bgrnd_RHS; end
            if value < min(app.myData.xdat(:)); value = min(app.myData.xdat(:)); end
            % 3 - Assigning the output
            app.bgrnd_LHS = value;
            app.Bgrnd_LHS_EditField.Value = app.bgrnd_LHS;
            fprintf("- Background: LHS (Start) = " + string(app.bgrnd_LHS) + " \n");
        end

        % Value changed function: Bgrnd_RHS_EditField
        function Bgrnd_RHS_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Bgrnd_RHS_EditField.Value;
            % 2 - Validity check on the input value
            if value < app.bgrnd_LHS; value = app.bgrnd_LHS; end
            if value > max(app.myData.xdat(:)); value = max(app.myData.xdat(:)); end
            % 3 - Assigning the output
            app.bgrnd_RHS = value;
            app.Bgrnd_RHS_EditField.Value = app.bgrnd_RHS;
            fprintf("- Background: RHS (End) = " + string(app.bgrnd_RHS) + " \n");
        end

        % Value changed function: Bgrnd_WindowSize_EditField
        function Bgrnd_WindowSize_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Bgrnd_WindowSize_EditField.Value;
            % 2 - Validity check on the input value
            if value < 0; value = 0; end
            % 3 - Assigning the output
            app.bgrnd_Win = value;
            app.Bgrnd_WindowSize_EditField.Value = app.bgrnd_Win;
            fprintf("- Background: Window Size = " + string(app.bgrnd_Win) + " \n");
        end

        % Value changed function: Bgrnd_ConstantOffset_EditField
        function Bgrnd_ConstantOffset_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Bgrnd_ConstantOffset_EditField.Value;
            % 3 - Assigning the output
            app.bgrnd_Offset = value;
            app.Bgrnd_ConstantOffset_EditField.Value = app.bgrnd_Offset;
            fprintf("- Background: Constant Offset = " + string(app.bgrnd_Offset) + " \n");
        end

        % Value changed function: Poly_OrderEditField
        function Poly_OrderEditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Poly_OrderEditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 0; end
            % 3 - Assigning the output
            app.bgrnd_Order = value;
            app.Poly_OrderEditField.Value = app.bgrnd_Order;
            fprintf("- Background (Polynomial): Order = " + string(app.bgrnd_Order) + " \n");
        end

        % Value changed function: SO_LambdaParameter_EditField
        function SO_LambdaParameter_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.SO_LambdaParameter_EditField.Value;
            % 2 - Assigning the output
            app.bgrnd_lambda = value;
            app.SO_LambdaParameter_EditField.Value = app.bgrnd_lambda;
            fprintf("- Background (Shirley-Offset): Lambda = " + string(app.bgrnd_lambda) + " \n");
        end

        % Value changed function: SO_DeltaParameter_EditField
        function SO_DeltaParameter_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.SO_DeltaParameter_EditField.Value;
            % 2 - Assigning the output
            app.bgrnd_delta = value;
            app.SO_DeltaParameter_EditField.Value = app.bgrnd_delta;
            fprintf("- Background (Shirley-Offset): Delta = " + string(app.bgrnd_delta) + " \n");
        end

        % Value changed function: Tou_CParameter_EditField
        function Tou_CParameter_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Tou_CParameter_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 1643; end
            % 3 - Assigning the output
            app.bgrnd_C = value;
            app.Tou_CParameter_EditField.Value = app.bgrnd_C;
            fprintf("- Background (Tougaard): C = " + string(app.bgrnd_C) + " \n");
        end

        % Value changed function: Tou_DParameter_EditField
        function Tou_DParameter_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Tou_DParameter_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 275; end
            % 3 - Assigning the output
            app.bgrnd_D = value;
            app.Tou_DParameter_EditField.Value = app.bgrnd_D;
            fprintf("- Background (Tougaard): D = " + string(app.bgrnd_D) + " \n");
        end

        % Button pushed function: PeakAdd_Button
        function PeakAdd_ButtonPushed(app, event)
            % - Adding one new peak to the UI tables
            peak_number    = app.Parameters_UITable.Data(end,1); peak_number = peak_number{1};
            if peak_number ~= 10
                peak_number     = peak_number+1;
                % -- Updating the Parameter Values UI Table
                Type    = app.Parameters_UITable.Data(end,2); Type = Type{1};
                BE      = app.Parameters_UITable.Data(end,3); BE = BE{1};
                INT     = app.Parameters_UITable.Data(end,4); INT = INT{1};
                FWHM    = app.Parameters_UITable.Data(end,5); FWHM = FWHM{1};
                MR      = app.Parameters_UITable.Data(end,6); MR = MR{1};
                LSE     = app.Parameters_UITable.Data(end,7); LSE = LSE{1};
                LSI     = app.Parameters_UITable.Data(end,8); LSI = LSI{1};
                LSW     = app.Parameters_UITable.Data(end,9); LSW = LSW{1};
                ASY     = app.Parameters_UITable.Data(end,10); ASY = ASY{1};
                app.Parameters_UITable.Data(end+1,:) = {peak_number, Type, BE, INT, FWHM, MR, LSE, LSI, LSW, ASY};
                % -- Updating the Static Confidence Intervals UI Table
                Type    = app.StaticCI_UITable.Data(end,2); Type = Type{1};
                BE      = app.StaticCI_UITable.Data(end,3); BE = BE{1};
                INT     = app.StaticCI_UITable.Data(end,4); INT = INT{1};
                FWHM    = app.StaticCI_UITable.Data(end,5); FWHM = FWHM{1};
                MR      = app.StaticCI_UITable.Data(end,6); MR = MR{1};
                LSE     = app.StaticCI_UITable.Data(end,7); LSE = LSE{1};
                LSI     = app.StaticCI_UITable.Data(end,8); LSI = LSI{1};
                LSW     = app.StaticCI_UITable.Data(end,9); LSW = LSW{1};
                ASY     = app.StaticCI_UITable.Data(end,10); ASY = ASY{1};
                app.StaticCI_UITable.Data(end+1,:) = {peak_number, Type, BE, INT, FWHM, MR, LSE, LSI, LSW, ASY};
                % -- Updating the Dynamic Confidence Intervals UI Table
                Type    = app.DynCI_UITable.Data(end,2); Type = Type{1};
                BE      = app.DynCI_UITable.Data(end,3); BE = BE{1};
                INT     = app.DynCI_UITable.Data(end,4); INT = INT{1};
                FWHM    = app.DynCI_UITable.Data(end,5); FWHM = FWHM{1};
                MR      = app.DynCI_UITable.Data(end,6); MR = MR{1};
                LSE     = app.DynCI_UITable.Data(end,7); LSE = LSE{1};
                LSI     = app.DynCI_UITable.Data(end,8); LSI = LSI{1};
                LSW     = app.DynCI_UITable.Data(end,9); LSW = LSW{1};
                ASY     = app.DynCI_UITable.Data(end,10); ASY = ASY{1};
                app.DynCI_UITable.Data(end+1,:) = {peak_number, Type, BE, INT, FWHM, MR, LSE, LSI, LSW, ASY};
                % -- Updating the Fixed Parameters UI Table
                Type    = app.FixParameters_UITable.Data(end,2); Type = Type{1};
                BE      = app.FixParameters_UITable.Data(end,3); BE = BE{1};
                INT     = app.FixParameters_UITable.Data(end,4); INT = INT{1};
                FWHM    = app.FixParameters_UITable.Data(end,5); FWHM = FWHM{1};
                MR      = app.FixParameters_UITable.Data(end,6); MR = MR{1};
                LSE     = app.FixParameters_UITable.Data(end,7); LSE = LSE{1};
                LSI     = app.FixParameters_UITable.Data(end,8); LSI = LSI{1};
                LSW     = app.FixParameters_UITable.Data(end,9); LSW = LSW{1};
                ASY     = app.FixParameters_UITable.Data(end,10); ASY = ASY{1};
                app.FixParameters_UITable.Data(end+1,:) = {peak_number, Type, BE, INT, FWHM, MR, LSE, LSI, LSW, ASY};
                % -- Assigning and printing the output
                app.num_of_peaks        = peak_number;
                app.tab_parameters      = array2table(app.Parameters_UITable.Data, 'VariableNames', app.Parameters_UITable.ColumnName);
                app.tab_static_ci       = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
                app.tab_dyn_ci          = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
                app.tab_fix_params      = array2table(app.FixParameters_UITable.Data, 'VariableNames', app.FixParameters_UITable.ColumnName);
                fprintf("-- Peak Added: N = " + string(peak_number) + " \n");
            else; fprintf("-- Currently only supports 10 peaks maximum...\n");
            end
            % - Update degrees of freedom
            app.DoF = extract_degrees_of_freedom(app);
            app.DoF_EditField.Value = app.DoF;
        end

        % Button pushed function: PeakDelete_Button
        function PeakDelete_ButtonPushed(app, event)
            % - Removing one peak from the UI tables
            peak_number    = app.Parameters_UITable.Data(end,1); peak_number = peak_number{1};
            if peak_number ~= 1 
                peak_number = peak_number - 1;
                % -- Updating the Parameter Values UI Table
                app.Parameters_UITable.Data(end,:) = []; 
                % -- Updating the Static Confidence Intervals UI Table
                app.StaticCI_UITable.Data(end,:) = [];
                % -- Updating the Dynamic Confidence Intervals UI Table
                app.DynCI_UITable.Data(end,:) = []; 
                % -- Updating the Fixed Parameters UI Table
                app.FixParameters_UITable.Data(end,:) = [];
                % -- Assigning the output
                app.num_of_peaks        = peak_number;
                app.tab_parameters      = array2table(app.Parameters_UITable.Data, 'VariableNames', app.Parameters_UITable.ColumnName);
                app.tab_static_ci       = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
                app.tab_dyn_ci          = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
                app.tab_fix_params      = array2table(app.FixParameters_UITable.Data, 'VariableNames', app.FixParameters_UITable.ColumnName);
               fprintf("-- Peak Removed: N = " + string(peak_number) + " \n");
            else; fprintf("-- Cannot remove first peak...\n");
            end
            % - Update degrees of freedom
            app.DoF = extract_degrees_of_freedom(app);
            app.DoF_EditField.Value = app.DoF;
        end

        % Cell edit callback: Parameters_UITable
        function Parameters_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - General Validity check on the input values
            if indices(2) == 3; if newData < min(app.myData.xdat(:)); newData = min(app.myData.xdat(:)); end; end       % -- If the BE is < min x value, pad it to the min value
            if indices(2) == 3; if newData > max(app.myData.xdat(:)); newData = max(app.myData.xdat(:)); end; end       % -- If the BE is > max x value, pad it to the max value
            if indices(2) == 4; if newData < 0; newData = 0; end; end       % -- If the INT is <0, pad it to 0
            if indices(2) == 4; if newData > max(app.myData.ydat(:)); newData = max(app.myData.ydat(:)); end; end       % -- If the INT is > max y value, pad it to the max value
            if indices(2) == 5; if newData < 0; newData = 0; end; end       % -- If the FWHM is negative, pad it to zero
            if indices(2) == 6; if newData < 0; newData = 0; end; end       % -- If the MR is negative, pad it to zero
            if indices(2) == 6; if newData > 1; newData = 1; end; end       % -- If the MR is >1, pad it to 1
            if indices(2) == 8; if newData < 0; newData = 0; end; end       % -- If the LSI is <0, pad it to 0
            if indices(2) == 9; if newData < 0; newData = 0; end; end       % -- If the LSW is <0, pad it to 0
            if indices(2) == 10; if newData < -1; newData = -1; end; end    % -- If the ASY is <-1, pad it to -1
            if indices(2) == 10; if newData > 1; newData = 1; end; end      % -- If the ASY is >1, pad it to 1
            % 3 - Force MR and ASY to be zero for certain curve types
            % Gaussian & Lorentzian Condition
            if strcmpi(app.Parameters_UITable.Data{indices(1),2}, "G - Gaussian") || strcmpi(app.Parameters_UITable.Data{indices(1),2}, "L - Lorentizan")
                if indices(2) == 6; newData = 0; end
                if indices(2) == 10; newData = 0; end
            % Doniach-Sunjic Condition
            elseif strcmpi(app.Parameters_UITable.Data{indices(1),2}, "DS - Doniach")
                if indices(2) == 6; newData = 0; end
                if indices(2) == 10; if newData < 0; newData = 0; end; end      % -- If the ASY is <0, pad it to 0
                if indices(2) == 10; if newData > 1; newData = 1; end; end      % -- If the ASY is >1, pad it to 1
            end
            % 4 - Assigning the output
            app.Parameters_UITable.Data{indices(1),indices(2)} = newData;
            app.tab_parameters  = array2table(app.Parameters_UITable.Data, 'VariableNames', app.Parameters_UITable.ColumnName);
            if indices(2) == 2;     peak_text = sprintf("Peak %i: Type = %s", indices(1), newData);
            elseif indices(2) == 3; peak_text = sprintf("Peak %i: BE = %.3f", indices(1), newData);
            elseif indices(2) == 4; peak_text = sprintf("Peak %i: INT = %.3f", indices(1), newData);
            elseif indices(2) == 5; peak_text = sprintf("Peak %i: FWHM = %.3f", indices(1), newData);
            elseif indices(2) == 6; peak_text = sprintf("Peak %i: MR = %.3f", indices(1), newData);
            elseif indices(2) == 7; peak_text = sprintf("Peak %i: LSE = %.3f", indices(1), newData);
            elseif indices(2) == 8; peak_text = sprintf("Peak %i: LSI = %.3f", indices(1), newData);
            elseif indices(2) == 9; peak_text = sprintf("Peak %i: LSE = %.3f", indices(1), newData);
            elseif indices(2) == 10; peak_text = sprintf("Peak %i: ASY = %.3f", indices(1), newData);
            end
            fprintf(peak_text + " \n");
            % 5 - If the curve type is changed, also change it for the other UI tables
            if indices(2) == 2
                app.StaticCI_UITable.Data{indices(1),indices(2)} = newData;
                app.DynCI_UITable.Data{indices(1),indices(2)} = newData;
                app.FixParameters_UITable.Data{indices(1),indices(2)} = newData;
                app.tab_static_ci       = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
                app.tab_dyn_ci          = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
                app.tab_fix_params      = array2table(app.FixParameters_UITable.Data, 'VariableNames', app.FixParameters_UITable.ColumnName);
            end
            % - Update degrees of freedom
            app.DoF = extract_degrees_of_freedom(app);
            app.DoF_EditField.Value = app.DoF;
        end

        % Value changed function: DynCI_CheckBox
        function DynCI_CheckBoxValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.DynCI_CheckBox.Value;
            % 2 - Set global variables
            if value == true
                app.static_ci  = false;
                app.DynCI_UITable.Enable = 'on';
                app.StaticCI_CheckBox.Value = false;
                app.StaticCI_UITable.Enable = 'off';
                fprintf("- Dynamic Confidence Intervals" + "\n");
            else
                app.static_ci  = true;
                app.DynCI_UITable.Enable = 'off';
                app.StaticCI_CheckBox.Value = true;
                app.StaticCI_UITable.Enable = 'on';
                fprintf("- Static Confidence Intervals" + "\n");
            end
            % - Update degrees of freedom
            app.DoF = extract_degrees_of_freedom(app);
            app.DoF_EditField.Value = app.DoF;
        end

        % Cell edit callback: DynCI_UITable
        function DynCI_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - General Validity check on the input values (must be positive values)
            if indices(2) == 3; if newData < 0; newData = 0; end; end
            if indices(2) == 4; if newData < 0; newData = 0; end; end
            if indices(2) == 5; if newData < 0; newData = 0; end; end
            if indices(2) == 6; if newData < 0; newData = 0; end; end
            if indices(2) == 7; if newData < 0; newData = 0; end; end
            if indices(2) == 8; if newData < 0; newData = 0; end; end
            if indices(2) == 9; if newData < 0; newData = 0; end; end
            if indices(2) == 10; if newData < 0; newData = 0; end; end
            % 3 - Assigning the output
            app.DynCI_UITable.Data{indices(1),indices(2)} = newData;
            app.tab_dyn_ci  = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
            if indices(2) == 3; peak_text = sprintf("Peak %i (+/-): BE = %.3f", indices(1), newData);
            elseif indices(2) == 4; peak_text = sprintf("Peak %i (+/-): INT = %.3f", indices(1), newData);
            elseif indices(2) == 5; peak_text = sprintf("Peak %i (+/-): FWHM = %.3f", indices(1), newData);
            elseif indices(2) == 6; peak_text = sprintf("Peak %i (+/-): MR = %.3f", indices(1), newData);
            elseif indices(2) == 7; peak_text = sprintf("Peak %i (+/-): LSE = %.3f", indices(1), newData);
            elseif indices(2) == 8; peak_text = sprintf("Peak %i (+/-): LSI = %.3f", indices(1), newData);
            elseif indices(2) == 9; peak_text = sprintf("Peak %i (+/-): LSE = %.3f", indices(1), newData);
            elseif indices(2) == 10; peak_text = sprintf("Peak %i (+/-): ASY = %.3f", indices(1), newData);
            end
            fprintf(peak_text + " \n");
            % - Update degrees of freedom
            app.DoF = extract_degrees_of_freedom(app);
            app.DoF_EditField.Value = app.DoF;
        end

        % Value changed function: StaticCI_CheckBox
        function StaticCI_CheckBoxValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.StaticCI_CheckBox.Value;
            % 2 - Set global variables
            if value == true
                app.static_ci  = true;
                app.StaticCI_UITable.Enable = 'on';
                app.DynCI_CheckBox.Value = false;
                app.DynCI_UITable.Enable = 'off';
                fprintf("- Static Confidence Intervals" + "\n");
            else
                app.static_ci  = false;
                app.StaticCI_UITable.Enable = 'off';
                app.DynCI_CheckBox.Value = true;
                app.DynCI_UITable.Enable = 'on';
                fprintf("- Dynamic Confidence Intervals" + "\n");
            end
            % - Update degrees of freedom
            app.DoF = extract_degrees_of_freedom(app);
            app.DoF_EditField.Value = app.DoF;
        end

        % Cell edit callback: StaticCI_UITable
        function StaticCI_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            data_entry = round(sort(str2double(split(newData,':'))), 2);
            % -- If a conditional peak constraint is added
            if isnan(data_entry)
                newData         = erase(newData," ");
                indx1           = strfind(string(newData),'(');
                indx2           = strfind(string(newData),')');
                peak_index      = round(str2double(newData(indx1+2:indx2-1)), 0);
                peak_constraint = round(str2double(newData(indx2+1:end)), 2);
                data_entry      = app.Parameters_UITable.Data{peak_index,indices(2)} + peak_constraint;
            end
            % 2 - Finding the min/max values that are allowed
            % -- BE
            if indices(2) == 3
                minVal = min(app.myData.xdat(:)); maxVal = max(app.myData.xdat(:));
            end
            % -- INT
            if indices(2) == 4
                minVal = 0; maxVal = +Inf;
            end
            % -- FWHM
            if indices(2) == 5
                minVal = 0; maxVal = +Inf;
            end
            % -- MR
            if indices(2) == 6
                minVal = 0; maxVal = 1;
            end
            % -- LSE
            if indices(2) == 7
                minVal = -Inf; maxVal = +Inf;
            end
            % -- LSI
            if indices(2) == 8
                minVal = 0; maxVal = +Inf;
            end
            % -- LSW
            if indices(2) == 9
                minVal = 0; maxVal = +Inf;
            end
            % -- ASY
            if indices(2) == 10
                % Gaussian & Lorentzian Condition
                if strcmpi(app.Parameters_UITable.Data{indices(1),2}, "G - Gaussian") || strcmpi(app.Parameters_UITable.Data{indices(1),2}, "L - Lorentizan")
                    minVal = 0; maxVal = 0;
                % Doniach-Sunjic Condition
                elseif strcmpi(app.Parameters_UITable.Data{indices(1),2}, "DS - Doniach")
                    minVal = 0; maxVal = 1;
                else
                    minVal = -1; maxVal = 1;
                end
            end
            % 3 - General Validity check on the input values
            % ---- If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) > 2
                data_entry = [minVal, maxVal];
            % ---- If a single null entry is made, default to a window of +/-0.1
            elseif isscalar(data_entry) && data_entry(1) == 0
                data_entry(1) = 0;
                data_entry(2) = 0;
            % ---- If a single entry is made, default to a window of +/-0.1
            elseif isscalar(data_entry)
                temp_val = data_entry;
                data_entry(1) = temp_val - 0.1;
                data_entry(2) = temp_val + 0.1;
            % ---- If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
            end
            % -- Forcing the max / min limits if exceeded
            if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
            if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
            data_entry  = sort(round([data_entry(1), data_entry(2)], 2));
            value       = string(data_entry(1) + ":" + data_entry(2)); 
            % 4 - Assigning the output
            app.StaticCI_UITable.Data{indices(1),indices(2)} = char(value);
            app.tab_static_ci  = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
            if indices(2) == 3; ci_text = sprintf("Peak %i (+/-): BE = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 4; ci_text = sprintf("Peak %i (+/-): INT = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 5; ci_text = sprintf("Peak %i (+/-): FWHM = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 6; ci_text = sprintf("Peak %i (+/-): MR = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 7; ci_text = sprintf("Peak %i (+/-): LSE = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 8; ci_text = sprintf("Peak %i (+/-): LSI = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 9; ci_text = sprintf("Peak %i (+/-): LSE = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            elseif indices(2) == 10; ci_text = sprintf("Peak %i (+/-): ASY = %.3f:%.3f", indices(1), data_entry(1), data_entry(2));
            end
            fprintf(ci_text + " \n");
            % - Update degrees of freedom
            app.DoF = extract_degrees_of_freedom(app);
            app.DoF_EditField.Value = app.DoF;
        end

        % Cell edit callback: FixParameters_UITable
        function FixParameters_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - Assigning the output
            app.FixParameters_UITable.Data{indices(1),indices(2)} = newData;
            app.tab_fix_params  = array2table(app.FixParameters_UITable.Data, 'VariableNames', app.FixParameters_UITable.ColumnName);
            if indices(2) == 3; peak_text = sprintf("Peak %i (Fix): BE = %i", indices(1), newData);
            elseif indices(2) == 4; peak_text = sprintf("Peak %i (Fix): INT = %i", indices(1), newData);
            elseif indices(2) == 5; peak_text = sprintf("Peak %i (Fix): FWHM = %i", indices(1), newData);
            elseif indices(2) == 6; peak_text = sprintf("Peak %i (Fix): MR = %i", indices(1), newData);
            elseif indices(2) == 7; peak_text = sprintf("Peak %i (Fix): LSE = %i", indices(1), newData);
            elseif indices(2) == 8; peak_text = sprintf("Peak %i (Fix): LSI = %i", indices(1), newData);
            elseif indices(2) == 9; peak_text = sprintf("Peak %i (Fix): LSE = %i", indices(1), newData);
            elseif indices(2) == 10; peak_text = sprintf("Peak %i (Fix): ASY = %i", indices(1), newData);
            end
            fprintf(peak_text + " \n");
            % - Update degrees of freedom
            app.DoF = extract_degrees_of_freedom(app);
            app.DoF_EditField.Value = app.DoF;
        end

        % Button pushed function: ViewModel_Button
        function ViewModel_ButtonPushed(app, event)
            % 1 - Update degrees of freedom
            app.DoF = extract_degrees_of_freedom(app);
            app.DoF_EditField.Value = app.DoF;
            % 2 - Extract current PES Model & update best fit measure
            app.myModel = extract_pes_model(app);
            app.ChiSq_EditField.Value = app.myModel.COST_CHISQ_NORM;
            % 3 - View the current PES Model
            view_pes_model(app);
        end

        % Button pushed function: ExportParams_Button
        function ExportParams_ButtonPushed(app, event)
            % 1 - Extract current PES Model & update best fit measure
            app.myModel = extract_pes_model(app);
            app.ChiSq_EditField.Value = app.myModel.COST_CHISQ_NORM;
            % 2 - User defined FileName and Path for the processed data
            fname = "Params" + string(yyyymmdd(datetime)) + "_" + sprintf("C%i", app.num_of_peaks); filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 3 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStr = struct();
            dataStr.FileName        = save_filename;
            dataStr.TimeStamp       = datetime;
            dataStr.num_of_peaks    = app.myModel.num_of_peaks;
            dataStr.tab_background  = app.myModel.tab_background;
            dataStr.tab_parameters  = app.myModel.tab_parameters;
            dataStr.static_ci       = app.myModel.static_ci;
            dataStr.tab_static_ci   = app.myModel.tab_static_ci;
            dataStr.tab_dyn_ci      = app.myModel.tab_dyn_ci;
            dataStr.tab_fix_params  = app.myModel.tab_fix_params;
            save(char(save_fullfile), 'dataStr', '-v7.3');
            disp('-> saved data : '); display(dataStr);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Button pushed function: ImportParams_Button
        function ImportParams_ButtonPushed(app, event)
            % 1 - Loading in the FileName and PathName of the selected data
            [FileName, PathName] = uigetfile({'*.mat'}, 'Pick a *.mat data file...');
            % - If Cancel is pressed, then return nothing
            if isequal(PathName,0) || isequal(FileName,0); return; end
            % 2 - Loading in the parameters
            importedParams  = load(string(PathName) + string(FileName));
            importedParams  = importedParams.dataStr;
            % 3 - Updating table of parameters
            app.num_of_peaks    = importedParams.num_of_peaks;
            % 3.1 - Initialising the parameters
            app.Parameters_UITable.Data     = table2array(importedParams.tab_parameters);
            app.tab_parameters              = array2table(app.Parameters_UITable.Data, 'VariableNames', app.Parameters_UITable.ColumnName);
            % 3.2 - Initialising the static confidence intervals
            app.StaticCI_UITable.Data       = table2array(importedParams.tab_static_ci);
            app.tab_static_ci               = array2table(app.StaticCI_UITable.Data, 'VariableNames', app.StaticCI_UITable.ColumnName);
            % 3.3 - Initialising the dynamic confidence intervals
            app.DynCI_UITable.Data     = table2array(importedParams.tab_dyn_ci);
            app.tab_dyn_ci              = array2table(app.DynCI_UITable.Data, 'VariableNames', app.DynCI_UITable.ColumnName);
            % 3.4 - Initialising the fixed parameters
            app.FixParameters_UITable.Data  = table2array(importedParams.tab_fix_params);
            app.tab_fix_params              = array2table(app.FixParameters_UITable.Data, 'VariableNames', app.FixParameters_UITable.ColumnName);
            % 3.5 - Choosing the type of confidence intervals to use
            app.static_ci                   = importedParams.static_ci;
            if app.static_ci == true;       app.StaticCI_CheckBox.Value = true; app.StaticCI_CheckBoxValueChanged();
            else;                           app.DynCI_CheckBox.Value = true; app.DynCI_CheckBoxValueChanged();
            end
            % 4 - Updating the background subtraction
            app.Bgrnd_Type_DropDown.Value               = importedParams.tab_background.Type;
            app.Bgrnd_LHS_EditField.Value               = importedParams.tab_background.LHS;
            app.Bgrnd_RHS_EditField.Value               = importedParams.tab_background.RHS;
            app.Bgrnd_WindowSize_EditField.Value        = importedParams.tab_background.Window;
            app.Bgrnd_ConstantOffset_EditField.Value    = importedParams.tab_background.Offset;
            app.Poly_OrderEditField.Value               = importedParams.tab_background.Order;
            app.SO_LambdaParameter_EditField.Value      = importedParams.tab_background.Lambda;
            app.SO_DeltaParameter_EditField.Value       = importedParams.tab_background.Delta;
            app.Tou_CParameter_EditField.Value          = importedParams.tab_background.C;
            app.Tou_DParameter_EditField.Value          = importedParams.tab_background.D;
            app.Bgrnd_Type_DropDownValueChanged(app);
            app.bgrnd_LHS           = app.Bgrnd_LHS_EditField.Value;
            app.bgrnd_RHS           = app.Bgrnd_RHS_EditField.Value;
            app.bgrnd_Win           = app.Bgrnd_WindowSize_EditField.Value;
            app.bgrnd_Offset        = app.Bgrnd_ConstantOffset_EditField.Value;
            app.bgrnd_Order         = app.Poly_OrderEditField.Value;
            app.bgrnd_lambda        = app.SO_LambdaParameter_EditField.Value;
            app.bgrnd_delta         = app.SO_DeltaParameter_EditField.Value;
            app.bgrnd_C             = app.Tou_CParameter_EditField.Value;
            app.bgrnd_D             = app.Tou_DParameter_EditField.Value;
        end

        % Value changed function: OptimizationType_DropDown
        function OptimizationType_DropDownValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.OptimizationType_DropDown.Value;
            value = string(value);
            % 2 - Assigning string to the background type
            if strcmpi(value,"lsqcurvefit - (Least Squares Fitting)");                          value = "lsqcurvefit";
            elseif strcmpi(value,"fmincon - (Functional Minimization Fit)");                    value = "fmincon";
            elseif strcmpi(value,"simulannealbnd - (Simulated Annealing Fitting)");             value = "simulannealbnd";
            elseif strcmpi(value,"fmincon - (Functional Minimization Fit - Weighted Peaks)");        value = "fmincon-peaks";
            elseif strcmpi(value,"simulannealbnd - (Simulated Annealing Fitting - Weighted Peaks)"); value = "simulannealbnd-peaks";
            end
            % 3 - Assigning the output
            app.solver_Type = value;
            fprintf("- Solver Type: " + string(app.solver_Type) + " \n");
        end

        % Value changed function: MaxIter_EditField
        function MaxIter_EditFieldValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.MaxIter_EditField.Value;
            value = round(value,0);
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 0; end
            % 3 - Assigning the output
            app.solver_MaxIter = value;
            app.MaxIter_EditField.Value = app.solver_MaxIter;
            fprintf("- Solver: Max. Iterations = " + string(app.solver_MaxIter) + " \n");
        end

        % Value changed function: FuncTol_EditField
        function FuncTol_EditFieldValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.FuncTol_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 0; end
            % 3 - Assigning the output
            app.solver_FuncTol = value;
            app.FuncTol_EditField.Value = app.solver_FuncTol;
            fprintf("- Solver: Function Tolerance = " + string(app.solver_FuncTol) + " \n");
        end

        % Value changed function: MaxFuncEval_EditField
        function MaxFuncEval_EditFieldValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.MaxFuncEval_EditField.Value;
            value = round(value,0);
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 0; end
            % 3 - Assigning the output
            app.solver_MaxFuncEval = value;
            app.MaxFuncEval_EditField.Value = app.solver_MaxFuncEval;
            fprintf("- Solver: Max. Function Evaluations = " + string(app.solver_MaxFuncEval) + " \n");
        end

        % Value changed function: FinDiffRelStep_EditField
        function FinDiffRelStep_EditFieldValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.FinDiffRelStep_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 0; end
            % 3 - Assigning the output
            app.solver_FinDiffRelStep = value;
            app.FinDiffRelStep_EditField.Value = app.solver_FinDiffRelStep;
            fprintf("- Solver: Finite Difference Relative Step = " + string(app.solver_FinDiffRelStep) + " \n");
        end

        % Button pushed function: RunOptimization_Button
        function RunOptimization_ButtonPushed(app, event)
            % 1 - Update degrees of freedom
            app.DoF = extract_degrees_of_freedom(app);
            app.DoF_EditField.Value = app.DoF;
            % 2 - Extract current PES Model & update best fit measure
            app.myModel = extract_pes_model(app);
            app.ChiSq_EditField.Value = app.myModel.COST_CHISQ_NORM;
            % 3 - Run the optimization algorithm
            run_optimization(app);
            % 4 - Extract current PES Model & update best fit measure
            app.myModel = extract_pes_model(app);
            app.ChiSq_EditField.Value = app.myModel.COST_CHISQ_NORM;
            % 5 - View the current PES Model
            view_pes_model(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'Photoelectron Spectroscopy (PES) Curve Fitter';
            app.DataFile_Label = uilabel(app.GridLayout);
            app.DataFile_Label.Tooltip = {'Loaded Filename'};
            app.DataFile_Label.Text = '<<  select a raw ''*.txt'' file or processed ''*.mat'' data file to begin fitting';
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.LOAD_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.LOAD_Button.ButtonPushedFcn = createCallbackFcn(app, @LOAD_ButtonPushed, true);
            app.LOAD_Button.Tooltip = {'Go back to the Main Menu.'};
            app.LOAD_Button.Text = {'LOAD...'; '(.txt / .mat)'};
            app.SAVEAS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.SAVEAS_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVEAS_ButtonPushed, true);
            app.SAVEAS_Button.Tooltip = {'Go back to the Main Menu.'};
            app.SAVEAS_Button.Text = {'Save As... '; '(.mat)'};
            app.RESET_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESET_Button.ButtonPushedFcn = createCallbackFcn(app, @RESET_ButtonPushed, true);
            app.RESET_Button.Tooltip = {'Go back to the Main Menu.'};
            app.RESET_Button.Text = 'RESET';
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Explore the depths of material analysis and XPS with this robust tool. It empowers users to construct multi-layered models, each layer featuring a uniform and homogenous material composition and thickness. The app draws upon an extensive database for material characteristics and integrates various options for IMFP parameters, including Optical (NIST), Universal, TPP-2M, and S1 through S4. It also offers a selection of photoionization cross-sections and asymmetry parameters from renowned databases, such as the Scofield (1973), Yeh & Lindau (1985), Trzhaskovskaya (2018) and Cant (2022) databases. Utilizing the Beer-Lambert law, the tool accurately computes the PES intensities for each layer, modulated by the electron IMFP based on the specific experimental setup. Additionally, it can graphically represent the variations in photon energy and emission angles.'};
            app.Image.ImageSource = 'Icon_PESModelFitter.jpeg';
            app.ViewRawData_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.ViewRawData_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewRawData_ButtonPushed, true);
            app.ViewRawData_Button.Tooltip = {'View PES Background Model'};
            app.ViewRawData_Button.Text = 'View Raw';
            app.ViewNiceData_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.ViewNiceData_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewNiceData_ButtonPushed, true);
            app.ViewNiceData_Button.Tooltip = {'View PES Background Model'};
            app.ViewNiceData_Button.Text = 'View Nice';
            app.BackgroundSubtractionPanel = uipanel(app.GridLayout);
            app.BackgroundSubtractionPanel.Tooltip = {'Background Subtraction Panel'};
            app.BackgroundSubtractionPanel.Title = 'Background Subtraction';
            app.BackgroundSubtraction_GridLayout = uigridlayout(app.BackgroundSubtractionPanel);
            app.Bgrnd_Type_DropDown = uidropdown(app.BackgroundSubtraction_GridLayout);
            app.Bgrnd_Type_DropDown.Items = {'L - (Linear)', 'P - (Polynomial)', 'S - (Shirley)', 'SO - (Shirley-Offset)', 'SS - (Shirley-Smart)', 'T1 - (Tougaard1)', 'T2 - (Tougaard2)'};
            app.Bgrnd_Type_DropDown.ValueChangedFcn = createCallbackFcn(app, @Bgrnd_Type_DropDownValueChanged, true);
            app.Bgrnd_Type_DropDown.Tooltip = {'Background Subtraction Type'};
            app.Bgrnd_Type_DropDown.Value = 'L - (Linear)';
            app.LHSStartEditFieldLabel = uilabel(app.BackgroundSubtraction_GridLayout);
            app.LHSStartEditFieldLabel.Text = 'LHS (Start):';
            app.Bgrnd_LHS_EditField = uieditfield(app.BackgroundSubtraction_GridLayout, 'numeric');
            app.Bgrnd_LHS_EditField.ValueDisplayFormat = '%.3f';
            app.Bgrnd_LHS_EditField.ValueChangedFcn = createCallbackFcn(app, @Bgrnd_LHS_EditFieldValueChanged, true);
            app.Bgrnd_LHS_EditField.Tooltip = {'LHS Starting Point of the background subtraction region of interest'};
            app.Bgrnd_LHS_EditField.Value = -5;
            app.RHSEndEditFieldLabel = uilabel(app.BackgroundSubtraction_GridLayout);
            app.RHSEndEditFieldLabel.Text = 'RHS (End):';
            app.Bgrnd_RHS_EditField = uieditfield(app.BackgroundSubtraction_GridLayout, 'numeric');
            app.Bgrnd_RHS_EditField.ValueDisplayFormat = '%.3f';
            app.Bgrnd_RHS_EditField.ValueChangedFcn = createCallbackFcn(app, @Bgrnd_RHS_EditFieldValueChanged, true);
            app.Bgrnd_RHS_EditField.Tooltip = {'RHS End Point of the background subtraction region of interest'};
            app.Bgrnd_RHS_EditField.Value = 5;
            app.WindowSizeEditFieldLabel = uilabel(app.BackgroundSubtraction_GridLayout);
            app.WindowSizeEditFieldLabel.Text = 'Window Size:';
            app.Bgrnd_WindowSize_EditField = uieditfield(app.BackgroundSubtraction_GridLayout, 'numeric');
            app.Bgrnd_WindowSize_EditField.Limits = [0 Inf];
            app.Bgrnd_WindowSize_EditField.ValueDisplayFormat = '%.3f';
            app.Bgrnd_WindowSize_EditField.ValueChangedFcn = createCallbackFcn(app, @Bgrnd_WindowSize_EditFieldValueChanged, true);
            app.Bgrnd_WindowSize_EditField.Tooltip = {'Averaging Window Size around the start/end position'};
            app.Bgrnd_WindowSize_EditField.Value = 0.5;
            app.ConstantOffsetEditFieldLabel = uilabel(app.BackgroundSubtraction_GridLayout);
            app.ConstantOffsetEditFieldLabel.Text = 'Constant Offset:';
            app.Bgrnd_ConstantOffset_EditField = uieditfield(app.BackgroundSubtraction_GridLayout, 'numeric');
            app.Bgrnd_ConstantOffset_EditField.ValueDisplayFormat = '%.3f';
            app.Bgrnd_ConstantOffset_EditField.ValueChangedFcn = createCallbackFcn(app, @Bgrnd_ConstantOffset_EditFieldValueChanged, true);
            app.Bgrnd_ConstantOffset_EditField.Tooltip = {'Constant Offset added to the background subtraction'};
            app.PolynomialArguments_Panel = uipanel(app.BackgroundSubtraction_GridLayout);
            app.PolynomialArguments_Panel.Tooltip = {'Polynomial Arguments Panel'};
            app.PolynomialArguments_Panel.Title = 'Polynomial Arguments';
            app.PolynomialArguments_GridLayout = uigridlayout(app.PolynomialArguments_Panel);
            app.OrderEditFieldLabel = uilabel(app.PolynomialArguments_GridLayout);
            app.OrderEditFieldLabel.Text = 'Order:';
            app.Poly_OrderEditField = uieditfield(app.PolynomialArguments_GridLayout, 'numeric');
            app.Poly_OrderEditField.Limits = [0 Inf];
            app.Poly_OrderEditField.RoundFractionalValues = 'on';
            app.Poly_OrderEditField.ValueDisplayFormat = '%.0f';
            app.Poly_OrderEditField.ValueChangedFcn = createCallbackFcn(app, @Poly_OrderEditFieldValueChanged, true);
            app.Poly_OrderEditField.Tooltip = {'Scalar of the Polynomial Order '};
            app.Poly_OrderEditField.Value = 3;
            app.SOArguments_Panel = uipanel(app.BackgroundSubtraction_GridLayout);
            app.SOArguments_Panel.Tooltip = {'Shirley-Offset Arguments Panel'};
            app.SOArguments_Panel.Title = 'Shirley-Offset Arguments';
            app.SOArguments_GridLayout = uigridlayout(app.SOArguments_Panel);
            app.LambdaLabel = uilabel(app.SOArguments_GridLayout);
            app.LambdaLabel.Text = 'Lambda:';
            app.SO_LambdaParameter_EditField = uieditfield(app.SOArguments_GridLayout, 'numeric');
            app.SO_LambdaParameter_EditField.Limits = [0 1];
            app.SO_LambdaParameter_EditField.ValueDisplayFormat = '%.3f';
            app.SO_LambdaParameter_EditField.ValueChangedFcn = createCallbackFcn(app, @SO_LambdaParameter_EditFieldValueChanged, true);
            app.SO_LambdaParameter_EditField.Tooltip = {'Scalar for the Shirley-Offset mixing ratio: lambda = 0 is Pure Shirley; lambda = 1 is Pure Linear.'};
            app.SO_LambdaParameter_EditField.Value = 0.5;
            app.DeltaLabel = uilabel(app.SOArguments_GridLayout);
            app.DeltaLabel.Text = 'Delta:';
            app.SO_DeltaParameter_EditField = uieditfield(app.SOArguments_GridLayout, 'numeric');
            app.SO_DeltaParameter_EditField.ValueDisplayFormat = '%.3f';
            app.SO_DeltaParameter_EditField.ValueChangedFcn = createCallbackFcn(app, @SO_DeltaParameter_EditFieldValueChanged, true);
            app.SO_DeltaParameter_EditField.Tooltip = {'Scalar for the Shirley curve offset along the x-axis'};
            app.TougaardArguments_Panel = uipanel(app.BackgroundSubtraction_GridLayout);
            app.TougaardArguments_Panel.Tooltip = {'Tougaard Arguments Panel'};
            app.TougaardArguments_Panel.Title = 'Tougaard Arguments';
            app.TougaardArguments_GridLayout = uigridlayout(app.TougaardArguments_Panel);
            app.CParameterEditFieldLabel = uilabel(app.TougaardArguments_GridLayout);
            app.CParameterEditFieldLabel.Text = 'C-Parameter:';
            app.Tou_CParameter_EditField = uieditfield(app.TougaardArguments_GridLayout, 'numeric');
            app.Tou_CParameter_EditField.Limits = [0 Inf];
            app.Tou_CParameter_EditField.RoundFractionalValues = 'on';
            app.Tou_CParameter_EditField.ValueDisplayFormat = '%.0f';
            app.Tou_CParameter_EditField.ValueChangedFcn = createCallbackFcn(app, @Tou_CParameter_EditFieldValueChanged, true);
            app.Tou_CParameter_EditField.Tooltip = {'Scalar of C-parameter in the Tougaard background'};
            app.Tou_CParameter_EditField.Value = 1643;
            app.DParameterEditFieldLabel = uilabel(app.TougaardArguments_GridLayout);
            app.DParameterEditFieldLabel.Text = 'D-Parameter:';
            app.Tou_DParameter_EditField = uieditfield(app.TougaardArguments_GridLayout, 'numeric');
            app.Tou_DParameter_EditField.Limits = [0 Inf];
            app.Tou_DParameter_EditField.RoundFractionalValues = 'on';
            app.Tou_DParameter_EditField.ValueDisplayFormat = '%.0f';
            app.Tou_DParameter_EditField.ValueChangedFcn = createCallbackFcn(app, @Tou_DParameter_EditFieldValueChanged, true);
            app.Tou_DParameter_EditField.Tooltip = {'Scalar of D-parameter in the Tougaard background'};
            app.Tou_DParameter_EditField.Value = 275;
            app.OptimizationAlgorithm_Panel = uipanel(app.GridLayout);
            app.OptimizationAlgorithm_Panel.Tooltip = {'Optimization Panel'};
            app.OptimizationAlgorithm_Panel.Title = 'Optimization Algorithms';
            app.OptimizationAlgorithm_GridLayout = uigridlayout(app.OptimizationAlgorithm_Panel);
            app.SolverTypeLabel = uilabel(app.OptimizationAlgorithm_GridLayout);
            app.SolverTypeLabel.Text = 'Solver Type:';
            app.OptimizationType_DropDown = uidropdown(app.OptimizationAlgorithm_GridLayout);
            app.OptimizationType_DropDown.Items = {'lsqcurvefit - (Least Squares Fitting)', 'fmincon - (Functional Minimization Fit)', 'simulannealbnd - (Simulated Annealing Fitting)'};
            app.OptimizationType_DropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationType_DropDownValueChanged, true);
            app.OptimizationType_DropDown.Tooltip = {'Solver Type'};
            app.OptimizationType_DropDown.Value = 'lsqcurvefit - (Least Squares Fitting)';
            app.RunOptimization_Button = uibutton(app.OptimizationAlgorithm_GridLayout, 'push');
            app.RunOptimization_Button.ButtonPushedFcn = createCallbackFcn(app, @RunOptimization_ButtonPushed, true);
            app.RunOptimization_Button.Tooltip = {'Run the Curve Fit Optimization Algorithm'};
            app.RunOptimization_Button.Text = 'Run Curve Fit Optimization';
            app.NumberoffitparametersLabel_3 = uilabel(app.OptimizationAlgorithm_GridLayout);
            app.NumberoffitparametersLabel_3.Text = 'Max. Iterations:';
            app.MaxIter_EditField = uieditfield(app.OptimizationAlgorithm_GridLayout, 'numeric');
            app.MaxIter_EditField.Limits = [0 Inf];
            app.MaxIter_EditField.ValueDisplayFormat = '%.0f';
            app.MaxIter_EditField.ValueChangedFcn = createCallbackFcn(app, @MaxIter_EditFieldValueChanged, true);
            app.MaxIter_EditField.Tooltip = {'Maximum Number of Solver Iterations'};
            app.MaxIter_EditField.Value = 5000;
            app.NumberoffitparametersLabel_2 = uilabel(app.OptimizationAlgorithm_GridLayout);
            app.NumberoffitparametersLabel_2.Text = 'Max. Func. Eval.:';
            app.MaxFuncEval_EditField = uieditfield(app.OptimizationAlgorithm_GridLayout, 'numeric');
            app.MaxFuncEval_EditField.Limits = [0 Inf];
            app.MaxFuncEval_EditField.ValueDisplayFormat = '%.0f';
            app.MaxFuncEval_EditField.ValueChangedFcn = createCallbackFcn(app, @MaxFuncEval_EditFieldValueChanged, true);
            app.MaxFuncEval_EditField.Tooltip = {'Maximum Number of Function Evaluations for the Solver'};
            app.MaxFuncEval_EditField.Value = 100000;
            app.NumberoffitparametersLabel = uilabel(app.OptimizationAlgorithm_GridLayout);
            app.NumberoffitparametersLabel.Text = 'Degrees of Freedom:';
            app.DoF_EditField = uieditfield(app.OptimizationAlgorithm_GridLayout, 'numeric');
            app.DoF_EditField.Limits = [0 Inf];
            app.DoF_EditField.ValueDisplayFormat = '%.0f';
            app.DoF_EditField.Editable = 'off';
            app.DoF_EditField.Tooltip = {'Total Degrees of Freedom of the fits (i.e. total number of fit parameters)'};
            app.NumberoffitparametersLabel_4 = uilabel(app.OptimizationAlgorithm_GridLayout);
            app.NumberoffitparametersLabel_4.Text = 'Function Tol.:';
            app.FuncTol_EditField = uieditfield(app.OptimizationAlgorithm_GridLayout, 'numeric');
            app.FuncTol_EditField.Limits = [0 Inf];
            app.FuncTol_EditField.ValueDisplayFormat = '%.1e';
            app.FuncTol_EditField.ValueChangedFcn = createCallbackFcn(app, @FuncTol_EditFieldValueChanged, true);
            app.FuncTol_EditField.Tooltip = {'Solver Function Tolerance'};
            app.FuncTol_EditField.Value = 1e-07;
            app.NumberoffitparametersLabel_5 = uilabel(app.OptimizationAlgorithm_GridLayout);
            app.NumberoffitparametersLabel_5.Text = 'Fin. Diff. Rel. Step:';
            app.FinDiffRelStep_EditField = uieditfield(app.OptimizationAlgorithm_GridLayout, 'numeric');
            app.FinDiffRelStep_EditField.Limits = [0 Inf];
            app.FinDiffRelStep_EditField.ValueDisplayFormat = '%.1e';
            app.FinDiffRelStep_EditField.ValueChangedFcn = createCallbackFcn(app, @FinDiffRelStep_EditFieldValueChanged, true);
            app.FinDiffRelStep_EditField.Tooltip = {'Finite Difference Relative Step Size for the Solver'};
            app.FinDiffRelStep_EditField.Value = 1e-05;
            app.MeasureofBestFitLabel = uilabel(app.OptimizationAlgorithm_GridLayout);
            app.MeasureofBestFitLabel.Text = 'Measure of Best Fit:';
            app.ChiSq_EditField = uieditfield(app.OptimizationAlgorithm_GridLayout, 'numeric');
            app.ChiSq_EditField.ValueDisplayFormat = '%.3e';
            app.ChiSq_EditField.Editable = 'off';
            app.ChiSq_EditField.Tooltip = {'Reduced chi-squared value of the data & model'};
            app.ViewModel_Button = uibutton(app.GridLayout, 'push');
            app.ViewModel_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewModel_ButtonPushed, true);
            app.ViewModel_Button.Tooltip = {'Run & View the Current PES Model'};
            app.ViewModel_Button.Text = 'Run / View Current PES Model';
            app.ExportParams_Button = uibutton(app.GridLayout, 'push');
            app.ExportParams_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportParams_ButtonPushed, true);
            app.ExportParams_Button.Tooltip = {'Export all the parameter arguments (Background, Parameters, Cofidence Intervals & Fix Parameters)'};
            app.ExportParams_Button.Text = 'Export Parameters (*.mat)';
            app.ImportParams_Button = uibutton(app.GridLayout, 'push');
            app.ImportParams_Button.ButtonPushedFcn = createCallbackFcn(app, @ImportParams_ButtonPushed, true);
            app.ImportParams_Button.Tooltip = {'Import all the parameter arguments (Background, Parameters, Cofidence Intervals & Fix Parameters)'};
            app.ImportParams_Button.Text = 'Import Parameters (*.mat)';
            app.CurveFittingModel_TabGroup = uitabgroup(app.GridLayout);
            app.ParameterValues_Tab = uitab(app.CurveFittingModel_TabGroup);
            app.ParameterValues_Tab.Tooltip = {'Get (or set) the parameter values'};
            app.ParameterValues_Tab.Title = 'Parameter Values';
            app.ParameterValues_GridLayout = uigridlayout(app.ParameterValues_Tab);
            app.ParameterValues_GridLayout.Tooltip = {'Parameter Values Tab'};
            app.Parameters_UITable = uitable(app.ParameterValues_GridLayout);
            app.Parameters_UITable.ColumnName = {'Peak'; 'Type'; 'BE'; 'INT'; 'FWHM'; 'MR'; 'LSE'; 'LSI'; 'LSW'; 'ASY'};
            app.Parameters_UITable.RowName = {};
            app.Parameters_UITable.CellEditCallback = createCallbackFcn(app, @Parameters_UITableCellEdit, true);
            app.Parameters_UITable.Tooltip = {'Table of all the parameter values for the PES model'};
            app.PeakAdd_Button = uibutton(app.ParameterValues_GridLayout, 'push');
            app.PeakAdd_Button.ButtonPushedFcn = createCallbackFcn(app, @PeakAdd_ButtonPushed, true);
            app.PeakAdd_Button.Tooltip = {'Add a new peak'};
            app.PeakAdd_Button.Text = '+';
            app.PeakDelete_Button = uibutton(app.ParameterValues_GridLayout, 'push');
            app.PeakDelete_Button.ButtonPushedFcn = createCallbackFcn(app, @PeakDelete_ButtonPushed, true);
            app.PeakDelete_Button.Tooltip = {'Remove the last peak'};
            app.PeakDelete_Button.Text = '-';
            app.FixParameters_Tab = uitab(app.CurveFittingModel_TabGroup);
            app.FixParameters_Tab.Tooltip = {'Specify which parameter values to fix'};
            app.FixParameters_Tab.Title = 'Fix Parameters';
            app.FixParameters_GridLayout = uigridlayout(app.FixParameters_Tab);
            app.FixParameters_GridLayout.Tooltip = {'Fixed Parameters Tab'};
            app.FixParameters_UITable = uitable(app.FixParameters_GridLayout);
            app.FixParameters_UITable.ColumnName = {'Peak'; 'Type'; 'BE'; 'INT'; 'FWHM'; 'MR'; 'LSE'; 'LSI'; 'LSW'; 'ASY'};
            app.FixParameters_UITable.RowName = {};
            app.FixParameters_UITable.CellEditCallback = createCallbackFcn(app, @FixParameters_UITableCellEdit, true);
            app.FixParameters_UITable.Tooltip = {'Table of all the fixed parameters'};
            app.DynCI_Tab = uitab(app.CurveFittingModel_TabGroup);
            app.DynCI_Tab.Tooltip = {'Dynamic Confidence Intervals based on a given window size'};
            app.DynCI_Tab.Title = 'Dynamic Confidence Intervals (+/-)';
            app.DynCI_GridLayout = uigridlayout(app.DynCI_Tab);
            app.DynCI_GridLayout.Tooltip = {'Confidence Intervals Tab'};
            app.DynCI_UITable = uitable(app.DynCI_GridLayout);
            app.DynCI_UITable.ColumnName = {'Peak'; 'Type'; 'BE'; 'INT'; 'FWHM'; 'MR'; 'LSE'; 'LSI'; 'LSW'; 'ASY'};
            app.DynCI_UITable.RowName = {};
            app.DynCI_UITable.CellEditCallback = createCallbackFcn(app, @DynCI_UITableCellEdit, true);
            app.DynCI_UITable.Tooltip = {'Table of all the confidence intervals'};
            app.DynCI_CheckBox = uicheckbox(app.DynCI_GridLayout);
            app.DynCI_CheckBox.ValueChangedFcn = createCallbackFcn(app, @DynCI_CheckBoxValueChanged, true);
            app.DynCI_CheckBox.Text = '';
            app.DynCI_CheckBox.Value = true;
            app.StaticCI_Tab = uitab(app.CurveFittingModel_TabGroup);
            app.StaticCI_Tab.Tooltip = {'Static Confidence Intervals based on a given range of allowed values'};
            app.StaticCI_Tab.Title = 'Static Confidence Intervals (lb:ub)';
            app.StaticCI_GridLayout = uigridlayout(app.StaticCI_Tab);
            app.StaticCI_GridLayout.Tooltip = {'Confidence Intervals Tab'};
            app.StaticCI_UITable = uitable(app.StaticCI_GridLayout);
            app.StaticCI_UITable.ColumnName = {'Peak'; 'Type'; 'BE'; 'INT'; 'FWHM'; 'MR'; 'LSE'; 'LSI'; 'LSW'; 'ASY'};
            app.StaticCI_UITable.RowName = {};
            app.StaticCI_UITable.CellEditCallback = createCallbackFcn(app, @StaticCI_UITableCellEdit, true);
            app.StaticCI_UITable.Tooltip = {'Table of all the confidence intervals'};
            app.StaticCI_CheckBox = uicheckbox(app.StaticCI_GridLayout);
            app.StaticCI_CheckBox.ValueChangedFcn = createCallbackFcn(app, @StaticCI_CheckBoxValueChanged, true);
            app.StaticCI_CheckBox.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_MatBase_PESCurveFitter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end