classdef App_MatBase_PESModelViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        ACM_Tab                        matlab.ui.container.Tab
        ACM_GridLayout                 matlab.ui.container.GridLayout
        ACModel_LayerDelete_Button     matlab.ui.control.Button
        ACModel_UITable                matlab.ui.control.Table
        ACModel_LayerAdd_Button        matlab.ui.control.Button
        ACModel_ExportDataMat          matlab.ui.control.Button
        ACModel_ExportDataTxt          matlab.ui.control.Button
        ACModel_ViewModel              matlab.ui.control.Button
        PESBgrnd_Tab                   matlab.ui.container.Tab
        PESBgrnd_GridLayout01          matlab.ui.container.GridLayout
        PESBgrnd_GridLayout02          matlab.ui.container.GridLayout
        SOArguments_Panel              matlab.ui.container.Panel
        SOArguments_GridLayout         matlab.ui.container.GridLayout
        SO_DeltaParameter_EditField    matlab.ui.control.NumericEditField
        DeltaLabel                     matlab.ui.control.Label
        SO_LambdaParameter_EditField   matlab.ui.control.NumericEditField
        LambdaLabel                    matlab.ui.control.Label
        TougaardArguments_Panel        matlab.ui.container.Panel
        TougaardArguments_GridLayout   matlab.ui.container.GridLayout
        Tou_DParameter_EditField       matlab.ui.control.NumericEditField
        DParameterEditFieldLabel       matlab.ui.control.Label
        Tou_CParameter_EditField       matlab.ui.control.NumericEditField
        CParameterEditFieldLabel       matlab.ui.control.Label
        PESBgrnd_ConstantOffset_EditField  matlab.ui.control.NumericEditField
        ConstantOffsetEditFieldLabel   matlab.ui.control.Label
        PESBgrnd_WindowSize_EditField  matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel       matlab.ui.control.Label
        PESBgrnd_RHS_EditField         matlab.ui.control.NumericEditField
        RHSEndEditFieldLabel           matlab.ui.control.Label
        PolynomialArguments_Panel      matlab.ui.container.Panel
        PESBgrnd_GridLayout04          matlab.ui.container.GridLayout
        Poly_OrderEditField            matlab.ui.control.NumericEditField
        OrderEditFieldLabel            matlab.ui.control.Label
        PESBgrnd_LHS_EditField         matlab.ui.control.NumericEditField
        LHSStartEditFieldLabel         matlab.ui.control.Label
        PESBgrnd_Type_DropDown         matlab.ui.control.DropDown
        BackgroundSubtractionTypeDropDownLabel  matlab.ui.control.Label
        PESBgrnd_ExportDataMat_Button  matlab.ui.control.Button
        PESBgrnd_ExportDataTxt_Button  matlab.ui.control.Button
        PESBgrnd_ViewModel_Button      matlab.ui.control.Button
        PESModel_Tab                   matlab.ui.container.Tab
        PESModel_GridLayout            matlab.ui.container.GridLayout
        PESModel_PeakDelete_Button     matlab.ui.control.Button
        PESModel_UITable               matlab.ui.control.Table
        PESModel_PeakAdd_Button        matlab.ui.control.Button
        PESModel_ExportDataMat_Button  matlab.ui.control.Button
        PESModel_ExportDataTxt_Button  matlab.ui.control.Button
        PESModel_ViewModel_Button      matlab.ui.control.Button
        AppButtons_GridLayout          matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        TRANSFER_Button                matlab.ui.control.Button
        APPINFO_Button                 matlab.ui.control.Button
        RESIZEUI_Button                matlab.ui.control.Button
        RESTART_Button                 matlab.ui.control.Button
        CLOSEFIGS_Button               matlab.ui.control.Button
        AppTitle                       matlab.ui.control.Label
        MAINMENU_GridLayout            matlab.ui.container.GridLayout
        MAINMENU_Button                matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % - Variables for the app
        % PES Spectral Intensity Curves Tab
        num_of_peaks            % Total number of peaks
        pes_model               % Data Structure of the PES Model
        tab_pes_model           % Table the contains all the PES Spectral Intensity Model Arguments
        % PES Background
        pes_bgrnd_model         % Data Structure of the PES Background Model
        bgrnd_type              % Background Type
        bgrnd_LHS               % LHS
        bgrnd_RHS               % RHS
        bgrnd_Win               % Window Size
        bgrnd_Offset            % Offset
        bgrnd_Order             % Order
        bgrnd_lambda            % C
        bgrnd_delta             % D
        bgrnd_C                 % C
        bgrnd_D                 % D
        % Atomic Concentration Curves Tab
        num_of_layers           % Total number of layers
        ac_model                % Data Structure of the AC Model
        tab_ac_model            % Table the contains all the AC Model Arguments
    end
    
    % Private methods available to the app only
    methods (Access = private)
        % -- Extract all the possible PES peak types
        function pes_peak_types = extract_pes_peak_types(app)
            pes_peak_types = {...
                'G - Gaussian',...
                'L - Lorentizan',...
                'DS - Doniach',...
                'sGL - Voigt',...
                'sGLA - Voigt',...
                'pGL - Voigt',...
                'pGLA - Voigt'};
        end

        % -- Determine PES Spectral Intensity Curve
        function pes_model = extract_pes_model(app)
            % 1 - Extracting best x-axis domain
            for i = 1:app.num_of_peaks
                BE(i)       = app.tab_pes_model{i,3}{1};
                INT(i)      = app.tab_pes_model{i,4}{1};
                FWHM(i)     = app.tab_pes_model{i,5}{1};
                MR(i)       = app.tab_pes_model{i,6}{1};
                LSE(i)      = app.tab_pes_model{i,7}{1};
                LSI(i)      = app.tab_pes_model{i,8}{1};
                LSW(i)      = app.tab_pes_model{i,9}{1};
                ASY(i)      = app.tab_pes_model{i,10}{1};
            end
            BE_LSE_Matrix   = [BE, BE+LSE];
            x_min   = min(BE_LSE_Matrix(:)) - 5;
            x_max   = max(BE_LSE_Matrix(:)) + 5;
            x       = x_min:0.02:x_max; x = x';
            % 2 - Extract the curve components for each peak
            y_comps = {};
            for i = 1:app.num_of_peaks
                % -- Extracting the component intensities
                y_comps{i} = PES_SpecIntCurve(x,...
                    app.tab_pes_model{i,2}{1},...
                    app.tab_pes_model{i,3}{1}, app.tab_pes_model{i,4}{1}, app.tab_pes_model{i,5}{1},...
                    app.tab_pes_model{i,6}{1}, app.tab_pes_model{i,7}{1}, app.tab_pes_model{i,8}{1},...
                    app.tab_pes_model{i,9}{1}, app.tab_pes_model{i,10}{1});
                AREA(i)      = trapz(x, y_comps{i});
            end
            % 3 - Sum up all of the curve components to get the final model PES curve
            y = zeros(size(x));
            for i = 1:app.num_of_peaks; y = y + y_comps{i}; end
            y(isnan(y)) = 0;
            % 4 - Storing all data in a MATLAB Data-Structure
            pes_model = struct();
            pes_model.label         = "PES_SpecIntCurve()";
            pes_model.raw_table     = app.tab_pes_model;
            pes_model.num_of_peaks  = app.num_of_peaks;
            pes_model.x             = x;
            pes_model.y             = y;
            pes_model.y_comps       = y_comps;
            pes_model.BE            = BE;
            pes_model.INT           = INT;
            pes_model.FWHM          = FWHM;
            pes_model.MR            = MR;
            pes_model.LSE           = LSE;
            pes_model.LSI           = LSI;
            pes_model.LSW           = LSW;
            pes_model.ASY           = ASY;
            pes_model.AREA          = AREA;
            pes_model.AREA0         = AREA ./ trapz(x, y);
        end

        % -- View PES Spectral Intensity Curve Model
        function view_pes_model(app)
            % 1 - Initialising the figure
            fig = figure(); 
            fig.Position(3) = 750;
            fig.Position(4) = 450;
            hold on;
            % 2 - Plotting all of the peak components
            cols = lines(app.pes_model.num_of_peaks);
            for i = 1:app.pes_model.num_of_peaks
                area(app.pes_model.x, app.pes_model.y_comps{i}, 'FaceColor', cols(i,:), 'FaceAlpha', 0.50, 'EdgeAlpha', 0);
                a = plot(app.pes_model.x, app.pes_model.y_comps{i}, 'k-', 'linewidth', 0.25);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 3 - Plotting the peak component energy locations
            for i = 1:app.pes_model.num_of_peaks
                if i == 1; lWidth = 2; else; lWidth = 1; end
                a = line([app.pes_model.BE(i), app.pes_model.BE(i)], [0, max(app.pes_model.y_comps{i})],...
                    'Color', [0 0 0], 'LineWidth', lWidth, 'Linestyle', '-');
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 4 - Plotting the final PES intensity spectrum
            a = scatter(app.pes_model.x, app.pes_model.y, 10, 'k', 'MarkerFaceColor', 'k', 'MarkerFaceAlpha',.2,'MarkerEdgeAlpha',.2);
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            plot(app.pes_model.x, app.pes_model.y, 'k-', 'color', 'k', 'linewidth', 2.0);
            % 5 - Formatting the figure
            title('PES Spectral Intensity Model', 'FontWeight', 'bold', 'FontSize', 12);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ylabel('Intensity (arb.)', 'FontWeight', 'bold', 'FontSize', 11);
            axis([min(app.pes_model.x(:)), max(app.pes_model.x(:)), min(app.pes_model.y(:)), 1.10*max(app.pes_model.y(:))]);
            ax = gca; ax.FontName  = 'Segoe UI'; 
            lgnd_txt = {};
            for i = 1:app.pes_model.num_of_peaks; lgnd_txt{i} = sprintf("Peak %i (%.1fpc)", i, 100*app.pes_model.AREA0(i)); end
            lgnd_txt{end+1} = "Final";
            legend(lgnd_txt, 'location', 'best');
        end

        % -- Extract all the possible PES Backgrounds
        function bgrnd_types = extract_pes_bgrnds(app)
            bgrnd_types = {...
                'None',...
                'L - Linear',...
                'P - Polynomial',...
                'S - Shirley',...
                'SO - Shirley-Offset',...
                'SS - Shirley-Smart',...
                'T1 - Tougaard1',...
                'T2 - Tougaard2'};
        end

        % -- Determine PES Background
        function pes_bgrnd_model = extract_pes_bgrnd_model(app)
            % 1 - Generating an artificial XPS spectrum
            xdat        = linspace(-10, 10, 1e3);
            ydat01      = PES_SpecIntCurve(xdat, "sGLA", 1, 5, 0.5, 0.5, -1, 0.5, 0, 0.1);
            ydat02      = StepModel_Erf_RHS(xdat, 1, 0.5, 0.2);
            ydat        = (ydat01 + ydat02) + 0.2*rand(1, length(ydat01)) + 0.1;
            % 2 - Extracting Start & End Point Indices
            % -- Start index points
            [~, StartIndx_lb]   = min(abs(xdat - app.bgrnd_LHS - app.bgrnd_Win));
            [~, StartIndx_ub]   = min(abs(xdat - app.bgrnd_LHS + app.bgrnd_Win));
            % -- End index points
            [~, EndIndx_lb]     = min(abs(xdat - app.bgrnd_RHS - app.bgrnd_Win));
            [~, EndIndx_ub]     = min(abs(xdat - app.bgrnd_RHS + app.bgrnd_Win));
            % - ROI indexes
            StartWin_indx      	= sort([StartIndx_lb, StartIndx_ub]);
            EndWin_indx     	= sort([EndIndx_lb, EndIndx_ub]);
            % - Extracting the data points of the background over the window size
            if iscolumn(xdat);  xdat_win      = [xdat(StartWin_indx(1):StartWin_indx(2));   xdat(EndWin_indx(1):EndWin_indx(2))];
            elseif isrow(xdat); xdat_win      = [xdat(StartWin_indx(1):StartWin_indx(2)),   xdat(EndWin_indx(1):EndWin_indx(2))];
            end
            if iscolumn(ydat); ydat_win       = [ydat(StartWin_indx(1):StartWin_indx(2));   ydat(EndWin_indx(1):EndWin_indx(2))];
            elseif isrow(ydat); ydat_win      = [ydat(StartWin_indx(1):StartWin_indx(2)),   ydat(EndWin_indx(1):EndWin_indx(2))];
            end
            % 3 - Extracting the background spectrum
            Type = char(lower(app.bgrnd_type));
            switch Type
                case lower({'None','L','S','SS'});      [roi_xdat, roi_ydat, roi_bgrnd] = PES_BgrndSubCurve(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset);
                case lower({'SO'});                     [roi_xdat, roi_ydat, roi_bgrnd] = PES_BgrndSubCurve(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_lambda, app.bgrnd_delta);
                case lower({'P'});                      [roi_xdat, roi_ydat, roi_bgrnd] = PES_BgrndSubCurve(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_Order);
                case lower({'T1'});                     [roi_xdat, roi_ydat, roi_bgrnd] = PES_BgrndSubCurve(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_C);
                case lower({'T2'});                     [roi_xdat, roi_ydat, roi_bgrnd] = PES_BgrndSubCurve(xdat, ydat, app.bgrnd_type, app.bgrnd_LHS, app.bgrnd_RHS, app.bgrnd_Win, app.bgrnd_Offset, app.bgrnd_C, app.bgrnd_D);
            end
            % 4 - Storing all data in a MATLAB Data-Structure
            pes_bgrnd_model = struct();
            pes_bgrnd_model.label           = "PES_BgrndSubCurve()";
            pes_bgrnd_model.Type            = app.bgrnd_type;
            pes_bgrnd_model.LHS             = app.bgrnd_LHS;
            pes_bgrnd_model.RHS             = app.bgrnd_RHS;
            pes_bgrnd_model.Window          = app.bgrnd_Win;
            pes_bgrnd_model.Offset          = app.bgrnd_Offset;
            pes_bgrnd_model.Order           = app.bgrnd_Order;
            pes_bgrnd_model.Lambda          = app.bgrnd_lambda;
            pes_bgrnd_model.Delta           = app.bgrnd_delta;
            pes_bgrnd_model.C               = app.bgrnd_C;
            pes_bgrnd_model.D               = app.bgrnd_D;
            pes_bgrnd_model.xdat_win        = xdat_win;
            pes_bgrnd_model.ydat_win        = ydat_win;
            pes_bgrnd_model.xdat            = xdat;
            pes_bgrnd_model.ydat            = ydat;
            pes_bgrnd_model.roi_xdat        = roi_xdat;
            pes_bgrnd_model.roi_ydat        = roi_ydat;
            pes_bgrnd_model.roi_bgrnd       = roi_bgrnd;
            pes_bgrnd_model.roi_ydat_final  = roi_ydat  - roi_bgrnd;
        end

        % -- View PES Background
        function view_pes_bgrnd_model(app)
            % 1 - Initialising the figure
            fig = figure(); 
            fig.Position(3) = 750;
            fig.Position(4) = 450;
            hold on;
            % - Extracting variables
            ROI     = [app.pes_bgrnd_model.LHS, app.pes_bgrnd_model.RHS];
            LHS     = app.pes_bgrnd_model.LHS + [-app.pes_bgrnd_model.Window, app.pes_bgrnd_model.Window];
            RHS     = app.pes_bgrnd_model.RHS + [-app.pes_bgrnd_model.Window, app.pes_bgrnd_model.Window];
            % -- Plotting the ROI, LHS and RHS analysis windows
            hMID    = patch([ROI(1), ROI(1), ROI(2), ROI(2), ROI(1)], [0, 1, 1, 0, 0].*2*max(app.pes_bgrnd_model.ydat(:)), [0.8 0.8 0.8], 'facealpha', 0.5, 'edgecolor', 'none');
            hLHS    = patch([LHS(1), LHS(1), LHS(2), LHS(2), LHS(1)], [0, 1, 1, 0, 0].*2*max(app.pes_bgrnd_model.ydat(:)), [0.2 0.7 0.2], 'facealpha', 0.2, 'edgecolor', 'none');
            hRHS    = patch([RHS(1), RHS(1), RHS(2), RHS(2), RHS(1)], [0, 1, 1, 0, 0].*2*max(app.pes_bgrnd_model.ydat(:)), [0.7 0.2 0.2], 'facealpha', 0.2, 'edgecolor', 'none');
            hLHS.Annotation.LegendInformation.IconDisplayStyle = 'off';
            hRHS.Annotation.LegendInformation.IconDisplayStyle = 'off';
            hMID.Annotation.LegendInformation.IconDisplayStyle = 'off';
            % -- Plotting a vertical line to show the ROI
            a = xline(ROI(1), 'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
            b = xline(ROI(2), 'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
            c = yline(0, 'Color', [0 0 0], 'LineWidth', 1, 'Linestyle', '-');
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            b.Annotation.LegendInformation.IconDisplayStyle = 'off';
            c.Annotation.LegendInformation.IconDisplayStyle = 'off';
            % -- Plotting the 1D data
            plot(app.pes_bgrnd_model.xdat, app.pes_bgrnd_model.ydat, 'b-', 'linewidth', 0.5);
            plot(app.pes_bgrnd_model.roi_xdat, app.pes_bgrnd_model.roi_ydat, 'b-', 'linewidth', 2);
            plot(app.pes_bgrnd_model.roi_xdat, app.pes_bgrnd_model.roi_bgrnd, 'r-', 'linewidth', 2);
            plot(app.pes_bgrnd_model.roi_xdat, app.pes_bgrnd_model.roi_ydat_final, 'k-', 'linewidth', 2);
            plot(app.pes_bgrnd_model.xdat_win, app.pes_bgrnd_model.ydat_win, 'r.');
            % 5 - Formatting the figure
            title(sprintf("PES Background Model(%s)", app.pes_bgrnd_model.Type), 'FontWeight', 'bold', 'FontSize', 12);
            xlabel('Binding Energy (eV)', 'FontWeight', 'bold', 'FontSize', 11);
            ylabel('Intensity (arb.)', 'FontWeight', 'bold', 'FontSize', 11);
            legend({'Initial Data', 'ROI: Data', 'ROI: Background', 'ROI: Final'}, 'location', 'best', 'fontsize', 9);
            % -- Determining the best limits for the plot
            axLim_y = [app.pes_bgrnd_model.roi_ydat; app.pes_bgrnd_model.roi_ydat_final; app.pes_bgrnd_model.roi_bgrnd];
            axLim_x = [mean(app.pes_bgrnd_model.roi_xdat(:)) - abs(0.65*range(app.pes_bgrnd_model.roi_xdat(:))); mean(app.pes_bgrnd_model.roi_xdat(:)) + abs(0.65*range(app.pes_bgrnd_model.roi_xdat(:)));];
            axis([axLim_x(1), axLim_x(2), min(axLim_y(:)), 1.10*max(axLim_y(:))]);
        end

        % -- Extract all the possible AC types
        function ac_types = extract_ac_types(app)
            ac_types = {...
                'StLHS',...
                'StRHS',...
                'StErfLHS',...
                'StErfRHS',...
                'StExLHS',...
                'StExRHS',...
                'StExTrLHS',...
                'StExTrRHS',...
                'StGaLHS',...
                'StGaRHS',...
                'StGaTrLHS',...
                'StGaTrRHS',...
                'ToHa',...
                'ToHaErf',...
                'ToHaErfLHS',...
                'ToHaErfRHS',...
                'ToHaEx',...
                'ToHaExLHS',...
                'ToHaExRHS',...
                'ToHaExTrLHS',...
                'ToHaExTrRHS',...
                'ToHaGa',...
                'ToHaGaLHS',...
                'ToHaGaRHS',...
                'ToHaGaTrLHS',...
                'ToHaGaTrRHS'};
        end

        % -- Determine AC Model
        function ac_model = extract_ac_model(app)
            % 1 - Extracting best x-axis domain
            for i = 1:app.num_of_layers
                Center(i)       = app.tab_ac_model{i,3}{1};
                Amplitude(i)    = app.tab_ac_model{i,4}{1};
                Width(i)        = app.tab_ac_model{i,5}{1};
                FWHM(i)         = app.tab_ac_model{i,6}{1};
                CDL(i)          = app.tab_ac_model{i,7}{1};
                Cutoff(i)       = app.tab_ac_model{i,8}{1};
            end
            X_Matrix   = [Center, Center+Width, Center-Width, Center+3*FWHM, Center-3*FWHM, Center+3*CDL, Center-3*CDL];
            x_min   = min(X_Matrix(:)) - 2;
            x_max   = max(X_Matrix(:)) + 2;
            x       = x_min:0.01:x_max; x = x';
            % 2 - Extract the curve components for each peak
            y_comps = {};
            for i = 1:app.num_of_layers
                % -- Extracting the component intensities
                if strcmpi(app.tab_ac_model{i,2}{1}, "StLHS") || strcmpi(app.tab_ac_model{i,2}{1}, "StRHS")
                    y_comps{i} = PES_AtmConcCurve(x, app.tab_ac_model{i,2}{1}, Center(i), Amplitude(i));
                elseif strcmpi(app.tab_ac_model{i,2}{1}, "StErfLHS") || strcmpi(app.tab_ac_model{i,2}{1}, "StErfRHS") || strcmpi(app.tab_ac_model{i,2}{1}, "StGaLHS") || strcmpi(app.tab_ac_model{i,2}{1}, "StGaRHS")
                    y_comps{i} = PES_AtmConcCurve(x, app.tab_ac_model{i,2}{1}, Center(i), Amplitude(i), FWHM(i));
                elseif strcmpi(app.tab_ac_model{i,2}{1}, "StGaTrLHS") || strcmpi(app.tab_ac_model{i,2}{1}, "StGaTrRHS")
                    y_comps{i} = PES_AtmConcCurve(x, app.tab_ac_model{i,2}{1}, Center(i), Amplitude(i), FWHM(i), Cutoff(i));
                elseif strcmpi(app.tab_ac_model{i,2}{1}, "StExLHS") || strcmpi(app.tab_ac_model{i,2}{1}, "StExRHS")
                    y_comps{i} = PES_AtmConcCurve(x, app.tab_ac_model{i,2}{1}, Center(i), Amplitude(i), CDL(i));
                elseif strcmpi(app.tab_ac_model{i,2}{1}, "StExTrLHS") || strcmpi(app.tab_ac_model{i,2}{1}, "StExTrRHS")
                    y_comps{i} = PES_AtmConcCurve(x, app.tab_ac_model{i,2}{1}, Center(i), Amplitude(i), CDL(i), Cutoff(i));
                elseif strcmpi(app.tab_ac_model{i,2}{1}, "ToHa")
                    y_comps{i} = PES_AtmConcCurve(x, app.tab_ac_model{i,2}{1}, Center(i), Amplitude(i), Width(i));
                elseif strcmpi(app.tab_ac_model{i,2}{1}, "ToHaErf") || strcmpi(app.tab_ac_model{i,2}{1}, "ToHaErfLHS") || strcmpi(app.tab_ac_model{i,2}{1}, "ToHaErfRHS")...
                         || strcmpi(app.tab_ac_model{i,2}{1}, "ToHaGa") || strcmpi(app.tab_ac_model{i,2}{1}, "ToHaGaLHS") || strcmpi(app.tab_ac_model{i,2}{1}, "ToHaGaRHS")
                    y_comps{i} = PES_AtmConcCurve(x, app.tab_ac_model{i,2}{1}, Center(i), Amplitude(i), Width(i), FWHM(i));
                elseif strcmpi(app.tab_ac_model{i,2}{1}, "ToHaGaTrLHS") || strcmpi(app.tab_ac_model{i,2}{1}, "ToHaGaTrRHS")
                    y_comps{i} = PES_AtmConcCurve(x, app.tab_ac_model{i,2}{1}, Center(i), Amplitude(i), Width(i), FWHM(i), Cutoff(i));
                elseif strcmpi(app.tab_ac_model{i,2}{1}, "ToHaEx") || strcmpi(app.tab_ac_model{i,2}{1}, "ToHaExLHS") || strcmpi(app.tab_ac_model{i,2}{1}, "ToHaExRHS")
                    y_comps{i} = PES_AtmConcCurve(x, app.tab_ac_model{i,2}{1}, Center(i), Amplitude(i), Width(i), CDL(i));
                elseif strcmpi(app.tab_ac_model{i,2}{1}, "ToHaExTrLHS") || strcmpi(app.tab_ac_model{i,2}{1}, "ToHaExTrRHS")
                    y_comps{i} = PES_AtmConcCurve(x, app.tab_ac_model{i,2}{1}, Center(i), Amplitude(i), Width(i), CDL(i), Cutoff(i));
                end
                AREA(i)      = trapz(x, y_comps{i});
            end
            % 3 - Sum up all of the curve components to get the final model PES curve
            y = zeros(size(x));
            for i = 1:app.num_of_layers; y = y + y_comps{i}; end
            y(isnan(y)) = 0;
            % 4 - Storing all data in a MATLAB Data-Structure
            ac_model = struct();
            ac_model.label          = "PES_AtmConcCurve()";
            ac_model.raw_table      = app.tab_ac_model;
            ac_model.num_of_layers  = app.num_of_layers;
            ac_model.x              = x;
            ac_model.y              = y;
            ac_model.y_comps        = y_comps;
            ac_model.Center         = Center;
            ac_model.Amplitude      = Amplitude;
            ac_model.Width          = Width;
            ac_model.FWHM           = FWHM;
            ac_model.CDL            = CDL;
            ac_model.Cutoff         = Cutoff;
            ac_model.AREA           = AREA;
            ac_model.AREA0          = AREA ./ trapz(x, y);
        end
  
        % -- View AC Model
        function view_ac_model(app)
            % 1 - Initialising the figure
            fig = figure(); 
            fig.Position(3) = 750;
            fig.Position(4) = 450;
            hold on;
            % 2 - Plotting all of the peak components
            cols = lines(app.ac_model.num_of_layers);
            for i = 1:app.ac_model.num_of_layers
                area(app.ac_model.x, app.ac_model.y_comps{i}, 'FaceColor', cols(i,:), 'FaceAlpha', 0.50, 'EdgeAlpha', 0);
                a = plot(app.ac_model.x, app.ac_model.y_comps{i}, 'k-', 'linewidth', 0.25);
                a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end
            % 3 - Plotting the final PES intensity spectrum
            a = scatter(app.ac_model.x, app.ac_model.y, 10, 'k', 'MarkerFaceColor', 'k', 'MarkerFaceAlpha',.2,'MarkerEdgeAlpha',.2);
            a.Annotation.LegendInformation.IconDisplayStyle = 'off';
            plot(app.ac_model.x, app.ac_model.y, 'k-', 'color', 'k', 'linewidth', 2.0);
            % 4 - Formatting the figure
            title('Atomic Concentration Model', 'FontWeight', 'bold', 'FontSize', 12);
            xlabel('X', 'FontWeight', 'bold', 'FontSize', 11);
            ylabel('Y', 'FontWeight', 'bold', 'FontSize', 11);
            axis([min(app.ac_model.x(:)), max(app.ac_model.x(:)), min(app.ac_model.y(:)), 1.10*max(app.ac_model.y(:))]);
            ax = gca; ax.FontName  = 'Segoe UI'; 
            lgnd_txt = {};
            for i = 1:app.ac_model.num_of_layers; lgnd_txt{i} = sprintf("Layer %i (%.1fpc)", i, 100*app.ac_model.AREA0(i)); end
            lgnd_txt{end+1} = "Final";
            legend(lgnd_txt, 'location', 'best');
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBase - PES Model Viewer App (procopios.constantinou@psi.ch)';
            % 1 - Variables associated with PES Spectral Intensity Curves Tab
            % - Initialising the table
            peak_types   = extract_pes_peak_types(app);
            app.PESModel_UITable.ColumnFormat      = {'numeric', peak_types, 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric'};
            app.PESModel_UITable.ColumnName        = {'Peak', 'Type', 'BE', 'INT', 'FWHM', 'MR', 'LSE', 'LSI', 'LSW', 'ASY'};
            app.PESModel_UITable.ColumnEditable    = [false,true,true,true,true,true,true,true,true,true];
            app.PESModel_UITable.Data              = {...
                1,peak_types{5},-98.9,1,0.4,0.5,-0.6,0.5,0,0;...
                2,peak_types{1},-100.5,0.05,1,0.0,0,0,0,0;...
                3,peak_types{1},-102.0,0.2,2,0.0,0,0,0,0};
            % - Initialising the column properties
            % 2 - Variables associated with PES Backgrounds Tab
            bgrnd_types = extract_pes_bgrnds(app);
            app.PESBgrnd_Type_DropDown.Items = bgrnd_types;
            % 3 - Variables associated with Atomic Concentration Curves Tab
            % - Initialising the table
            ac_types   = extract_ac_types(app);
            app.ACModel_UITable.ColumnFormat      = {'numeric', ac_types, 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric'};
            app.ACModel_UITable.ColumnName        = {'Layer', 'Type', 'Center', 'Amplitude', 'Width', 'FWHM', 'CDL', 'Cutoff'};
            app.ACModel_UITable.ColumnEditable    = [false,true,true,true,true,true,true,true];
            app.ACModel_UITable.Data              = {...
                1,'ToHa', 1, 1, 2, 0.5, 0, 0;...
                2,'ToHaErfRHS', 3, 1, 2, 0.5, 0, 0;...
                3,'ToHaErfLHS', 5, 1, 2, 0.5, 0, 0;...
                4,'ToHa', 9, 1, 6, 0.5, 0, 0};
            % 4 - Default parameters upon startup
            % -- PES Model
            app.num_of_peaks        = 3;
            app.tab_pes_model       = array2table(app.PESModel_UITable.Data, 'VariableNames', app.PESModel_UITable.ColumnName);
            % -- PES Background
            app.PolynomialArguments_Panel.Enable = 'off';
            app.SOArguments_Panel.Enable = 'off';
            app.TougaardArguments_Panel.Enable = 'off';
            app.PESBgrnd_Type_DropDown.Value = app.PESBgrnd_Type_DropDown.Items{4};
            app.bgrnd_type          = "S";
            app.bgrnd_LHS           = app.PESBgrnd_LHS_EditField.Value;
            app.bgrnd_RHS           = app.PESBgrnd_RHS_EditField.Value;
            app.bgrnd_Win           = app.PESBgrnd_WindowSize_EditField.Value;
            app.bgrnd_Offset        = app.PESBgrnd_ConstantOffset_EditField.Value;
            app.bgrnd_Order         = app.Poly_OrderEditField.Value;
            app.bgrnd_lambda        = app.SO_LambdaParameter_EditField.Value;
            app.bgrnd_delta         = app.SO_DeltaParameter_EditField.Value;
            app.bgrnd_C             = app.Tou_CParameter_EditField.Value;
            app.bgrnd_D             = app.Tou_DParameter_EditField.Value;
            % -- Atomic Concentration Model
            app.num_of_layers       = 4;
            app.tab_ac_model        = array2table(app.ACModel_UITable.Data, 'VariableNames', app.ACModel_UITable.ColumnName);
            % 5 - Extracting all models
            app.pes_model = extract_pes_model(app);
            app.pes_bgrnd_model = extract_pes_bgrnd_model(app);
            app.ac_model = extract_ac_model(app);
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run App_MatBase;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
           % Remove the UI handle and restart
            close(gcbf); run App_MatBase_PESModelViewer;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 860;
            screen_size(4) = 375;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            disp('PES Spectral Intensity Model : ');    disp(app.pes_model);
            disp('PES Background Model : ');            disp(app.pes_bgrnd_model);
            disp('Atomic Concentration Model : ');      disp(app.ac_model);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'pes_model', app.pes_model);
            assignin('base', 'pes_bgrnd_model', app.pes_bgrnd_model);
            assignin('base', 'ac_model', app.ac_model);
        end

        % Cell edit callback: PESModel_UITable
        function PESModel_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - Force MR and ASY to zero for certain curve types
            if indices(2) == 2
                % Gaussian & Lorentzian Condition
                if strcmpi(newData, "G - Gaussian") || strcmpi(newData, "L - Lorentizan")
                    app.PESModel_UITable.Data{indices(1),6}             = 0;
                    app.PESModel_UITable.Data{indices(1),10}            = 0;
                % Doniach-Sunjic Condition
                elseif strcmpi(newData, "DS - Doniach")
                    app.PESModel_UITable.Data{indices(1),6}             = 0;
                end
            end
            % 3 - Validity check on the input values
            if indices(2) == 4; if newData < 0; newData = 0; end; end       % -- If the INT is <0, pad it to 0
            if indices(2) == 5; if newData < 0; newData = 0; end; end       % -- If the FWHM is negative, pad it to zero
            if indices(2) == 6; if newData < 0; newData = 0; end; end       % -- If the MR is negative, pad it to zero
            if indices(2) == 6; if newData > 1; newData = 1; end; end       % -- If the MR is >1, pad it to 1
            if indices(2) == 8; if newData < 0; newData = 0; end; end       % -- If the LSI is <0, pad it to 0
            if indices(2) == 9; if newData < 0; newData = 0; end; end       % -- If the LSW is <0, pad it to 0
            if indices(2) == 10; if newData < -1; newData = -1; end; end    % -- If the ASY is <-1, pad it to -1
            if indices(2) == 10; if newData > 1; newData = 1; end; end      % -- If the ASY is >1, pad it to 1
            app.PESModel_UITable.Data{indices(1),indices(2)} = newData;
            % 4 - Assigning the output
            if indices(2) == 2;     peak_text = sprintf("Peak %i: Type = %s", indices(1), newData);
            elseif indices(2) == 3; peak_text = sprintf("Peak %i: BE = %.3f", indices(1), newData);
            elseif indices(2) == 4; peak_text = sprintf("Peak %i: INT = %.3f", indices(1), newData);
            elseif indices(2) == 5; peak_text = sprintf("Peak %i: FWHM = %.3f", indices(1), newData);
            elseif indices(2) == 6; peak_text = sprintf("Peak %i: MR = %.3f", indices(1), newData);
            elseif indices(2) == 7; peak_text = sprintf("Peak %i: LSE = %.3f", indices(1), newData);
            elseif indices(2) == 8; peak_text = sprintf("Peak %i: LSI = %.3f", indices(1), newData);
            elseif indices(2) == 9; peak_text = sprintf("Peak %i: LSE = %.3f", indices(1), newData);
            elseif indices(2) == 10; peak_text = sprintf("Peak %i: ASY = %.3f", indices(1), newData);
            end
            app.tab_pes_model  = array2table(app.PESModel_UITable.Data, 'VariableNames', app.PESModel_UITable.ColumnName);
            fprintf(peak_text + " \n");
            % 5 - Extracting PES Intensity Model
            app.pes_model = extract_pes_model(app);
        end

        % Button pushed function: PESModel_PeakAdd_Button
        function PESModel_PeakAdd_ButtonPushed(app, event)
            % 1 - Adding one new layer to the table
            peak_number    = app.PESModel_UITable.Data(end,1); peak_number = peak_number{1};
            if peak_number ~= 10
                peak_number     = peak_number+1;
                Type    = app.PESModel_UITable.Data(end,2); Type = Type{1};
                BE      = app.PESModel_UITable.Data(end,3); BE = BE{1};
                INT     = app.PESModel_UITable.Data(end,4); INT = INT{1};
                FWHM    = app.PESModel_UITable.Data(end,5); FWHM = FWHM{1};
                MR      = app.PESModel_UITable.Data(end,6); MR = MR{1};
                LSE     = app.PESModel_UITable.Data(end,7); LSE = LSE{1};
                LSI     = app.PESModel_UITable.Data(end,8); LSI = LSI{1};
                LSW     = app.PESModel_UITable.Data(end,9); LSW = LSW{1};
                ASY     = app.PESModel_UITable.Data(end,10); ASY = ASY{1};
                app.PESModel_UITable.Data(end+1,:) = {peak_number, Type, BE, INT, FWHM, MR, LSE, LSI, LSW, ASY};
                % 2 - Assigning and printing the output
                app.num_of_peaks = peak_number;
                app.tab_pes_model  = array2table(app.PESModel_UITable.Data, 'VariableNames', app.PESModel_UITable.ColumnName);
                fprintf("-- Peak Added: N = " + string(peak_number) + " \n");
            else; fprintf("-- Currently only supports 10 peaks maximum...\n");
            end
            % 2 - Evaluating the new model
            app.pes_model = extract_pes_model(app);
        end

        % Button pushed function: PESModel_PeakDelete_Button
        function PESModel_PeakDelete_ButtonPushed(app, event)
            % 1 - Removing one layer from the table
            peak_number    = app.PESModel_UITable.Data(end,1); peak_number = peak_number{1};
            if peak_number ~= 1 
                peak_number = peak_number - 1;
                app.PESModel_UITable.Data(end,:) = []; 
                fprintf("-- Peak Removed: N = " + string(peak_number) + " \n");
            else; fprintf("-- Cannot remove first peak...\n");
            end
            % 2 - Assigning the output
            app.num_of_peaks = peak_number;
            app.tab_pes_model   = array2table(app.PESModel_UITable.Data, 'VariableNames', app.PESModel_UITable.ColumnName);
            app.pes_model           = extract_pes_model(app);
        end

        % Button pushed function: PESModel_ViewModel_Button
        function PESModel_ViewModel_ButtonPushed(app, event)
            % 1 - Extracting PES Intensity Model
            app.pes_model = extract_pes_model(app);
            % 2 - Plotting PES Intensity Model
            view_pes_model(app);
        end

        % Button pushed function: PESModel_ExportDataTxt_Button
        function PESModel_ExportDataTxt_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            fname = ""; filter = {'*.txt'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            T_Data = table(app.pes_model.x);
            T_Data.Properties.VariableNames{1} = 'Ebe_eV';
            T_Data{:,2}= app.pes_model.y; 
            T_Data.Properties.VariableNames{2} = 'Total';
            for i = 1:app.pes_model.num_of_peaks
                T_Data{:,i+2} = app.pes_model.y_comps{i}; 
                T_Data.Properties.VariableNames{i+2} = char(sprintf("Peak%i", i));
            end
            writetable(T_Data, char(string(save_fullfile(1:end-4)) + ".txt"),'Delimiter','\t');
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Button pushed function: PESModel_ExportDataMat_Button
        function PESModel_ExportDataMat_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            fname = ""; filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStr = app.pes_model; 
            save(char(save_fullfile), 'dataStr', '-v7.3');
            disp('-> saved data : '); display(app.pes_model);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Cell edit callback: ACModel_UITable
        function ACModel_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 3 - Validity check on the input values
            if indices(2) == 4; if newData < 0; newData = 0; end; end       % -- If the Amplitude is <0, pad it to 0
            if indices(2) == 5; if newData < 0; newData = 0; end; end       % -- If the Width is negative, pad it to zero
            if indices(2) == 6; if newData < 0; newData = 0; end; end       % -- If the FWHM is negative, pad it to zero
            if indices(2) == 7; if newData < 0; newData = 0; end; end       % -- If the CDL is <0, pad it to 0
            app.ACModel_UITable.Data{indices(1),indices(2)} = newData;
            % 4 - Assigning the output
            if indices(2) == 2;     layer_text = sprintf("Layer %i: Type = %s", indices(1), newData);
            elseif indices(2) == 3; layer_text = sprintf("Layer %i: Center = %.3f", indices(1), newData);
            elseif indices(2) == 4; layer_text = sprintf("Layer %i: Amplitude = %.3f", indices(1), newData);
            elseif indices(2) == 5; layer_text = sprintf("Layer %i: Width = %.3f", indices(1), newData);
            elseif indices(2) == 6; layer_text = sprintf("Layer %i: FWHM = %.3f", indices(1), newData);
            elseif indices(2) == 7; layer_text = sprintf("Layer %i: CDL = %.3f", indices(1), newData);
            elseif indices(2) == 8; layer_text = sprintf("Layer %i: Cutoff = %.3f", indices(1), newData);
            end
            app.tab_ac_model  = array2table(app.ACModel_UITable.Data, 'VariableNames', app.ACModel_UITable.ColumnName);
            fprintf(layer_text + " \n");
            % 5 - Extracting AC Model
            app.ac_model = extract_ac_model(app);
        end

        % Button pushed function: ACModel_LayerAdd_Button
        function ACModel_LayerAdd_ButtonPushed(app, event)
            % 1 - Adding one new layer to the table
            layer_number    = app.ACModel_UITable.Data(end,1); layer_number = layer_number{1};
            if layer_number ~= 10
                layer_number    = layer_number+1;
                Type        = app.ACModel_UITable.Data(end,2); Type = Type{1};
                Center      = app.ACModel_UITable.Data(end,3); Center = Center{1};
                Amplitude   = app.ACModel_UITable.Data(end,4); Amplitude = Amplitude{1};
                Width       = app.ACModel_UITable.Data(end,5); Width = Width{1};
                FWHM        = app.ACModel_UITable.Data(end,6); FWHM = FWHM{1};
                CDL         = app.ACModel_UITable.Data(end,7); CDL = CDL{1};
                Cutoff      = app.ACModel_UITable.Data(end,8); Cutoff = Cutoff{1};
                Center      = Center + Width;
                app.ACModel_UITable.Data(end+1,:) = {layer_number, Type, Center, Amplitude, Width, FWHM, CDL, Cutoff};
                % 2 - Assigning and printing the output
                app.num_of_layers = layer_number;
                app.tab_ac_model  = array2table(app.ACModel_UITable.Data, 'VariableNames', app.ACModel_UITable.ColumnName);
                fprintf("-- Layer Added: N = " + string(layer_number) + " \n");
            else; fprintf("-- Currently only supports 10 layers maximum...\n");
            end
            % 2 - Evaluating the new model
            app.ac_model = extract_ac_model(app);
        end

        % Button pushed function: ACModel_LayerDelete_Button
        function ACModel_LayerDelete_ButtonPushed(app, event)
            % 1 - Removing one layer from the table
            layer_number    = app.ACModel_UITable.Data(end,1); layer_number = layer_number{1};
            if layer_number ~= 1 
                layer_number = layer_number - 1;
                app.ACModel_UITable.Data(end,:) = []; 
                fprintf("-- Layer Removed: N = " + string(layer_number) + " \n");
            else; fprintf("-- Cannot remove first layer...\n");
            end
            % 2 - Assigning the output
            app.num_of_layers   = layer_number;
            app.tab_ac_model    = array2table(app.ACModel_UITable.Data, 'VariableNames', app.ACModel_UITable.ColumnName);
            app.ac_model        = extract_ac_model(app);
        end

        % Button pushed function: ACModel_ViewModel
        function ACModel_ViewModelButtonPushed(app, event)
            % 1 - Extracting AC Model
            app.ac_model = extract_ac_model(app);
            % 2 - Plotting AC Model
            view_ac_model(app);
        end

        % Button pushed function: ACModel_ExportDataTxt
        function ACModel_ExportDataTxtButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            fname = ""; filter = {'*.txt'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            T_Data = table(app.ac_model.x);
            T_Data.Properties.VariableNames{1} = 'X';
            T_Data{:,2}= app.ac_model.y; 
            T_Data.Properties.VariableNames{2} = 'Total';
            for i = 1:app.ac_model.num_of_layers
                T_Data{:,i+2} = app.ac_model.y_comps{i}; 
                T_Data.Properties.VariableNames{i+2} = char(sprintf("Layer%i", i));
            end
            writetable(T_Data, char(string(save_fullfile(1:end-4)) + ".txt"),'Delimiter','\t');
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Button pushed function: ACModel_ExportDataMat
        function ACModel_ExportDataMatButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            fname = ""; filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStr = app.ac_model; 
            save(char(save_fullfile), 'dataStr', '-v7.3');
            disp('-> saved data : '); display(app.ac_model);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Value changed function: PESBgrnd_Type_DropDown
        function PESBgrnd_Type_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PESBgrnd_Type_DropDown.Value;
            value = string(value);
            % 2 - Assigning string to the background type
            if strcmpi(value,"None");                         value = "None";
            elseif strcmpi(value,"L - Linear");               value = "L";
            elseif strcmpi(value,"P - Polynomial");           value = "P";
            elseif strcmpi(value,"S - Shirley");              value = "S";
            elseif strcmpi(value,"SO - Shirley-Offset");      value = "SO";
            elseif strcmpi(value,"SS - Shirley-Smart");       value = "SS";
            elseif strcmpi(value,"T1 - Tougaard1");           value = "T1";
            elseif strcmpi(value,"T2 - Tougaard2");           value = "T2";
            end
            % 3 - Validity check on the background
            Type = char(lower(value));
            switch Type
                case lower({'None','L','S','SS'})      
                    app.PolynomialArguments_Panel.Enable = 'off';
                    app.SOArguments_Panel.Enable = 'off';
                    app.TougaardArguments_Panel.Enable = 'off';
                case lower({'SO'})                    
                    app.PolynomialArguments_Panel.Enable = 'off';
                    app.SOArguments_Panel.Enable = 'on';
                    app.TougaardArguments_Panel.Enable = 'off';
                case lower({'P'})                    
                    app.PolynomialArguments_Panel.Enable = 'on';
                    app.SOArguments_Panel.Enable = 'off';
                    app.TougaardArguments_Panel.Enable = 'off';
                case lower({'T1', 'T2'})                    
                    app.PolynomialArguments_Panel.Enable = 'off';
                    app.SOArguments_Panel.Enable = 'off';
                    app.TougaardArguments_Panel.Enable = 'on';
            end
            % 4 - Assigning the output
            app.bgrnd_type = value;
            fprintf("-- PES Background Type: " + string(app.bgrnd_type) + " \n");
        end

        % Value changed function: PESBgrnd_LHS_EditField
        function PESBgrnd_LHS_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PESBgrnd_LHS_EditField.Value;
            % 2 - Validity check on the input value
            if value > app.bgrnd_RHS; value = app.bgrnd_RHS; end
            % 3 - Assigning the output
            app.bgrnd_LHS = value;
            app.PESBgrnd_LHS_EditField.Value = app.bgrnd_LHS;
            fprintf("-- PES Background: LHS (Start) = " + string(app.bgrnd_LHS) + " \n");
        end

        % Value changed function: PESBgrnd_RHS_EditField
        function PESBgrnd_RHS_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PESBgrnd_RHS_EditField.Value;
            % 2 - Validity check on the input value
            if value < app.bgrnd_LHS; value = app.bgrnd_LHS; end
            % 3 - Assigning the output
            app.bgrnd_RHS = value;
            app.PESBgrnd_RHS_EditField.Value = app.bgrnd_RHS;
            fprintf("-- PES Background: RHS (End) = " + string(app.bgrnd_RHS) + " \n");
        end

        % Value changed function: PESBgrnd_WindowSize_EditField
        function PESBgrnd_WindowSize_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PESBgrnd_WindowSize_EditField.Value;
            % 2 - Validity check on the input value
            if value < 0; value = 0; end
            % 3 - Assigning the output
            app.bgrnd_Win = value;
            app.PESBgrnd_WindowSize_EditField.Value = app.bgrnd_Win;
            fprintf("-- PES Background: Window Size = " + string(app.bgrnd_Win) + " \n");
        end

        % Value changed function: PESBgrnd_ConstantOffset_EditField
        function PESBgrnd_ConstantOffset_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PESBgrnd_ConstantOffset_EditField.Value;
            % 3 - Assigning the output
            app.bgrnd_Offset = value;
            app.PESBgrnd_ConstantOffset_EditField.Value = app.bgrnd_Offset;
            fprintf("-- PES Background: Constant Offset = " + string(app.bgrnd_Offset) + " \n");
        end

        % Value changed function: Poly_OrderEditField
        function Poly_OrderEditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Poly_OrderEditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 0; end
            % 3 - Assigning the output
            app.bgrnd_Order = value;
            app.Poly_OrderEditField.Value = app.bgrnd_Order;
            fprintf("-- PES Background (Polynomial): Order = " + string(app.bgrnd_Order) + " \n");
        end

        % Value changed function: SO_LambdaParameter_EditField
        function SO_LambdaParameter_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.SO_LambdaParameter_EditField.Value;
            % 2 - Assigning the output
            app.bgrnd_lambda = value;
            app.SO_LambdaParameter_EditField.Value = app.bgrnd_lambda;
            fprintf("-- PES Background (Shirley-Offset): Lambda = " + string(app.bgrnd_lambda) + " \n");
        end

        % Value changed function: SO_DeltaParameter_EditField
        function SO_DeltaParameter_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.SO_DeltaParameter_EditField.Value;
            % 2 - Assigning the output
            app.bgrnd_delta = value;
            app.SO_DeltaParameter_EditField.Value = app.bgrnd_delta;
            fprintf("-- PES Background (Shirley-Offset): Delta = " + string(app.bgrnd_delta) + " \n");
        end

        % Value changed function: Tou_CParameter_EditField
        function Tou_CParameter_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Tou_CParameter_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 1643; end
            % 3 - Assigning the output
            app.bgrnd_C = value;
            app.Tou_CParameter_EditField.Value = app.bgrnd_C;
            fprintf("-- PES Background (Tougaard): C = " + string(app.bgrnd_C) + " \n");
        end

        % Value changed function: Tou_DParameter_EditField
        function Tou_DParameter_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Tou_DParameter_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || value < 0; value = 275; end
            % 3 - Assigning the output
            app.bgrnd_D = value;
            app.Tou_DParameter_EditField.Value = app.bgrnd_D;
            fprintf("-- PES Background (Tougaard): D = " + string(app.bgrnd_D) + " \n");
        end

        % Button pushed function: PESBgrnd_ViewModel_Button
        function PESBgrnd_ViewModel_ButtonPushed(app, event)
            % 1 - Extracting PES Background Model
            app.pes_bgrnd_model = extract_pes_bgrnd_model(app);
            % 2 - View PES Background Model
            view_pes_bgrnd_model(app);
        end

        % Button pushed function: PESBgrnd_ExportDataTxt_Button
        function PESBgrnd_ExportDataTxt_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            fname = ""; filter = {'*.txt'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            T_Data = table(app.pes_bgrnd_model.roi_xdat);       T_Data.Properties.VariableNames{1} = 'roi_xdat';
            T_Data{:,2} = app.pes_bgrnd_model.roi_ydat;         T_Data.Properties.VariableNames{2} = 'roi_ydat';
            T_Data{:,3} = app.pes_bgrnd_model.roi_bgrnd;        T_Data.Properties.VariableNames{3} = 'roi_bgrnd';
            T_Data{:,4} = app.pes_bgrnd_model.roi_ydat_final;   T_Data.Properties.VariableNames{4} = 'roi_ydat_final';
            writetable(T_Data, char(string(save_fullfile(1:end-4)) + ".txt"),'Delimiter','\t');
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Button pushed function: PESBgrnd_ExportDataMat_Button
        function PESBgrnd_ExportDataMat_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            fname = ""; filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStr = app.pes_bgrnd_model; 
            save(char(save_fullfile), 'dataStr', '-v7.3');
            disp('-> saved data : '); display(app.pes_bgrnd_model);
            % 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'Photoelectron Spectroscopy (PES) Model Viewer';
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Explore the depths of material analysis and XPS with this robust tool. It empowers users to construct multi-layered models, each layer featuring a uniform and homogenous material composition and thickness. The app draws upon an extensive database for material characteristics and integrates various options for IMFP parameters, including Optical (NIST), Universal, TPP-2M, and S1 through S4. It also offers a selection of photoionization cross-sections and asymmetry parameters from renowned databases, such as the Scofield (1973), Yeh & Lindau (1985), Trzhaskovskaya (2018) and Cant (2022) databases. Utilizing the Beer-Lambert law, the tool accurately computes the PES intensities for each layer, modulated by the electron IMFP based on the specific experimental setup. Additionally, it can graphically represent the variations in photon energy and emission angles.'};
            app.Image.ImageSource = 'Icon_PESModelViewer.jpeg';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PESModel_Tab = uitab(app.TabGroup);
            app.PESModel_Tab.Tooltip = {'PES Spectral Intensity Model'};
            app.PESModel_Tab.Title = 'PES Spectral Intensity Model';
            app.PESModel_GridLayout = uigridlayout(app.PESModel_Tab);
            app.PESModel_ViewModel_Button = uibutton(app.PESModel_GridLayout, 'push');
            app.PESModel_ViewModel_Button.ButtonPushedFcn = createCallbackFcn(app, @PESModel_ViewModel_ButtonPushed, true);
            app.PESModel_ViewModel_Button.Tooltip = {'View PES Spectral Intensity Model'};
            app.PESModel_ViewModel_Button.Text = 'View PES Spectral Intensity Model';
            app.PESModel_ExportDataTxt_Button = uibutton(app.PESModel_GridLayout, 'push');
            app.PESModel_ExportDataTxt_Button.ButtonPushedFcn = createCallbackFcn(app, @PESModel_ExportDataTxt_ButtonPushed, true);
            app.PESModel_ExportDataTxt_Button.Tooltip = {'Export the PES Spectral Intensity Model (as a *.txt file)'};
            app.PESModel_ExportDataTxt_Button.Text = 'Export Data (*.txt)';
            app.PESModel_ExportDataMat_Button = uibutton(app.PESModel_GridLayout, 'push');
            app.PESModel_ExportDataMat_Button.ButtonPushedFcn = createCallbackFcn(app, @PESModel_ExportDataMat_ButtonPushed, true);
            app.PESModel_ExportDataMat_Button.Tooltip = {'Export the PES Spectral Intensity Model (as a *.mat file)'};
            app.PESModel_ExportDataMat_Button.Text = 'Export Data (*.mat)';
            app.PESModel_PeakAdd_Button = uibutton(app.PESModel_GridLayout, 'push');
            app.PESModel_PeakAdd_Button.ButtonPushedFcn = createCallbackFcn(app, @PESModel_PeakAdd_ButtonPushed, true);
            app.PESModel_PeakAdd_Button.Tooltip = {'Add a new peak'};
            app.PESModel_PeakAdd_Button.Text = '+';
            app.PESModel_UITable = uitable(app.PESModel_GridLayout);
            app.PESModel_UITable.ColumnName = {'Peak'; 'Type'; 'BE'; 'INT'; 'FWHM'; 'MR'; 'LSE'; 'LSI'; 'LSW'; 'ASY'};
            app.PESModel_UITable.RowName = {};
            app.PESModel_UITable.CellEditCallback = createCallbackFcn(app, @PESModel_UITableCellEdit, true);
            app.PESModel_UITable.Tooltip = {'Table the contains all the PES Spectral Intensity Model arguments'};
            app.PESModel_PeakDelete_Button = uibutton(app.PESModel_GridLayout, 'push');
            app.PESModel_PeakDelete_Button.ButtonPushedFcn = createCallbackFcn(app, @PESModel_PeakDelete_ButtonPushed, true);
            app.PESModel_PeakDelete_Button.Tooltip = {'Remove the last peak'};
            app.PESModel_PeakDelete_Button.Text = '-';
            app.PESBgrnd_Tab = uitab(app.TabGroup);
            app.PESBgrnd_Tab.Tooltip = {'PES Background Models'};
            app.PESBgrnd_Tab.Title = 'PES Background Models';
            app.PESBgrnd_GridLayout01 = uigridlayout(app.PESBgrnd_Tab);
            app.PESBgrnd_ViewModel_Button = uibutton(app.PESBgrnd_GridLayout01, 'push');
            app.PESBgrnd_ViewModel_Button.ButtonPushedFcn = createCallbackFcn(app, @PESBgrnd_ViewModel_ButtonPushed, true);
            app.PESBgrnd_ViewModel_Button.Tooltip = {'View PES Background Model'};
            app.PESBgrnd_ViewModel_Button.Text = 'View PES Background Model';
            app.PESBgrnd_ExportDataTxt_Button = uibutton(app.PESBgrnd_GridLayout01, 'push');
            app.PESBgrnd_ExportDataTxt_Button.ButtonPushedFcn = createCallbackFcn(app, @PESBgrnd_ExportDataTxt_ButtonPushed, true);
            app.PESBgrnd_ExportDataTxt_Button.Tooltip = {'Export the PES Background Model (as a *.txt file)'};
            app.PESBgrnd_ExportDataTxt_Button.Text = 'Export Data (*.txt)';
            app.PESBgrnd_ExportDataMat_Button = uibutton(app.PESBgrnd_GridLayout01, 'push');
            app.PESBgrnd_ExportDataMat_Button.ButtonPushedFcn = createCallbackFcn(app, @PESBgrnd_ExportDataMat_ButtonPushed, true);
            app.PESBgrnd_ExportDataMat_Button.Tooltip = {'Export the PES Background Model (as a *.mat file)'};
            app.PESBgrnd_ExportDataMat_Button.Text = 'Export Data (*.mat)';
            app.PESBgrnd_GridLayout02 = uigridlayout(app.PESBgrnd_GridLayout01);
            app.BackgroundSubtractionTypeDropDownLabel = uilabel(app.PESBgrnd_GridLayout02);
            app.BackgroundSubtractionTypeDropDownLabel.Text = 'Background Subtraction Type:';
            app.PESBgrnd_Type_DropDown = uidropdown(app.PESBgrnd_GridLayout02);
            app.PESBgrnd_Type_DropDown.Items = {'L - (Linear)', 'P - (Polynomial)', 'S - (Shirley)', 'SO - (Shirley-Offset)', 'SS - (Shirley-Smart)', 'T1 - (Tougaard1)', 'T2 - (Tougaard2)'};
            app.PESBgrnd_Type_DropDown.ValueChangedFcn = createCallbackFcn(app, @PESBgrnd_Type_DropDownValueChanged, true);
            app.PESBgrnd_Type_DropDown.Tooltip = {'Background Subtraction Type'};
            app.PESBgrnd_Type_DropDown.Value = 'L - (Linear)';
            app.LHSStartEditFieldLabel = uilabel(app.PESBgrnd_GridLayout02);
            app.LHSStartEditFieldLabel.Text = 'LHS (Start):';
            app.PESBgrnd_LHS_EditField = uieditfield(app.PESBgrnd_GridLayout02, 'numeric');
            app.PESBgrnd_LHS_EditField.ValueDisplayFormat = '%.3f';
            app.PESBgrnd_LHS_EditField.ValueChangedFcn = createCallbackFcn(app, @PESBgrnd_LHS_EditFieldValueChanged, true);
            app.PESBgrnd_LHS_EditField.Tooltip = {'LHS Starting Point of the background subtraction region of interest'};
            app.PESBgrnd_LHS_EditField.Value = -5;
            app.PolynomialArguments_Panel = uipanel(app.PESBgrnd_GridLayout02);
            app.PolynomialArguments_Panel.Tooltip = {'Polynomial Arguments Panel'};
            app.PolynomialArguments_Panel.Title = 'Polynomial Arguments';
            app.PESBgrnd_GridLayout04 = uigridlayout(app.PolynomialArguments_Panel);
            app.OrderEditFieldLabel = uilabel(app.PESBgrnd_GridLayout04);
            app.OrderEditFieldLabel.Text = 'Order:';
            app.Poly_OrderEditField = uieditfield(app.PESBgrnd_GridLayout04, 'numeric');
            app.Poly_OrderEditField.Limits = [0 Inf];
            app.Poly_OrderEditField.RoundFractionalValues = 'on';
            app.Poly_OrderEditField.ValueDisplayFormat = '%.0f';
            app.Poly_OrderEditField.ValueChangedFcn = createCallbackFcn(app, @Poly_OrderEditFieldValueChanged, true);
            app.Poly_OrderEditField.Tooltip = {'Scalar of the Polynomial Order '};
            app.Poly_OrderEditField.Value = 3;
            app.RHSEndEditFieldLabel = uilabel(app.PESBgrnd_GridLayout02);
            app.RHSEndEditFieldLabel.Text = 'RHS (End):';
            app.PESBgrnd_RHS_EditField = uieditfield(app.PESBgrnd_GridLayout02, 'numeric');
            app.PESBgrnd_RHS_EditField.ValueDisplayFormat = '%.3f';
            app.PESBgrnd_RHS_EditField.ValueChangedFcn = createCallbackFcn(app, @PESBgrnd_RHS_EditFieldValueChanged, true);
            app.PESBgrnd_RHS_EditField.Tooltip = {'RHS End Point of the background subtraction region of interest'};
            app.PESBgrnd_RHS_EditField.Value = 5;
            app.WindowSizeEditFieldLabel = uilabel(app.PESBgrnd_GridLayout02);
            app.WindowSizeEditFieldLabel.Text = 'Window Size:';
            app.PESBgrnd_WindowSize_EditField = uieditfield(app.PESBgrnd_GridLayout02, 'numeric');
            app.PESBgrnd_WindowSize_EditField.Limits = [0 Inf];
            app.PESBgrnd_WindowSize_EditField.ValueDisplayFormat = '%.3f';
            app.PESBgrnd_WindowSize_EditField.ValueChangedFcn = createCallbackFcn(app, @PESBgrnd_WindowSize_EditFieldValueChanged, true);
            app.PESBgrnd_WindowSize_EditField.Tooltip = {'Averaging Window Size around the start/end position'};
            app.PESBgrnd_WindowSize_EditField.Value = 0.5;
            app.ConstantOffsetEditFieldLabel = uilabel(app.PESBgrnd_GridLayout02);
            app.ConstantOffsetEditFieldLabel.Text = 'Constant Offset:';
            app.PESBgrnd_ConstantOffset_EditField = uieditfield(app.PESBgrnd_GridLayout02, 'numeric');
            app.PESBgrnd_ConstantOffset_EditField.ValueDisplayFormat = '%.3f';
            app.PESBgrnd_ConstantOffset_EditField.ValueChangedFcn = createCallbackFcn(app, @PESBgrnd_ConstantOffset_EditFieldValueChanged, true);
            app.PESBgrnd_ConstantOffset_EditField.Tooltip = {'Constant Offset added to the background subtraction'};
            app.TougaardArguments_Panel = uipanel(app.PESBgrnd_GridLayout02);
            app.TougaardArguments_Panel.Tooltip = {'Tougaard Arguments Panel'};
            app.TougaardArguments_Panel.Title = 'Tougaard Arguments';
            app.TougaardArguments_GridLayout = uigridlayout(app.TougaardArguments_Panel);
            app.CParameterEditFieldLabel = uilabel(app.TougaardArguments_GridLayout);
            app.CParameterEditFieldLabel.Text = 'C-Parameter:';
            app.Tou_CParameter_EditField = uieditfield(app.TougaardArguments_GridLayout, 'numeric');
            app.Tou_CParameter_EditField.Limits = [0 Inf];
            app.Tou_CParameter_EditField.RoundFractionalValues = 'on';
            app.Tou_CParameter_EditField.ValueDisplayFormat = '%.0f';
            app.Tou_CParameter_EditField.ValueChangedFcn = createCallbackFcn(app, @Tou_CParameter_EditFieldValueChanged, true);
            app.Tou_CParameter_EditField.Tooltip = {'Scalar of C-parameter in the Tougaard background'};
            app.Tou_CParameter_EditField.Value = 1643;
            app.DParameterEditFieldLabel = uilabel(app.TougaardArguments_GridLayout);
            app.DParameterEditFieldLabel.Text = 'D-Parameter:';
            app.Tou_DParameter_EditField = uieditfield(app.TougaardArguments_GridLayout, 'numeric');
            app.Tou_DParameter_EditField.Limits = [0 Inf];
            app.Tou_DParameter_EditField.RoundFractionalValues = 'on';
            app.Tou_DParameter_EditField.ValueDisplayFormat = '%.0f';
            app.Tou_DParameter_EditField.ValueChangedFcn = createCallbackFcn(app, @Tou_DParameter_EditFieldValueChanged, true);
            app.Tou_DParameter_EditField.Tooltip = {'Scalar of D-parameter in the Tougaard background'};
            app.Tou_DParameter_EditField.Value = 275;
            app.SOArguments_Panel = uipanel(app.PESBgrnd_GridLayout02);
            app.SOArguments_Panel.Tooltip = {'Shirley-Offset Arguments Panel'};
            app.SOArguments_Panel.Title = 'Shirley-Offset Arguments';
            app.SOArguments_GridLayout = uigridlayout(app.SOArguments_Panel);
            app.LambdaLabel = uilabel(app.SOArguments_GridLayout);
            app.LambdaLabel.Text = 'Lambda:';
            app.SO_LambdaParameter_EditField = uieditfield(app.SOArguments_GridLayout, 'numeric');
            app.SO_LambdaParameter_EditField.Limits = [0 1];
            app.SO_LambdaParameter_EditField.ValueDisplayFormat = '%.3f';
            app.SO_LambdaParameter_EditField.ValueChangedFcn = createCallbackFcn(app, @SO_LambdaParameter_EditFieldValueChanged, true);
            app.SO_LambdaParameter_EditField.Tooltip = {'Scalar for the Shirley-Offset mixing ratio: lambda = 0 is Pure Shirley; lambda = 1 is Pure Linear.'};
            app.SO_LambdaParameter_EditField.Value = 0.5;
            app.DeltaLabel = uilabel(app.SOArguments_GridLayout);
            app.DeltaLabel.Text = 'Delta:';
            app.SO_DeltaParameter_EditField = uieditfield(app.SOArguments_GridLayout, 'numeric');
            app.SO_DeltaParameter_EditField.ValueDisplayFormat = '%.3f';
            app.SO_DeltaParameter_EditField.ValueChangedFcn = createCallbackFcn(app, @SO_DeltaParameter_EditFieldValueChanged, true);
            app.SO_DeltaParameter_EditField.Tooltip = {'Scalar for the Shirley curve offset along the x-axis'};
            app.ACM_Tab = uitab(app.TabGroup);
            app.ACM_Tab.Tooltip = {'Atomic Concentration Model'};
            app.ACM_Tab.Title = 'Atomic Concentration Model';
            app.ACM_GridLayout = uigridlayout(app.ACM_Tab);
            app.ACModel_ViewModel = uibutton(app.ACM_GridLayout, 'push');
            app.ACModel_ViewModel.ButtonPushedFcn = createCallbackFcn(app, @ACModel_ViewModelButtonPushed, true);
            app.ACModel_ViewModel.Tooltip = {'View Atomic Concentration Model'};
            app.ACModel_ViewModel.Text = 'View Atomic Concentration Model';
            app.ACModel_ExportDataTxt = uibutton(app.ACM_GridLayout, 'push');
            app.ACModel_ExportDataTxt.ButtonPushedFcn = createCallbackFcn(app, @ACModel_ExportDataTxtButtonPushed, true);
            app.ACModel_ExportDataTxt.Tooltip = {'Export the Atomic Concentration Model (as a *.txt file)'};
            app.ACModel_ExportDataTxt.Text = 'Export Data (*.txt)';
            app.ACModel_ExportDataMat = uibutton(app.ACM_GridLayout, 'push');
            app.ACModel_ExportDataMat.ButtonPushedFcn = createCallbackFcn(app, @ACModel_ExportDataMatButtonPushed, true);
            app.ACModel_ExportDataMat.Tooltip = {'Export the Atomic Concentration Model (as a *.mat file)'};
            app.ACModel_ExportDataMat.Text = 'Export Data (*.mat)';
            app.ACModel_LayerAdd_Button = uibutton(app.ACM_GridLayout, 'push');
            app.ACModel_LayerAdd_Button.ButtonPushedFcn = createCallbackFcn(app, @ACModel_LayerAdd_ButtonPushed, true);
            app.ACModel_LayerAdd_Button.Tooltip = {'Add a new layer'};
            app.ACModel_LayerAdd_Button.Text = '+';
            app.ACModel_UITable = uitable(app.ACM_GridLayout);
            app.ACModel_UITable.ColumnName = {'Layer'; 'Type'; 'Center'; 'Amplitude'; 'Width'; 'FWHM'; 'CDL'; 'Cutoff'};
            app.ACModel_UITable.RowName = {};
            app.ACModel_UITable.CellEditCallback = createCallbackFcn(app, @ACModel_UITableCellEdit, true);
            app.ACModel_UITable.Tooltip = {'Table the contains all the Atomic Concentration Model arguments'};
            app.ACModel_LayerDelete_Button = uibutton(app.ACM_GridLayout, 'push');
            app.ACModel_LayerDelete_Button.ButtonPushedFcn = createCallbackFcn(app, @ACModel_LayerDelete_ButtonPushed, true);
            app.ACModel_LayerDelete_Button.Tooltip = {'Remove the last layer'};
            app.ACModel_LayerDelete_Button.Text = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_MatBase_PESModelViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end