classdef App_MatBase_Photoionization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ExportEbeSpec_Panel           matlab.ui.container.Panel
        ExportEbeSpec_GridLayout      matlab.ui.container.GridLayout
        ExportData_EbeSpec_Button     matlab.ui.control.Button
        PlotData_EbeSpec_Button       matlab.ui.control.Button
        ExportXSect_Panel             matlab.ui.container.Panel
        ExportXSect_GridLayout        matlab.ui.container.GridLayout
        ExportData_XSect_Button       matlab.ui.control.Button
        MaxEph_EditField              matlab.ui.control.NumericEditField
        MaxeVEditFieldLabel           matlab.ui.control.Label
        PlotData_XSect_Button         matlab.ui.control.Button
        MinEph_EditField              matlab.ui.control.NumericEditField
        MineVEditFieldLabel           matlab.ui.control.Label
        Formalism_DropDown            matlab.ui.control.DropDown
        FormalismDropDownLabel        matlab.ui.control.Label
        XSectParams_Panel             matlab.ui.container.Panel
        XSectParams_GridLayout        matlab.ui.container.GridLayout
        PlotAll_Delta_Button          matlab.ui.control.Button
        PlotCL_Delta_Button           matlab.ui.control.Button
        Delta_EditField               matlab.ui.control.NumericEditField
        SigmabarnatomLabel_4          matlab.ui.control.Label
        PlotAll_Gamma_Button          matlab.ui.control.Button
        PlotCL_Gamma_Button           matlab.ui.control.Button
        Gamma_EditField               matlab.ui.control.NumericEditField
        SigmabarnatomLabel_3          matlab.ui.control.Label
        PlotAll_Beta_Button           matlab.ui.control.Button
        PlotCL_Beta_Button            matlab.ui.control.Button
        Beta_EditField                matlab.ui.control.NumericEditField
        SigmabarnatomLabel_2          matlab.ui.control.Label
        PlotAll_Sigma_Button          matlab.ui.control.Button
        PlotCL_Sigma_Button           matlab.ui.control.Button
        Sigma_EditField               matlab.ui.control.NumericEditField
        SigmabarnatomLabel            matlab.ui.control.Label
        PhotonEnergy_EditField        matlab.ui.control.NumericEditField
        PhotonEnergyeVEditFieldLabel  matlab.ui.control.Label
        CoreLevel_DropDown            matlab.ui.control.DropDown
        CoreLevelDropDownLabel        matlab.ui.control.Label
        BE_UITable                    matlab.ui.control.Table
        PeriodicTableSingle_ButtonGroup  matlab.ui.container.ButtonGroup
        Lr_Button                     matlab.ui.control.ToggleButton
        No_Button                     matlab.ui.control.ToggleButton
        Md_Button                     matlab.ui.control.ToggleButton
        Fm_Button                     matlab.ui.control.ToggleButton
        Es_Button                     matlab.ui.control.ToggleButton
        Cf_Button                     matlab.ui.control.ToggleButton
        Bk_Button                     matlab.ui.control.ToggleButton
        Cm_Button                     matlab.ui.control.ToggleButton
        Am_Button                     matlab.ui.control.ToggleButton
        Pu_Button                     matlab.ui.control.ToggleButton
        Np_Button                     matlab.ui.control.ToggleButton
        U_Button                      matlab.ui.control.ToggleButton
        Pa_Button                     matlab.ui.control.ToggleButton
        Th_Button                     matlab.ui.control.ToggleButton
        Lu_Button                     matlab.ui.control.ToggleButton
        Yb_Button                     matlab.ui.control.ToggleButton
        Tm_Button                     matlab.ui.control.ToggleButton
        Er_Button                     matlab.ui.control.ToggleButton
        Ho_Button                     matlab.ui.control.ToggleButton
        Dy_Button                     matlab.ui.control.ToggleButton
        Tb_Button                     matlab.ui.control.ToggleButton
        Gd_Button                     matlab.ui.control.ToggleButton
        Eu_Button                     matlab.ui.control.ToggleButton
        Sm_Button                     matlab.ui.control.ToggleButton
        Pm_Button                     matlab.ui.control.ToggleButton
        Nd_Button                     matlab.ui.control.ToggleButton
        Pr_Button                     matlab.ui.control.ToggleButton
        Ce_Button                     matlab.ui.control.ToggleButton
        Og_Button                     matlab.ui.control.ToggleButton
        Ts_Button                     matlab.ui.control.ToggleButton
        Lv_Button                     matlab.ui.control.ToggleButton
        Mc_Button                     matlab.ui.control.ToggleButton
        Fl_Button                     matlab.ui.control.ToggleButton
        Nh_Button                     matlab.ui.control.ToggleButton
        Cn_Button                     matlab.ui.control.ToggleButton
        Rg_Button                     matlab.ui.control.ToggleButton
        Ds_Button                     matlab.ui.control.ToggleButton
        Mt_Button                     matlab.ui.control.ToggleButton
        Hs_Button                     matlab.ui.control.ToggleButton
        Bh_Button                     matlab.ui.control.ToggleButton
        Sg_Button                     matlab.ui.control.ToggleButton
        Db_Button                     matlab.ui.control.ToggleButton
        Rf_Button                     matlab.ui.control.ToggleButton
        Ac_Button                     matlab.ui.control.ToggleButton
        Ra_Button                     matlab.ui.control.ToggleButton
        Fr_Button                     matlab.ui.control.ToggleButton
        Rn_Button                     matlab.ui.control.ToggleButton
        At_Button                     matlab.ui.control.ToggleButton
        Po_Button                     matlab.ui.control.ToggleButton
        Bi_Button                     matlab.ui.control.ToggleButton
        Pb_Button                     matlab.ui.control.ToggleButton
        Tl_Button                     matlab.ui.control.ToggleButton
        Hg_Button                     matlab.ui.control.ToggleButton
        Au_Button                     matlab.ui.control.ToggleButton
        Pt_Button                     matlab.ui.control.ToggleButton
        Ir_Button                     matlab.ui.control.ToggleButton
        Os_Button                     matlab.ui.control.ToggleButton
        Re_Button                     matlab.ui.control.ToggleButton
        W_Button                      matlab.ui.control.ToggleButton
        Ta_Button                     matlab.ui.control.ToggleButton
        Hf_Button                     matlab.ui.control.ToggleButton
        La_Button                     matlab.ui.control.ToggleButton
        Ba_Button                     matlab.ui.control.ToggleButton
        Cs_Button                     matlab.ui.control.ToggleButton
        Xe_Button                     matlab.ui.control.ToggleButton
        I_Button                      matlab.ui.control.ToggleButton
        Te_Button                     matlab.ui.control.ToggleButton
        Sb_Button                     matlab.ui.control.ToggleButton
        Sn_Button                     matlab.ui.control.ToggleButton
        In_Button                     matlab.ui.control.ToggleButton
        Cd_Button                     matlab.ui.control.ToggleButton
        Ag_Button                     matlab.ui.control.ToggleButton
        Pd_Button                     matlab.ui.control.ToggleButton
        Rh_Button                     matlab.ui.control.ToggleButton
        Ru_Button                     matlab.ui.control.ToggleButton
        Tc_Button                     matlab.ui.control.ToggleButton
        Mo_Button                     matlab.ui.control.ToggleButton
        Nb_Button                     matlab.ui.control.ToggleButton
        Zr_Button                     matlab.ui.control.ToggleButton
        Y_Button                      matlab.ui.control.ToggleButton
        Sr_Button                     matlab.ui.control.ToggleButton
        Rb_Button                     matlab.ui.control.ToggleButton
        Kr_Button                     matlab.ui.control.ToggleButton
        Br_Button                     matlab.ui.control.ToggleButton
        Se_Button                     matlab.ui.control.ToggleButton
        As_Button                     matlab.ui.control.ToggleButton
        Ge_Button                     matlab.ui.control.ToggleButton
        Ga_Button                     matlab.ui.control.ToggleButton
        Zn_Button                     matlab.ui.control.ToggleButton
        Cu_Button                     matlab.ui.control.ToggleButton
        Ni_Button                     matlab.ui.control.ToggleButton
        Co_Button                     matlab.ui.control.ToggleButton
        Fe_Button                     matlab.ui.control.ToggleButton
        Mn_Button                     matlab.ui.control.ToggleButton
        Cr_Button                     matlab.ui.control.ToggleButton
        V_Button                      matlab.ui.control.ToggleButton
        Ti_Button                     matlab.ui.control.ToggleButton
        Sc_Button                     matlab.ui.control.ToggleButton
        Ca_Button                     matlab.ui.control.ToggleButton
        K_Button                      matlab.ui.control.ToggleButton
        Ar_Button                     matlab.ui.control.ToggleButton
        Cl_Button                     matlab.ui.control.ToggleButton
        S_Button                      matlab.ui.control.ToggleButton
        P_Button                      matlab.ui.control.ToggleButton
        Si_Button                     matlab.ui.control.ToggleButton
        Al_Button                     matlab.ui.control.ToggleButton
        Label_12                      matlab.ui.control.Label
        Label_11                      matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Mg_Button                     matlab.ui.control.ToggleButton
        Na_Button                     matlab.ui.control.ToggleButton
        Ne_Button                     matlab.ui.control.ToggleButton
        F_Button                      matlab.ui.control.ToggleButton
        O_Button                      matlab.ui.control.ToggleButton
        N_Button                      matlab.ui.control.ToggleButton
        C_Button                      matlab.ui.control.ToggleButton
        B_Button                      matlab.ui.control.ToggleButton
        Be_Button                     matlab.ui.control.ToggleButton
        Li_Button                     matlab.ui.control.ToggleButton
        He_Button                     matlab.ui.control.ToggleButton
        Label_17                      matlab.ui.control.Label
        Label_16                      matlab.ui.control.Label
        Label_15                      matlab.ui.control.Label
        Label_14                      matlab.ui.control.Label
        Label_13                      matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        H_Button                      matlab.ui.control.ToggleButton
        Label_18                      matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Material_EditField            matlab.ui.control.EditField
        ElementLabel                  matlab.ui.control.Label
        AppButtons_GridLayout         matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        SAVE_Button                   matlab.ui.control.Button
        TRANSFER_Button               matlab.ui.control.Button
        APPINFO_Button                matlab.ui.control.Button
        RESIZEUI_Button               matlab.ui.control.Button
        RESTART_Button                matlab.ui.control.Button
        CLOSEFIGS_Button              matlab.ui.control.Button
        AppTitle                      matlab.ui.control.Label
        MAINMENU_GridLayout           matlab.ui.container.GridLayout
        MAINMENU_Button               matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % - MATLAB data structure to summarise all data for analysis
        xsectData
        % - Variables for determining the photoionization cross-sections
        element          % single selected element
        formalism        % formalism of photoionization cross-sections
        clevel           % core-level selected
        eph              % photon energy defined by user
        eph_R            % photon energy range of formalism
        % - Final values of the photoionization parameters
        sigma            % Subshell photoionization cross section in units of barn/atom
        beta             % Beta (dipole) parameter of the photoelectron angular distribution
        gamma            % Gamma (non-dipole) parameter of the photoelectron angular distribution
        delta            % Delta (non-dipole) parameter of the photoelectron angular distribution
        sigma_R          % Range: Subshell photoionization cross section in units of barn/atom
        beta_R           % Range: Beta (dipole) parameter of the photoelectron angular distribution
        gamma_R          % Range: Gamma (non-dipole) parameter of the photoelectron angular distribution
        delta_R          % Range: Delta (non-dipole) parameter of the photoelectron angular distribution
        % - Variables for plotting and exporting XSect data
        eph_min          % minimum photon energy (eV)
        eph_max          % maximum photon energy (eV)
    end
    
    % Private methods available to the app only
    methods (Access = private)
        % Code that executes to calculate sigma
        function update_values(app)
            % 1 - Extract the latest values based on the current inputs
            app.xsectData = extract_myData(app);
            % 2 - Update the sigma edit-field to display value in barn/atom
            if app.sigma < 0 || isnan(app.sigma) || isempty(app.sigma); app.sigma = 0; end
            app.Sigma_EditField.Value = app.sigma;
            fprintf("---> sigma: " + string(app.sigma) + " barn/atom \n");
            % 3 - Update the beta edit-field to display value
            if isempty(app.beta) || isnan(app.beta); app.beta = 0; end
            app.Beta_EditField.Value = app.beta;
            fprintf("---> beta: " + string(app.beta) + " \n");
            % 4 - Update the gamma edit-field to display value
            if isempty(app.gamma) || isnan(app.gamma); app.gamma = 0; end
            app.Gamma_EditField.Value = app.gamma;
            fprintf("---> gamma: " + string(app.gamma) + " \n");
            % 5 - Update the delta edit-field to display value
            if isempty(app.delta) || isnan(app.delta); app.delta = 0; end
            app.Delta_EditField.Value = app.delta;
            fprintf("---> delta: " + string(app.delta) + " \n");
            % 6 - Update the relative intensities
            for i = 1:length(app.xsectData.be)
                app.BE_UITable.Data{i,3} = round(app.xsectData.rsigma(i),4);
            end
        end

        % Extract the data structure
        function xsectData = extract_myData(app)
            % 1 - Extracting the binding energies of the chosen element
            [be, cls] = calc_be(app.element, [], [], 0);
            % 2 - Defining the photon energy range for the formalism used
            app.eph_R = [];
            if strcmpi(app.formalism,"S1973");      app.eph_R = 1000:10:1500000;
            elseif strcmpi(app.formalism,"YL1985"); app.eph_R = 10:10:1500;
            elseif strcmpi(app.formalism,"T2018");  app.eph_R = 1500:10:10000;
            elseif strcmpi(app.formalism,"C2022");  app.eph_R = 1500:10:10000;
            end
            app.eph_R = app.eph_R';
            % 3 - Extracting the photoionization parameters
            [app.sigma, app.beta, app.gamma, app.delta]             = calc_xsect(app.element, app.clevel, app.eph, app.formalism, 0);
            [app.sigma_R, app.beta_R, app.gamma_R, app.delta_R]     = calc_xsect(app.element, app.clevel, app.eph_R, app.formalism, 0);
            [sigma_All, ~, ~, ~]                                    = calc_xsect(app.element, cls, app.eph, app.formalism, 0);
            % 4 - Extracting the relative cross-section of each core-level
            if isnan(app.sigma); rel_xsect = sigma_All./ max(sigma_All(:)); rel_xsect = rel_xsect';
            else; rel_xsect = sigma_All./ app.sigma; rel_xsect = rel_xsect';
            end
            % 5 - Defining the final data structure
            xsectData               = struct();
            % -- Defining the input arguments
            xsectData.formalism     = string(app.formalism);
            xsectData.element       = string(app.element);
            xsectData.cls           = cls;
            xsectData.be            = be;
            xsectData.rsigma        = round(rel_xsect, 4);
            % -- Extracting the cross-sections at the chosen photon energy
            xsectData.clevel        = app.clevel;
            xsectData.eph           = app.eph;
            xsectData.sigma         = app.sigma;
            xsectData.beta          = app.beta;
            xsectData.gamma         = app.gamma;
            xsectData.delta         = app.delta;
            % -- Extracting the cross-section across the full photon energy range
            xsectData.eph_R         = app.eph_R;
            xsectData.sigma_R       = app.sigma_R;
            xsectData.beta_R        = app.beta_R;
            xsectData.gamma_R       = app.gamma_R;
            xsectData.delta_R       = app.delta_R;
        end

        % Plot sigma photoionization figure
        function plot_sigma_cl(app)
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = 100; fig.Position(2) = 100;
            fig.Position(3) = 650; 
            fig.Position(4) = 400;
            % - Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            title(t,sprintf("Sigma : %s - %s", app.element, app.formalism),'FontWeight','bold', 'fontsize', 14);
            % - Plot of SIGMA
            nexttile();hold on; grid on; grid minor;
            loglog(app.xsectData.eph_R, app.xsectData.sigma_R, '-', 'LineWidth', 2, 'markersize', 5);
            stem(app.xsectData.eph, app.xsectData.sigma, 'markerfacecolor', 'none', 'color','k', 'linewidth', 1.5);
            % - Add a legend
            legend(app.xsectData.clevel, 'fontsize', 9, 'location', 'eastoutside');
            % - Labelling the x- and y-axes
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \sigma [barn/atom] ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'log'; ax.XScale = 'log';
            axis([...
                0.50*min(app.xsectData.eph_R(:)), 1.50*max(app.xsectData.eph_R(:)),...
                1e-8, 1e8]);
        end

        % Plot beta photoionization figure
        function plot_beta_cl(app)
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = 100; fig.Position(2) = 100;
            fig.Position(3) = 650; 
            fig.Position(4) = 400;
            % - Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            title(t,sprintf("Beta : %s - %s", app.element, app.formalism),'FontWeight','bold', 'fontsize', 14);
            % - Plot of BETA
            nexttile();hold on; grid on; grid minor;
            plot(app.xsectData.eph_R, app.xsectData.beta_R, '-', 'LineWidth', 2, 'markersize', 5);
            stem(app.xsectData.eph, app.xsectData.beta, 'markerfacecolor', 'none', 'color','k', 'linewidth', 1.5);
            % - Add a legend
            legend(app.xsectData.clevel, 'fontsize', 9, 'location', 'eastoutside');
            % - Labelling the x- and y-axes
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \beta ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; ax.XScale = 'linear';
            axis([...
                0.50*min(app.xsectData.eph_R(:)), 1.50*max(app.xsectData.eph_R(:)),...
                -1, 2.6]);
        end

        % Plot gamma photoionization figure
        function plot_gamma_cl(app)
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = 100; fig.Position(2) = 100;
            fig.Position(3) = 650; 
            fig.Position(4) = 400;
            % - Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            title(t,sprintf("Gamma : %s - %s", app.element, app.formalism),'FontWeight','bold', 'fontsize', 14);
            % - Plot of BETA
            nexttile();hold on; grid on; grid minor;
            plot(app.xsectData.eph_R, app.xsectData.gamma_R, '-', 'LineWidth', 2, 'markersize', 5);
            stem(app.xsectData.eph, app.xsectData.gamma, 'markerfacecolor', 'none', 'color','k', 'linewidth', 1.5);
            % - Add a legend
            legend(app.xsectData.clevel, 'fontsize', 9, 'location', 'eastoutside');
            % - Labelling the x- and y-axes
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \gamma ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; ax.XScale = 'linear';
            axis([...
                0.50*min(app.xsectData.eph_R(:)), 1.50*max(app.xsectData.eph_R(:)),...
                -1, 2.6]);
        end

        % Plot delta photoionization figure
        function plot_delta_cl(app)
            % - Creating a figure
            fig = figure(); 
            fig.Position(1) = 100; fig.Position(2) = 100;
            fig.Position(3) = 650; 
            fig.Position(4) = 400;
            % - Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            title(t,sprintf("Delta : %s - %s", app.element, app.formalism),'FontWeight','bold', 'fontsize', 14);
            % - Plot of BETA
            nexttile();hold on; grid on; grid minor;
            plot(app.xsectData.eph_R, app.xsectData.delta_R, '-', 'LineWidth', 2, 'markersize', 5);
            stem(app.xsectData.eph, app.xsectData.delta, 'markerfacecolor', 'none', 'color','k', 'linewidth', 1.5);
            % - Add a legend
            legend(app.xsectData.clevel, 'fontsize', 9, 'location', 'eastoutside');
            % - Labelling the x- and y-axes
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \delta ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; ax.XScale = 'linear';
            axis([...
                0.50*min(app.xsectData.eph_R(:)), 1.50*max(app.xsectData.eph_R(:)),...
                0.0, 0.6]);
        end

        % Plot full sigma photoionization figure
        function plot_sigma_all(app)
            [sigma_All, ~, ~, ~]  = calc_xsect(app.element, app.xsectData.cls, app.eph_R, app.formalism, 0);
            nan_indx    = find(all(isnan(sigma_All),1));
            sigma_final = sigma_All; sigma_final(:,nan_indx) = [];
            cls_final   = app.xsectData.cls; cls_final(nan_indx) = [];
            % - Plotting the photoionisation figure
            % -- Creating a figure
            fig = figure(); 
            fig.Position(1) = 100; fig.Position(2) = 100;
            fig.Position(3) = 650; 
            fig.Position(4) = 400;
            % -- Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            title(t,sprintf("Sigma : %s - %s", app.element, app.formalism),'FontWeight','bold', 'fontsize', 14);
            % -- Plot of SIGMA
            nexttile();hold on; grid on; grid minor;
            loglog(app.xsectData.eph_R, sigma_final, '-', 'LineWidth', 2, 'markersize', 5);
            % -- Add a legend
            legend(cls_final, 'fontsize', 9, 'location', 'eastoutside');
            % -- Labelling the x- and y-axes
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \sigma [barn/atom] ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'log'; ax.XScale = 'log';
            axis([...
                0.50*min(app.xsectData.eph_R(:)), 1.50*max(app.xsectData.eph_R(:)),...
                1e-8, 1e8]);
        end

        % Plot full beta photoionization figure
        function plot_beta_all(app)
            [~, beta_All, ~, ~]  = calc_xsect(app.element, app.xsectData.cls, app.eph_R, app.formalism, 0);
            nan_indx    = find(all(isnan(beta_All),1));
            beta_final = beta_All; beta_final(:,nan_indx) = [];
            cls_final   = app.xsectData.cls; cls_final(nan_indx) = [];
            % - Plotting the photoionisation figure
            % -- Creating a figure
            fig = figure(); 
            fig.Position(1) = 100; fig.Position(2) = 100;
            fig.Position(3) = 650; 
            fig.Position(4) = 400;
            % -- Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            title(t,sprintf("Beta : %s - %s", app.element, app.formalism),'FontWeight','bold', 'fontsize', 14);
            % -- Plot of beta
            nexttile();hold on; grid on; grid minor;
            plot(app.xsectData.eph_R, beta_final, '-', 'LineWidth', 2, 'markersize', 5);
            yline(0,'k-');
            % -- Add a legend
            legend(cls_final, 'fontsize', 9, 'location', 'eastoutside');
            % -- Labelling the x- and y-axes
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \beta ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; ax.XScale = 'linear';
            axis([...
                0.50*min(app.xsectData.eph_R(:)), 1.50*max(app.xsectData.eph_R(:)),...
                -1, 2.6]);
        end

        % Plot full gamma photoionization figure
        function plot_gamma_all(app)
            [~, ~, gamma_All, ~]  = calc_xsect(app.element, app.xsectData.cls, app.eph_R, app.formalism, 0);
            nan_indx    = find(all(isnan(gamma_All),1));
            gamma_final = gamma_All; gamma_final(:,nan_indx) = [];
            cls_final   = app.xsectData.cls; cls_final(nan_indx) = [];
            % - Plotting the photoionisation figure
            % -- Creating a figure
            fig = figure(); 
            fig.Position(1) = 100; fig.Position(2) = 100;
            fig.Position(3) = 650; 
            fig.Position(4) = 400;
            % -- Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            title(t,sprintf("Gamma : %s - %s", app.element, app.formalism),'FontWeight','bold', 'fontsize', 14);
            % -- Plot of gamma
            nexttile();hold on; grid on; grid minor;
            plot(app.xsectData.eph_R, gamma_final, '-', 'LineWidth', 2, 'markersize', 5);
            yline(0,'k-');
            % -- Add a legend
            legend(cls_final, 'fontsize', 9, 'location', 'eastoutside');
            % -- Labelling the x- and y-axes
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \gamma ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; ax.XScale = 'linear';
            axis([...
                0.50*min(app.xsectData.eph_R(:)), 1.50*max(app.xsectData.eph_R(:)),...
                -1, 2.6]);
        end

        % Plot full delta photoionization figure
        function plot_delta_all(app)
            [~, ~, ~, delta_All]  = calc_xsect(app.element, app.xsectData.cls, app.eph_R, app.formalism, 0);
            nan_indx    = find(all(isnan(delta_All),1));
            delta_final = delta_All; delta_final(:,nan_indx) = [];
            cls_final   = app.xsectData.cls; cls_final(nan_indx) = [];
            % - Plotting the photoionisation figure
            % -- Creating a figure
            fig = figure(); 
            fig.Position(1) = 100; fig.Position(2) = 100;
            fig.Position(3) = 650; 
            fig.Position(4) = 400;
            % -- Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            title(t,sprintf("Delta : %s - %s", app.element, app.formalism),'FontWeight','bold', 'fontsize', 14);
            % -- Plot of gamma
            nexttile();hold on; grid on; grid minor;
            plot(app.xsectData.eph_R, delta_final, '-', 'LineWidth', 2, 'markersize', 5);
            yline(0,'k-');
            % -- Add a legend
            legend(cls_final, 'fontsize', 9, 'location', 'eastoutside');
            % -- Labelling the x- and y-axes
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \delta ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; ax.XScale = 'linear';
            axis([...
                0.50*min(app.xsectData.eph_R(:)), 1.50*max(app.xsectData.eph_R(:)),...
                0.0, 0.6]);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBase - Photoionization Energy, Cross-Sections and Asymmetry App (procopios.constantinou@psi.ch)';
            % 1 - Default parameters upon startup
            app.xsectData   = struct();
            app.element     = "Si";
            app.formalism   = "C2022";
            app.clevel      = "1s1";
            app.eph         = 2000;
            app.eph_R       = [];
            app.eph_min     = 1500;
            app.eph_max     = 10000;
            % 2 - First Tab Initialisation
            % -- Initialising the UI table
            app.BE_UITable.Data = []; 
            s = uistyle('HorizontalAlignment','left'); addStyle(app.BE_UITable,s);
            % -- Updating the table and figures
            % Formalism_DropDownValueChanged(app);
            PeriodicTableSingle_ButtonGroupSelectionChanged(app);
            % CoreLevel_DropDownValueChanged(app);
            % PhotonEnergy_EditFieldValueChanged(app);
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run App_MatBase;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
           % Remove the UI handle and restart
            close(gcbf); run App_MatBase_Photoionization;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 1020;
            screen_size(4) = 465;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            app.xsectData = extract_myData(app);
            disp('xsectData : '); disp(app.xsectData);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            app.xsectData = extract_myData(app);
            assignin('base', 'xsectData', app.xsectData);
        end

        % Button pushed function: SAVE_Button
        function SAVE_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            fname = sprintf("%s_%s_%s", app.element, app.clevel, app.formalism);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            app.xsectData = extract_myData(app);
            dataStr = app.xsectData; 
            save(char(save_fullfile), 'dataStr', '-v7.3');
            disp('-> saved data : '); display(app.xsectData);
            %% 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Selection changed function: PeriodicTableSingle_ButtonGroup
        function PeriodicTableSingle_ButtonGroupSelectionChanged(app, event)
            % 1 - Extracting the input defined by the user
            selectedButton  = app.PeriodicTableSingle_ButtonGroup.SelectedObject;
            app.element     = selectedButton.Text;
            fprintf("--> Element: " + string(app.element) + "\n");
            % 2 - Extracting the photoionisation parameters from the materials database
            app.xsectData   = extract_myData(app);
            % 3 - Updating the relevant UI components
            app.Material_EditField.Value = app.element;
            % -- Update UITable for BE spectrum
            app.BE_UITable.Data = [];
            for i = 1:length(app.xsectData.be)
                app.BE_UITable.Data{i,1} = char(app.xsectData.cls(i));
                app.BE_UITable.Data{i,2} = round(app.xsectData.be(i),4);
                app.BE_UITable.Data{i,3} = round(app.xsectData.rsigma(i),4);
            end
            % -- Update the list of core-levels that can be chosen
            [be, cls] = calc_be(app.element, [], [], 0);
            [be, indx] = sort(be); cls = cls(indx);
            for i = 1:length(be); cl_labels(i) = sprintf("%s-%.2feV",cls(i),be(i)); end
            app.CoreLevel_DropDown.Items = cl_labels;
            CoreLevel_DropDownValueChanged(app);
        end

        % Value changed function: Formalism_DropDown
        function Formalism_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Formalism_DropDown.Value;
            value = string(value);
            % 2 - Validity check on the material
            if strcmpi(value,"Scofield(1973) - 1 to 1500 keV");                 value = "S1973";
            elseif strcmpi(value,"Yeh Lindau(1985) - 10 to 1500 eV");           value = "YL1985";
            elseif strcmpi(value,"Trzhaskovskaya(2018) - 1500 to 10000 eV");    value = "T2018";
            elseif strcmpi(value,"Cant(2022) - 1500 to 10000 eV");              value = "C2022";
            end
            % 3 - Validity check on the buttons to plot photoionization parameters
            if value == "S1973"
                app.PlotCL_Sigma_Button.Enable  = 'on';
                app.PlotAll_Sigma_Button.Enable = 'on';
                app.PlotCL_Beta_Button.Enable   = 'off';
                app.PlotAll_Beta_Button.Enable  = 'off';
                app.PlotCL_Gamma_Button.Enable  = 'off';
                app.PlotAll_Gamma_Button.Enable = 'off';
                app.PlotCL_Delta_Button.Enable  = 'off';
                app.PlotAll_Delta_Button.Enable = 'off';
            elseif value == "YL1985"
                app.PlotCL_Sigma_Button.Enable  = 'on';
                app.PlotAll_Sigma_Button.Enable = 'on';
                app.PlotCL_Beta_Button.Enable   = 'on';
                app.PlotAll_Beta_Button.Enable  = 'on';
                app.PlotCL_Gamma_Button.Enable  = 'off';
                app.PlotAll_Gamma_Button.Enable = 'off';
                app.PlotCL_Delta_Button.Enable  = 'off';
                app.PlotAll_Delta_Button.Enable = 'off';
            elseif value == "T2018" || value == "C2022"
                app.PlotCL_Sigma_Button.Enable  = 'on';
                app.PlotAll_Sigma_Button.Enable = 'on';
                app.PlotCL_Beta_Button.Enable   = 'on';
                app.PlotAll_Beta_Button.Enable  = 'on';
                app.PlotCL_Gamma_Button.Enable  = 'on';
                app.PlotAll_Gamma_Button.Enable = 'on';
                app.PlotCL_Delta_Button.Enable  = 'on';
                app.PlotAll_Delta_Button.Enable = 'on';
            end
            % 4 - Assigning the output
            app.formalism = value;
            fprintf("--> formalism: " + string(app.formalism) + "\n");
            % 5 - Update the values
            update_values(app);
        end

        % Value changed function: CoreLevel_DropDown
        function CoreLevel_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.CoreLevel_DropDown.Value;
            value = string(value(1:3));
            % 2 - Validity check on the input
            % 3 - Assigning the output
            app.clevel = value;
            fprintf("--> Core-Level: " + string(app.clevel) + "\n");
            % 4 - Validity check on the photon energy
            [be, ~] = calc_be(app.element, app.clevel, [], 0);
            if app.eph < be
                app.eph = be;
                app.PhotonEnergy_EditField.Value = app.eph;
            end
            if app.eph_min < be
                app.eph_min = be;
                app.MinEph_EditField.Value = app.eph;
            end
            if app.eph_max < be
                app.eph_max = be;
                app.MaxEph_EditField.Value = app.eph;
            end

            % 5 - Calculating sigma
            update_values(app);
        end

        % Value changed function: PhotonEnergy_EditField
        function PhotonEnergy_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PhotonEnergy_EditField.Value;
            % 2 - Validity check on the input value
            [be, ~] = calc_be(app.element, app.clevel, [], 0);
            if isempty(value) || isnan(value) || value < be
                value = be;
            end
            app.eph = value;
            app.PhotonEnergy_EditField.Value = app.eph;
            % 2 - Assigning the output
            fprintf("--> Photon Energy: " + string(app.eph) + " eV\n");
            % 3 - Calculating sigma
            update_values(app);
        end

        % Button pushed function: PlotCL_Sigma_Button
        function PlotCL_Sigma_ButtonPushed(app, event)
            plot_sigma_cl(app);
        end

        % Button pushed function: PlotAll_Sigma_Button
        function PlotAll_Sigma_ButtonPushed(app, event)
            plot_sigma_all(app);
        end

        % Button pushed function: PlotCL_Beta_Button
        function PlotCL_Beta_ButtonPushed(app, event)
            plot_beta_cl(app);
        end

        % Button pushed function: PlotAll_Beta_Button
        function PlotAll_Beta_ButtonPushed(app, event)
            plot_beta_all(app);
        end

        % Button pushed function: PlotCL_Gamma_Button
        function PlotCL_Gamma_ButtonPushed(app, event)
            plot_gamma_cl(app);
        end

        % Button pushed function: PlotAll_Gamma_Button
        function PlotAll_Gamma_ButtonPushed(app, event)
            plot_gamma_all(app);
        end

        % Button pushed function: PlotCL_Delta_Button
        function PlotCL_Delta_ButtonPushed(app, event)
            plot_delta_cl(app);
        end

        % Button pushed function: PlotAll_Delta_Button
        function PlotAll_Delta_ButtonPushed(app, event)
            plot_delta_all(app);
        end

        % Value changed function: MinEph_EditField
        function MinEph_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.MinEph_EditField.Value;
            % 2 - Validity check on the input value
            [be, ~] = calc_be(app.element, app.clevel, [], 0);
            if isempty(value) || isnan(value) || value < be
                value = be;
            end
            if value > app.eph_max; value = app.eph_max; end
            % 3 - Validity check on the input value
            app.eph_min = value;
            app.MinEph_EditField.Value = app.eph_min;
        end

        % Value changed function: MaxEph_EditField
        function MaxEph_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.MaxEph_EditField.Value;
            % 2 - Validity check on the input value
            [be, ~] = calc_be(app.element, app.clevel, [], 0);
            if isempty(value) || isnan(value) || value < be
                value = be;
            end
            if value < app.eph_min; value = app.eph_min; end
            % 3 - Validity check on the input value
            app.eph_max = value;
            app.MaxEph_EditField.Value     = app.eph_max;
        end

        % Button pushed function: PlotData_XSect_Button
        function PlotData_XSect_ButtonPushed(app, event)
            % 1 - Defining the photon energy range for the formalism used
            eph_range = app.eph_min:1:app.eph_max;
            eph_range = eph_range';
            % 2 - Extracting the photoionization parameters
            [xsect_dat, ~, ~, ~] = calc_xsect(app.element, app.clevel, eph_range, app.formalism, 0);
            % 3 - Plotting the photoionisation figure
            % -- Creating a figure
            fig = figure(); 
            fig.Position(1) = 100; fig.Position(2) = 100;
            fig.Position(3) = 650; 
            fig.Position(4) = 400;
            % -- Creating a tiled axis
            t = tiledlayout(1,1);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            title(t,sprintf("Sigma : %s - %s", app.element, app.formalism),'FontWeight','bold', 'fontsize', 16);
            % -- Plot
            nexttile();hold on; grid on; grid minor;
            loglog(eph_range, xsect_dat, '-', 'LineWidth', 2, 'markersize', 5);
            % -- Add a legend
            legend(app.clevel, 'fontsize', 9, 'location', 'eastoutside');
            % -- Labelling the x- and y-axes
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' \sigma [barn/atom] ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'log'; ax.XScale = 'log';
            axis([...
                0.95*min(eph_range(:)), 1.05*max(eph_range(:)),...
                0.95*min(xsect_dat(:)), 1.05*max(xsect_dat(:))]);
        end

        % Button pushed function: ExportData_XSect_Button
        function ExportData_XSect_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.txt'};
            fname = sprintf("%s%s_%s", app.element, app.clevel, app.formalism);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % 2.1 - Defining the photon energy range for the formalism used
            eph_range = app.eph_min:1:app.eph_max;
            eph_range = eph_range';
            % 2.2 - Extracting the photoionization parameters
            [xsect_sigma, xsect_beta, xsect_gamma, xsect_delta] = calc_xsect(app.element, app.clevel, eph_range, app.formalism, 0);
            T_Data = table(eph_range, xsect_sigma, xsect_beta, xsect_gamma, xsect_delta);
            T_Data.Properties.VariableNames = {'Eph_eV',...
                char(sprintf("Sigma_%s%s", app.xsectData.element, app.xsectData.clevel)), char(sprintf("Beta_%s%s", app.xsectData.element, app.xsectData.clevel)),...
                char(sprintf("Gamma_%s%s", app.xsectData.element, app.xsectData.clevel)), char(sprintf("Delta_%s%s", app.xsectData.element, app.xsectData.clevel))};
            writetable(T_Data, char(string(save_fullfile(1:end-4)) + ".txt"),'Delimiter','\t');
            %% 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Button pushed function: PlotData_EbeSpec_Button
        function PlotData_EbeSpec_ButtonPushed(app, event)
            view_be(app.element);
        end

        % Button pushed function: ExportData_EbeSpec_Button
        function ExportData_EbeSpec_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.txt'};
            fname = sprintf("Ebe_%s", app.element);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            % 2.1 - Extracting the binding energies of the chosen element
            [be, cls] = calc_be(app.element, [], [], 0);
            % 2.2 - Extracting the photoionization parameters
            T_Data = table(cls, be);
            T_Data.Properties.VariableNames = {'CLs','Ebe'};
            writetable(T_Data, char(string(save_fullfile(1:end-4)) + ".txt"),'Delimiter','\t');
            %% 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'Photoionization Energy, Cross-Sections and Asymmetry Database';
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.SAVE_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.SAVE_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVE_ButtonPushed, true);
            app.SAVE_Button.Tooltip = {'Save the data from the app as a MATLAB data structure file.'};
            app.SAVE_Button.Text = 'Save As... (.mat)';
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Extract the binding energy spectrum, photoionization cross-sections, and photoionization asymmetry parameters of all elements between 1-98 with our powerful app. Utilize a range of formalisms, including the Scofield (1973), Yeh & Lindau (1985), Trzhaskovskaya (2018) and Cant (2022) methods. Easily identify the best core-levels to probe by finding the maximum cross-sections and gain a comprehensive overview of the core-level spectra of all relevant elements!'};
            app.Image.ImageSource = 'Icon_Photoionization.jpeg';
            app.ElementLabel = uilabel(app.GridLayout);
            app.ElementLabel.Text = 'Element:';
            app.Material_EditField = uieditfield(app.GridLayout, 'text');
            app.Material_EditField.Editable = 'off';
            app.Material_EditField.Tooltip = {'Selected element from the Periodic Table.'};
            app.Material_EditField.Value = 'Si';
            app.PeriodicTableSingle_ButtonGroup = uibuttongroup(app.GridLayout);
            app.PeriodicTableSingle_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PeriodicTableSingle_ButtonGroupSelectionChanged, true);
            app.PeriodicTableSingle_ButtonGroup.Tooltip = {'Select an element.'};
            app.Label = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label.Text = '1';
            app.Label_18 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_18.Text = '18';
            app.H_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.H_Button.Text = 'H';
            app.Label_2 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_2.Text = '2';
            app.Label_13 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_13.Text = '13';
            app.Label_14 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_14.Text = '14';
            app.Label_15 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_15.Text = '15';
            app.Label_16 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_16.Text = '16';
            app.Label_17 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_17.Text = '17';
            app.He_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.He_Button.Text = 'He';
            app.Li_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Li_Button.Text = 'Li';
            app.Be_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Be_Button.Text = 'Be';
            app.B_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.B_Button.Text = 'B';
            app.C_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.C_Button.Text = 'C';
            app.N_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.N_Button.Text = 'N';
            app.O_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.O_Button.Text = 'O';
            app.F_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.F_Button.Text = 'F';
            app.Ne_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ne_Button.Text = 'Ne';
            app.Na_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Na_Button.Text = 'Na';
            app.Mg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mg_Button.Text = 'Mg';
            app.Label_3 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_8.Text = '8';
            app.Label_9 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_9.Text = '9';
            app.Label_10 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_10.Text = '10';
            app.Label_11 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_11.Text = '11';
            app.Label_12 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_12.Text = '12';
            app.Al_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Al_Button.Text = 'Al';
            app.Si_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Si_Button.Text = 'Si';
            app.Si_Button.Value = true;
            app.P_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.P_Button.Text = 'P';
            app.S_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.S_Button.Text = 'S';
            app.Cl_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cl_Button.Text = 'Cl';
            app.Ar_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ar_Button.Text = 'Ar';
            app.K_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.K_Button.Text = 'K';
            app.Ca_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ca_Button.Text = 'Ca';
            app.Sc_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sc_Button.Text = 'Sc';
            app.Ti_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ti_Button.Text = 'Ti';
            app.V_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.V_Button.Text = 'V';
            app.Cr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cr_Button.Text = 'Cr';
            app.Mn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mn_Button.Text = 'Mn';
            app.Fe_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fe_Button.Text = 'Fe';
            app.Co_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Co_Button.Text = 'Co';
            app.Ni_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ni_Button.Text = 'Ni';
            app.Cu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cu_Button.Text = 'Cu';
            app.Zn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Zn_Button.Text = 'Zn';
            app.Ga_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ga_Button.Text = 'Ga';
            app.Ge_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ge_Button.Text = 'Ge';
            app.As_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.As_Button.Text = 'As';
            app.Se_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Se_Button.Text = 'Se';
            app.Br_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Br_Button.Text = 'Br';
            app.Kr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Kr_Button.Text = 'Kr';
            app.Rb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rb_Button.Text = 'Rb';
            app.Sr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sr_Button.Text = 'Sr';
            app.Y_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Y_Button.Text = 'Y';
            app.Zr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Zr_Button.Text = 'Zr';
            app.Nb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Nb_Button.Text = 'Nb';
            app.Mo_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mo_Button.Text = 'Mo';
            app.Tc_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tc_Button.Text = 'Tc';
            app.Ru_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ru_Button.Text = 'Ru';
            app.Rh_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rh_Button.Text = 'Rh';
            app.Pd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pd_Button.Text = 'Pd';
            app.Ag_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ag_Button.Text = 'Ag';
            app.Cd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cd_Button.Text = 'Cd';
            app.In_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.In_Button.Text = 'In';
            app.Sn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sn_Button.Text = 'Sn';
            app.Sb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sb_Button.Text = 'Sb';
            app.Te_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Te_Button.Text = 'Te';
            app.I_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.I_Button.Text = 'I';
            app.Xe_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Xe_Button.Text = 'Xe';
            app.Cs_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cs_Button.Text = 'Cs';
            app.Ba_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ba_Button.Text = 'Ba';
            app.La_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.La_Button.Text = 'La';
            app.Hf_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Hf_Button.Text = 'Hf';
            app.Ta_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ta_Button.Text = 'Ta';
            app.W_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.W_Button.Text = 'W';
            app.Re_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Re_Button.Text = 'Re';
            app.Os_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Os_Button.Text = 'Os';
            app.Ir_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ir_Button.Text = 'Ir';
            app.Pt_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pt_Button.Text = 'Pt';
            app.Au_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Au_Button.Text = 'Au';
            app.Hg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Hg_Button.Text = 'Hg';
            app.Tl_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tl_Button.Text = 'Tl';
            app.Pb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pb_Button.Text = 'Pb';
            app.Bi_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Bi_Button.Text = 'Bi';
            app.Po_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Po_Button.Text = 'Po';
            app.At_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.At_Button.Text = 'At';
            app.Rn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rn_Button.Text = 'Rn';
            app.Fr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fr_Button.Text = 'Fr';
            app.Ra_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ra_Button.Text = 'Ra';
            app.Ac_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ac_Button.Text = 'Ac';
            app.Rf_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rf_Button.Enable = 'off';
            app.Rf_Button.Text = 'Rf';
            app.Db_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Db_Button.Enable = 'off';
            app.Db_Button.Text = 'Db';
            app.Sg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sg_Button.Enable = 'off';
            app.Sg_Button.Text = 'Sg';
            app.Bh_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Bh_Button.Enable = 'off';
            app.Bh_Button.Text = 'Bh';
            app.Hs_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Hs_Button.Enable = 'off';
            app.Hs_Button.Text = 'Hs';
            app.Mt_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mt_Button.Enable = 'off';
            app.Mt_Button.Text = 'Mt';
            app.Ds_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ds_Button.Enable = 'off';
            app.Ds_Button.Text = 'Ds';
            app.Rg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rg_Button.Enable = 'off';
            app.Rg_Button.Text = 'Rg';
            app.Cn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cn_Button.Enable = 'off';
            app.Cn_Button.Text = 'Cn';
            app.Nh_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Nh_Button.Enable = 'off';
            app.Nh_Button.Text = 'Nh';
            app.Fl_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fl_Button.Enable = 'off';
            app.Fl_Button.Text = 'Fl';
            app.Mc_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mc_Button.Enable = 'off';
            app.Mc_Button.Text = 'Mc';
            app.Lv_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Lv_Button.Enable = 'off';
            app.Lv_Button.Text = 'Lv';
            app.Ts_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ts_Button.Enable = 'off';
            app.Ts_Button.Text = 'Ts';
            app.Og_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Og_Button.Enable = 'off';
            app.Og_Button.Text = 'Og';
            app.Ce_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ce_Button.Text = 'Ce';
            app.Pr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pr_Button.Text = 'Pr';
            app.Nd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Nd_Button.Text = 'Nd';
            app.Pm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pm_Button.Text = 'Pm';
            app.Sm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sm_Button.Text = 'Sm';
            app.Eu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Eu_Button.Text = 'Eu';
            app.Gd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Gd_Button.Text = 'Gd';
            app.Tb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tb_Button.Text = 'Tb';
            app.Dy_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Dy_Button.Text = 'Dy';
            app.Ho_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ho_Button.Text = 'Ho';
            app.Er_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Er_Button.Text = 'Er';
            app.Tm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tm_Button.Text = 'Tm';
            app.Yb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Yb_Button.Text = 'Yb';
            app.Lu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Lu_Button.Text = 'Lu';
            app.Th_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Th_Button.Text = 'Th';
            app.Pa_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pa_Button.Text = 'Pa';
            app.U_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.U_Button.Text = 'U';
            app.Np_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Np_Button.Text = 'Np';
            app.Pu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pu_Button.Text = 'Pu';
            app.Am_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Am_Button.Text = 'Am';
            app.Cm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cm_Button.Text = 'Cm';
            app.Bk_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Bk_Button.Text = 'Bk';
            app.Cf_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cf_Button.Text = 'Cf';
            app.Es_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Es_Button.Enable = 'off';
            app.Es_Button.Text = 'Es';
            app.Fm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fm_Button.Enable = 'off';
            app.Fm_Button.Text = 'Fm';
            app.Md_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Md_Button.Enable = 'off';
            app.Md_Button.Text = 'Md';
            app.No_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.No_Button.Enable = 'off';
            app.No_Button.Text = 'No';
            app.Lr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Lr_Button.Enable = 'off';
            app.Lr_Button.Text = 'Lr';
            app.BE_UITable = uitable(app.GridLayout);
            app.BE_UITable.ColumnName = {'Level'; 'BE (eV)'; 'Relative Sigma'};
            app.BE_UITable.RowName = {};
            app.BE_UITable.ColumnSortable = [false true true];
            app.BE_UITable.Multiselect = 'off';
            app.BE_UITable.Tooltip = {'Table that shows the accesible core-levels, along with their corresponding binding energies (eV) and relative intensities at the given photon energy.'};
            app.XSectParams_Panel = uipanel(app.GridLayout);
            app.XSectParams_Panel.Title = 'Cross-Sections & Asymmetry Parameters';
            app.XSectParams_GridLayout = uigridlayout(app.XSectParams_Panel);
            app.CoreLevelDropDownLabel = uilabel(app.XSectParams_GridLayout);
            app.CoreLevelDropDownLabel.Text = 'Core-Level:';
            app.CoreLevel_DropDown = uidropdown(app.XSectParams_GridLayout);
            app.CoreLevel_DropDown.ValueChangedFcn = createCallbackFcn(app, @CoreLevel_DropDownValueChanged, true);
            app.CoreLevel_DropDown.Tooltip = {'Select a core-level.'};
            app.PhotonEnergyeVEditFieldLabel = uilabel(app.XSectParams_GridLayout);
            app.PhotonEnergyeVEditFieldLabel.Text = 'Photon Energy (eV):';
            app.PhotonEnergy_EditField = uieditfield(app.XSectParams_GridLayout, 'numeric');
            app.PhotonEnergy_EditField.Limits = [0 Inf];
            app.PhotonEnergy_EditField.ValueDisplayFormat = '%.0f';
            app.PhotonEnergy_EditField.ValueChangedFcn = createCallbackFcn(app, @PhotonEnergy_EditFieldValueChanged, true);
            app.PhotonEnergy_EditField.Tooltip = {'Photon energy in eV.'};
            app.PhotonEnergy_EditField.Value = 2000;
            app.SigmabarnatomLabel = uilabel(app.XSectParams_GridLayout);
            app.SigmabarnatomLabel.Text = 'Sigma (barn/atom):';
            app.Sigma_EditField = uieditfield(app.XSectParams_GridLayout, 'numeric');
            app.Sigma_EditField.ValueDisplayFormat = '%.2e';
            app.Sigma_EditField.Editable = 'off';
            app.Sigma_EditField.Tooltip = {'Sigma subshell photoionization cross section in units of barn/atom.'};
            app.PlotCL_Sigma_Button = uibutton(app.XSectParams_GridLayout, 'push');
            app.PlotCL_Sigma_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotCL_Sigma_ButtonPushed, true);
            app.PlotCL_Sigma_Button.Tooltip = {'Plot the sigma subshell photoionization cross section of the selected core-level and element.'};
            app.PlotCL_Sigma_Button.Text = 'Plot CL';
            app.PlotAll_Sigma_Button = uibutton(app.XSectParams_GridLayout, 'push');
            app.PlotAll_Sigma_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotAll_Sigma_ButtonPushed, true);
            app.PlotAll_Sigma_Button.Tooltip = {'Plot the sigma subshell photoionization cross section of all core-levels for the selected element.'};
            app.PlotAll_Sigma_Button.Text = 'Plot All';
            app.SigmabarnatomLabel_2 = uilabel(app.XSectParams_GridLayout);
            app.SigmabarnatomLabel_2.Text = 'Beta:';
            app.Beta_EditField = uieditfield(app.XSectParams_GridLayout, 'numeric');
            app.Beta_EditField.ValueDisplayFormat = '%.2e';
            app.Beta_EditField.Editable = 'off';
            app.Beta_EditField.Tooltip = {'Beta (dipole) parameter of the photoelectron angular distribution.'};
            app.PlotCL_Beta_Button = uibutton(app.XSectParams_GridLayout, 'push');
            app.PlotCL_Beta_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotCL_Beta_ButtonPushed, true);
            app.PlotCL_Beta_Button.Tooltip = {'Plot the beta (dipole) parameter of the photoelectron angular distribution of the selected core-level and element.'};
            app.PlotCL_Beta_Button.Text = 'Plot CL';
            app.PlotAll_Beta_Button = uibutton(app.XSectParams_GridLayout, 'push');
            app.PlotAll_Beta_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotAll_Beta_ButtonPushed, true);
            app.PlotAll_Beta_Button.Tooltip = {'Plot the beta (dipole) parameter of the photoelectron angular distribution of all core-levels for the selected element.'};
            app.PlotAll_Beta_Button.Text = 'Plot All';
            app.SigmabarnatomLabel_3 = uilabel(app.XSectParams_GridLayout);
            app.SigmabarnatomLabel_3.Text = 'Gamma:';
            app.Gamma_EditField = uieditfield(app.XSectParams_GridLayout, 'numeric');
            app.Gamma_EditField.ValueDisplayFormat = '%.2e';
            app.Gamma_EditField.Editable = 'off';
            app.Gamma_EditField.Tooltip = {'Gamma (non-dipole) parameter of the photoelectron angular distribution.'};
            app.PlotCL_Gamma_Button = uibutton(app.XSectParams_GridLayout, 'push');
            app.PlotCL_Gamma_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotCL_Gamma_ButtonPushed, true);
            app.PlotCL_Gamma_Button.Tooltip = {'Plot the gamma (non-dipole) parameter of the photoelectron angular distribution of the selected core-level and element.'};
            app.PlotCL_Gamma_Button.Text = 'Plot CL';
            app.PlotAll_Gamma_Button = uibutton(app.XSectParams_GridLayout, 'push');
            app.PlotAll_Gamma_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotAll_Gamma_ButtonPushed, true);
            app.PlotAll_Gamma_Button.Tooltip = {'Plot the gamma (non-dipole) parameter of the photoelectron angular distribution of all core-levels for the selected element.'};
            app.PlotAll_Gamma_Button.Text = 'Plot All';
            app.SigmabarnatomLabel_4 = uilabel(app.XSectParams_GridLayout);
            app.SigmabarnatomLabel_4.Text = 'Delta:';
            app.Delta_EditField = uieditfield(app.XSectParams_GridLayout, 'numeric');
            app.Delta_EditField.ValueDisplayFormat = '%.2e';
            app.Delta_EditField.Editable = 'off';
            app.Delta_EditField.Tooltip = {'Delta (non-dipole) parameter of the photoelectron angular distribution.'};
            app.PlotCL_Delta_Button = uibutton(app.XSectParams_GridLayout, 'push');
            app.PlotCL_Delta_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotCL_Delta_ButtonPushed, true);
            app.PlotCL_Delta_Button.Tooltip = {'Plot the delta (non-dipole) parameter of the photoelectron angular distribution of the selected core-level and element.'};
            app.PlotCL_Delta_Button.Text = 'Plot CL';
            app.PlotAll_Delta_Button = uibutton(app.XSectParams_GridLayout, 'push');
            app.PlotAll_Delta_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotAll_Delta_ButtonPushed, true);
            app.PlotAll_Delta_Button.Tooltip = {'Plot the delta (non-dipole) parameter of the photoelectron angular distribution of all core-levels for the selected element.'};
            app.PlotAll_Delta_Button.Text = 'Plot All';
            app.FormalismDropDownLabel = uilabel(app.GridLayout);
            app.FormalismDropDownLabel.Text = 'Formalism:';
            app.Formalism_DropDown = uidropdown(app.GridLayout);
            app.Formalism_DropDown.Items = {'Cant(2022) - 1500 to 10000 eV', 'Trzhaskovskaya(2018) - 1500 to 10000 eV', 'Yeh Lindau(1985) - 10 to 1500 eV', 'Scofield(1973) - 1 to 1500 keV'};
            app.Formalism_DropDown.ValueChangedFcn = createCallbackFcn(app, @Formalism_DropDownValueChanged, true);
            app.Formalism_DropDown.Tooltip = {'Select the formalism to be used to calculate the photoionisation cross-section and asymmetry parameter. For more information, see references:'; '[1] Scofield, J H. Theoretical photoionization cross sections from 1 to 1500 keV.. United States: N. p., 1973. Web. doi:10.2172/4545040.'; '[2] J.J. Yeh, I. Lindau. ATOMIC DATA AND NUCLEAR DATA TABLES 32, l-l 55 (1985). Web. doi:10.1016/0092-640X(85)90016-6'; '[3] M.B. Trzhaskovskaya, V.G. Yarzhemsky. Atomic Data and Nuclear Data Tables 119 (2018) 99–174. Web. doi:10.1016/j.adt.2017.04.003'; '[4] David J. H. Cant, Ben F. Spencer, Wendy R. Flavell, Alexander G. Shard. Surf Interface Anal. 2022; 54(4): 442-454. doi:10.1002/sia.7059'};
            app.Formalism_DropDown.Value = 'Cant(2022) - 1500 to 10000 eV';
            app.ExportXSect_Panel = uipanel(app.GridLayout);
            app.ExportXSect_Panel.Title = 'Cross-Sections : Export Data Range';
            app.ExportXSect_GridLayout = uigridlayout(app.ExportXSect_Panel);
            app.MineVEditFieldLabel = uilabel(app.ExportXSect_GridLayout);
            app.MineVEditFieldLabel.Text = 'Min (eV):';
            app.MinEph_EditField = uieditfield(app.ExportXSect_GridLayout, 'numeric');
            app.MinEph_EditField.Limits = [0 Inf];
            app.MinEph_EditField.ValueDisplayFormat = '%.0f';
            app.MinEph_EditField.ValueChangedFcn = createCallbackFcn(app, @MinEph_EditFieldValueChanged, true);
            app.MinEph_EditField.Tooltip = {'Enter the minimum photon energy in units of eV'};
            app.MinEph_EditField.Value = 1500;
            app.PlotData_XSect_Button = uibutton(app.ExportXSect_GridLayout, 'push');
            app.PlotData_XSect_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotData_XSect_ButtonPushed, true);
            app.PlotData_XSect_Button.Tooltip = {'Plot of the cross-section & asymmetry parameters of the selected core-level and element.'};
            app.PlotData_XSect_Button.Text = 'Plot XSect Data';
            app.MaxeVEditFieldLabel = uilabel(app.ExportXSect_GridLayout);
            app.MaxeVEditFieldLabel.Text = 'Max (eV):';
            app.MaxEph_EditField = uieditfield(app.ExportXSect_GridLayout, 'numeric');
            app.MaxEph_EditField.Limits = [0 1500000];
            app.MaxEph_EditField.ValueDisplayFormat = '%.0f';
            app.MaxEph_EditField.ValueChangedFcn = createCallbackFcn(app, @MaxEph_EditFieldValueChanged, true);
            app.MaxEph_EditField.Tooltip = {'Enter the maximum photon energy in units of eV'};
            app.MaxEph_EditField.Value = 10000;
            app.ExportData_XSect_Button = uibutton(app.ExportXSect_GridLayout, 'push');
            app.ExportData_XSect_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportData_XSect_ButtonPushed, true);
            app.ExportData_XSect_Button.Tooltip = {'Export data as a text-file (.txt) of the cross-section & asymmetry parameters of the selected core-level and element.'};
            app.ExportData_XSect_Button.Text = 'Export XSect Data (.txt)';
            app.ExportEbeSpec_Panel = uipanel(app.GridLayout);
            app.ExportEbeSpec_Panel.Title = 'Photoionization Energies';
            app.ExportEbeSpec_GridLayout = uigridlayout(app.ExportEbeSpec_Panel);
            app.PlotData_EbeSpec_Button = uibutton(app.ExportEbeSpec_GridLayout, 'push');
            app.PlotData_EbeSpec_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotData_EbeSpec_ButtonPushed, true);
            app.PlotData_EbeSpec_Button.Tooltip = {'Plot the binding energies of all core-levels for the selected element.'};
            app.PlotData_EbeSpec_Button.Text = 'Plot Binding Energy Spectrum';
            app.ExportData_EbeSpec_Button = uibutton(app.ExportEbeSpec_GridLayout, 'push');
            app.ExportData_EbeSpec_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportData_EbeSpec_ButtonPushed, true);
            app.ExportData_EbeSpec_Button.Tooltip = {'Export data as a text-file (.txt) of the binding energies of all core-levels for the selected element.'};
            app.ExportData_EbeSpec_Button.Text = 'Export Binding Energy Data (.txt)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_MatBase_Photoionization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end