classdef App_MatBase_XPSSensitivityFactor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        IMFPParams_Panel                matlab.ui.container.Panel
        IMFPParams_GridLayout           matlab.ui.container.GridLayout
        IMFP_EditField                  matlab.ui.control.NumericEditField
        CalculatedIMFPnmLabel           matlab.ui.control.Label
        Ek_EditField                    matlab.ui.control.NumericEditField
        SigmabarnatomLabel_5            matlab.ui.control.Label
        FormalismIMFP_DropDown          matlab.ui.control.DropDown
        IMFPFormalismDropDownLabel      matlab.ui.control.Label
        ExportSF_Panel                  matlab.ui.container.Panel
        ExportSF_GridLayout             matlab.ui.container.GridLayout
        Theta_Panel                     matlab.ui.container.Panel
        ExportTheta_GridLayout          matlab.ui.container.GridLayout
        ExportDataTheta_SF_Button       matlab.ui.control.Button
        PlotDataTheta_SF_Button         matlab.ui.control.Button
        MinTheta_EditField              matlab.ui.control.NumericEditField
        MindegLabel                     matlab.ui.control.Label
        MaxTheta_EditField              matlab.ui.control.NumericEditField
        MaxdegLabel                     matlab.ui.control.Label
        PhotonEnergy_Panel              matlab.ui.container.Panel
        ExportPhotonEnergy_GridLayout   matlab.ui.container.GridLayout
        ExportDataPhotonEnergy_SF_Button  matlab.ui.control.Button
        PlotDataPhotonEnergy_SF_Button  matlab.ui.control.Button
        MinEph_EditField                matlab.ui.control.NumericEditField
        MineVEditFieldLabel             matlab.ui.control.Label
        MaxEph_EditField                matlab.ui.control.NumericEditField
        MaxeVEditFieldLabel             matlab.ui.control.Label
        SF_EditField                    matlab.ui.control.NumericEditField
        SigmabarnatomLabel_6            matlab.ui.control.Label
        XSectParams_Panel               matlab.ui.container.Panel
        XSectParams_GridLayout          matlab.ui.container.GridLayout
        Delta_EditField                 matlab.ui.control.NumericEditField
        SigmabarnatomLabel_4            matlab.ui.control.Label
        Gamma_EditField                 matlab.ui.control.NumericEditField
        SigmabarnatomLabel_3            matlab.ui.control.Label
        Beta_EditField                  matlab.ui.control.NumericEditField
        SigmabarnatomLabel_2            matlab.ui.control.Label
        Sigma_EditField                 matlab.ui.control.NumericEditField
        SigmabarnatomLabel              matlab.ui.control.Label
        PhotonEnergy_EditField          matlab.ui.control.NumericEditField
        PhotonEnergyeVEditFieldLabel    matlab.ui.control.Label
        CoreLevel_DropDown              matlab.ui.control.DropDown
        CoreLevelDropDownLabel          matlab.ui.control.Label
        Formalism_XSect_DropDown        matlab.ui.control.DropDown
        XSectFormalismLabel             matlab.ui.control.Label
        Geometry_Panel                  matlab.ui.container.Panel
        Geometry_GridLayout             matlab.ui.container.GridLayout
        P_EditField                     matlab.ui.control.NumericEditField
        PEditFieldLabel                 matlab.ui.control.Label
        omega_EditField                 matlab.ui.control.NumericEditField
        phidegLabel_2                   matlab.ui.control.Label
        phi_EditField                   matlab.ui.control.NumericEditField
        phidegLabel                     matlab.ui.control.Label
        theta_EditField                 matlab.ui.control.NumericEditField
        phidegLabel_3                   matlab.ui.control.Label
        Geometry_Label                  matlab.ui.control.Label
        Geometry_Image                  matlab.ui.control.Image
        PeriodicTableSingle_ButtonGroup  matlab.ui.container.ButtonGroup
        SF1FLabel                       matlab.ui.control.Label
        Lr_Button                       matlab.ui.control.ToggleButton
        No_Button                       matlab.ui.control.ToggleButton
        Md_Button                       matlab.ui.control.ToggleButton
        Fm_Button                       matlab.ui.control.ToggleButton
        Es_Button                       matlab.ui.control.ToggleButton
        Cf_Button                       matlab.ui.control.ToggleButton
        Bk_Button                       matlab.ui.control.ToggleButton
        Cm_Button                       matlab.ui.control.ToggleButton
        Am_Button                       matlab.ui.control.ToggleButton
        Pu_Button                       matlab.ui.control.ToggleButton
        Np_Button                       matlab.ui.control.ToggleButton
        U_Button                        matlab.ui.control.ToggleButton
        Pa_Button                       matlab.ui.control.ToggleButton
        Th_Button                       matlab.ui.control.ToggleButton
        Lu_Button                       matlab.ui.control.ToggleButton
        Yb_Button                       matlab.ui.control.ToggleButton
        Tm_Button                       matlab.ui.control.ToggleButton
        Er_Button                       matlab.ui.control.ToggleButton
        Ho_Button                       matlab.ui.control.ToggleButton
        Dy_Button                       matlab.ui.control.ToggleButton
        Tb_Button                       matlab.ui.control.ToggleButton
        Gd_Button                       matlab.ui.control.ToggleButton
        Eu_Button                       matlab.ui.control.ToggleButton
        Sm_Button                       matlab.ui.control.ToggleButton
        Pm_Button                       matlab.ui.control.ToggleButton
        Nd_Button                       matlab.ui.control.ToggleButton
        Pr_Button                       matlab.ui.control.ToggleButton
        Ce_Button                       matlab.ui.control.ToggleButton
        Og_Button                       matlab.ui.control.ToggleButton
        Ts_Button                       matlab.ui.control.ToggleButton
        Lv_Button                       matlab.ui.control.ToggleButton
        Mc_Button                       matlab.ui.control.ToggleButton
        Fl_Button                       matlab.ui.control.ToggleButton
        Nh_Button                       matlab.ui.control.ToggleButton
        Cn_Button                       matlab.ui.control.ToggleButton
        Rg_Button                       matlab.ui.control.ToggleButton
        Ds_Button                       matlab.ui.control.ToggleButton
        Mt_Button                       matlab.ui.control.ToggleButton
        Hs_Button                       matlab.ui.control.ToggleButton
        Bh_Button                       matlab.ui.control.ToggleButton
        Sg_Button                       matlab.ui.control.ToggleButton
        Db_Button                       matlab.ui.control.ToggleButton
        Rf_Button                       matlab.ui.control.ToggleButton
        Ac_Button                       matlab.ui.control.ToggleButton
        Ra_Button                       matlab.ui.control.ToggleButton
        Fr_Button                       matlab.ui.control.ToggleButton
        Rn_Button                       matlab.ui.control.ToggleButton
        At_Button                       matlab.ui.control.ToggleButton
        Po_Button                       matlab.ui.control.ToggleButton
        Bi_Button                       matlab.ui.control.ToggleButton
        Pb_Button                       matlab.ui.control.ToggleButton
        Tl_Button                       matlab.ui.control.ToggleButton
        Hg_Button                       matlab.ui.control.ToggleButton
        Au_Button                       matlab.ui.control.ToggleButton
        Pt_Button                       matlab.ui.control.ToggleButton
        Ir_Button                       matlab.ui.control.ToggleButton
        Os_Button                       matlab.ui.control.ToggleButton
        Re_Button                       matlab.ui.control.ToggleButton
        W_Button                        matlab.ui.control.ToggleButton
        Ta_Button                       matlab.ui.control.ToggleButton
        Hf_Button                       matlab.ui.control.ToggleButton
        La_Button                       matlab.ui.control.ToggleButton
        Ba_Button                       matlab.ui.control.ToggleButton
        Cs_Button                       matlab.ui.control.ToggleButton
        Xe_Button                       matlab.ui.control.ToggleButton
        I_Button                        matlab.ui.control.ToggleButton
        Te_Button                       matlab.ui.control.ToggleButton
        Sb_Button                       matlab.ui.control.ToggleButton
        Sn_Button                       matlab.ui.control.ToggleButton
        In_Button                       matlab.ui.control.ToggleButton
        Cd_Button                       matlab.ui.control.ToggleButton
        Ag_Button                       matlab.ui.control.ToggleButton
        Pd_Button                       matlab.ui.control.ToggleButton
        Rh_Button                       matlab.ui.control.ToggleButton
        Ru_Button                       matlab.ui.control.ToggleButton
        Tc_Button                       matlab.ui.control.ToggleButton
        Mo_Button                       matlab.ui.control.ToggleButton
        Nb_Button                       matlab.ui.control.ToggleButton
        Zr_Button                       matlab.ui.control.ToggleButton
        Y_Button                        matlab.ui.control.ToggleButton
        Sr_Button                       matlab.ui.control.ToggleButton
        Rb_Button                       matlab.ui.control.ToggleButton
        Kr_Button                       matlab.ui.control.ToggleButton
        Br_Button                       matlab.ui.control.ToggleButton
        Se_Button                       matlab.ui.control.ToggleButton
        As_Button                       matlab.ui.control.ToggleButton
        Ge_Button                       matlab.ui.control.ToggleButton
        Ga_Button                       matlab.ui.control.ToggleButton
        Zn_Button                       matlab.ui.control.ToggleButton
        Cu_Button                       matlab.ui.control.ToggleButton
        Ni_Button                       matlab.ui.control.ToggleButton
        Co_Button                       matlab.ui.control.ToggleButton
        Fe_Button                       matlab.ui.control.ToggleButton
        Mn_Button                       matlab.ui.control.ToggleButton
        Cr_Button                       matlab.ui.control.ToggleButton
        V_Button                        matlab.ui.control.ToggleButton
        Ti_Button                       matlab.ui.control.ToggleButton
        Sc_Button                       matlab.ui.control.ToggleButton
        Ca_Button                       matlab.ui.control.ToggleButton
        K_Button                        matlab.ui.control.ToggleButton
        Ar_Button                       matlab.ui.control.ToggleButton
        Cl_Button                       matlab.ui.control.ToggleButton
        S_Button                        matlab.ui.control.ToggleButton
        P_Button                        matlab.ui.control.ToggleButton
        Si_Button                       matlab.ui.control.ToggleButton
        Al_Button                       matlab.ui.control.ToggleButton
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Mg_Button                       matlab.ui.control.ToggleButton
        Na_Button                       matlab.ui.control.ToggleButton
        Ne_Button                       matlab.ui.control.ToggleButton
        F_Button                        matlab.ui.control.ToggleButton
        O_Button                        matlab.ui.control.ToggleButton
        N_Button                        matlab.ui.control.ToggleButton
        C_Button                        matlab.ui.control.ToggleButton
        B_Button                        matlab.ui.control.ToggleButton
        Be_Button                       matlab.ui.control.ToggleButton
        Li_Button                       matlab.ui.control.ToggleButton
        He_Button                       matlab.ui.control.ToggleButton
        Label_17                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        H_Button                        matlab.ui.control.ToggleButton
        Label_18                        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Material_EditField              matlab.ui.control.EditField
        ElementLabel                    matlab.ui.control.Label
        AppButtons_GridLayout           matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        SAVE_Button                     matlab.ui.control.Button
        TRANSFER_Button                 matlab.ui.control.Button
        APPINFO_Button                  matlab.ui.control.Button
        RESIZEUI_Button                 matlab.ui.control.Button
        RESTART_Button                  matlab.ui.control.Button
        CLOSEFIGS_Button                matlab.ui.control.Button
        AppTitle                        matlab.ui.control.Label
        MAINMENU_GridLayout             matlab.ui.container.GridLayout
        MAINMENU_Button                 matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % - MATLAB data structure to summarise all data for analysis
        sfData
        % - Variables for the app
        % --- Element selected
        element         % single selected element
        % --- Experimental Geometry
        theta           % scalar of the polar angle between the photoelectron vector relative to electric field vector (i.e. at normal emission: LV (p-pol, E//MP) = 0, LH (s-pol, E⊥MP) = 90) [degree]
        phi             % scalar of the azimuthal angle between the photon momentum vector relative to the projection of the photoelectron vector on to the plane perpendicular to the electric field vector (i.e. normal emission = 0) [degree]
        omega           % scalar of the angle between the monochromator-sampe-lens
        P               % scalar of degree of polarization, where 1 or 0 is equivalent to full linear polarization, and 0.5 is equivalent to unpolarized light.
        % --- IMFP parameters 
        formalism_imfp  % string for imfp calculator formalism. Default:"TPP2M" ["Universal","TPP2M","TPP2M-avg","Optical","S1","S2"]
        ele_kin         % electron kinetic energy (for PES; KE = BE - PHI) (eV)
        ele_imfp        % electron IMFP values (Angstrom)
        % --- Cross-section parameters
        formalism_xsect % string of the photoionization cross-section formalism to use. Default:"Cant2022" ["Scofield1973","YehLindau1985","Trzhaskovskaya2018","Cant2022"]
        clevel          % core-level selected
        eph             % photon energy defined by user
        sigma           % Subshell photoionization cross section in units of barn/atom
        beta            % Beta (dipole) parameter of the photoelectron angular distribution
        gamma           % Gamma (non-dipole) parameter of the photoelectron angular distribution
        delta           % Delta (non-dipole) parameter of the photoelectron angular distribution
        % --- Sensitivity Factor
        theta_min       % minimum emission angle (deg.)
        theta_max       % maximum emission angle (deg.)
        eph_min         % minimum photon energy (eV)
        eph_max         % maximum photon energy (eV)
        sf              % sensitivity factor
        sfParams        % parameters used to derive the sensitivity factor
    end
    
    % Private methods available to the app only
    methods (Access = private)
        % Code that executes to calculate the sensitivity factor
        function update_values(app)
            % 1 - Extracting the Sensitivity Factor (SF) based on the inputs
            [app.sf, app.sfParams] = calc_sf(app.element, app.clevel, app.element, app.eph, app.theta, app.phi, app.P, app.formalism_xsect, app.formalism_imfp);
            % 2 - Assigning all the values to the app
            app.sigma       = app.sfParams.sigma;
            app.beta        = app.sfParams.beta;
            app.gamma       = app.sfParams.gamma;
            app.delta       = app.sfParams.delta;
            app.ele_kin     = app.sfParams.ke;
            app.ele_imfp    = app.sfParams.imfp;
            % 2 - Update the edit-fields to display values
            app.Sigma_EditField.Value   = app.sigma;
            app.Beta_EditField.Value    = app.beta;
            app.Gamma_EditField.Value   = app.gamma;
            app.Delta_EditField.Value   = app.delta;
            app.Ek_EditField.Value      = app.ele_kin;
            app.IMFP_EditField.Value    = app.ele_imfp;
            app.SF_EditField.Value      = app.sf;
        end

        % Extract the data structure
        function sfData = extract_myData(app)
            % 1 - Sensitivity Factor at the given photon energy
            [sfData.sf, sfData.sfParams] = calc_sf(app.element, app.clevel, app.element, app.eph, app.theta, app.phi, app.P, app.formalism_xsect, app.formalism_imfp);
            % 2 - Sensitivity Factor versus photon energy
            sfData.eph_R = app.eph_min:1:app.eph_max; sfData.eph_R = sfData.eph_R';
            [sfData.sf_vs_hv, sfData.sfParams_vs_hv] = calc_sf(app.element, app.clevel, app.element, sfData.eph_R, app.theta, app.phi, app.P, app.formalism_xsect, app.formalism_imfp);
            % 3 - Sensitivity Factor versus emission angle
            sfData.theta_R = app.theta_min:1:app.theta_max;
            [sfData.sf_vs_theta, sfData.sfParams_vs_theta] = calc_sf(app.element, app.clevel, app.element, app.eph, sfData.theta_R, app.phi, app.P, app.formalism_xsect, app.formalism_imfp);
        end

        % Plot sensitivity factor versus photon energy
        function plot_sf_vs_hv(app)
            fig = figure(); 
            fig.Position(1) = 100; fig.Position(2) = 100;
            fig.Position(3) = 550; 
            fig.Position(4) = 400;
            hold on; grid on; grid minor;
            plot(app.sfData.eph_R, app.sfData.sf_vs_hv, '-', 'LineWidth', 2);
            txt_leg = sprintf("%s%s",app.sfData.sfParams.element, app.sfData.sfParams.corelevel);
            legend(txt_leg, 'fontsize', 9, 'location', 'northeast');
            % -- Adding annotation with the experimental variables
            txt_str = sprintf("theta = %.2f eV. \nphi = %.2f deg. \nP = %.2f", app.theta, app.phi, app.P);
            hAnnotation = annotation('textbox',[0.15, 0.17, 0.1, 0.1],'string', txt_str, 'FontSize', 9, 'BackgroundColor','w');
            % - Labelling the x- and y-axes
            xlabel(' Photon Energy [eV] ', 'FontWeight','bold');
            ylabel(' Sensitivity Factor ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'log'; ax.XScale = 'linear';
            axis([...
                0.90*min(app.sfData.eph_R(:)), 1.10*max(app.sfData.eph_R(:)),...
                0.90*min(app.sfData.sf_vs_hv(:)), 1.1*max(app.sfData.sf_vs_hv(:))]);
        end

        % Plot sensitivity factor versus theta
        function plot_sf_vs_theta(app)
            fig = figure(); 
            fig.Position(1) = 100; fig.Position(2) = 100;
            fig.Position(3) = 550; 
            fig.Position(4) = 400;
            hold on; grid on; grid minor;
            plot(app.sfData.theta_R, app.sfData.sf_vs_theta, '-', 'LineWidth', 2);
            txt_leg = sprintf("%s%s",app.sfData.sfParams.element, app.sfData.sfParams.corelevel);
            legend(txt_leg, 'fontsize', 9, 'location', 'northeast');
            % -- Adding annotation with the experimental variables
            txt_str = sprintf("hv = %.2f eV. \nphi = %.2f deg. \nP = %.2f", app.eph, app.phi, app.P);
            hAnnotation = annotation('textbox',[0.15, 0.17, 0.1, 0.1],'string', txt_str, 'FontSize', 9, 'BackgroundColor','w');
            % - Labelling the x- and y-axes
            xlabel(' Emission Angle [degree] ', 'FontWeight','bold');
            ylabel(' Sensitivity Factor ', 'FontWeight','bold');
            ax = gca; ax.YScale = 'linear'; ax.XScale = 'linear';
            axis([...
                0, 90,...
                0.90*min(app.sfData.sf_vs_theta(:)), 1.1*max(app.sfData.sf_vs_theta(:))]);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MatBase - XPS Sensitivity Factor App (procopios.constantinou@psi.ch)';
            % 1 - Default parameters upon startup
            app.sfData      = struct();
            % --- Element selected
            app.element     = app.Material_EditField.Value;
            % --- Experimental Geometry
            app.theta       = app.theta_EditField.Value;
            app.phi         = app.phi_EditField.Value;
            app.omega       = app.omega_EditField.Value;
            app.P           = app.P_EditField.Value;
            % --- IMFP parameters 
            app.formalism_imfp  = "JTP";
            % --- Cross-section parameters
            app.formalism_xsect = "C2022";
            app.clevel          = "3p1";
            app.eph             = app.PhotonEnergy_EditField.Value;
            % --- Sensitivity Factor
            app.theta_min       = app.MinTheta_EditField.Value;
            app.theta_max       = app.MaxTheta_EditField.Value;
            app.eph_min         = app.MinEph_EditField.Value;
            app.eph_max         = app.MaxEph_EditField.Value;
            % -- Initialising fields
            PeriodicTableSingle_ButtonGroupSelectionChanged(app);
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run App_MatBase;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
           % Remove the UI handle and restart
            close(gcbf); run App_MatBase_XPSSensitivityFactor;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 970;
            screen_size(4) = 540;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            app.sfData  = extract_myData(app);
            disp('sfData : '); disp(app.sfData);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            app.sfData  = extract_myData(app);
            assignin('base', 'sfData', app.sfData);
        end

        % Button pushed function: SAVE_Button
        function SAVE_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            fname = sprintf("%s_%s_SFs", app.element, app.clevel);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            app.sfData = extract_myData(app);
            dataStr = app.sfData; 
            save(char(save_fullfile), 'dataStr', '-v7.3');
            disp('-> saved data : '); display(app.sfData);
            %% 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Selection changed function: PeriodicTableSingle_ButtonGroup
        function PeriodicTableSingle_ButtonGroupSelectionChanged(app, event)
            % 1 - Extracting the input defined by the user
            selectedButton  = app.PeriodicTableSingle_ButtonGroup.SelectedObject;
            app.element     = selectedButton.Text;
            fprintf("--> Element: " + string(app.element) + "\n");
            % 2 - Updating the relevant UI components
            app.Material_EditField.Value = app.element;
            % 3 - Update the list of core-levels that can be chosen
            [be, cls] = calc_be(app.element, [], [], 0);
            [be, indx] = sort(be); cls = cls(indx);
            for i = 1:length(be); cl_labels(i) = sprintf("%s-%.2feV",cls(i),be(i)); end
            app.CoreLevel_DropDown.Items = cl_labels;
            CoreLevel_DropDownValueChanged(app);
            % 4 - Update the values
            update_values(app);
        end

        % Value changed function: theta_EditField
        function theta_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.theta_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value); value = 0;
            elseif value > 90; value = 90;
            end
            % 3 - Assigning the output
            app.theta = value;
            fprintf("--> theta: " + string(app.theta) + " deg.\n");
            % 4 - Update the values
            update_values(app);
        end

        % Value changed function: phi_EditField
        function phi_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.phi_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value); value = 0;
            elseif value > 180; value = 180;
            end
            % 3 - Assigning the output
            app.phi = value;
            fprintf("--> phi: " + string(app.phi) + " deg.\n");
            % 4 - Update the values
            update_values(app);
        end

        % Value changed function: omega_EditField
        function omega_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.omega_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value); value = 90;
            elseif value > 90; value = 90;
            end
            % 3 - Assigning the output
            app.omega = value;
            fprintf("--> omega: " + string(app.omega) + " deg.\n");
            % 4 - Update the values
            update_values(app);
        end

        % Value changed function: P_EditField
        function P_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.P_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value) || value < 0; value = 0;
            elseif value > 1; value = 1;
            end
            % 3 - Assigning the output
            app.P = value;
            fprintf("--> P: " + string(app.P) + "\n");
            % 4 - Update the values
            update_values(app);
        end

        % Value changed function: FormalismIMFP_DropDown
        function FormalismIMFP_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.FormalismIMFP_DropDown.Value;
            value = string(value);
            % 2 - Validity check on the material
            if strcmpi(value,"Universal");              value = "Universal";
            elseif strcmpi(value,"Optical(NIST)");      value = "NIST";
            elseif strcmpi(value,"TPP-2M");             value = "TPP2M";
            elseif strcmpi(value,"TPP-2M(avg.)");       value = "TPP2M-avg";
            elseif strcmpi(value,"S1");                 value = "S1";
            elseif strcmpi(value,"S2");                 value = "S2";
            elseif strcmpi(value,"S3");                 value = "S3";
            elseif strcmpi(value,"S4");                 value = "S4";
            elseif strcmpi(value,"JTP");                value = "JTP";
            end
            % 4 - Assigning the output
            app.formalism_imfp = value;
            fprintf("--> imfp formalism: " + string(app.formalism_imfp) + "\n");
            % 5 - Update the values
            update_values(app);
        end

        % Value changed function: Formalism_XSect_DropDown
        function Formalism_XSect_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Formalism_XSect_DropDown.Value;
            value = string(value);
            % 2 - Validity check on the material
            if strcmpi(value,"Scofield(1973) - 1 to 1500 keV");                 value = "S1973";
            elseif strcmpi(value,"Yeh Lindau(1985) - 10 to 1500 eV");           value = "YL1985";
            elseif strcmpi(value,"Trzhaskovskaya(2018) - 1500 to 10000 eV");    value = "T2018";
            elseif strcmpi(value,"Cant(2022) - 1500 to 10000 eV");              value = "C2022";
            end
            % 4 - Assigning the output
            app.formalism_xsect = value;
            fprintf("--> xsect formalism: " + string(app.formalism_xsect) + "\n");
            % 5 - Update the values
            update_values(app);
        end

        % Value changed function: CoreLevel_DropDown
        function CoreLevel_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.CoreLevel_DropDown.Value;
            value = string(value(1:3));
            % 2 - Validity check on the input
            % 3 - Assigning the output
            app.clevel = value;
            fprintf("--> Core-Level: " + string(app.clevel) + "\n");
            % 5 - Validity check on the photon energy
            [be, ~] = calc_be(app.element, app.clevel, [], 0);
            if app.eph < be
                app.eph = be;
                app.PhotonEnergy_EditField.Value = app.eph;
            end
            if app.eph_min < be
                app.eph_min = be;
                app.MinEph_EditField.Value = app.eph;
            end
            if app.eph_max < be
                app.eph_max = be;
                app.MaxEph_EditField.Value = app.eph;
            end
            % 4 - Calculating sigma
            update_values(app);
        end

        % Value changed function: PhotonEnergy_EditField
        function PhotonEnergy_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.PhotonEnergy_EditField.Value;
            % 2 - Validity check on the input value
            [be, ~] = calc_be(app.element, app.clevel, [], 0);
            if isempty(value) || isnan(value) || value < be
                value = be;
            end
            app.eph = value;
            app.PhotonEnergy_EditField.Value = app.eph;
            % 2 - Assigning the output
            fprintf("--> Photon Energy: " + string(app.eph) + " eV\n");
            % 3 - Calculating sigma
            update_values(app);
        end

        % Value changed function: MinEph_EditField
        function MinEph_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.MinEph_EditField.Value;
            % 2 - Validity check on the input value
            [be, ~] = calc_be(app.element, app.clevel, [], 0);
            if isempty(value) || isnan(value) || value < be
                value = be;
            end
            if value > app.eph_max; value = app.eph_max; end
            % 3 - Validity check on the input value
            app.eph_min = value;
            app.MinEph_EditField.Value = app.eph_min;
        end

        % Value changed function: MaxEph_EditField
        function MaxEph_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.MaxEph_EditField.Value;
            % 2 - Validity check on the input value
            [be, ~] = calc_be(app.element, app.clevel, [], 0);
            if isempty(value) || isnan(value) || value < be
                value = be;
            end
            if value < app.eph_min; value = app.eph_min; end
            % 3 - Validity check on the input value
            app.eph_max = value;
            app.MaxEph_EditField.Value     = app.eph_max;
        end

        % Value changed function: MinTheta_EditField
        function MinTheta_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.MinTheta_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value)
                value = 0;
            elseif value > app.theta_max
                value = app.theta_max;
            end
            % 3 - Validity check on the input value
            app.theta_min = value;
            app.MinTheta_EditField.Value = app.theta_min;
        end

        % Value changed function: MaxTheta_EditField
        function MaxTheta_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.MaxTheta_EditField.Value;
            % 2 - Validity check on the input value
            if isempty(value) || isnan(value)
                value = 90;
            elseif value < app.theta_min
                value = app.theta_min;
            end
            % 3 - Validity check on the input value
            app.theta_max = value;
            app.MaxTheta_EditField.Value     = app.theta_max;
        end

        % Button pushed function: PlotDataPhotonEnergy_SF_Button
        function PlotDataPhotonEnergy_SF_ButtonPushed(app, event)
            app.sfData = extract_myData(app);
            plot_sf_vs_hv(app);
        end

        % Button pushed function: ExportDataPhotonEnergy_SF_Button
        function ExportDataPhotonEnergy_SF_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.txt'};
            fname = sprintf("%s%s_SF_vs_hv", app.element, app.clevel);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            app.sfData = extract_myData(app);
            T_Data = table(app.sfData.eph_R, app.sfData.sf_vs_hv);
            T_Data.Properties.VariableNames = {'Eph_eV', char(sprintf("SF_%s%s", app.element, app.clevel))};
            writetable(T_Data, char(string(save_fullfile(1:end-4)) + ".txt"),'Delimiter','\t');
            %% 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Button pushed function: PlotDataTheta_SF_Button
        function PlotDataTheta_SF_ButtonPushed(app, event)
            app.sfData = extract_myData(app);
            plot_sf_vs_theta(app);
        end

        % Button pushed function: ExportDataTheta_SF_Button
        function ExportDataTheta_SF_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.txt'};
            fname = sprintf("%s%s_SF_vs_theta", app.element, app.clevel);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            app.sfData = extract_myData(app);
            T_Data = table(app.sfData.theta_R', app.sfData.sf_vs_theta');
            T_Data.Properties.VariableNames = {'Theta_degree', char(sprintf("SF_%s%s", app.element, app.clevel))};
            writetable(T_Data, char(string(save_fullfile(1:end-4)) + ".txt"),'Delimiter','\t');
            %% 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'XPS Sensitivity Factor Calculator';
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.SAVE_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.SAVE_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVE_ButtonPushed, true);
            app.SAVE_Button.Tooltip = {'Save the data from the app as a MATLAB data structure file.'};
            app.SAVE_Button.Text = 'Save As... (.mat)';
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Enhance your quantitative analysis with our app designed to calculate the XPS Sensitivity Factor - a crucial metric in analysis. It integrates the IMFP, photoionization cross-sections, and asymmetry databases. Customize your setup by specifying the experimental geometry, photon polarization, and the formalisms for both IMFP and cross-section determination. Select your desired core-level, and with a simple click, generate plots of the sensitivity factor against photon energy and emission angle. This tool is indispensable for precise, quantitative XPS analysis.'};
            app.Image.ImageSource = 'Icon_SensitivityFactorXPS.jpeg';
            app.ElementLabel = uilabel(app.GridLayout);
            app.ElementLabel.Text = 'Element:';
            app.Material_EditField = uieditfield(app.GridLayout, 'text');
            app.Material_EditField.Editable = 'off';
            app.Material_EditField.Tooltip = {'Selected element from the Periodic Table.'};
            app.Material_EditField.Value = 'Si';
            app.PeriodicTableSingle_ButtonGroup = uibuttongroup(app.GridLayout);
            app.PeriodicTableSingle_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PeriodicTableSingle_ButtonGroupSelectionChanged, true);
            app.PeriodicTableSingle_ButtonGroup.Tooltip = {'Select an element.'};
            app.Label = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label.Text = '1';
            app.Label_18 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_18.Text = '18';
            app.H_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.H_Button.Text = 'H';
            app.Label_2 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_2.Text = '2';
            app.Label_13 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_13.Text = '13';
            app.Label_14 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_14.Text = '14';
            app.Label_15 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_15.Text = '15';
            app.Label_16 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_16.Text = '16';
            app.Label_17 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_17.Text = '17';
            app.He_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.He_Button.Text = 'He';
            app.Li_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Li_Button.Text = 'Li';
            app.Be_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Be_Button.Text = 'Be';
            app.B_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.B_Button.Text = 'B';
            app.C_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.C_Button.Text = 'C';
            app.N_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.N_Button.Text = 'N';
            app.O_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.O_Button.Text = 'O';
            app.F_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.F_Button.Text = 'F';
            app.Ne_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ne_Button.Text = 'Ne';
            app.Na_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Na_Button.Text = 'Na';
            app.Mg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mg_Button.Text = 'Mg';
            app.Label_3 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_8.Text = '8';
            app.Label_9 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_9.Text = '9';
            app.Label_10 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_10.Text = '10';
            app.Label_11 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_11.Text = '11';
            app.Label_12 = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.Label_12.Text = '12';
            app.Al_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Al_Button.Text = 'Al';
            app.Si_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Si_Button.Text = 'Si';
            app.Si_Button.Value = true;
            app.P_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.P_Button.Text = 'P';
            app.S_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.S_Button.Text = 'S';
            app.Cl_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cl_Button.Text = 'Cl';
            app.Ar_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ar_Button.Text = 'Ar';
            app.K_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.K_Button.Text = 'K';
            app.Ca_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ca_Button.Text = 'Ca';
            app.Sc_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sc_Button.Text = 'Sc';
            app.Ti_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ti_Button.Text = 'Ti';
            app.V_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.V_Button.Text = 'V';
            app.Cr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cr_Button.Text = 'Cr';
            app.Mn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mn_Button.Text = 'Mn';
            app.Fe_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fe_Button.Text = 'Fe';
            app.Co_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Co_Button.Text = 'Co';
            app.Ni_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ni_Button.Text = 'Ni';
            app.Cu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cu_Button.Text = 'Cu';
            app.Zn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Zn_Button.Text = 'Zn';
            app.Ga_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ga_Button.Text = 'Ga';
            app.Ge_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ge_Button.Text = 'Ge';
            app.As_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.As_Button.Text = 'As';
            app.Se_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Se_Button.Text = 'Se';
            app.Br_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Br_Button.Text = 'Br';
            app.Kr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Kr_Button.Text = 'Kr';
            app.Rb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rb_Button.Text = 'Rb';
            app.Sr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sr_Button.Text = 'Sr';
            app.Y_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Y_Button.Text = 'Y';
            app.Zr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Zr_Button.Text = 'Zr';
            app.Nb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Nb_Button.Text = 'Nb';
            app.Mo_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mo_Button.Text = 'Mo';
            app.Tc_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tc_Button.Text = 'Tc';
            app.Ru_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ru_Button.Text = 'Ru';
            app.Rh_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rh_Button.Text = 'Rh';
            app.Pd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pd_Button.Text = 'Pd';
            app.Ag_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ag_Button.Text = 'Ag';
            app.Cd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cd_Button.Text = 'Cd';
            app.In_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.In_Button.Text = 'In';
            app.Sn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sn_Button.Text = 'Sn';
            app.Sb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sb_Button.Text = 'Sb';
            app.Te_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Te_Button.Text = 'Te';
            app.I_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.I_Button.Text = 'I';
            app.Xe_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Xe_Button.Text = 'Xe';
            app.Cs_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cs_Button.Text = 'Cs';
            app.Ba_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ba_Button.Text = 'Ba';
            app.La_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.La_Button.Text = 'La';
            app.Hf_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Hf_Button.Text = 'Hf';
            app.Ta_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ta_Button.Text = 'Ta';
            app.W_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.W_Button.Text = 'W';
            app.Re_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Re_Button.Text = 'Re';
            app.Os_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Os_Button.Text = 'Os';
            app.Ir_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ir_Button.Text = 'Ir';
            app.Pt_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pt_Button.Text = 'Pt';
            app.Au_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Au_Button.Text = 'Au';
            app.Hg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Hg_Button.Text = 'Hg';
            app.Tl_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tl_Button.Text = 'Tl';
            app.Pb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pb_Button.Text = 'Pb';
            app.Bi_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Bi_Button.Text = 'Bi';
            app.Po_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Po_Button.Text = 'Po';
            app.At_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.At_Button.Text = 'At';
            app.Rn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rn_Button.Text = 'Rn';
            app.Fr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fr_Button.Text = 'Fr';
            app.Ra_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ra_Button.Text = 'Ra';
            app.Ac_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ac_Button.Text = 'Ac';
            app.Rf_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rf_Button.Enable = 'off';
            app.Rf_Button.Text = 'Rf';
            app.Db_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Db_Button.Enable = 'off';
            app.Db_Button.Text = 'Db';
            app.Sg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sg_Button.Enable = 'off';
            app.Sg_Button.Text = 'Sg';
            app.Bh_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Bh_Button.Enable = 'off';
            app.Bh_Button.Text = 'Bh';
            app.Hs_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Hs_Button.Enable = 'off';
            app.Hs_Button.Text = 'Hs';
            app.Mt_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mt_Button.Enable = 'off';
            app.Mt_Button.Text = 'Mt';
            app.Ds_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ds_Button.Enable = 'off';
            app.Ds_Button.Text = 'Ds';
            app.Rg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rg_Button.Enable = 'off';
            app.Rg_Button.Text = 'Rg';
            app.Cn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cn_Button.Enable = 'off';
            app.Cn_Button.Text = 'Cn';
            app.Nh_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Nh_Button.Enable = 'off';
            app.Nh_Button.Text = 'Nh';
            app.Fl_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fl_Button.Enable = 'off';
            app.Fl_Button.Text = 'Fl';
            app.Mc_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mc_Button.Enable = 'off';
            app.Mc_Button.Text = 'Mc';
            app.Lv_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Lv_Button.Enable = 'off';
            app.Lv_Button.Text = 'Lv';
            app.Ts_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ts_Button.Enable = 'off';
            app.Ts_Button.Text = 'Ts';
            app.Og_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Og_Button.Enable = 'off';
            app.Og_Button.Text = 'Og';
            app.Ce_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ce_Button.Text = 'Ce';
            app.Pr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pr_Button.Text = 'Pr';
            app.Nd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Nd_Button.Text = 'Nd';
            app.Pm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pm_Button.Text = 'Pm';
            app.Sm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sm_Button.Text = 'Sm';
            app.Eu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Eu_Button.Text = 'Eu';
            app.Gd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Gd_Button.Text = 'Gd';
            app.Tb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tb_Button.Text = 'Tb';
            app.Dy_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Dy_Button.Text = 'Dy';
            app.Ho_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ho_Button.Text = 'Ho';
            app.Er_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Er_Button.Text = 'Er';
            app.Tm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tm_Button.Text = 'Tm';
            app.Yb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Yb_Button.Text = 'Yb';
            app.Lu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Lu_Button.Text = 'Lu';
            app.Th_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Th_Button.Text = 'Th';
            app.Pa_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pa_Button.Text = 'Pa';
            app.U_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.U_Button.Text = 'U';
            app.Np_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Np_Button.Text = 'Np';
            app.Pu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pu_Button.Text = 'Pu';
            app.Am_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Am_Button.Text = 'Am';
            app.Cm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cm_Button.Text = 'Cm';
            app.Bk_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Bk_Button.Text = 'Bk';
            app.Cf_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cf_Button.Text = 'Cf';
            app.Es_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Es_Button.Enable = 'off';
            app.Es_Button.Text = 'Es';
            app.Fm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fm_Button.Enable = 'off';
            app.Fm_Button.Text = 'Fm';
            app.Md_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Md_Button.Enable = 'off';
            app.Md_Button.Text = 'Md';
            app.No_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.No_Button.Enable = 'off';
            app.No_Button.Text = 'No';
            app.Lr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Lr_Button.Enable = 'off';
            app.Lr_Button.Text = 'Lr';
            app.SF1FLabel = uilabel(app.PeriodicTableSingle_ButtonGroup);
            app.SF1FLabel.Interpreter = 'latex';
            app.SF1FLabel.Text = 'SF = σλ(1+ F(β,γ,δ))';
            app.Geometry_Panel = uipanel(app.GridLayout);
            app.Geometry_Panel.Title = 'Experimental Geometry';
            app.Geometry_GridLayout = uigridlayout(app.Geometry_Panel);
            app.Geometry_Image = uiimage(app.Geometry_GridLayout);
            app.Geometry_Image.Tooltip = {'Image of the XPS experimental geometry.'};
            app.Geometry_Image.ImageSource = 'Photoionization_Experimental_Geometry.png';
            app.Geometry_Label = uilabel(app.Geometry_GridLayout);
            app.Geometry_Label.Text = 'Diagram illustrating the angles involved in the calculation of asymmetry effects, where hv denotes the direction of photon flux, E denotes the direction of polarisation, e denotes the direction of photoemission, and θ, ϕ and ω refer to the angles for anisotropic emission of electrons.';
            app.phidegLabel_3 = uilabel(app.Geometry_GridLayout);
            app.phidegLabel_3.Text = 'theta (deg.):';
            app.theta_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.theta_EditField.Limits = [0 90];
            app.theta_EditField.ValueDisplayFormat = '%.2f';
            app.theta_EditField.ValueChangedFcn = createCallbackFcn(app, @theta_EditFieldValueChanged, true);
            app.theta_EditField.Tooltip = {'The polar angle (in degrees) between the photoelectron vector relative to electric field vector (i.e. at normal emission: LV (p-pol, E//MP) = 0, LH (s-pol, E⊥MP) = 90)'};
            app.phidegLabel = uilabel(app.Geometry_GridLayout);
            app.phidegLabel.Text = 'phi (deg.):';
            app.phi_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.phi_EditField.Limits = [0 180];
            app.phi_EditField.ValueDisplayFormat = '%.2f';
            app.phi_EditField.ValueChangedFcn = createCallbackFcn(app, @phi_EditFieldValueChanged, true);
            app.phi_EditField.Tooltip = {'The azimuthal angle (in degrees) between the photon momentum vector relative to the projection of the photoelectron vector on to the plane perpendicular to the electric field vector (i.e. normal emission = 0)'};
            app.phidegLabel_2 = uilabel(app.Geometry_GridLayout);
            app.phidegLabel_2.Text = 'omega (deg.):';
            app.omega_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.omega_EditField.Limits = [0 90];
            app.omega_EditField.ValueDisplayFormat = '%.2f';
            app.omega_EditField.ValueChangedFcn = createCallbackFcn(app, @omega_EditFieldValueChanged, true);
            app.omega_EditField.Tooltip = {'The angle (in degrees) between the monochromator-sampe-lens geometry. '};
            app.omega_EditField.Value = 90;
            app.PEditFieldLabel = uilabel(app.Geometry_GridLayout);
            app.PEditFieldLabel.Text = 'P:';
            app.P_EditField = uieditfield(app.Geometry_GridLayout, 'numeric');
            app.P_EditField.Limits = [0 1];
            app.P_EditField.ValueDisplayFormat = '%.2f';
            app.P_EditField.ValueChangedFcn = createCallbackFcn(app, @P_EditFieldValueChanged, true);
            app.P_EditField.Tooltip = {'Scalar of degree of polarization, where 1 or 0 is equivalent to full linear polarization, and 0.5 is equivalent to unpolarized light.'};
            app.P_EditField.Value = 0.5;
            app.XSectParams_Panel = uipanel(app.GridLayout);
            app.XSectParams_Panel.Title = 'Photoionization Cross-Sections & Asymmetry Parameters';
            app.XSectParams_GridLayout = uigridlayout(app.XSectParams_Panel);
            app.XSectFormalismLabel = uilabel(app.XSectParams_GridLayout);
            app.XSectFormalismLabel.Text = 'XSect Formalism:';
            app.Formalism_XSect_DropDown = uidropdown(app.XSectParams_GridLayout);
            app.Formalism_XSect_DropDown.Items = {'Cant(2022) - 1500 to 10000 eV', 'Trzhaskovskaya(2018) - 1500 to 10000 eV', 'Yeh Lindau(1985) - 10 to 1500 eV', 'Scofield(1973) - 1 to 1500 keV'};
            app.Formalism_XSect_DropDown.ValueChangedFcn = createCallbackFcn(app, @Formalism_XSect_DropDownValueChanged, true);
            app.Formalism_XSect_DropDown.Tooltip = {'Select the formalism to be used to calculate the photoionisation cross-section and asymmetry parameter. For more information, see references:'; '[1] Scofield, J H. Theoretical photoionization cross sections from 1 to 1500 keV.. United States: N. p., 1973. Web. doi:10.2172/4545040.'; '[2] J.J. Yeh, I. Lindau. ATOMIC DATA AND NUCLEAR DATA TABLES 32, l-l 55 (1985). Web. doi:10.1016/0092-640X(85)90016-6'; '[3] M.B. Trzhaskovskaya, V.G. Yarzhemsky. Atomic Data and Nuclear Data Tables 119 (2018) 99–174. Web. doi:10.1016/j.adt.2017.04.003'; '[4] David J. H. Cant, Ben F. Spencer, Wendy R. Flavell, Alexander G. Shard. Surf Interface Anal. 2022; 54(4): 442-454. doi:10.1002/sia.7059'};
            app.Formalism_XSect_DropDown.Value = 'Cant(2022) - 1500 to 10000 eV';
            app.CoreLevelDropDownLabel = uilabel(app.XSectParams_GridLayout);
            app.CoreLevelDropDownLabel.Text = 'Core-Level:';
            app.CoreLevel_DropDown = uidropdown(app.XSectParams_GridLayout);
            app.CoreLevel_DropDown.ValueChangedFcn = createCallbackFcn(app, @CoreLevel_DropDownValueChanged, true);
            app.CoreLevel_DropDown.Tooltip = {'Select a core-level.'};
            app.PhotonEnergyeVEditFieldLabel = uilabel(app.XSectParams_GridLayout);
            app.PhotonEnergyeVEditFieldLabel.Text = 'Photon Energy (eV):';
            app.PhotonEnergy_EditField = uieditfield(app.XSectParams_GridLayout, 'numeric');
            app.PhotonEnergy_EditField.Limits = [0 Inf];
            app.PhotonEnergy_EditField.ValueDisplayFormat = '%.0f';
            app.PhotonEnergy_EditField.ValueChangedFcn = createCallbackFcn(app, @PhotonEnergy_EditFieldValueChanged, true);
            app.PhotonEnergy_EditField.Tooltip = {'Photon energy in eV.'};
            app.PhotonEnergy_EditField.Value = 2000;
            app.SigmabarnatomLabel = uilabel(app.XSectParams_GridLayout);
            app.SigmabarnatomLabel.Text = 'Sigma (barn/atom):';
            app.Sigma_EditField = uieditfield(app.XSectParams_GridLayout, 'numeric');
            app.Sigma_EditField.ValueDisplayFormat = '%.2e';
            app.Sigma_EditField.Editable = 'off';
            app.Sigma_EditField.Tooltip = {'Sigma subshell photoionization cross section in units of barn/atom.'};
            app.SigmabarnatomLabel_2 = uilabel(app.XSectParams_GridLayout);
            app.SigmabarnatomLabel_2.Text = 'Beta:';
            app.Beta_EditField = uieditfield(app.XSectParams_GridLayout, 'numeric');
            app.Beta_EditField.ValueDisplayFormat = '%.2e';
            app.Beta_EditField.Editable = 'off';
            app.Beta_EditField.Tooltip = {'Beta (dipole) parameter of the photoelectron angular distribution.'};
            app.SigmabarnatomLabel_3 = uilabel(app.XSectParams_GridLayout);
            app.SigmabarnatomLabel_3.Text = 'Gamma:';
            app.Gamma_EditField = uieditfield(app.XSectParams_GridLayout, 'numeric');
            app.Gamma_EditField.ValueDisplayFormat = '%.2e';
            app.Gamma_EditField.Editable = 'off';
            app.Gamma_EditField.Tooltip = {'Gamma (non-dipole) parameter of the photoelectron angular distribution.'};
            app.SigmabarnatomLabel_4 = uilabel(app.XSectParams_GridLayout);
            app.SigmabarnatomLabel_4.Text = 'Delta:';
            app.Delta_EditField = uieditfield(app.XSectParams_GridLayout, 'numeric');
            app.Delta_EditField.ValueDisplayFormat = '%.2e';
            app.Delta_EditField.Editable = 'off';
            app.Delta_EditField.Tooltip = {'Delta (non-dipole) parameter of the photoelectron angular distribution.'};
            app.ExportSF_Panel = uipanel(app.GridLayout);
            app.ExportSF_Panel.Title = 'Sensitivity Factor';
            app.ExportSF_GridLayout = uigridlayout(app.ExportSF_Panel);
            app.SigmabarnatomLabel_6 = uilabel(app.ExportSF_GridLayout);
            app.SigmabarnatomLabel_6.Text = 'SF:';
            app.SF_EditField = uieditfield(app.ExportSF_GridLayout, 'numeric');
            app.SF_EditField.ValueDisplayFormat = '%.4e';
            app.SF_EditField.Editable = 'off';
            app.SF_EditField.Tooltip = {'Output of the Sensitivity Factor (SF)'};
            app.PhotonEnergy_Panel = uipanel(app.ExportSF_GridLayout);
            app.PhotonEnergy_Panel.Title = 'Photon Energy Dependence';
            app.ExportPhotonEnergy_GridLayout = uigridlayout(app.PhotonEnergy_Panel);
            app.MaxeVEditFieldLabel = uilabel(app.ExportPhotonEnergy_GridLayout);
            app.MaxeVEditFieldLabel.Text = 'Max (eV):';
            app.MaxEph_EditField = uieditfield(app.ExportPhotonEnergy_GridLayout, 'numeric');
            app.MaxEph_EditField.Limits = [0 1500000];
            app.MaxEph_EditField.ValueDisplayFormat = '%.0f';
            app.MaxEph_EditField.ValueChangedFcn = createCallbackFcn(app, @MaxEph_EditFieldValueChanged, true);
            app.MaxEph_EditField.Tooltip = {'Enter the maximum photon energy in units of eV'};
            app.MaxEph_EditField.Value = 10000;
            app.MineVEditFieldLabel = uilabel(app.ExportPhotonEnergy_GridLayout);
            app.MineVEditFieldLabel.Text = 'Min (eV):';
            app.MinEph_EditField = uieditfield(app.ExportPhotonEnergy_GridLayout, 'numeric');
            app.MinEph_EditField.Limits = [0 Inf];
            app.MinEph_EditField.ValueDisplayFormat = '%.0f';
            app.MinEph_EditField.ValueChangedFcn = createCallbackFcn(app, @MinEph_EditFieldValueChanged, true);
            app.MinEph_EditField.Tooltip = {'Enter the minimum photon energy in units of eV'};
            app.MinEph_EditField.Value = 1500;
            app.PlotDataPhotonEnergy_SF_Button = uibutton(app.ExportPhotonEnergy_GridLayout, 'push');
            app.PlotDataPhotonEnergy_SF_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotDataPhotonEnergy_SF_ButtonPushed, true);
            app.PlotDataPhotonEnergy_SF_Button.Tooltip = {'Plot the sensitivity factor (SF) versus photon energy'};
            app.PlotDataPhotonEnergy_SF_Button.Text = 'Plot SF Data';
            app.ExportDataPhotonEnergy_SF_Button = uibutton(app.ExportPhotonEnergy_GridLayout, 'push');
            app.ExportDataPhotonEnergy_SF_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportDataPhotonEnergy_SF_ButtonPushed, true);
            app.ExportDataPhotonEnergy_SF_Button.Tooltip = {'Export data as a text-file (.txt) of the sensitivity factor (SF) versus photon energy'};
            app.ExportDataPhotonEnergy_SF_Button.Text = 'Export SF Data (.txt)';
            app.Theta_Panel = uipanel(app.ExportSF_GridLayout);
            app.Theta_Panel.Title = 'Theta Dependence';
            app.ExportTheta_GridLayout = uigridlayout(app.Theta_Panel);
            app.MaxdegLabel = uilabel(app.ExportTheta_GridLayout);
            app.MaxdegLabel.Text = 'Max (deg.):';
            app.MaxTheta_EditField = uieditfield(app.ExportTheta_GridLayout, 'numeric');
            app.MaxTheta_EditField.Limits = [0 90];
            app.MaxTheta_EditField.ValueDisplayFormat = '%.0f';
            app.MaxTheta_EditField.ValueChangedFcn = createCallbackFcn(app, @MaxTheta_EditFieldValueChanged, true);
            app.MaxTheta_EditField.Tooltip = {'Enter the maximum emission angle in units of degrees'};
            app.MaxTheta_EditField.Value = 90;
            app.MindegLabel = uilabel(app.ExportTheta_GridLayout);
            app.MindegLabel.Text = 'Min (deg.):';
            app.MinTheta_EditField = uieditfield(app.ExportTheta_GridLayout, 'numeric');
            app.MinTheta_EditField.Limits = [0 90];
            app.MinTheta_EditField.ValueDisplayFormat = '%.0f';
            app.MinTheta_EditField.ValueChangedFcn = createCallbackFcn(app, @MinTheta_EditFieldValueChanged, true);
            app.MinTheta_EditField.Tooltip = {'Enter the minimum emission angle in units of degrees'};
            app.PlotDataTheta_SF_Button = uibutton(app.ExportTheta_GridLayout, 'push');
            app.PlotDataTheta_SF_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotDataTheta_SF_ButtonPushed, true);
            app.PlotDataTheta_SF_Button.Tooltip = {'Plot the sensitivity factor (SF) versus emission angle'};
            app.PlotDataTheta_SF_Button.Text = 'Plot SF Data';
            app.ExportDataTheta_SF_Button = uibutton(app.ExportTheta_GridLayout, 'push');
            app.ExportDataTheta_SF_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTheta_SF_ButtonPushed, true);
            app.ExportDataTheta_SF_Button.Tooltip = {'Export data as a text-file (.txt) of the sensitivity factor (SF) versus emission angle'};
            app.ExportDataTheta_SF_Button.Text = 'Export SF Data (.txt)';
            app.IMFPParams_Panel = uipanel(app.GridLayout);
            app.IMFPParams_Panel.Title = 'IMFP Parameters';
            app.IMFPParams_GridLayout = uigridlayout(app.IMFPParams_Panel);
            app.IMFPFormalismDropDownLabel = uilabel(app.IMFPParams_GridLayout);
            app.IMFPFormalismDropDownLabel.Text = 'IMFP Formalism:';
            app.FormalismIMFP_DropDown = uidropdown(app.IMFPParams_GridLayout);
            app.FormalismIMFP_DropDown.Items = {'Universal', 'Optical(NIST)', 'TPP-2M', 'TPP-2M(avg.)', 'S1', 'S2', 'S3', 'S4', 'JTP'};
            app.FormalismIMFP_DropDown.ValueChangedFcn = createCallbackFcn(app, @FormalismIMFP_DropDownValueChanged, true);
            app.FormalismIMFP_DropDown.Tooltip = {'Select the formalism to be used to calculate the inelastic mean free path (IMFP) parameter. For more information, see references:'; '[1] M. P. Seah, Quantitative electron spectroscopy of surfaces A Standard Data Base for Electron Inelastic Mean Free Paths in Solids (1979)'; '[2] S. Tanuma, Calculations of Electron Inelastic Mean Free Paths. V. Data for 14 Organic Compounds over the 50-2000 eV Range (1994)'; '[3] S. Tanuma, Calculation of electron inelastic mean free paths (IMFPs) VII. Reliability of the TPP-2M IMFP predictive equation (2003)'; '[4] S. Tanuma, Calculations of electron inelasticmean free paths. IX. Data for 41 elemental solids over the 50 eV to 30 keV range (2011)'; '[5] M. P. Seah, An accurate and simple universal curve for the energy-dependent electron inelastic mean free path (2011)'};
            app.FormalismIMFP_DropDown.Value = 'JTP';
            app.SigmabarnatomLabel_5 = uilabel(app.IMFPParams_GridLayout);
            app.SigmabarnatomLabel_5.Text = 'Electron Kinetic Energy (eV):';
            app.Ek_EditField = uieditfield(app.IMFPParams_GridLayout, 'numeric');
            app.Ek_EditField.ValueDisplayFormat = '%.0f';
            app.Ek_EditField.Editable = 'off';
            app.Ek_EditField.Tooltip = {'The electron kinetic energy in electron-Volts for the particular core-level and photon energy being used.'};
            app.CalculatedIMFPnmLabel = uilabel(app.IMFPParams_GridLayout);
            app.CalculatedIMFPnmLabel.Text = 'IMFP (nm):';
            app.IMFP_EditField = uieditfield(app.IMFPParams_GridLayout, 'numeric');
            app.IMFP_EditField.Limits = [0 Inf];
            app.IMFP_EditField.ValueDisplayFormat = '%.3f';
            app.IMFP_EditField.Editable = 'off';
            app.IMFP_EditField.Tooltip = {'The calculated IMFP value using the chosen calculator formalism in nano-meters.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_MatBase_XPSSensitivityFactor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end